classdef biolocomotionGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        loadDataTab                 matlab.ui.container.Tab
        buttonCsvSelect             matlab.ui.control.Button
        checkBCsv                   matlab.ui.control.CheckBox
        CSVFileLabel                matlab.ui.control.Label
        textCsvDataFile             matlab.ui.control.EditField
        buttonBrowse                matlab.ui.control.Button
        textWorkingDir              matlab.ui.control.EditField
        StudyDirectoryListBoxLabel  matlab.ui.control.Label
        listStudyDir                matlab.ui.control.ListBox
        Button                      matlab.ui.control.Button
        identifyNodesTab            matlab.ui.container.Tab
        CSVFileLabel_2              matlab.ui.control.Label
        textCsvDataFile_2           matlab.ui.control.EditField
    end

    
    properties (Access = private)
        p_selectedFile; % Description
    end
    

    methods (Access = private)

        % Button pushed function: buttonBrowse
        function buttonBrowsePushed(app, event)
            pathName = uigetdir('Select the log file  ');%TODO -- this should be locked after good selection

            try %TODO -- for some reason this sometimes crashes if it is on my first screen only??
                app.textWorkingDir.Value = pathName; %TODO -- should trim this to show a ../../dir
            catch
               warning("select a valid directory");
               app.textWorkingDir.Value = "Select a study directory";
            end
            
            app.listStudyDir.Items = cellstr(ls(pathName));

            
        end

        % Value changed function: listStudyDir
        function listStudyDirValueChanged(app, event)
            app.p_selectedFile = string(app.listStudyDir.Value);
        end

        % Button pushed function: buttonCsvSelect
        function buttonCsvSelectPushed(app, event)
            app.textCsvDataFile.Value = app.p_selectedFile;
            app.textCsvDataFile_2.Value = app.p_selectedFile;
            app.checkBCsv.Value = 1; %TODO -- check that this file is a csv FIle
            checkBCsvValueChanged(app, event); %lock the value trough the checkbox
        end

        % Value changed function: checkBCsv
        function checkBCsvValueChanged(app, event)
            value = app.checkBCsv.Value;
            if(value == 1)
                app.buttonCsvSelect.Enable = 'off';
            else
                app.buttonCsvSelect.Enable = 'on';
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            run scr\gui\createNodeStandard.mlapp
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.loadDataTab = uitab(app.TabGroup);
            app.loadDataTab.Title = 'loadData ';
            app.buttonCsvSelect = uibutton(app.loadDataTab, 'push');
            app.buttonCsvSelect.ButtonPushedFcn = createCallbackFcn(app, @buttonCsvSelectPushed, true);
            app.buttonCsvSelect.Text = '<<';
            app.checkBCsv = uicheckbox(app.loadDataTab);
            app.checkBCsv.ValueChangedFcn = createCallbackFcn(app, @checkBCsvValueChanged, true);
            app.checkBCsv.Text = 'Lock';
            app.CSVFileLabel = uilabel(app.loadDataTab);
            app.CSVFileLabel.Text = 'CSV File';
            app.textCsvDataFile = uieditfield(app.loadDataTab, 'text');
            app.textCsvDataFile.Editable = 'off';
            app.buttonBrowse = uibutton(app.loadDataTab, 'push');
            app.buttonBrowse.ButtonPushedFcn = createCallbackFcn(app, @buttonBrowsePushed, true);
            app.buttonBrowse.Text = 'Browse';
            app.textWorkingDir = uieditfield(app.loadDataTab, 'text');
            app.textWorkingDir.Editable = 'off';
            app.textWorkingDir.Value = 'Select a Directory';
            app.StudyDirectoryListBoxLabel = uilabel(app.loadDataTab);
            app.StudyDirectoryListBoxLabel.Text = 'Study Directory';
            app.listStudyDir = uilistbox(app.loadDataTab);
            app.listStudyDir.Items = {};
            app.listStudyDir.ValueChangedFcn = createCallbackFcn(app, @listStudyDirValueChanged, true);
            app.listStudyDir.Value = {};
            app.Button = uibutton(app.loadDataTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.identifyNodesTab = uitab(app.TabGroup);
            app.identifyNodesTab.Title = 'identifyNodes';
            app.CSVFileLabel_2 = uilabel(app.identifyNodesTab);
            app.CSVFileLabel_2.Text = 'CSV File';
            app.textCsvDataFile_2 = uieditfield(app.identifyNodesTab, 'text');
            app.textCsvDataFile_2.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = biolocomotionGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end