classdef createBlankStudyArchitectureGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ui_createBlankStudyArchitecture  matlab.ui.Figure
        tabg_group                   matlab.ui.container.TabGroup
        tab_createStudy              matlab.ui.container.Tab
        panel_createStudy            matlab.ui.container.Panel
        label_saveName               matlab.ui.control.Label
        editF_saveName               matlab.ui.control.EditField
        button_browse                matlab.ui.control.Button
        button_create                matlab.ui.control.Button
        button_thisIsAStudy          matlab.ui.control.Button
        label_workingDir             matlab.ui.control.Label
        tab_copyStudyFiles           matlab.ui.container.Tab
        panel_copyStudyFiles         matlab.ui.container.Panel
        button_downloadRenameTool    matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        tab_createTrials             matlab.ui.container.Tab
        panel_createTrials           matlab.ui.container.Panel
        button_refresh               matlab.ui.control.Button
        GenerateTrialsButton         matlab.ui.control.Button
        GlobalFilesLabel             matlab.ui.control.Label
        listB_workingDir             matlab.ui.control.ListBox
        CurrentTrailsLabel           matlab.ui.control.Label
        listB_videoFiles             matlab.ui.control.ListBox
        label_step1                  matlab.ui.control.Label
        tab_batchCopy                matlab.ui.container.Tab
        panel_batchCopy              matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        RefreshAllButton             matlab.ui.control.Button
        ResourcesandSharedDataLabel  matlab.ui.control.Label
        listB_workingDir_2           matlab.ui.control.ListBox
        BatchCopyButton              matlab.ui.control.Button
        button_prev                  matlab.ui.control.Button
        button_next                  matlab.ui.control.Button
        label_errorMsg               matlab.ui.control.Label
    end

 %TODO -- this file should create AnimationView objects and Animation view should have a class and a gui
 %TODO -- this so that any type of data can create an animation view object and be displaied on a general plot gui
 %TODO -- for now just skipping that and just plotting superimposed here
 %TODO -- ideally this will export a view object for every kind: super imposed? sidebyside? etc

%---------------------------- CONSTANT PROPERTIES ---------------------------------%    
    
    properties (Access = private, Constant = true)
        % variables that control app's visibility workflow function
        isTabChar = 'tab_';
        
        % number of initial steps, pair with startedFlag non constant property
        % change this according to the app's workflow before it unlocks the buton next
        numInitialSteps = 1;
    end
    
    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'createBlankStudyArchitectureGui';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
        
        % important/ relevant output from this app
        savedVarName = 'createBlankStudyArchitecture';
        
        % the full app, required to populate this app with a previous session
        % this contains ALL components, even those stored in savedVarName
        savedAppName = 'createBlankStudyArchitectureApp';
        
        % relevant variables to save in savedVarName
        % savedAppName saves this plus everything else too
        %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
        varsToSaveinStd =  {};
    end
    

%----------------------------- CLASS PROPERTIES ----------------------------------%
    
     properties (Access = private)
        % this variable stores the working directory
        workingDir = '';
        
        % started flag to make sure there is name and directory
        startedFlag = 0;
        
        % tab control variable, start at first tab
        tabControl = 1;
        
        % application tabs, used to move around with buttons, computed at startup
        applicationTabs;
        

    end
    
    properties (Access = public)
        
    end
    
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%
    
    methods (Access = private)
            
        function setInitialGuiParam(app)
            % fist turn all panels off
            % get names of all tabs and indexes in app.
            tnames  = fieldnames(app);
            
            % tabs are in the order that they apear in the gui in sequence
            %TODO -- if tabs have multiple children this wont work
            app.applicationTabs = tnames(contains(tnames,app.isTabChar));
            
            % disable all panels
            for(i=1: length(app.applicationTabs))
                app.(app.applicationTabs{i}).Children.Visible = 'off';
            end
            
            % the first pannel and tab controlled by class tab control
            app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
            app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            
        end%setInitialGuiParam
%-----------------------------------------------------------------------------------%

%-----------------------------------------------------------------------------------%

        function loadAppState(app, loadedFile)
            % if load previous datafile, then populate all variables
            % app-defined user properties
            % tab one, edit fields
            app.editF_nodeStandard.Value    = loadedFile.editF_nodeStandard.Value;
            app.editF_motionData.Value      = loadedFile.editF_motionData.Value;
            app.editF_cameraObject.Value    = loadedFile.editF_cameraObject.Value;
            app.listB_videoFiles.Items      = loadedFile.listB_videoFiles.Items;
            app.chkB_nodeStandardLock.Value = loadedFile.chkB_nodeStandardLock.Value;
            app.chkB_motionDataLock.Value   = loadedFile.chkB_motionDataLock.Value;
            app.chkB_cameraObjectLock.Value = loadedFile.chkB_cameraObjectLock.Value;
            % make sure checkbox enables/disables
            chkB_motionDataLockValueChanged(app);
            chkB_nodeStandardLockValueChanged(app);
            chkB_cameraObjectLockValueChanged(app);
            
            % tab two
            app.listB_videosToMap.Items     = loadedFile.listB_videosToMap.Items;
            app.table_cameraVideoMap.Data   = loadedFile.table_cameraVideoMap.Data;
            
            % other public properties
            importObjectInstances(app);
            
            % move the user to the viewer
            advanceTab(app,2);
        end%loadAppState
%-----------------------------------------------------------------------------------%

        function incStartFlag(app, cnt)
            % increase or decrease the start flag with bound checking
            % this ommits the value of cnt and only uses its sign.
            if(cnt>0 && app.startedFlag<app.numInitialSteps)
                app.startedFlag = app.startedFlag + 1;
            elseif(cnt<0 && app.startedFlag>0)
                app.startedFlag = app.startedFlag - 1;
            end
            
            % determine if the start is done and enable prev and next buttons
            if(app.startedFlag>=app.numInitialSteps)
                app.button_next.Enable = 'on';
                app.button_prev.Enable = 'on';
            else
                app.button_next.Enable = 'off';
                app.button_prev.Enable = 'off';
            end
        end%incStartFlag
%-----------------------------------------------------------------------------------%
        
        function advanceTab(app, cnt)
            % first check if cnt will request a tab out of range
            if((app.tabControl+cnt)>length(app.applicationTabs) || (app.tabControl+cnt)<1)
                dispMsg(app, 'e', 'tab change request out of range');
            else
                % clear errors if any
                dispMsg(app, 'c');
                
                % disable the current panel
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'off';
                
                % increase tab control and activate next panel and tab
                app.tabControl = app.tabControl+cnt;
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
                app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            end
        end%advanceTab
%-----------------------------------------------------------------------------------%

        function s = varsToSaveinStdFcn(app)
            % saves just the relevant variables that come out of this class not to save ui elements
            for(i = 1:length(app.varsToSaveinStd))
                s.(app.varsToSaveinStd{i}) = app.(app.varsToSaveinStd{i});
            end                
        end%varsToSaveinStdFcn
%-----------------------------------------------------------------------------------%     

        function importObjectInstances(app)
            readNodeStandardObject(app);
            readMotionDataObject(app);
            readCameraObject(app);
            readVideoObject(app);
        end%importObjectInstances
%-----------------------------------------------------------------------------------%  

        function readNodeStandardObject(app)
            %TODO -- should i need try catch here to make sure that this is a standard node
            % aquire the value of the node standard and place it in a private variable here (copy)
            tmp = fullfile(app.workingDir, app.editF_nodeStandard.Value);
            tstruct = load(tmp, createNodeStandardGui.savedVarName);
            app.nodeStd = tstruct.(createNodeStandardGui.savedVarName);
        end%readNodeStandardObject
%-----------------------------------------------------------------------------------%
    
        function readMotionDataObject(app)
            %TODO -- should i need try catch here to make sure that this is a standard motion
            %TODO -- this is kinda hardcoded, the number 1
            % aquire the value of the node standard and place it in a private variable here (copy)
            tmp = fullfile(app.workingDir, app.editF_motionData.Value);
            tstruct = load(tmp, motionCsvToNodeStdMapGui.savedVarName);
            app.motionData = tstruct.(motionCsvToNodeStdMapGui.savedVarName).(motionCsvToNodeStdMapGui.varsToSaveinStd{1});
        end%readMotionDataObject
%-----------------------------------------------------------------------------------%
    
        function readCameraObject(app)
            %TODO -- should i need try catch here to make sure that this is a standard camera
            %TODO -- this is kinda hardcoded, the number 4
            % aquire the value of the node standard and place it in a private variable here (copy)
            tmp = fullfile(app.workingDir, app.editF_cameraObject.Value);
            tstruct = load(tmp, createCameraObjGui.savedVarName);
            app.cameraObjs = tstruct.(createCameraObjGui.savedVarName).(createCameraObjGui.varsToSaveinStd{1});
        end%readCameraObject
%-----------------------------------------------------------------------------------%

        function readVideoObject(app)
            %TODO -- this is not ok, but for now
        end%readVideoObject
%-----------------------------------------------------------------------------------%

        function loadVarsFromTemplate(app, ttemplate)
            % step 2 is indifferent so run it as-is
            button_popNodeTableButtonPushed(app);
            
            % technically colum 3 is already populated from step 2, whatever.
            % copy the node assignment from the template experiment
            app.table_nodeAssign.Data = ttemplate.table_nodeAssign.Data;
            
            % compute step 4 and five
            % the user can do this technically but it should be ready so do it automatically and save time
            button_computeMapMatrixPushed(app);
            button_populateTrimNanButtonPushed(app);
        end%loadVarsFromTemplate
%-----------------------------------------------------------------------------------%

        function createDirectoryTree(app)
            % create study tree
            %TODO -- this does nto verify that is on a place that the user has write access
            app.workingDir = fullfile(app.workingDir, app.editF_saveName.Value);
            app.label_workingDir.Text = app.workingDir;
            
            mkdir(app.workingDir)
            
            %TODO -- should i be getting these names from an enaum or something?
            % generate the standard name of theirectories
            mkdir(fullfile(app.workingDir,'global'));
            mkdir(fullfile(app.workingDir,'res'));
            mkdir(fullfile(app.workingDir,'doc'));
            mkdir(fullfile(app.workingDir,'trial'));
            mkdir(fullfile(app.workingDir,'share'));
            
        end%dispMsg
%-----------------------------------------------------------------------------------%

        function dispMsg(app, ttype, tmsg)
            % For log messaging blue
            if(strcmpi(ttype, 'L'))
                app.label_errorMsg.Text = ['LOG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.45,0.74];
                
            % for error messages red
            elseif(strcmpi(ttype, 'E'))
                app.label_errorMsg.Text = ['ERROR: ' tmsg];
                app.label_errorMsg.FontColor = [0.64,0.08,0.18];
                
            % general propose message black
            elseif(strcmpi(ttype, 'M'))
                app.label_errorMsg.Text = ['MSG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.00,0.00];
            
            % for clearing the mesage
            elseif(strcmpi(ttype, 'C'))
                app.label_errorMsg.Text = '';
            end
        end%dispMsg
%-----------------------------------------------------------------------------------%
        
    end%private methods

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setInitialGuiParam(app);
        end

        % Button pushed function: button_next
        function button_nextButtonPushed(app, event)
            % move to next tab
            advanceTab(app, 1);
        end

        % Button pushed function: button_prev
        function button_prevButtonPushed(app, event)
            % move to previous tab
            advanceTab(app, -1);
        end

        % Callback function
        function button_motionDataSelectButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that ta csv is actually selected
            % if more than one file is selected, then trow error
            if(length(app.listB_workingDir.Value)>1)
                dispMsg(app, 'e', 'that field only accepts one value.');
            else
                % clear the error if any
                dispMsg(app, 'c');
                app.editF_motionData.Value = char(app.listB_workingDir.Value);
                
                %TODO -- check that this file is a motiondata File
                % lock the value trough the checkbox
                app.chkB_motionDataLock.Value = 1;
                chkB_motionDataLockValueChanged(app, event);
            end
        end

        % Callback function
        function button_nodeStandardButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that ta csv is actually selected
            % if more than one file is selected, then trow error
            if(length(app.listB_workingDir.Value)>1)
                dispMsg(app, 'e', 'that field only accepts one value.');
            else
                % clear the error if any
                dispMsg(app, 'c');
                app.editF_nodeStandard.Value = char(app.listB_workingDir.Value);
                
                %TODO -- check that this file is a nodestd File
                % lock the value trough the checkbox
                app.chkB_nodeStandardLock.Value = 1;
                chkB_nodeStandardLockValueChanged(app, event);
            end
        end

        % Callback function
        function button_videoSelectButtonPushed(app, event)
            % assign value of all current selected files to this field, append current ones
            % only take the ones taht are unique
            %TODO -- this needs to be verified that that video is actually selected
            app.listB_videoFiles.Items = unique([app.listB_videoFiles.Items app.listB_workingDir.Value]);
        end

        % Button pushed function: button_refresh
        function button_refreshButtonPushed(app, event)
            loadWorkingDirFiles(app);
        end

        % Callback function
        function button_cameraObjectButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that corret is actually selected
            % if more than one file is selected, then trow error
            if(length(app.listB_workingDir.Value)>1)
                ispMsg(app, 'e', 'that field only accepts one value.');
            else
                % clear the error if any
                dispMsg(app, 'c');
                app.editF_cameraObject.Value = char(app.listB_workingDir.Value);
                
                %TODO -- check that this file is a correct File
                % lock the value trough the checkbox
                app.chkB_cameraObjectLock.Value = 1;
                chkB_cameraObjectLockValueChanged(app, event);
            end
        end

        % Callback function
        function button_deleteVideoPushed(app, event)
            if(~isempty(app.listB_videoFiles.Value))
               app.listB_videoFiles.Items(strcmp(app.listB_videoFiles.Items', app.listB_videoFiles.Value)) =  [];
            end
        end

        % Callback function
        function button_populateTableButtonPushed(app, event)
            % first import objects from selected fields into app vars
            importObjectInstances(app);
            
            % first copy the contents of listbox one into listbox 2
            %TODO -- make this come from the vide object holder or struct
            app.listB_videosToMap.Items = app.listB_videoFiles.Items;

            % populate the cameras on the table, first make an emptu first column
            app.table_cameraVideoMap.Data = cell(length(fieldnames(app.cameraObjs)),1);
            app.table_cameraVideoMap.Data = [app.table_cameraVideoMap.Data, fieldnames(app.cameraObjs)];
        end

        % Callback function
        function button_addButtonPushed(app, event)
            % first make sure that both a video and a cell on the table are selected
            if(app.videoSelCell~=0 & isscalar(app.videoSelCell) & ~isempty(app.listB_videosToMap.Value))
                % clear error if any   
                dispMsg(app, 'c');
                
                % assign this value to table
                app.table_cameraVideoMap.Data(app.videoSelCell,1) = {app.listB_videosToMap.Value};
                
                % delete this element from availiable cameras
                app.listB_videosToMap.Items(strcmp(app.listB_videosToMap.Items', app.listB_videosToMap.Value)) =  [];
                
            else
                % trow error
                dispMsg(app, 'e', 'need to select one cell of table and one video');
            end
        end

        % Callback function
        function table_cameraVideoMapCellSelection(app, event)
            % for some reason it does not like to be directly assigned to private var
            % indices is probably adefined attribute of this function.
            indices = event.Indices(:,1);
            app.videoSelCell = unique(indices);
        end

        % Callback function
        function button_saveAllButtonPushed(app, event)
            % disable wargings from saving stuff such as uia components
            %TODO -- just dont try to save them to prevent warning
            warning('off');
            
            % make a struct of what I want to save to that they keep those variable names
            tmp.(app.savedAppName) = app;
            tmp.(app.savedVarName) = varsToSaveinStdFcn(app);
            
            % save file
            %TODO -- does this needs a try catch?
            save(fullfile(app.workingDir, [app.savedAppName '.mat']), '-struct', 'tmp');
            app.label_existingFile.Text = {['There is a ' app.savedAppName ' File in the Working Directory.'] 'Saving will overwrite it.'};
            loadWorkingDirFiles(app);
            
            % alert the user it is done
            msgbox({'All Saved!!!';'You can keep modfying or just close the window'},'Success');
            
            % turn warnings back on
            warning('on');
            %TODO -- this is a matlab bug
            warning off MATLAB:subscripting:noSubscriptsSpecified;
        end

        % Button pushed function: button_browse
        function button_browseButtonPushed(app, event)
            
            % get the new study directory
            %TODO -- i dont know if this will work in mac and linux
            [tpath] = uigetdir(getenv('HOMEPATH'),'Select directory where the Study will be created');
            
            if(tpath ~= 0)
                app.workingDir = tpath;
            
                % load the study directory
                app.label_workingDir.Text = app.workingDir;
                
                % enable all buttons
                app.button_create.Enable        = 'on';
                app.button_thisIsAStudy.Enable  = 'on';
                app.editF_saveName.Enable       = 'on';
            else
                dispMsg(app, 'That was not a valid directory');
            end
        end

        % Button pushed function: button_thisIsAStudy
        function button_thisIsAStudyButtonPushed(app, event)
            % if there are files with standard names in working dir, load them automatically
            app.editF_saveName.Value = app.workingDir;
            
            % disable all buttons
            app.button_browse.Enable        = 'off';
            app.button_create.Enable        = 'off';
            app.button_thisIsAStudy.Enable  = 'off';
            app.editF_saveName.Enable       = 'off';
        end

        % Button pushed function: button_create
        function button_createButtonPushed(app, event)
            
            % make sure it is a valid name
            if(isvarname(app.editF_saveName.Value))
                % make directory in this location with this name
                createDirectoryTree(app);             
                
                % disable all buttons
                app.button_browse.Enable        = 'off';
                app.button_create.Enable        = 'off';
                app.button_thisIsAStudy.Enable  = 'off';
                app.editF_saveName.Enable       = 'off';
                
                %enable controllers to move in tabs
                incStartFlag(app, 1);
                
                dispMsg(app, 'c');
            
            % display message if it is a worng name
            else
                dispMsg(app, 'e', 'That is not a valid name, use aA to zZ, 0 to 9, and _');
            end
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ui_createBlankStudyArchitecture = uifigure;
            app.ui_createBlankStudyArchitecture.Name = 'Motion Animation Viewer';
            app.tabg_group = uitabgroup(app.ui_createBlankStudyArchitecture);
            app.tab_createStudy = uitab(app.tabg_group);
            app.tab_createStudy.Title = 'Create Study';
            app.panel_createStudy = uipanel(app.tab_createStudy);
            app.label_saveName = uilabel(app.panel_createStudy);
            app.label_saveName.Text = 'Study Name';
            app.editF_saveName = uieditfield(app.panel_createStudy, 'text');
            app.editF_saveName.Editable = 'off';
            app.button_browse = uibutton(app.panel_createStudy, 'push');
            app.button_browse.ButtonPushedFcn = createCallbackFcn(app, @button_browseButtonPushed, true);
            app.button_browse.Text = 'Browse';
            app.button_create = uibutton(app.panel_createStudy, 'push');
            app.button_create.ButtonPushedFcn = createCallbackFcn(app, @button_createButtonPushed, true);
            app.button_create.Enable = 'off';
            app.button_create.Text = 'Create New';
            app.button_thisIsAStudy = uibutton(app.panel_createStudy, 'push');
            app.button_thisIsAStudy.ButtonPushedFcn = createCallbackFcn(app, @button_thisIsAStudyButtonPushed, true);
            app.button_thisIsAStudy.Enable = 'off';
            app.button_thisIsAStudy.Text = 'This is a Stidy';
            app.label_workingDir = uilabel(app.panel_createStudy);
            app.label_workingDir.Text = '';
            app.tab_copyStudyFiles = uitab(app.tabg_group);
            app.tab_copyStudyFiles.Title = 'Copy Study Files';
            app.panel_copyStudyFiles = uipanel(app.tab_copyStudyFiles);
            app.button_downloadRenameTool = uibutton(app.panel_copyStudyFiles, 'push');
            app.button_downloadRenameTool.Text = 'Get Excel Rename Tool';
            app.TextArea = uitextarea(app.panel_copyStudyFiles);
            app.tab_createTrials = uitab(app.tabg_group);
            app.tab_createTrials.Title = 'Create Trials';
            app.panel_createTrials = uipanel(app.tab_createTrials);
            app.button_refresh = uibutton(app.panel_createTrials, 'push');
            app.button_refresh.ButtonPushedFcn = createCallbackFcn(app, @button_refreshButtonPushed, true);
            app.button_refresh.Text = 'Refresh All';
            app.GenerateTrialsButton = uibutton(app.panel_createTrials, 'push');
            app.GenerateTrialsButton.Text = 'Generate Trials';
            app.GlobalFilesLabel = uilabel(app.panel_createTrials);
            app.GlobalFilesLabel.Text = 'Global Files';
            app.listB_workingDir = uilistbox(app.panel_createTrials);
            app.listB_workingDir.Items = {};
            app.listB_workingDir.Multiselect = 'on';
            app.listB_workingDir.Value = {};
            app.CurrentTrailsLabel = uilabel(app.panel_createTrials);
            app.CurrentTrailsLabel.Text = 'Current Trails';
            app.listB_videoFiles = uilistbox(app.panel_createTrials);
            app.listB_videoFiles.Items = {};
            app.listB_videoFiles.Multiselect = 'on';
            app.listB_videoFiles.Value = {};
            app.label_step1 = uilabel(app.panel_createTrials);
            app.label_step1.Text = '(1)';
            app.tab_batchCopy = uitab(app.tabg_group);
            app.tab_batchCopy.Title = 'Batch Copy';
            app.panel_batchCopy = uipanel(app.tab_batchCopy);
            app.UITable = uitable(app.panel_batchCopy);
            app.UITable.ColumnName = {'Trials'};
            app.UITable.RowName = {};
            app.RefreshAllButton = uibutton(app.panel_batchCopy, 'push');
            app.RefreshAllButton.Text = 'Refresh All';
            app.ResourcesandSharedDataLabel = uilabel(app.panel_batchCopy);
            app.ResourcesandSharedDataLabel.Text = 'Resources and Shared Data';
            app.listB_workingDir_2 = uilistbox(app.panel_batchCopy);
            app.listB_workingDir_2.Items = {};
            app.listB_workingDir_2.Multiselect = 'on';
            app.listB_workingDir_2.Value = {};
            app.BatchCopyButton = uibutton(app.panel_batchCopy, 'push');
            app.BatchCopyButton.Text = 'Batch Copy >>';
            app.button_prev = uibutton(app.ui_createBlankStudyArchitecture, 'push');
            app.button_prev.ButtonPushedFcn = createCallbackFcn(app, @button_prevButtonPushed, true);
            app.button_prev.Enable = 'off';
            app.button_prev.Text = '<<Prev';
            app.button_next = uibutton(app.ui_createBlankStudyArchitecture, 'push');
            app.button_next.ButtonPushedFcn = createCallbackFcn(app, @button_nextButtonPushed, true);
            app.button_next.Enable = 'off';
            app.button_next.Text = 'Next>>';
            app.label_errorMsg = uilabel(app.ui_createBlankStudyArchitecture);
            app.label_errorMsg.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = createBlankStudyArchitectureGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ui_createBlankStudyArchitecture)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ui_createBlankStudyArchitecture)
        end
    end
end