classdef createNodeStandard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ui_createNodeStandard          matlab.ui.Figure
        tab_group                      matlab.ui.container.TabGroup
        tab_start                      matlab.ui.container.Tab
        panel_start                    matlab.ui.container.Panel
        label_targetDir                matlab.ui.control.Label
        button_create                  matlab.ui.control.Button
        button_changeDir               matlab.ui.control.Button
        editF_saveAsLabel              matlab.ui.control.Label
        editF_saveAs                   matlab.ui.control.EditField
        button_loadFromFile            matlab.ui.control.Button
        label_loadFromFile             matlab.ui.control.Label
        tab_nodes                      matlab.ui.container.Tab
        panel_nodes                    matlab.ui.container.Panel
        table_recordedNodes            matlab.ui.control.Table
        button_make                    matlab.ui.control.Button
        button_deleteSelection         matlab.ui.control.Button
        button_uploadImage             matlab.ui.control.Button
        uia_image                      matlab.ui.control.UIAxes
        button_openImage               matlab.ui.control.Button
        button_addRow                  matlab.ui.control.Button
        label_tableExplanation         matlab.ui.control.Label
        textA_nodeListLabel            matlab.ui.control.Label
        textA_nodeList                 matlab.ui.control.TextArea
        tab_nodeGraph                  matlab.ui.container.Tab
        panel_nodeGraphMap             matlab.ui.container.Panel
        table_adjacencyMatrix          matlab.ui.control.Table
        button_makeAdjacencyMatrix     matlab.ui.control.Button
        spinner_resizeColWidthsLabel   matlab.ui.control.Label
        spinner_resizeColWidths        matlab.ui.control.Spinner
        button_makeGraph               matlab.ui.control.Button
        button_openGraphvsImage        matlab.ui.control.Button
        tab_nameEdges                  matlab.ui.container.Tab
        panel_nameEdges                matlab.ui.container.Panel
        table_edgeNames                matlab.ui.control.Table
        table_nodeTypes                matlab.ui.control.Table
        button_populateEdgeNodeTables  matlab.ui.control.Button
        button_doneFillEmptySpaces     matlab.ui.control.Button
        tab_MakeBodyKinematics         matlab.ui.container.Tab
        panel_makeBodyKinematics       matlab.ui.container.Panel
        button_createBodyKinematics    matlab.ui.control.Button
        button_saveAndFinish           matlab.ui.control.Button
        button_prev                    matlab.ui.control.Button
        button_next                    matlab.ui.control.Button
        label_errorMsg                 matlab.ui.control.Label
    end


    properties (Access = private, Constant = true)
        % used to append characters from user entry prior to storage in Node table
        emptyRow = {'' false false}
        rightChar   =  'R';
        leftChar    =  'L';
        
        % variables that control app's visibility workflow function
        isPanelChar = 'panel_';
        initPanelChar = 'panel_start';
        
        % number of initial steps, pair with startedFlag non constant property
        % change this according to the app's workflow before it unlocks the buton next
        numInitialSteps = 2;
        
    end
    
    properties (Access = private)
        % root drectory for Node Standards
        %TODO -- Change this to Desktop
        workingDir = '';
        
        % used to transfer values from user entry to Node table
        % a value of 0 is no cells selected
        selectedTableNodes = 0;
        
        % used before the creation of the graph to hold node values
        nodes;
        
        % tab control
        tc = 1;
        
        % started flag to make sure there is name and directory
        startedFlag = 0;

    end
    
    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'createNodeStandard';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
    end
    
    properties (Access = public)
        % conectivty graph, cant name it graph because it is reserved
        conGraph;
        graphPlot;
        adjMatrix;
        
        % store values for Nodes and their coordinate expansions
        stdName;        % name of this standard
        image;          % reference image for standard
        
        % kinematic body
        body;

    end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%

    methods (Access = private)
        
        function loadAppState(app)
            %TODO -- error checking here try catch?
            %TODO -- supress warnings when loading file for fig?
            [tfile, tpath] = uigetfile('*.mat');
            loadedFile = load(fullfile(tpath, tfile));
            
            if(~strcmp(class(loadedFile.app), app.appName))
                app.label_errorMsg = 'ERROR: not the right kind of file';
            
            else    
                % if load previous datafile, then populate all variables, there seems not to be a better way to do this
                
                % app-defined user properties
                %app.button_addRow                 = loadedFile.app.button_addRow;
                %app.button_changeDir              = loadedFile.app.button_changeDir;
                %    app.button_changeDir.Enable       = loadedFile.app.button_changeDir.Enable;
                %app.button_create                 = loadedFile.app.button_create;
                %    app.button_create.Enable          = loadedFile.app.button_create.Enable;
                %app.button_createBodyKinematics   = loadedFile.app.button_createBodyKinematics;
                %app.button_deleteSelection        = loadedFile.app.button_deleteSelection;
                %app.button_doneFillEmptySpaces    = loadedFile.app.button_doneFillEmptySpaces;
                %app.button_loadFromFile           = loadedFile.app.button_loadFromFile;
                %app.button_make                   = loadedFile.app.button_make;
                %app.button_makeAdjacencyMatrix    = loadedFile.app.button_makeAdjacencyMatrix;
                %app.button_makeGraph              = loadedFile.app.button_makeGraph;
                %app.button_next                   = loadedFile.app.button_next;
                %app.button_openGraphvsImage       = loadedFile.app.button_openGraphvsImage;
                %app.button_openImage              = loadedFile.app.button_openImage;
                    app.button_openImage.Enable       = loadedFile.app.button_openImage.Enable;
                %app.button_populateEdgeNodeTables = loadedFile.app.button_populateEdgeNodeTables;
                %app.button_prev                   = loadedFile.app.button_prev;
                %app.button_saveAndFinish          = loadedFile.app.button_saveAndFinish;
                    app.button_saveAndFinish.Enable   = loadedFile.app.button_saveAndFinish.Enable;
                %app.button_uploadImage            = loadedFile.app.button_uploadImage;
                %app.editF_saveAs                  = loadedFile.app.editF_saveAs;
                %    app.editF_saveAs.Value            = loadedFile.app.editF_saveAs.Value;
                %    app.editF_saveAs.Enable           = loadedFile.app.editF_saveAs.Enable;
                %app.label_errorMsg                = loadedFile.app.label_errorMsg;
                    app.label_errorMsg.Text           = loadedFile.app.label_errorMsg.Text;
                %app.label_loadFromFile            = loadedFile.app.label_loadFromFile;
                %app.label_tableExplanation        = loadedFile.app.label_tableExplanation;
                %app.label_targetDir               = loadedFile.app.label_targetDir;
                %    app.label_targetDir.Text          = loadedFile.app.label_targetDir.Text;
                %app.textA_nodeListLabel           = loadedFile.app.textA_nodeListLabel;
                %app.panel_makeBodyKinematics      = loadedFile.app.panel_makeBodyKinematics;
                %app.panel_nameEdges               = loadedFile.app.panel_nameEdges;
                %app.panel_nodeGraphMap            = loadedFile.app.panel_nodeGraphMap;
                %app.panel_nodes                   = loadedFile.app.panel_nodes;
                %app.panel_start                   = loadedFile.app.panel_start;
                %app.spinner_resizeColWidthsLabel  = loadedFile.app.spinner_resizeColWidthsLabel;
                %app.editF_saveAsLabel             = loadedFile.app.editF_saveAsLabel;
                %app.spinner_resizeColWidths       = loadedFile.app.spinner_resizeColWidths;
                    app.spinner_resizeColWidths.Value = loadedFile.app.spinner_resizeColWidths.Value;
                %app.tab_MakeBodyKinematics        = loadedFile.app.tab_MakeBodyKinematics;
                %app.tab_nameEdges                 = loadedFile.app.tab_nameEdges;
                %app.tab_nodeGraph                 = loadedFile.app.tab_nodeGraph;
                %app.tab_nodes                     = loadedFile.app.tab_nodes;
                %app.tab_start                     = loadedFile.app.tab_start;
                %app.tab_group                      = loadedFile.app.tab_group;
                %app.table_adjacencyMatrix         = loadedFile.app.table_adjacencyMatrix;
                    app.table_adjacencyMatrix.ColumnName     = loadedFile.app.table_adjacencyMatrix.ColumnName;
                    app.table_adjacencyMatrix.RowName        = loadedFile.app.table_adjacencyMatrix.RowName;
                    app.table_adjacencyMatrix.ColumnEditable = loadedFile.app.table_adjacencyMatrix.ColumnEditable;
                    app.table_adjacencyMatrix.ColumnFormat   = loadedFile.app.table_adjacencyMatrix.ColumnFormat;
                    app.table_adjacencyMatrix.ColumnWidth    = loadedFile.app.table_adjacencyMatrix.ColumnWidth;
                    app.table_adjacencyMatrix.Data           = loadedFile.app.table_adjacencyMatrix.Data;
                %app.table_edgeNames               = loadedFile.app.table_edgeNames;
                    app.table_edgeNames.Data          = loadedFile.app.table_edgeNames.Data;
                %app.table_nodeTypes               = loadedFile.app.table_nodeTypes;
                    app.table_nodeTypes.ColumnFormat  = loadedFile.app.table_nodeTypes.ColumnFormat;
                    app.table_nodeTypes.Data          = loadedFile.app.table_nodeTypes.Data;
                %app.table_recordedNodes           = loadedFile.app.table_recordedNodes;
                    app.table_recordedNodes.Data      = loadedFile.app.table_recordedNodes.Data;
                %app.textA_nodeList                = loadedFile.app.textA_nodeList;
                    app.textA_nodeList.Value          = loadedFile.app.textA_nodeList.Value;
                %app.uia_image                     = loadedFile.app.uia_image;
                
                % user-defined public properties
                app.conGraph  = loadedFile.app.conGraph;
                app.graphPlot = loadedFile.app.graphPlot;
                app.adjMatrix = loadedFile.app.adjMatrix;
                app.stdName   = loadedFile.app.stdName;
                app.image     = loadedFile.app.image;
                app.body      = loadedFile.app.body;
    
                % load other stuff
                imshow(app.image, 'parent', app.uia_image);
                
            end%if file is correct
            
            % delete loaded instance, but first display the name of the standard selected
            app.label_loadFromFile.Text = fullfile(tpath, tfile);
            clear('loadedFile', 'tfile', 'tpath');
            
        end%load file function
    
    
        function v = incStartFlag(app, cnt)
            % increase or decrease the start flag with bound checking
            % this ommits the value of cnt and only uses its sign.
            if(cnt>0 && app.startedFlag<app.numInitialSteps)
                app.startedFlag = app.startedFlag + 1;
            elseif(cnt<0 && app.startedFlag>0)
                app.startedFlag = app.startedFlag - 1;
            end
            
            % determine if the start is done.
            if(app.startedFlag>=app.numInitialSteps)
                v = true;
            else
                v = false;
            end
        end%increase start flag
    
    
    end%private methods
       

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % start array with one empty line
            %TODO -- this does not belong here
            button_addRowButtonPushed(app);
            
            %TODO -- is pwd always at biolocomotion when starting?
            %TODO -- what about if the app is launched from an exe
            %TODO -- is prob best if it saves in Host's home, for now save in ./res
            app.label_targetDir.Text = app.workingDir;
            
            %TODO -- a little hardcoded?
            % fist turn all tabs off
            % get names of all panels and indexes in app. computing here is slower but app may change?
            tnames  = fieldnames(app);
            tpanels = find(contains(tnames,app.isPanelChar));
            
            for(i=1: length(tpanels))
                app.(tnames{tpanels(i)}).Visible = 'off';
            end
            
            % the first pannel and tab controlled by class constants
            tpanel = find(contains(tnames,app.initPanelChar));
            app.(tnames{tpanel}).Visible = 'on';
            
            % activate appropiate tab. at init = 1
            app.tab_group.SelectedTab = app.tab_group.Children(app.tc);
            
            % set a default value to root Directory
            %TODO -- ideally this should be in the users HOME, but seems not to be easy
            app.label_targetDir.Text = app.workingDir;
           
        end

        % Button pushed function: button_make
        function button_makeButtonPushed(app, event)
            %TODO -- i am not trimming witespaces
            
            % first delete empty cells
            tmp = strcmp(app.table_recordedNodes.Data(:,1), '');
            app.table_recordedNodes.Data = app.table_recordedNodes.Data(~tmp,:);
            
            % check that matrix is not empty
            if(isempty(app.table_recordedNodes.Data))
                % if it is empty then insert one space
                button_addRowButtonPushed(app);
                app.label_errorMsg.Text = 'ERROR: Marix is empty.';
                
            % then make sure all items are unique
            % if the are not unique, display error
            elseif(size(app.table_recordedNodes.Data(:,1),1) ~= size(unique(app.table_recordedNodes.Data(:,1)),1))
                app.label_errorMsg.Text = 'ERROR: values are not unique!';
            
            % if they are unique then proceed
            else
                % if there was an error, clear it
                app.label_errorMsg.Text = '';
                
                % assign values
                tnodes = app.table_recordedNodes.Data(:,1);
                tright = logical(cell2mat(app.table_recordedNodes.Data(:,2)));
                tleft  = logical(cell2mat(app.table_recordedNodes.Data(:,3)));
                
                app.nodes = {};
                
                % first create the nodes, if R or L is selcted then create those
                % if neither R nor L is selected, then copy the vaue as-is
                for(i = 1: size(tnodes,1))
                    if(tright(i))
                        tmp = [char(tnodes(i)) app.rightChar];
                        app.nodes = [app.nodes; tmp];
                    end
                    if(tleft(i))
                        tmp = [char(tnodes(i)) app.leftChar];
                        app.nodes = [app.nodes; tmp];
                    end
                    if((~tright(i)) && (~tleft(i)))
                        tmp = char(tnodes(i));
                        app.nodes = [app.nodes; tmp];
                    end       
                end%for RL
                
                % display the results in the text area
                app.textA_nodeList.Value = app.nodes;

            end%if values are unique
                

        end

        % Button pushed function: button_deleteSelection
        function button_deleteSelectionPushed(app, event)
            % cherry-pick values from table and remove them
            % user needs to make a selection first, then press
            % if nothing is selected then selected nodes is zero, that is not a valid index;
            % also check if matrix is empty already
            % cells selected array comes from an ISR table_recordedNodesCellSelection()

            if(~isempty(app.selectedTableNodes(:,1)) && app.selectedTableNodes ~= 0)
                app.label_errorMsg.Text = '';
                app.table_recordedNodes.Data(app.selectedTableNodes(:,1),:) = [];
                app.selectedTableNodes = 0;
            else
                app.label_errorMsg.Text = 'ERROR: Select at least one cell';
            end
        end

        % Cell selection callback: table_recordedNodes
        function table_recordedNodesCellSelection(app, event)
           % for some reason it does not like to be directly assigned to private var
           % indices is probably adefined attribute of this function.
            indices = event.Indices(:,1);
           app.selectedTableNodes = indices;
        end

        % Button pushed function: button_uploadImage
        function button_uploadImageButtonPushed(app, event)
            %TODO -- needs to be wraped in try-catch
            %TODO -- make sure file selected is less than 500kb
            %TODO -- this may be different for unix systems
            %s = dir([path file]);
            %if (s.bytes < 500000)
            
            [file,path] = uigetfile('*png', 'Select a PNG image (500kB max)');
            app.button_openImage.Enable = 'on';
            
            % save image for standard
            app.image = imread(fullfile(path, file));
            
            %display image on app
            imshow(app.image, 'parent', app.uia_image);
            
        end

        % Button pushed function: button_create
        function button_createPushed(app, event)
            %TODO -- format input text to make sure it follows rules
            
            if(isempty(app.workingDir))
                app.label_errorMsg.Text = 'A propper directory must be selected first!';
            elseif(isempty(app.editF_saveAs.Value))
                app.label_errorMsg.Text = 'Name cannot be Empty!';
            elseif(~isempty(dir([app.workingDir '\' app.editF_saveAs.Value '.mat'])))
                app.label_errorMsg.Text = 'There is a file with that name here already';
            else
                % disable and lock fields
                app.label_errorMsg.Text    = '';
                app.editF_saveAs.Enable     = 'off';
                app.button_create.Enable    = 'off';
                app.button_changeDir.Enable = 'off';
                
                % name the app
                app.stdName = app.editF_saveAs.Value;
                
                % add one to the start flag and disable button for load file
                incStartFlag(app, 1);
                app.button_loadFromFile.Enable = 'off';
            end
            
        end

        % Button pushed function: button_changeDir
        function button_changeDirPushed(app, event)
            %TODO -- this should default to the users desktop
            %TODO -- for some reason this sometimes crashes if it is on my first screen only??
            try
                app.workingDir = uigetdir('Select annother target directory');
                app.label_targetDir.Text = app.workingDir;
                
                % add one to the start flag and disable button for load file
                incStartFlag(app, 1);
                app.button_loadFromFile.Enable = 'off';
            catch
                msgbox('You must select a directory!', 'Chaos!', 'warn');
            end
            
        end

        % Button pushed function: button_openImage
        function button_openImageButtonPushed(app, event)
            % the button is enabled by loading an image, then display this to zoom
            imshow(app.image);
        end

        % Button pushed function: button_addRow
        function button_addRowButtonPushed(app, event)
            app.table_recordedNodes.Data = [app.table_recordedNodes.Data; app.emptyRow]; 
        end

        % Button pushed function: button_makeAdjacencyMatrix
        function button_makeAdjacencyMatrixButtonPushed(app, event)
            % pupulate the table with Node Standard Names with empty values
            % first get the values to name the columns and rows which equals app.nodes
            %TODO -- if data table is empty this might crash
            %TODO -- only OR current matrix if there is data already, create other button to delete
            %TODO -- this kinda sucks to use but i guess ok for now.
            
            % initialize the table with empty values
            %TODO -- how do I mask the lower part of this matrix?
            app.table_adjacencyMatrix.Data = false(size(app.nodes,1));
            
            % allow user to modify table
            app.table_adjacencyMatrix.ColumnEditable = true;
            
            % make table a checkbox one for every column which = size(nodes)
            % TODO -- this format is applying it to the whole table
            tmpcell = cell(1, size(app.nodes,1));
            tmpcell(1:end) = {'logical'};
            app.table_adjacencyMatrix.ColumnFormat = tmpcell;
            
            % format width with the initial value of spinner
            % future rezising will happen on the spinner_..ValueChanged callback
            tcolsize = cell(1, size(app.nodes,1));
            tcolsize(1:end) = num2cell(app.spinner_resizeColWidths.Value);
            app.table_adjacencyMatrix.ColumnWidth = tcolsize;
            
            % include names of columns and rows
            app.table_adjacencyMatrix.ColumnName = app.nodes;
            app.table_adjacencyMatrix.RowName    = app.nodes;

        end

        % Value changed function: spinner_resizeColWidths
        function spinner_resizeColWidthsValueChanged(app, event)
            value = app.spinner_resizeColWidths.Value;
            
            % format width as value from spinner field
            % this applies to all columns in matrix
            tmp = cell(1, size(app.nodes,1));
            tmp(1:end) = num2cell(value);
            app.table_adjacencyMatrix.ColumnWidth = tmp;
            
        end

        % Button pushed function: button_makeGraph
        function button_makeGraphButtonPushed(app, event)
            % make a connectiviy graph from adj matrix
            %TODO -- if matrix is empty it may crash
            
            % first clear diagonal and lower half
            %TODO -- is it easier to use the lower triangle because of scrolling?
            %TODO -- display a message that this will happen and put instructions in text too
            tmp = logical(triu(ones(size(app.nodes,1)))-eye(size(app.nodes,1)));
            app.table_adjacencyMatrix.Data = tmp&app.table_adjacencyMatrix.Data;
            
            % make a full adj matrix (symetric) by the following
            app.adjMatrix = app.table_adjacencyMatrix.Data | transpose(app.table_adjacencyMatrix.Data);
            
            % create the first viewing of the conectivity matrix using the graph set
            app.conGraph = graph(app.adjMatrix, app.nodes);
            
            % show on image, plot externally, for some reason uiaxes as of today does not import annotations
            plot(app.conGraph, 'NodeLabel',app.conGraph.Nodes.Name);
            
        end

        % Button pushed function: button_populateEdgeNodeTables
        function button_populateEdgeNodeTablesButtonPushed(app, event)
                       
            % populate the table of edges.
            app.table_edgeNames.Data = app.conGraph.Edges.EndNodes(:,1);
            app.table_edgeNames.Data = [app.table_edgeNames.Data app.conGraph.Edges.EndNodes(:,2)];
            tmpcell = cell(size(app.table_edgeNames.Data,1),1);
            tmpcell(1:end) = {''};
            app.table_edgeNames.Data = [app.table_edgeNames.Data tmpcell];
            
            % populate the table of node types
            app.table_nodeTypes.Data = app.conGraph.Nodes.Name;
            % second colum is a drop down for type of node
            tmpcell = cell(size(app.table_nodeTypes.Data,1),1);
            tmpcell(1:end) = {'type:'};
            app.table_nodeTypes.Data = [app.table_nodeTypes.Data tmpcell];
            % thid column is logical to find who is the origin
            tmpcell = cell(size(app.table_nodeTypes.Data,1),1);
            tmpcell(1:end) = {false};
            app.table_nodeTypes.Data = [app.table_nodeTypes.Data tmpcell];
            
            % make table an option one
            %TODO -- is this ok?
            app.table_nodeTypes.ColumnFormat = {[] fieldnames(bioJoint.jointTypes)' 'logical'};
        end

        % Button pushed function: button_doneFillEmptySpaces
        function button_doneFillEmptySpacesPushed(app, event)
            
            %TODO -- all tables Data could be referenced by column name too! but with a struct
            %TODO -- all names must be unique
            %TODO -- only one origin should be permitted and you must enter origin
            if(any(strcmp(app.table_nodeTypes.Data(:,2),'type:')))
                % then send a warning that all should be declared
                msgbox('All items in Node Table should be declared', 'Chaos!', 'warn');
                
            else
                % assign a default edge name to the ones not filled
                % a default of node1_node2
                tmp = strcmp(app.table_edgeNames.Data(:,3), '');
                app.table_edgeNames.Data(tmp,3) = strcat(app.table_edgeNames.Data(tmp,1), '_', app.table_edgeNames.Data(tmp,2));
                % Then upadate the edges table variable with all this
                app.conGraph.Edges.Name = app.table_edgeNames.Data(:,3);
                
                % append to the variable nodes the names types and the origin
                app.conGraph.Nodes.Type = app.table_nodeTypes.Data(:,2);
                app.conGraph.Nodes.Origin = logical(cell2mat(app.table_nodeTypes.Data(:,3)));
            end
            
        end

        % Button pushed function: button_createBodyKinematics
        function button_createBodyKinematicsButtonPushed(app, event)
            % plot the new and complete conectivity graph and save it to a fig variable
            figure;
            app.graphPlot = plot(app.conGraph,'NodeLabel',app.conGraph.Nodes.Name, 'EdgeLabel',app.conGraph.Edges.Name);
            
            % aquire the nodes, joints, and origin to be highlighted.
            tmp = strcmp(app.conGraph.Nodes.Type, 'node');
            tnodes = app.conGraph.Nodes.Name(tmp);
            tmp = strcmp(app.conGraph.Nodes.Type, 'joint');
            tjoints = app.conGraph.Nodes.Name(tmp);
            tmp = app.conGraph.Nodes.Origin;
            torigin = app.conGraph.Nodes.Name(tmp);
            toriginType = app.conGraph.Nodes.Type(tmp);
            
            % show nodes in red, joints in blue, edges that connect node-node in red, the rest black
            highlight(app.graphPlot,tnodes,'NodeColor','r');
            highlight(app.graphPlot,tjoints,'NodeColor','b');
            highlight(app.graphPlot,'Edges',true(size(app.conGraph.Edges,1),1),'EdgeColor','k');
            % make the origin bigger, then green
            highlight(app.graphPlot,torigin);
            highlight(app.graphPlot,torigin,'NodeColor','green');
            
            % variables to make links later
            tnodeEdges = false(length(app.conGraph.Edges.EndNodes),1);
            toriginEdges = false(length(app.conGraph.Edges.EndNodes),1);
            
            % edges that connect node-to-node are driven
            tfrom = false(length(app.conGraph.Edges.EndNodes),1);
            tto = false(length(app.conGraph.Edges.EndNodes),1);
            
            for i =1:length(tnodes)
                tto = strcmp(app.conGraph.Edges.EndNodes(:,2),tnodes(i))|tto;
                tfrom = strcmp(app.conGraph.Edges.EndNodes(:,1),tnodes(i))|tfrom;
            end
            tnodeEdges = tto&tfrom;
            highlight(app.graphPlot,'Edges',tnodeEdges,'EdgeColor','r');
            
            % if origin is a node, then all edges that connect to and from it are driven
            if(strcmp(app.conGraph.Nodes.Type(app.conGraph.Nodes.Origin), 'node'))
                toriginEdges = strcmp(app.conGraph.Edges.EndNodes(:,1),app.conGraph.Nodes.Name(app.conGraph.Nodes.Origin)) | ...
                               strcmp(app.conGraph.Edges.EndNodes(:,2),app.conGraph.Nodes.Name(app.conGraph.Nodes.Origin));
                highlight(app.graphPlot,'Edges',toriginEdges,'EdgeColor','c');
            end
            
            %TODO -- make sure that I save this as a fig and that it preserves the highlight commands
            %TODO -- add weight all == 1 to lines and plot with weights proportionally
            %----------------------------------------------------------------------------------------%
            % Create body kinematics.
            %TODO -- there is a correct way to transform from joint to joint, that is, not passing trough nodes
            %TODO -- make a graph of just the joints and make sure that all are connected and there are no orphans
            %TODO -- retrieve with biconncomp(G)?to nsure that nodes in joint-node-joint config are one link and not 2
            %TODO -- how to interpret links that are ligaments or non actuated joitns,, almost soft-joints?
            
            app.body = bioLinkage(app.stdName, bioJoint(char(torigin),char(toriginType)));
            
            for(i =1:length(app.conGraph.Nodes.Name))
               app.body.addJoints(bioJoint(app.conGraph.Nodes.Name{1},app.conGraph.Nodes.Type{1}));
            end
            
            for(i =1:length(app.conGraph.Edges.Name))
               app.body.addLinks(bioLink(app.conGraph.Edges.Name{1}));
            end
            
            app.body.adjMatrix = app.adjMatrix;
            app.body.graph = app.conGraph;
            app.body.standard = app.stdName;
            
            % activate the next button to save all
            app.button_saveAndFinish.Enable = 'on';
            
            
        end

        % Button pushed function: button_next
        function button_nextButtonPushed(app, event)
            % make sure user cant proceed unless name and directory are changed.
            if(~incStartFlag(app, 0))
                app.label_errorMsg.Text = 'ERROR: This section must be completed.';
            else
                % fist turn all tabs off
                % get names of all panels and indexes in app. computing here is slower but app may change?
                tnames  = fieldnames(app);
                tpanels = find(contains(tnames,app.isPanelChar));
                
                for(i=1: length(tpanels))
                    app.(tnames{tpanels(i)}).Visible = 'off';
                end
                
                % increment tab control if no bigger than size of tab
                if(app.tc < size(app.tab_group.Children,1))
                    app.tc = app.tc+1;
                end
                
                app.(tnames{tpanels(app.tc)}).Visible = 'on';
                
                % activate appropiate tab
                app.tab_group.SelectedTab = app.tab_group.Children(app.tc);
            end
            
        end

        % Button pushed function: button_prev
        function button_prevButtonPushed(app, event)
            % fist turn all tabs off
            % get names of all panels and indexes in app. computing here is slower but app may change?
            tnames  = fieldnames(app);
            tpanels = find(contains(tnames,app.isPanelChar));
            
            for(i=1: length(tpanels))
                app.(tnames{tpanels(i)}).Visible = 'off';
            end
            
            % change tab control if no smaller than one
            if(app.tc > 1)
                app.tc = app.tc-1;
            end
            
            app.(tnames{tpanels(app.tc)}).Visible = 'on';
            
            % activate appropiate tab
            app.tab_group.SelectedTab = app.tab_group.Children(app.tc);
        end

        % Button pushed function: button_saveAndFinish
        function button_saveAndFinishButtonPushed(app, event)
            %TODO -- trim image name so it does not have spaces
            %TODO -- format input text to make sure it follows rules
            %TODO -- save the app only, not the event.
            %TODO -- the name nodeStd is hardcoded for now.
            nodeStd = app;
            save(fullfile(app.workingDir, [app.editF_saveAs.Value '.mat']), 'nodeStd');
            msgbox({'All Saved!!!';'You can keep modfying or just close the window'},'Success');

        end

        % Button pushed function: button_openGraphvsImage
        function button_openGraphvsImageButtonPushed(app, event)
            %TODO -- subplot should have both images near each other
            % create figure in expternal window
            figure();
            subplot(1,2,1), imshow(app.image);
            subplot(1,2,2), plot(app.conGraph);
        end

        % Button pushed function: button_loadFromFile
        function button_loadFromFileButtonPushed(app, event)
            loadAppState(app);
            app.button_loadFromFile.Enable = 'off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ui_createNodeStandard = uifigure;
            app.ui_createNodeStandard.Name = 'Create Node Standard';
            app.tab_group = uitabgroup(app.ui_createNodeStandard);
            app.tab_start = uitab(app.tab_group);
            app.tab_start.Title = 'Start';
            app.panel_start = uipanel(app.tab_start);
            app.label_targetDir = uilabel(app.panel_start);
            app.label_targetDir.Tooltip = {'No spaces'; ' no symbols'; ' no underscores no extensions.'; 'Just use camelCase. There is no error checking.'};
            app.label_targetDir.Text = '';
            app.button_create = uibutton(app.panel_start, 'push');
            app.button_create.ButtonPushedFcn = createCallbackFcn(app, @button_createPushed, true);
            app.button_create.Text = 'Create (2)';
            app.button_changeDir = uibutton(app.panel_start, 'push');
            app.button_changeDir.ButtonPushedFcn = createCallbackFcn(app, @button_changeDirPushed, true);
            app.button_changeDir.Text = 'Change Directory (1)';
            app.editF_saveAsLabel = uilabel(app.panel_start);
            app.editF_saveAsLabel.Text = 'Save As:';
            app.editF_saveAs = uieditfield(app.panel_start, 'text');
            app.editF_saveAs.Tooltip = {'No spaces no symbols no underscores no extensions.'; 'Just use camelCase. There is no error checking.'};
            app.button_loadFromFile = uibutton(app.panel_start, 'push');
            app.button_loadFromFile.ButtonPushedFcn = createCallbackFcn(app, @button_loadFromFileButtonPushed, true);
            app.button_loadFromFile.Text = 'Load From File (0)';
            app.label_loadFromFile = uilabel(app.panel_start);
            app.label_loadFromFile.Text = '';
            app.tab_nodes = uitab(app.tab_group);
            app.tab_nodes.Title = 'Node Creation and Definition';
            app.panel_nodes = uipanel(app.tab_nodes);
            app.table_recordedNodes = uitable(app.panel_nodes);
            app.table_recordedNodes.ColumnName = {'Nodes'; 'R'; 'L'};
            app.table_recordedNodes.RowName = {};
            app.table_recordedNodes.ColumnEditable = true;
            app.table_recordedNodes.CellSelectionCallback = createCallbackFcn(app, @table_recordedNodesCellSelection, true);
            app.button_make = uibutton(app.panel_nodes, 'push');
            app.button_make.ButtonPushedFcn = createCallbackFcn(app, @button_makeButtonPushed, true);
            app.button_make.Text = 'Make(4)>>';
            app.button_deleteSelection = uibutton(app.panel_nodes, 'push');
            app.button_deleteSelection.ButtonPushedFcn = createCallbackFcn(app, @button_deleteSelectionPushed, true);
            app.button_deleteSelection.Text = 'Delete Selection (3)';
            app.button_uploadImage = uibutton(app.panel_nodes, 'push');
            app.button_uploadImage.ButtonPushedFcn = createCallbackFcn(app, @button_uploadImageButtonPushed, true);
            app.button_uploadImage.Text = 'Upload Image (5)';
            app.uia_image = uiaxes(app.panel_nodes);
            app.button_openImage = uibutton(app.panel_nodes, 'push');
            app.button_openImage.ButtonPushedFcn = createCallbackFcn(app, @button_openImageButtonPushed, true);
            app.button_openImage.Enable = 'off';
            app.button_openImage.Text = 'Open Image in Window';
            app.button_addRow = uibutton(app.panel_nodes, 'push');
            app.button_addRow.ButtonPushedFcn = createCallbackFcn(app, @button_addRowButtonPushed, true);
            app.button_addRow.Text = 'Add Row (3)';
            app.label_tableExplanation = uilabel(app.panel_nodes);
            app.label_tableExplanation.Text = 'Symetry R/L make independent nodes. X/Y/Z are node components.';
            app.textA_nodeListLabel = uilabel(app.panel_nodes);
            app.textA_nodeListLabel.Text = 'Node Coordinates List';
            app.textA_nodeList = uitextarea(app.panel_nodes);
            app.textA_nodeList.Editable = 'off';
            app.tab_nodeGraph = uitab(app.tab_group);
            app.tab_nodeGraph.Title = 'Node Graph Mapping';
            app.panel_nodeGraphMap = uipanel(app.tab_nodeGraph);
            app.table_adjacencyMatrix = uitable(app.panel_nodeGraphMap);
            app.table_adjacencyMatrix.ColumnName = {''};
            app.table_adjacencyMatrix.RowName = {};
            app.button_makeAdjacencyMatrix = uibutton(app.panel_nodeGraphMap, 'push');
            app.button_makeAdjacencyMatrix.ButtonPushedFcn = createCallbackFcn(app, @button_makeAdjacencyMatrixButtonPushed, true);
            app.button_makeAdjacencyMatrix.Text = 'Make Adjacency Matrix (6)';
            app.spinner_resizeColWidthsLabel = uilabel(app.panel_nodeGraphMap);
            app.spinner_resizeColWidthsLabel.Text = 'Resize Col Widths';
            app.spinner_resizeColWidths = uispinner(app.panel_nodeGraphMap);
            app.spinner_resizeColWidths.Step = 5;
            app.spinner_resizeColWidths.Limits = [1 400];
            app.spinner_resizeColWidths.ValueDisplayFormat = '%.0f';
            app.spinner_resizeColWidths.ValueChangedFcn = createCallbackFcn(app, @spinner_resizeColWidthsValueChanged, true);
            app.spinner_resizeColWidths.Value = 30;
            app.button_makeGraph = uibutton(app.panel_nodeGraphMap, 'push');
            app.button_makeGraph.ButtonPushedFcn = createCallbackFcn(app, @button_makeGraphButtonPushed, true);
            app.button_makeGraph.Text = 'Make Graph (7)';
            app.button_openGraphvsImage = uibutton(app.panel_nodeGraphMap, 'push');
            app.button_openGraphvsImage.ButtonPushedFcn = createCallbackFcn(app, @button_openGraphvsImageButtonPushed, true);
            app.button_openGraphvsImage.Text = 'Open Graph vs Image (8)';
            app.tab_nameEdges = uitab(app.tab_group);
            app.tab_nameEdges.Title = 'Name Edges And Node Types';
            app.panel_nameEdges = uipanel(app.tab_nameEdges);
            app.table_edgeNames = uitable(app.panel_nameEdges);
            app.table_edgeNames.ColumnName = {'Node n'; 'Node k'; 'Edge Name'};
            app.table_edgeNames.RowName = {};
            app.table_edgeNames.ColumnEditable = [false false true];
            app.table_nodeTypes = uitable(app.panel_nameEdges);
            app.table_nodeTypes.ColumnName = {'Name'; 'Node/Joint'; 'Origin?'};
            app.table_nodeTypes.RowName = {};
            app.table_nodeTypes.ColumnEditable = [false true true];
            app.button_populateEdgeNodeTables = uibutton(app.panel_nameEdges, 'push');
            app.button_populateEdgeNodeTables.ButtonPushedFcn = createCallbackFcn(app, @button_populateEdgeNodeTablesButtonPushed, true);
            app.button_populateEdgeNodeTables.Text = 'Populate Tables (9)';
            app.button_doneFillEmptySpaces = uibutton(app.panel_nameEdges, 'push');
            app.button_doneFillEmptySpaces.ButtonPushedFcn = createCallbackFcn(app, @button_doneFillEmptySpacesPushed, true);
            app.button_doneFillEmptySpaces.Text = 'Done / Fill Empty Spaces (10)';
            app.tab_MakeBodyKinematics = uitab(app.tab_group);
            app.tab_MakeBodyKinematics.Title = 'Make Body Kinematics';
            app.panel_makeBodyKinematics = uipanel(app.tab_MakeBodyKinematics);
            app.button_createBodyKinematics = uibutton(app.panel_makeBodyKinematics, 'push');
            app.button_createBodyKinematics.ButtonPushedFcn = createCallbackFcn(app, @button_createBodyKinematicsButtonPushed, true);
            app.button_createBodyKinematics.Text = 'Create Body Kinematics (11)';
            app.button_saveAndFinish = uibutton(app.panel_makeBodyKinematics, 'push');
            app.button_saveAndFinish.ButtonPushedFcn = createCallbackFcn(app, @button_saveAndFinishButtonPushed, true);
            app.button_saveAndFinish.Enable = 'off';
            app.button_saveAndFinish.Text = 'Save and Finish! (12)';
            app.button_prev = uibutton(app.ui_createNodeStandard, 'push');
            app.button_prev.ButtonPushedFcn = createCallbackFcn(app, @button_prevButtonPushed, true);
            app.button_prev.Text = '<<Prev';
            app.button_next = uibutton(app.ui_createNodeStandard, 'push');
            app.button_next.ButtonPushedFcn = createCallbackFcn(app, @button_nextButtonPushed, true);
            app.button_next.Text = 'Next>>';
            app.label_errorMsg = uilabel(app.ui_createNodeStandard);
            app.label_errorMsg.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = createNodeStandard

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ui_createNodeStandard)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ui_createNodeStandard)
        end
    end
end