classdef mapMotionCsvToNodeStd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ui_template                  matlab.ui.Figure
        tab_group                    matlab.ui.container.TabGroup
        tab_loadData                 matlab.ui.container.Tab
        panel_loadData               matlab.ui.container.Panel
        button_loadFromFile          matlab.ui.control.Button
        label_loadFromFile           matlab.ui.control.Label
        button_csvSelect             matlab.ui.control.Button
        chkb_csvLock                 matlab.ui.control.CheckBox
        button_nodeStandard          matlab.ui.control.Button
        chkb_nodeStandardLock        matlab.ui.control.CheckBox
        button_createNodeStd         matlab.ui.control.Button
        label_studyDirectoryListBox  matlab.ui.control.Label
        list_studyDir                matlab.ui.control.ListBox
        label_csvFile                matlab.ui.control.Label
        text_csvDataFile             matlab.ui.control.EditField
        label_nodeStandard           matlab.ui.control.Label
        text_nodeStandardFile        matlab.ui.control.EditField
        button_browseWorkingDir      matlab.ui.control.Button
        label_workingDir             matlab.ui.control.Label
        tab_identifyNodes            matlab.ui.container.Tab
        panel_identifyNodes          matlab.ui.container.Panel
        button_popNodeTable          matlab.ui.control.Button
        table_nodeAssign             matlab.ui.control.Table
        table_csvHeaders             matlab.ui.control.Table
        button_addSelection          matlab.ui.control.Button
        button_deleteMappedValue     matlab.ui.control.Button
        button_computeMapMatrix      matlab.ui.control.Button
        button_insertZero            matlab.ui.control.Button
        tab_trimData                 matlab.ui.container.Tab
        panel_trimData               matlab.ui.container.Panel
        table_trimData               matlab.ui.control.Table
        label_headNan                matlab.ui.control.Label
        editF_headNan                matlab.ui.control.NumericEditField
        label_tailNan                matlab.ui.control.Label
        editF_tailNan                matlab.ui.control.NumericEditField
        spinner_headTrim             matlab.ui.control.Spinner
        label_samplesLeft            matlab.ui.control.Label
        editF_samplesLeft            matlab.ui.control.NumericEditField
        spinner_tailTrim             matlab.ui.control.Spinner
        label_percentTrim            matlab.ui.control.Label
        editF_percentTrim            matlab.ui.control.NumericEditField
        label_nonNanRow              matlab.ui.control.Label
        label_trimExtra              matlab.ui.control.Label
        button_populateTrimNan       matlab.ui.control.Button
        button_trimData              matlab.ui.control.Button
        label_numbRows               matlab.ui.control.Label
        editF_numbRows               matlab.ui.control.NumericEditField
        label_divider1               matlab.ui.control.Label
        label_rawData                matlab.ui.control.Label
        label_trimmedCopiedData      matlab.ui.control.Label
        tab_refineData               matlab.ui.container.Tab
        panel_refineData             matlab.ui.container.Panel
        table_fillGaps               matlab.ui.control.Table
        button_plotSelection         matlab.ui.control.Button
        button_populateData          matlab.ui.control.Button
        label_polyDegree             matlab.ui.control.Label
        slider_polyDegree            matlab.ui.control.Slider
        button_closeGaps             matlab.ui.control.Button
        tan_saveAll                  matlab.ui.container.Tab
        panel_saveAll                matlab.ui.container.Panel
        button_prev                  matlab.ui.control.Button
        button_next                  matlab.ui.control.Button
        label_errorMsg               matlab.ui.control.Label
    end


%---------------------------- CONSTANT PROPERTIES ---------------------------------%    
    
    properties (Access = private, Constant = true)
        % variables that control app's visibility workflow function
        isPanelChar = 'panel_';
        initPanelChar = 'panel_loadData';
        
        % number of initial steps, pair with startedFlag non constant property
        % change this according to the app's workflow before it unlocks the buton next
        numInitialSteps = 3;
        
        % relevant variables to save in savedVarName
        % savedAppName saves this plus everything else too
        %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
        varsToSaveinStd =  {'appName';
                            'appVersion';
                            'appAuthor';}

    end
    
    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'mapMotionCsvToNodeStd';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
        % important/ relevant output from this app
        savedVarName = 'motionToStdMap';
        % the full app, required to populate this app with a previous session
        % this contains ALL components, even those stored in savedVarName
        savedAppName = 'motionToStdMapApp';
          
        % constants used to expand the nodes into XYZ coordinates
        xCoorSuffix = '_X';
        yCoorSuffix = '_Y';
        zCoorSuffix = '_Z';
        notRecordedLabel = 'NR';
        notRecordedIndex = '0'
    end
    

%----------------------------- CLASS PROPERTIES ----------------------------------%
    
     properties (Access = private)
        % started flag to make sure there is name and directory
        startedFlag = 0;
        
        % tab control variable, start at first tab
        tc = 1;

        % this variable stores the working directory
        workingDir = '';
        
        % the file selected from the directory list
        listSelectedFile = '';
        
        % local copy of node standard imported
        nodeStandardNames;
        
        % nodes from standard expanded with sufix for XYZ coordiantes
        nodeStdExpanded;
        
        % selected cells from csv header and mapped tables ISR updated pointer
        csvHeadersSelCells=0;
        mappedNodeSelCells=0;
        fillGapsSelCells=0;
        
        % index used for polinomial fit gap filling
        isNanIndex = struct();
    end
    
        
     properties (Access = public)
       % where all csv data is saved
       csvDataRaw;
       csvData;
       
       % csv to node standard map
       cvsToNodeStdMap;
        
    end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%
    
    methods (Access = private)
        
        function loadAppState(app)
            %TODO -- error checking here try catch?
            %TODO -- supress warnings when loading file for fig?
            [tfile, tpath] = uigetfile('*.mat');
            % get file but only the app section of it
            tmatfile = load(fullfile(tpath, tfile));
            loadedFile = tmatfile.(app.savedAppName);
            clear('tmatfile')
            
            if(~strcmp(class(loadedFile), app.appName))
                app.label_errorMsg = 'ERROR: not the right kind of file';
            
            else    
                % if load previous datafile, then populate all variables
                % app-defined user properties
                
                
            end%if file is correct
            
            % delete loaded instance, but first display the name of the standard selected
            app.label_loadFromFile.Text = fullfile(tpath, tfile);
            clear('loadedFile', 'tfile', 'tpath');
            
        end%load file function
        %---------------------------------------------------------------------------% 
        
        function s = varsToSaveinStdFcn(app)
            % saves just the relevant variables that come out of this class not to save ui elements
            %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
            for(i = 1:length(app.varsToSaveinStd))
                s.(app.varsToSaveinStd{i}) = app.(app.varsToSaveinStd{i});
            end                
        end%vars to save fnc
         %---------------------------------------------------------------------------%       
    
        function loadWorkingDirFiles(app)   
                %TODO -- should trim this to show a ../../dir
                app.label_workingDir.Text = app.workingDir;

                % dont display . and ..
                tmp = cellstr(ls(app.workingDir));
                app.list_studyDir.Items = tmp(3:end);
                
                % enable buttons that add files now, disable browsing
                app.button_browseWorkingDir.Enable = 'off';
                app.button_loadFromFile.Enable     = 'off';
                app.button_csvSelect.Enable     = 'on';
                app.button_nodeStandard.Enable  = 'on';
                
                % increment the starting flag increment x/numInitialSteps
                %TODO -- does this belong on a small function?
                incStartFlag(app, 1);
                
        end%load working dir function
        %---------------------------------------------------------------------------%
        
        function v = incStartFlag(app, cnt)
            % increase or decrease the start flag with bound checking
            % this ommits the value of cnt and only uses its sign.
            if(cnt>0 && app.startedFlag<app.numInitialSteps)
                app.startedFlag = app.startedFlag + 1;
            elseif(cnt<0 && app.startedFlag>0)
                app.startedFlag = app.startedFlag - 1;
            end
            
            % determine if the start is done.
            if(app.startedFlag>=app.numInitialSteps)
                v = true;
            else
                v = false;
            end
        end%increase start flag
        %---------------------------------------------------------------------------%    
         
        function readNodeStandard(app)
            %TODO -- should i need try catch here to make sure that this is a standard node
            %TODO -- this needs a try catch
            
            % aquire the value of the node standard and place it in a private variable here (copy)
            %TODO -- is this hardcoded or pointless?
            tmp = fullfile(app.workingDir, app.text_nodeStandardFile.Value);
            tstruct = load(tmp, createNodeStandard.savedVarName);
            app.nodeStandardNames = tstruct.(createNodeStandard.savedVarName).conGraph.Nodes.Name;
        end%readNodeStandard(app)
        %---------------------------------------------------------------------------%    
    

        function readCsvFile(app)
            %TODO -- should i need try catch here to make sure that this is a standard node dir?
            %TODO -- this needs a try catch
            %read fille with all experiment data to local var
            app.csvDataRaw = importdata(fullfile(app.workingDir, app.text_csvDataFile.Value));
            app.csvDataRaw.fileName = app.text_csvDataFile.Value;

        end%readCsvFile(app)
        %---------------------------------------------------------------------------%
    
    end%private methods


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tworkingDir)

            % fist turn all tabs off
            % get names of all panels and indexes in app. computing here is slower but app may change?
            tnames  = fieldnames(app);
            tpanels = find(contains(tnames,app.isPanelChar));
            
            for(i=1: length(tpanels))
                app.(tnames{tpanels(i)}).Visible = 'off';
            end
            
            % the first pannel and tab controlled by class constants
            tpanel = find(contains(tnames,app.initPanelChar));
            app.(tnames{tpanel}).Visible = 'on';
            
            % activate appropiate tab. at init = 1
            app.tab_group.SelectedTab = app.tab_group.Children(app.tc);
            
            % if an input is sent to the app then assign working directory
            %TODO -- ommiting try catch for now because this comes within the app
            if(nargin==2)
                app.workingDir = tworkingDir;
                % load files into directory list if user provides this input
                loadWorkingDirFiles(app);
            end
            
        end

        % Button pushed function: button_next
        function button_nextButtonPushed(app, event)
            % make sure user cant proceed unless name and directory are changed.
            if(~incStartFlag(app, 0))
                app.label_errorMsg.Text = 'ERROR: This section must be completed.';
            else
                % remove error labels if any
                app.label_errorMsg.Text = '';
                
                % fist turn all tabs off
                % get names of all panels and indexes in app. computing here is slower but app may change?
                tnames  = fieldnames(app);
                tpanels = find(contains(tnames,app.isPanelChar));
                
                for(i=1: length(tpanels))
                    app.(tnames{tpanels(i)}).Visible = 'off';
                end
                
                % increment tab control if no bigger than size of tab
                if(app.tc < size(app.tab_group.Children,1))
                    app.tc = app.tc+1;
                end
                
                app.(tnames{tpanels(app.tc)}).Visible = 'on';
                
                % activate appropiate tab
                app.tab_group.SelectedTab = app.tab_group.Children(app.tc);
            end
        end

        % Button pushed function: button_prev
        function button_prevButtonPushed(app, event)
            % fist turn all tabs off
            % get names of all panels and indexes in app. computing here is slower but app may change?
            tnames  = fieldnames(app);
            tpanels = find(contains(tnames,app.isPanelChar));
            
            for(i=1: length(tpanels))
                app.(tnames{tpanels(i)}).Visible = 'off';
            end
            
            % change tab control if no smaller than one
            if(app.tc > 1)
                app.tc = app.tc-1;
            end
            
            app.(tnames{tpanels(app.tc)}).Visible = 'on';
            
            % activate appropiate tab
            app.tab_group.SelectedTab = app.tab_group.Children(app.tc);
            
            % remove error labels if any
            app.label_errorMsg.Text = '';
            
        end

        % Button pushed function: button_loadFromFile
        function button_loadFromFileButtonPushed(app, event)
            % load from an existing file
            loadAppState(app);
            app.button_loadFromFile.Enable = 'off';
        end

        % Button pushed function: button_browseWorkingDir
        function button_browseWorkingDirButtonPushed(app, event)
            % select a directory for this study
            try
                %TODO -- for some reason this sometimes crashes if it is on my first screen only??
                app.workingDir = uigetdir('Select the study directory  ');
                
            catch
                %TODO -- popup mesages could be hidden in the back?   
                msgbox({'Not a valid entry';'You must select a directory'},'Invalid','warn');
                app.text_workingDir.Value = "Select a study directory";
            end
            
            % load files into directory list
            loadWorkingDirFiles(app);
            
        end

        % Value changed function: chkb_csvLock
        function chkb_csvLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkb_csvLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.button_csvSelect.Enable = 'off';
                incStartFlag(app, 1);
            else
                app.button_csvSelect.Enable = 'on';
                incStartFlag(app, -1);
            end
        end

        % Button pushed function: button_csvSelect
        function button_csvSelectButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that ta csv is actually selected
            app.text_csvDataFile.Value = app.listSelectedFile;
            
            %TODO -- check that this file is a csv File
            % lock the value trough the checkbox
            app.chkb_csvLock.Value = 1;
            chkb_csvLockValueChanged(app, event); 
        end

        % Value changed function: list_studyDir
        function list_studyDirValueChanged(app, event)
            % updates the value of the user selecton in a private variable to be used
            % as a pointer to associate it with a type of file in button_xxxSelectPushed
            value = app.list_studyDir.Value;
            app.listSelectedFile = string(value);
        end

        % Value changed function: chkb_nodeStandardLock
        function chkb_nodeStandardLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkb_nodeStandardLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.button_nodeStandard.Enable = 'off';
                app.button_createNodeStd.Enable = 'off';
                incStartFlag(app, 1);
            else
                app.button_nodeStandard.Enable = 'on';
                app.button_createNodeStd.Enable = 'on';
                incStartFlag(app, -1);
            end
        end

        % Button pushed function: button_nodeStandard
        function button_nodeStandardButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that node standard is actually selected
            app.text_nodeStandardFile.Value = app.listSelectedFile;
            
            %TODO -- check that this file is a csv File
            % lock the value trough the checkbox
            app.chkb_nodeStandardLock.Value = 1;
            chkb_nodeStandardLockValueChanged(app, event); 
        end

        % Button pushed function: button_popNodeTable
        function button_popNodeTableButtonPushed(app, event)
            % frist aquire the values from node standard and csv experiment
            readNodeStandard(app);
            readCsvFile(app);
            
            %---------------------------------------------------------------------------%
            % get the column headers from csv file and assign to table, make it into a nx1 array
            app.table_csvHeaders.Data = transpose(app.csvDataRaw.colheaders);
            
            % expand the node coordiante into its components
            for(i = 1: length(app.nodeStandardNames))
                app.nodeStdExpanded = [app.nodeStdExpanded; {[app.nodeStandardNames{i}, app.xCoorSuffix]}; ...
                                                            {[app.nodeStandardNames{i}, app.yCoorSuffix]}; ...
                                                            {[app.nodeStandardNames{i}, app.zCoorSuffix]} ];
            end
            
            % frist and second columns are empty
            tmp = cell(length(app.nodeStdExpanded),1);
            tmp(:) = {''};
            app.table_nodeAssign.Data = [tmp, tmp];
            
            % second column gets node standard names with XYZ expansion
            app.table_nodeAssign.Data = [app.table_nodeAssign.Data app.nodeStdExpanded];
            
            % enable buttons
            app.button_addSelection.Enable = 'on';
            app.button_deleteMappedValue.Enable = 'on';
            app.button_insertZero.Enable = 'on';
            app.button_computeMapMatrix.Enable = 'on';
            
        end

        % Cell selection callback: table_csvHeaders
        function table_csvHeadersCellSelection(app, event)
            % for some reason it does not like to be directly assigned to private var
            % assign value to private variable (array) on a selection event
            indices = event.Indices(:,1);
            app.csvHeadersSelCells = indices;
            
        end

        % Cell selection callback: table_nodeAssign
        function table_nodeAssignCellSelection(app, event)
            % for some reason it does not like to be directly assigned to private var
            % assign value to private variable (array) on a selection event
            indices = event.Indices(:,1);
            app.mappedNodeSelCells = indices;
            
        end

        % Button pushed function: button_addSelection
        function button_addSelectionButtonPushed(app, event)
            % add selected CSV header to the table that will be the map to node std
            % make sure there is an actual selection
            if(~isempty(app.csvHeadersSelCells) & app.csvHeadersSelCells~=0)
                % the selected cells to be copied
                tmpcells = app.table_csvHeaders.Data(app.csvHeadersSelCells,1);
                
                % take the index of the first entry that is empty
                tindex = find(strcmp(app.table_nodeAssign.Data(:,1),''),1);
                
                % make sure the assignment does not exceed the table expected row n
                % assign the value of the node name and the row number. that correspond to csv col number
                if((tindex+length(tmpcells)-1)<=length(app.table_nodeAssign.Data))
                    app.table_nodeAssign.Data(tindex:(tindex+length(tmpcells)-1),2) = tmpcells;
                    app.table_nodeAssign.Data(tindex:(tindex+length(tmpcells)-1),1) = num2cell(app.csvHeadersSelCells);
                    app.label_errorMsg.Text = '';
                else
                    app.label_errorMsg.Text = 'ERROR: selected cells will make array bigger than it must';
                end
                % clear the selected cell pointer
                app.csvHeadersSelCells = 0;
            else
                app.label_errorMsg.Text = 'ERROR: you must select a cell from the CSV Header table';
            end
        end

        % Button pushed function: button_deleteMappedValue
        function button_deleteMappedValueButtonPushed(app, event)
            % cherry-pick values from table and remove them
            % user needs to make a selection first, then press
            % cells selected array comes from an ISR table_nodeAssignCellSelection()
            if(~isempty(app.mappedNodeSelCells) & app.mappedNodeSelCells ~= 0)
                % deletes cell content, not cell row 
                app.table_nodeAssign.Data(app.mappedNodeSelCells,1) = {''};
                app.table_nodeAssign.Data(app.mappedNodeSelCells,2) = {''};
                % clear the selected cell pointer
                app.mappedNodeSelCells = 0;
                app.label_errorMsg.Text = '';
            else
                app.label_errorMsg.Text = 'ERROR: you must select a cell from the mapped node table';
            end
        end

        % Button pushed function: button_insertZero
        function button_insertZeroPushed(app, event)
            % cherry-pick values from table and add "not recorded" or NR
            % user needs to make a selection first, then press
            % cells selected array comes from an ISR table_nodeAssignCellSelection()
            % take the index of the first entry that is empty
            tindex = find(strcmp(app.table_nodeAssign.Data(:,1),''),1);
                
            % assign a not recorded value and an index of zero
            app.table_nodeAssign.Data(tindex,2) = {app.notRecordedLabel};
            app.table_nodeAssign.Data(tindex,1) = {app.notRecordedIndex};
            % clear the selected cell pointer
            app.mappedNodeSelCells = 0;
            app.label_errorMsg.Text = '';

        end

        % Button pushed function: button_computeMapMatrix
        function button_computeMapMatrixPushed(app, event)
            %TODO -- this does not check that all components are 2d or 3d only
            %TODO -- a 1d is useless and untrue and not populating a node requires a change in node standard
            %TODO -- you can insert NR but i amnot sure what it will do for now
            
            %TODO -- if statement here
            % first make sure that all the table is full
            
            %TODO -- if statement here
            % then make sure that after removing the notRecordedLabel zeros, all items are unique
            
            % make a map
            % pass data as-is
            tmap = app.table_nodeAssign.Data;
            
            % create empty column for the suffix
            tmp = cell(length(tmap),1);
            tmp(:) = {''};
            % column append
            tmap = [tmap, tmp];
            
            %TODO -- this is saving the suffix, should i do just X and not _X? prob not as _X is public
            %TODO -- make them chars not cell chars
            % for items containing X suffix, make new column then remove suffix from col 2
            trows = find(contains(tmap(:,3), app.xCoorSuffix));
            tmap(trows,4) = {app.xCoorSuffix};
            tmap(trows,3) = cellfun(@(tvar) tvar(1:end-length(app.xCoorSuffix)), tmap(trows,3), 'Uniform', 0);
            
            % for items containing Y suffix, make new column then remove suffix from col 2
            trows = find(contains(tmap(:,3), app.yCoorSuffix));
            tmap(trows,4) = {app.yCoorSuffix};
            tmap(trows,3) = cellfun(@(tvar) tvar(1:end-length(app.yCoorSuffix)), tmap(trows,3), 'Uniform', 0);
            
            % for items containing Z suffix, make new column then remove suffix from col 2
            trows = find(contains(tmap(:,3), app.zCoorSuffix));
            tmap(trows,4) = {app.zCoorSuffix};
            tmap(trows,3) = cellfun(@(tvar) tvar(1:end-length(app.zCoorSuffix)), tmap(trows,3), 'Uniform', 0);

            % make array into table
            app.cvsToNodeStdMap = cell2table(tmap, 'VariableNames', {'csvColNum' 'csvHead' 'Nodes' 'Coordinate'});
            
            % send a message that the process is completed
            app.label_errorMsg.Text = 'LOG: Matrix was succesfully computed!!';
            
            
        end

        % Button pushed function: button_populateTrimNan
        function button_populateTrimNanButtonPushed(app, event)
            % take Raw csv Data and compute the Head and Tail #nans
            % find the index of first row (any, 2) in data that is non NaN
            %TODO -- use diff on tmp to find subsequent data points non NAN after/before Head/Tail
            tnotnanrow = find(~any(isnan(app.csvDataRaw.data),2));
            %populate the values 
            app.editF_headNan.Value  = tnotnanrow(1);
            app.editF_tailNan.Value  = tnotnanrow(end);
            app.editF_numbRows.Value = size(app.csvDataRaw.data,1);
            
            % compute each node for Head and tail Nans, then grab every 3rd element for XYZ
            %TODO -- i am assuming that each node has the same nans in XYZ, if not grab the largest
            tnansumhead = sum(isnan(app.csvDataRaw.data(1:tnotnanrow(1),:)));
            tnansumhead = tnansumhead(:,1:3:end);
            tnansumtail = sum(isnan(app.csvDataRaw.data(tnotnanrow(end):end,:)));
            tnansumtail = tnansumtail(:,1:3:end);
            
            %TODO -- do I need a number of dimensions per node constant in case there os other data aside from video?
            % populate table with findings
            app.table_trimData.Data = app.nodeStandardNames;
            app.table_trimData.Data = [app.table_trimData.Data num2cell(tnansumhead((app.cvsToNodeStdMap.csvColNum(3:3:end)/3)')')];
            app.table_trimData.Data = [app.table_trimData.Data num2cell(tnansumtail((app.cvsToNodeStdMap.csvColNum(3:3:end)/3)')')];
            
            % enable trim button
            app.button_trimData.Enable = 'on';
            
        end

        % Button pushed function: button_trimData
        function button_trimDataButtonPushed(app, event)
            % make a copy of the data and trim edges with head and tail values plus user input
            % first enssure that user input does not create a bad index for array
            %TODO -- make this if

            % grab de index and make them numeric
            thead = app.editF_headNan.Value + app.spinner_headTrim.Value;
            ttail = app.editF_tailNan.Value + app.spinner_tailTrim.Value;
            
            % first initialize
            app.csvData = table();
            
            for(i = 1: length(app.nodeStandardNames))
                % first find the column, just the first instance as it goes XYZ
                tmp = find(contains(app.cvsToNodeStdMap.Nodes, app.nodeStandardNames{i}),1);
                % then get the XYZ col number, not needed but otherwise too long
                tcoridx = app.cvsToNodeStdMap.csvColNum(tmp:tmp+2);
                % append the data to the table
                app.csvData.(app.nodeStandardNames{i}) = app.csvDataRaw.data(thead:ttail,tcoridx);
            end
            
            % populate result values, use inclusive sum
            app.editF_percentTrim.Value = 100*(ttail-thead+1)/app.editF_numbRows.Value;
            app.editF_samplesLeft.Value = ttail-thead+1;
            
        end

        % Button pushed function: button_populateData
        function button_populateDataButtonPushed(app, event)
            % compute the statistics of all the gaps in data
            
            for tnames = app.csvData.Properties.VariableNames
                % find the nan data and OR its XYZ columns, take the largest
                % append false at the end to see if there is a sequence at start or end (should not)
                tmp = [false; any(isnan(app.csvData.(char(tnames))),2); false];
                tstarts = strfind(tmp',[0 1])';
                % take the last nan, no the first not-nan
                tends   = strfind(tmp',[1 0])'-1;
                % get the start-end for each gap, will be used in polyfit
                app.isNanIndex.(char(tnames)) = [tstarts tends];
                
                % the number of NaN's in a row, inclusive sum
                tnans = diff(app.isNanIndex.(char(tnames)),1,2)+1;
                
                tnumgaps = size(tnans,1);
                % if there are no gaps then this is all useless
                if(tnumgaps == 0)
                    tmax    = 0;
                    tmean   = 0;
                    tstddev = 0;
                else
                    tmax = max(tnans);
                    tmean = round(mean(tnans),2);
                    tstddev = round(std(tnans),2);
                end
                
 
                % update table with trimmed data
                app.table_fillGaps.Data =  [app.table_fillGaps.Data; {char(tnames) tnumgaps tmean tstddev tmax}];
            end

            % enable plot and close gap buttons
            app.button_plotSelection.Enable = 'on';
            app.button_closeGaps.Enable = 'on';
            
            %TODO -- add labels at the bottom to see how many gaps sum all are there still to fix, also grand max and mean
        end

        % Cell selection callback: table_fillGaps
        function table_fillGapsCellSelection(app, event)
            % for some reason it does not like to be directly assigned to private var
            % assign value to private variable (array) on a selection event
            indices = event.Indices(:,1);
            app.fillGapsSelCells = indices;
        end

        % Button pushed function: button_plotSelection
        function button_plotSelectionButtonPushed(app, event)
            %TODO -- this is hardcoded xyz
            %TODO -- offe rmore plotting options
            %TODO -- ideally point or indicate to the gaps if any
            %TODO -- mange plot handles propperly
            % user needs to make a selection first, then press
            % cells selected array comes from an ISR table_nodeAssignCellSelection()
            if(~isempty(app.fillGapsSelCells) & app.fillGapsSelCells~= 0)
                % make a time series with time as the sample size
                ttime = linspace(1, size(app.csvData,1), size(app.csvData,1));
                tnodename = char(app.table_fillGaps.Data(app.fillGapsSelCells,1));

                % create subplot with X, Y, Z, XYZ plots
                figure();
                subplot(3,2,1);
                plot(ttime, app.csvData.(tnodename)(:,1), 'Color','b');
                title('X');
                subplot(3,2,3);
                plot(ttime, app.csvData.(tnodename)(:,2), 'Color','r');
                title('Y');
                subplot(3,2,5);
                plot(ttime, app.csvData.(tnodename)(:,3), 'Color','g');
                title('Z');
                subplot(3,2,[2,4,6]);
                plot3(app.csvData.(tnodename)(:,1), app.csvData.(tnodename)(:,2), app.csvData.(tnodename)(:,3), 'Color','m');
                title('XYZ');
                
                sgtitle(['Plot for Node: ' tnodename]);
                
                % clear the selected cell pointer
                app.fillGapsSelCells = 0;
                app.label_errorMsg.Text = '';
            else
                app.label_errorMsg.Text = 'ERROR: you must select a cell from the table';
            end
            
        end

        % Button pushed function: button_closeGaps
        function button_closeGapsButtonPushed(app, event)
            %TODO -- this uses a n degree polynomial to fit the data, for many reasons this may be not ok
            %TODO -- add more detail selection of data and gap size and points used to do the fit
            %TODO -- offer a list of possible algorithms, not just least square
            %TODO -- for example, if this is wave data then this will give trash
            %TODO -- this should probably cause a pop-up to massage the data
            %TODO -- is taking a fit for x,y,z different than a fit for xyz?
            %TODO -- this does not take into account the fact that a point is part of a linkage
            %TODO -- it may be beter for this part to be in an outside file?
            %TODO -- ideally each node could be computed separatelly, for now apply to all data
            
            % take the user's input for polynomial degree
            tdegree = app.slider_polyDegree.Value;
            % take the polynomial with n+1 points at either end of the gap
            tnumpoints = 2*(tdegree+1);
            
            save(fullfile(app.workingDir, 'polyfitme.mat'));
            
%             for tnames = app.csvData.Properties.VariableNames
%                 % find the nan data and OR its XYZ columns, take the largest
%                 % append false at the end to see if there is a sequence at start or end (should not)
%                 tmp = [false; any(isnan(app.csvData.(char(tnames))),2); false];
%                 tstarts = strfind(tmp',[0 1])';
%                 % take the last nan, no the first not-nan
%                 tends   = strfind(tmp',[1 0])'-1;
%                 % get the start-end for each gap, will be used in polyfit
%                 app.isNanIndex.(char(tnames)) = [tstarts tends];
%                 
%                 % the number of NaN's in a row, inclusive sum
%                 tnans = diff(app.isNanIndex.(char(tnames)),1,2)+1;
%                 
%                 tnumgaps = size(tnans,1);
%                 % if there are no gaps then this is all useless
%                 if(tnumgaps == 0)
%                     tmax    = 0;
%                     tmean   = 0;
%                     tstddev = 0;
%                 else
%                     tmax = max(tnans);
%                     tmean = round(mean(tnans),2);
%                     tstddev = round(std(tnans),2);
%                 end
%                 
%  
%                 % update table with trimmed data
%                 app.table_fillGaps.Data =  [app.table_fillGaps.Data; {char(tnames) tnumgaps tmean tstddev tmax}];
%             end
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ui_template = uifigure;
            app.ui_template.Name = 'UI Figure';
            app.tab_group = uitabgroup(app.ui_template);
            app.tab_loadData = uitab(app.tab_group);
            app.tab_loadData.Title = 'Load Data ';
            app.panel_loadData = uipanel(app.tab_loadData);
            app.button_loadFromFile = uibutton(app.panel_loadData, 'push');
            app.button_loadFromFile.ButtonPushedFcn = createCallbackFcn(app, @button_loadFromFileButtonPushed, true);
            app.button_loadFromFile.Text = 'Load From File (0)';
            app.label_loadFromFile = uilabel(app.panel_loadData);
            app.label_loadFromFile.Text = '';
            app.button_csvSelect = uibutton(app.panel_loadData, 'push');
            app.button_csvSelect.ButtonPushedFcn = createCallbackFcn(app, @button_csvSelectButtonPushed, true);
            app.button_csvSelect.Enable = 'off';
            app.button_csvSelect.Text = '<<';
            app.chkb_csvLock = uicheckbox(app.panel_loadData);
            app.chkb_csvLock.ValueChangedFcn = createCallbackFcn(app, @chkb_csvLockValueChanged, true);
            app.chkb_csvLock.Text = 'Lock';
            app.button_nodeStandard = uibutton(app.panel_loadData, 'push');
            app.button_nodeStandard.ButtonPushedFcn = createCallbackFcn(app, @button_nodeStandardButtonPushed, true);
            app.button_nodeStandard.Enable = 'off';
            app.button_nodeStandard.Text = '<<';
            app.chkb_nodeStandardLock = uicheckbox(app.panel_loadData);
            app.chkb_nodeStandardLock.ValueChangedFcn = createCallbackFcn(app, @chkb_nodeStandardLockValueChanged, true);
            app.chkb_nodeStandardLock.Text = 'Lock';
            app.button_createNodeStd = uibutton(app.panel_loadData, 'push');
            app.button_createNodeStd.Text = 'Create Node Standard';
            app.label_studyDirectoryListBox = uilabel(app.panel_loadData);
            app.label_studyDirectoryListBox.Text = 'Study Directory';
            app.list_studyDir = uilistbox(app.panel_loadData);
            app.list_studyDir.Items = {};
            app.list_studyDir.ValueChangedFcn = createCallbackFcn(app, @list_studyDirValueChanged, true);
            app.list_studyDir.Value = {};
            app.label_csvFile = uilabel(app.panel_loadData);
            app.label_csvFile.Text = 'CSV File';
            app.text_csvDataFile = uieditfield(app.panel_loadData, 'text');
            app.text_csvDataFile.Editable = 'off';
            app.label_nodeStandard = uilabel(app.panel_loadData);
            app.label_nodeStandard.Text = 'Node Standard';
            app.text_nodeStandardFile = uieditfield(app.panel_loadData, 'text');
            app.text_nodeStandardFile.Editable = 'off';
            app.button_browseWorkingDir = uibutton(app.panel_loadData, 'push');
            app.button_browseWorkingDir.ButtonPushedFcn = createCallbackFcn(app, @button_browseWorkingDirButtonPushed, true);
            app.button_browseWorkingDir.Text = 'Browse Working Dir';
            app.label_workingDir = uilabel(app.panel_loadData);
            app.label_workingDir.Text = '';
            app.tab_identifyNodes = uitab(app.tab_group);
            app.tab_identifyNodes.Title = 'Identify Nodes';
            app.panel_identifyNodes = uipanel(app.tab_identifyNodes);
            app.button_popNodeTable = uibutton(app.panel_identifyNodes, 'push');
            app.button_popNodeTable.ButtonPushedFcn = createCallbackFcn(app, @button_popNodeTableButtonPushed, true);
            app.button_popNodeTable.Text = 'Populate Table';
            app.table_nodeAssign = uitable(app.panel_identifyNodes);
            app.table_nodeAssign.ColumnName = {'Col#'; 'CSV Headers'; 'Node Standard'};
            app.table_nodeAssign.RowName = {};
            app.table_nodeAssign.ColumnEditable = false;
            app.table_nodeAssign.CellSelectionCallback = createCallbackFcn(app, @table_nodeAssignCellSelection, true);
            app.table_csvHeaders = uitable(app.panel_identifyNodes);
            app.table_csvHeaders.ColumnName = {'CSV Headers'};
            app.table_csvHeaders.RowName = {};
            app.table_csvHeaders.ColumnEditable = false;
            app.table_csvHeaders.CellSelectionCallback = createCallbackFcn(app, @table_csvHeadersCellSelection, true);
            app.button_addSelection = uibutton(app.panel_identifyNodes, 'push');
            app.button_addSelection.ButtonPushedFcn = createCallbackFcn(app, @button_addSelectionButtonPushed, true);
            app.button_addSelection.Enable = 'off';
            app.button_addSelection.Text = 'Add Selection >>';
            app.button_deleteMappedValue = uibutton(app.panel_identifyNodes, 'push');
            app.button_deleteMappedValue.ButtonPushedFcn = createCallbackFcn(app, @button_deleteMappedValueButtonPushed, true);
            app.button_deleteMappedValue.Enable = 'off';
            app.button_deleteMappedValue.Text = 'Delete Mapped Value';
            app.button_computeMapMatrix = uibutton(app.panel_identifyNodes, 'push');
            app.button_computeMapMatrix.ButtonPushedFcn = createCallbackFcn(app, @button_computeMapMatrixPushed, true);
            app.button_computeMapMatrix.Enable = 'off';
            app.button_computeMapMatrix.Text = 'Compute Map Matrix';
            app.button_insertZero = uibutton(app.panel_identifyNodes, 'push');
            app.button_insertZero.ButtonPushedFcn = createCallbackFcn(app, @button_insertZeroPushed, true);
            app.button_insertZero.Enable = 'off';
            app.button_insertZero.Text = 'Insert Zero >>';
            app.tab_trimData = uitab(app.tab_group);
            app.tab_trimData.Title = 'Trim Data';
            app.panel_trimData = uipanel(app.tab_trimData);
            app.table_trimData = uitable(app.panel_trimData);
            app.table_trimData.ColumnName = {'Node'; '# Head NaN''s'; '# Tail NaN''s'};
            app.table_trimData.RowName = {};
            app.label_headNan = uilabel(app.panel_trimData);
            app.label_headNan.Text = 'HEAD';
            app.editF_headNan = uieditfield(app.panel_trimData, 'numeric');
            app.editF_headNan.ValueDisplayFormat = '%.0f';
            app.editF_headNan.Editable = 'off';
            app.label_tailNan = uilabel(app.panel_trimData);
            app.label_tailNan.Text = 'TAIL';
            app.editF_tailNan = uieditfield(app.panel_trimData, 'numeric');
            app.editF_tailNan.ValueDisplayFormat = '%.0f';
            app.editF_tailNan.Editable = 'off';
            app.spinner_headTrim = uispinner(app.panel_trimData);
            app.spinner_headTrim.ValueDisplayFormat = '%.0f';
            app.label_samplesLeft = uilabel(app.panel_trimData);
            app.label_samplesLeft.Text = 'Samples Left';
            app.editF_samplesLeft = uieditfield(app.panel_trimData, 'numeric');
            app.editF_samplesLeft.ValueDisplayFormat = '%.0f';
            app.editF_samplesLeft.Editable = 'off';
            app.spinner_tailTrim = uispinner(app.panel_trimData);
            app.spinner_tailTrim.ValueDisplayFormat = '%.0f';
            app.label_percentTrim = uilabel(app.panel_trimData);
            app.label_percentTrim.Text = 'Percent Trimmed';
            app.editF_percentTrim = uieditfield(app.panel_trimData, 'numeric');
            app.editF_percentTrim.Editable = 'off';
            app.label_nonNanRow = uilabel(app.panel_trimData);
            app.label_nonNanRow.Text = 'non-NaN Row';
            app.label_trimExtra = uilabel(app.panel_trimData);
            app.label_trimExtra.Text = 'Trim An Extra # Samples';
            app.button_populateTrimNan = uibutton(app.panel_trimData, 'push');
            app.button_populateTrimNan.ButtonPushedFcn = createCallbackFcn(app, @button_populateTrimNanButtonPushed, true);
            app.button_populateTrimNan.Text = 'Populate All';
            app.button_trimData = uibutton(app.panel_trimData, 'push');
            app.button_trimData.ButtonPushedFcn = createCallbackFcn(app, @button_trimDataButtonPushed, true);
            app.button_trimData.Enable = 'off';
            app.button_trimData.Text = 'Trim Data';
            app.label_numbRows = uilabel(app.panel_trimData);
            app.label_numbRows.Text = '# Rows Raw Data';
            app.editF_numbRows = uieditfield(app.panel_trimData, 'numeric');
            app.editF_numbRows.ValueDisplayFormat = '%.0f';
            app.editF_numbRows.Editable = 'off';
            app.label_divider1 = uilabel(app.panel_trimData);
            app.label_divider1.Text = '----------------------------------------------------------------------------------------------------------------------------------------------------------';
            app.label_rawData = uilabel(app.panel_trimData);
            app.label_rawData.Text = 'RAW DATA';
            app.label_trimmedCopiedData = uilabel(app.panel_trimData);
            app.label_trimmedCopiedData.Text = 'TRIMMED COPIED DATA';
            app.tab_refineData = uitab(app.tab_group);
            app.tab_refineData.Title = 'Refine Data';
            app.panel_refineData = uipanel(app.tab_refineData);
            app.table_fillGaps = uitable(app.panel_refineData);
            app.table_fillGaps.ColumnName = {'Node'; '# Gaps'; 'Mean Gap'; 'StdDev Gap'; 'Max Gap'};
            app.table_fillGaps.RowName = {};
            app.table_fillGaps.CellSelectionCallback = createCallbackFcn(app, @table_fillGapsCellSelection, true);
            app.button_plotSelection = uibutton(app.panel_refineData, 'push');
            app.button_plotSelection.ButtonPushedFcn = createCallbackFcn(app, @button_plotSelectionButtonPushed, true);
            app.button_plotSelection.Enable = 'off';
            app.button_plotSelection.Text = 'Plot Selection';
            app.button_populateData = uibutton(app.panel_refineData, 'push');
            app.button_populateData.ButtonPushedFcn = createCallbackFcn(app, @button_populateDataButtonPushed, true);
            app.button_populateData.Text = 'Populate Data';
            app.label_polyDegree = uilabel(app.panel_refineData);
            app.label_polyDegree.Text = 'Polynomial Degree';
            app.slider_polyDegree = uislider(app.panel_refineData);
            app.slider_polyDegree.Limits = [1 5];
            app.slider_polyDegree.MajorTicks = [1 2 3 4 5];
            app.slider_polyDegree.MajorTickLabels = {'1', '2', '3', '4', '5'};
            app.slider_polyDegree.MinorTicks = [];
            app.slider_polyDegree.Value = 3;
            app.button_closeGaps = uibutton(app.panel_refineData, 'push');
            app.button_closeGaps.ButtonPushedFcn = createCallbackFcn(app, @button_closeGapsButtonPushed, true);
            app.button_closeGaps.Enable = 'off';
            app.button_closeGaps.Text = 'Close Gaps';
            app.tan_saveAll = uitab(app.tab_group);
            app.tan_saveAll.Title = 'Save All';
            app.panel_saveAll = uipanel(app.tan_saveAll);
            app.button_prev = uibutton(app.ui_template, 'push');
            app.button_prev.ButtonPushedFcn = createCallbackFcn(app, @button_prevButtonPushed, true);
            app.button_prev.Text = '<<Prev';
            app.button_next = uibutton(app.ui_template, 'push');
            app.button_next.ButtonPushedFcn = createCallbackFcn(app, @button_nextButtonPushed, true);
            app.button_next.Text = 'Next>>';
            app.label_errorMsg = uilabel(app.ui_template);
            app.label_errorMsg.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mapMotionCsvToNodeStd(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ui_template)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ui_template)
        end
    end
end