classdef mapMotionCsvToNodeStd_before < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ui_mapMotionCsvToNodeStd    matlab.ui.Figure
        tab_group                   matlab.ui.container.TabGroup
        tab_loadData                matlab.ui.container.Tab
        button_csvSelect            matlab.ui.control.Button
        chkb_csv                    matlab.ui.control.CheckBox
        CSVFileLabel                matlab.ui.control.Label
        text_csvDataFile            matlab.ui.control.EditField
        button_browse               matlab.ui.control.Button
        text_workingDir             matlab.ui.control.EditField
        StudyDirectoryListBoxLabel  matlab.ui.control.Label
        list_studyDir               matlab.ui.control.ListBox
        button_nodeStandard         matlab.ui.control.Button
        chkb_nodeStandard           matlab.ui.control.CheckBox
        NodeStandardLabel           matlab.ui.control.Label
        text_nodeStandardFile       matlab.ui.control.EditField
        button_createNodeStd        matlab.ui.control.Button
        button_loadFromFile         matlab.ui.control.Button
        tab_identifyNodes           matlab.ui.container.Tab
        button_popNodeTable         matlab.ui.control.Button
        button_makeDiagonalTrue     matlab.ui.control.Button
        table_csvHeaders            matlab.ui.control.Table
        table_nodeAssign            matlab.ui.control.Table
        button_addSelection         matlab.ui.control.Button
        button_DeleteMappedValue    matlab.ui.control.Button
        InsertEmptySpaceButton      matlab.ui.control.Button
        button_prev                 matlab.ui.control.Button
        button_next                 matlab.ui.control.Button
        label_errorMsg              matlab.ui.control.Label
    end


%----------------------------- PRIVATE PROPERTIES ---------------------------------%    
    
    properties (Access = private, Constant = true)
        % used to append characters from user entry prior to storage in Node table
        emptyRow = {'' false false}
        rightChar   =  'R';
        leftChar    =  'L';
        
        % variables that control app's visibility workflow function
        isPanelChar = 'panel_';
        initPanelChar = 'panel_start';
        
        % number of initial steps, pair with startedFlag non constant property
        % change this according to the app's workflow before it unlocks the buton next
        numInitialSteps = 0;
        
    end
    
    properties (Access = private)
        % started flag to make sure there is name and directory
        startedFlag = 0;
        
        % tab control variable, start at first tab
        tc = 1;
        
        % used to asign from working dir to each important field
        p_selectedFile;
        
        %TODO -- this may be best if public, but start here
        % this will aquire the value of vars saved from a Node Standard directory
        nodeStandard;
        
        % map of csv to node standard conversion matrix
        csvToNodeMap;
        csvToNodeMapNames;
        
        % selected table elements for csv to node map
        csvHeadersSelCells
        csvToNodeMapSelCells
        
        %TODO -- do I need a separate variable to store working dir? public?
        % this variable stores the working directory
        % it may seem redundant with the edit box text field
        % if that variable needs to be public then this is best practice
        % this variable should only be read, to write use the edit box method
        workingDir = '';
        
        % this stores the experiemnt csv data
        csvData
    end

%----------------------------- PUBLIC PROPERTIES ----------------------------------%
    
    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'mapMotionCsvToNodeStd';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
    end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%
    
    methods (Access = private)
        
        function readNodeStandard(app)
            %TODO -- should i need try catch here to make sure that this is a standard node dir?
            %TODO -- this needs a try catch
            
            % first aquire the values of node standard in the working directory
            % point out the right file for Node Standard, this is a directory
            tnodeStdDir = fullfile(app.workingDir, app.text_nodeStandardFile.Value);
            tmp = cellstr(ls(tnodeStdDir));
            
            % this will grab the first item that ends in '.mat' if it is a propper node standard it should be ok
            for i = 1:size(tmp,1)
                if(contains(char(tmp(i)), '.mat'))
                    % exit for loop with current index value
                    break;
                end
            end
            
            % rewrite the value of tmp to point to .mat index
            tmp = char(tmp(i));
            app.nodeStandard = load(fullfile(tnodeStdDir, tmp));
        end%readNodeStandard(app)
    
    
        %---------------------------------------------------------------------------%
        function readCsvFile(app)
            %TODO -- should i need try catch here to make sure that this is a standard node dir?
            %TODO -- this needs a try catch
            
            %TODO -- why do i need this id?
            tfid = fopen(fullfile(app.workingDir, app.text_csvDataFile.Value));
            
            %read fille with all experiment data
            app.csvData = importdata(fullfile(app.workingDir, app.text_csvDataFile.Value));

        end%readCsvFile(app)
        %---------------------------------------------------------------------------%
        
    end%methods (Access = private)
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %TODO -- a little hardcoded?
            % fist turn all tabs off
            % get names of all panels and indexes in app. computing here is slower but app may change?
            tnames  = fieldnames(app);
            tpanels = find(contains(tnames,app.isPanelChar));
            
            for(i=1: length(tpanels))
                app.(tnames{tpanels(i)}).Visible = 'off';
            end
            
            tpanel = find(contains(tnames,app.initPanelChar));
            app.(tnames{tpanel}).Visible = 'on';
            
            % set a default value to root Directory
            %TODO -- ideally this should be in the users HOME, but seems not to be easy
            app.label_targetDir.Text = app.rootDir;
        end

        % Button pushed function: button_browse
        function button_browsePushed(app, event)
            %TODO -- does this need try catch?
            pathName = uigetdir('Select the log file  ');%TODO -- this should be locked after good selection
            app.workingDir = pathName;
            %TODO -- for some reason this sometimes crashes if it is on my first screen only??
            try
                %TODO -- should trim this to show a ../../dir
                app.text_workingDir.Value = app.workingDir;
                app.text_workingDir.HorizontalAlignment = 'right';
                % dont display . and ..
                tmp = cellstr(ls(app.workingDir));
                app.list_studyDir.Items = tmp(3:end);
                
                % enable buttons that add files now, disable browsing
                app.button_browse.Enable        = 'off';
                app.button_csvSelect.Enable     = 'on';
                app.button_nodeStandard.Enable  = 'on';
                
            catch
                %TODO -- popup mesages could be hidden in the back?   
                msgbox({'Not a valid entry';'You must select a directory'},'Invalid','warn');
                app.text_workingDir.Value = "Select a study directory";
            end
            %---------------------------------------------------------------------------%   
        end

        % Value changed function: list_studyDir
        function list_studyDirValueChanged(app, event)
            % updates the value of the user selecton in a private variable to be used
            % as a pointer to associate it with a type of file in button_xxxSelectPushed
            app.p_selectedFile = string(app.list_studyDir.Value);
        end

        % Button pushed function: button_csvSelect
        function button_csvSelectPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that ta csv is actually selected
            app.text_csvDataFile.Value = app.p_selectedFile;
            
            %TODO -- check that this file is a csv FIle
            app.chkb_csv.Value = 1;
            
            % lock the value trough the checkbox
            chkb_csvValueChanged(app, event); 
        end

        % Value changed function: chkb_csv
        function chkb_csvValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkb_csv.Value;
            
            % check and uncheck the box enables/disables the button to asign new values
            if(value == 1)
                app.button_csvSelect.Enable = 'off';
            else
                app.button_csvSelect.Enable = 'on';
            end
        end

        % Button pushed function: button_createNodeStd
        function button_createNodeStdPushed(app, event)
            %TODO -- pass current directory of study to save this file
            run scr\gui\createNodeStandard.mlapp
        end

        % Button pushed function: button_nodeStandard
        function button_nodeStandardButtonPushed(app, event)
            % assign value of current selected file to this field
            app.text_nodeStandardFile.Value = app.p_selectedFile;

            %TODO -- check that this file is a csv FIle
            app.chkb_nodeStandard.Value = 1;
            % lock the value trough the checkbox
            chkb_nodeStandardValueChanged(app, event); 
        end

        % Value changed function: chkb_nodeStandard
        function chkb_nodeStandardValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkb_nodeStandard.Value;
            % check and uncheck the box enables/disables the button to asign new values
            if(value == 1)
                app.button_createNodeStd.Enable = 'off';
                app.button_nodeStandard.Enable  = 'off';
            else
                app.button_createNodeStd.Enable = 'on';
                app.button_nodeStandard.Enable  = 'on';
            end
        end

        % Button pushed function: button_popNodeTable
        function button_popNodeTableButtonPushed(app, event)
 
            % frist aquire the values from node standard and csv experiment
            readNodeStandard(app);
            readCsvFile(app);
            
            %---------------------------------------------------------------------------%
            % pupulate the table with rows as Node Standard and columns as csv recordings
            %TODO -- there are some 1s and 2s that are hardcoded here
            % first get the values to name the columns and rows
            tcsvNames = transpose(app.csvData.textdata);
            %TODO -- stdNodeNames is kinda hardcoded, need a struct to have this names
            %TODO -- this should also be called stdNodeNamesExtended or something to add _X
            tnodeNames = app.nodeStandard.stdNodeNames;
            
            % include names of columns and rows
            app.table_nodeAssign.ColumnName     = {'From CSV'; 'Node Standard'};
            app.table_csvHeaders.ColumnName     = {'From CSV'};
            
            % initialize the data arrays
            app.table_csvHeaders.Data = tcsvNames;
            app.table_nodeAssign.Data  = cell(size(tnodeNames,1),2);
            
            % fill first column with '' and secodn with names
            tmp = cell(size(tnodeNames,1), 1);
            tmp(1:end) = {''};
            app.table_nodeAssign.Data(:,1)  = tmp;
            app.table_nodeAssign.Data(:,2)  = tnodeNames;
            
            % allow user to modify table
            app.table_nodeAssign.ColumnEditable = [false false];
            app.table_csvHeaders.ColumnEditable = [false];
            
%             % make all columns the same size
%             app.table_csvHeaders.ColumnWidth = {140};
%             app.table_nodeAssign.ColumnWidth = {140 140};
                        
            % display table
            app.table_nodeAssign.Visible = 'on';
            app.table_csvHeaders.Visible = 'on';
            
            
        end

        % Value changed function: text_workingDir
        function text_workingDirValueChanged(app, event)
            % assign value to different variable in case this needs to be public later
            % this seems not to work at all, moved it to browse push button method
            app.workingDir = app.text_workingDir.Value;
            
        end

        % Button pushed function: button_makeDiagonalTrue
        function button_makeDiagonalTruePushed(app, event)
            % create a logical array with identity matrix and OR to the table            
            tmp = logical(eye(size(app.table_nodeAssign1.Data)));
            app.table_nodeAssign1.Data = tmp|app.table_nodeAssign1.Data;
        end

        % Cell selection callback: table_csvHeaders
        function table_csvHeadersCellSelection(app, event)
            % for some reason it does not like to be directly assigned to private var
            % assign value to private variable (array) on a selection event
            indices = event.Indices(:,1);
            app.csvHeadersSelCells = indices;
            display(indices);
        end

        % Cell selection callback: table_nodeAssign
        function table_nodeAssignCellSelection(app, event)
            % for some reason it does not like to be directly assigned to private var
            % assign value to private variable (array) on a selection event
            % only take the values form the first column
            indices = event.Indices(:,1);
            app.csvToNodeMapSelCells = indices;
        end

        % Button pushed function: button_addSelection
        function button_addSelectionButtonPushed(app, event)
            %TODO -- need to add something in case there is matrix missmatch, should be able to accept that
            %csvToNodeMap;
            %csvToNodeMapNames;

            if(~isempty(app.csvHeadersSelCells))
                disp('here');
                tmpcells = app.table_csvHeaders.Data(app.csvHeadersSelCells,1);
                
                %TODO -- using node assign here, but any var should have only one map
                for i = 1 : size(app.table_nodeAssign.Data,1)
                    disp('now');
                    tmpLastEntry = app.table_nodeAssign.Data(i,1);
                    if(strcmp(tmpLastEntry, ''))
                        break;
                    end
                end
                disp(i);
                if((i+size(tmpcells,1)-1)<size(app.table_nodeAssign.Data,1))
                    app.table_nodeAssign.Data(i:(i+size(tmpcells,1)-1),1) = tmpcells;
                else
                    %TODO -- add a waring label that selection is bigger than space
                end
                
                app.csvToNodeMapSelCells = 0;
            end
            
            
        end

        % Button pushed function: button_DeleteMappedValue
        function button_DeleteMappedValueButtonPushed(app, event)
            % cherry-pick values from table and remove them
            % user needs to make a selection first, then press
            % cells selected array comes from an ISR table_nodeAssignCellSelection()
            if(app.csvToNodeMapSelCells ~= 0)
                app.table_nodeAssign.Data(app.csvToNodeMapSelCells,1) = {''};
                app.csvToNodeMapSelCells = 0;
            end
        end

        % Button pushed function: InsertEmptySpaceButton
        function InsertEmptySpaceButtonPushed(app, event)
            %TODO -- implement and insert something like NaN value
        end

        % Button pushed function: button_next
        function button_nextButtonPushed(app, event)
            % make sure user cant proceed unless name and directory are changed.
            if(app.startedFlag<app.numInitialSteps)
                app.label_errorMsg.Text = 'ERROR: This section must be completed.';
            else
                % fist turn all tabs off
                % get names of all panels and indexes in app. computing here is slower but app may change?
                tnames  = fieldnames(app);
                tpanels = find(contains(tnames,app.isPanelChar));
                
                for(i=1: length(tpanels))
                    app.(tnames{tpanels(i)}).Visible = 'off';
                end
                
                % increment tab control if no bigger than size of tab
                if(app.tc < size(app.tabGroup.Children,1))
                    app.tc = app.tc+1;
                end
                
                app.(tnames{tpanels(app.tc)}).Visible = 'on';
                
                % activate appropiate tab
                app.tabGroup.SelectedTab = app.tabGroup.Children(app.tc);
            end
        end

        % Button pushed function: button_prev
        function button_prevButtonPushed(app, event)
            % fist turn all tabs off
            % get names of all panels and indexes in app. computing here is slower but app may change?
            tnames  = fieldnames(app);
            tpanels = find(contains(tnames,app.isPanelChar));
            
            for(i=1: length(tpanels))
                app.(tnames{tpanels(i)}).Visible = 'off';
            end
            
            % change tab control if no smaller than one
            if(app.tc > 1)
                app.tc = app.tc-1;
            end
            
            app.(tnames{tpanels(app.tc)}).Visible = 'on';
            
            % activate appropiate tab
            app.tab_group.SelectedTab = app.tab_group.Children(app.tc);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ui_mapMotionCsvToNodeStd = uifigure;
            app.ui_mapMotionCsvToNodeStd.Name = 'UI Figure';
            app.tab_group = uitabgroup(app.ui_mapMotionCsvToNodeStd);
            app.tab_loadData = uitab(app.tab_group);
            app.tab_loadData.Title = 'loadData ';
            app.button_csvSelect = uibutton(app.tab_loadData, 'push');
            app.button_csvSelect.ButtonPushedFcn = createCallbackFcn(app, @button_csvSelectPushed, true);
            app.button_csvSelect.Enable = 'off';
            app.button_csvSelect.Text = '<<';
            app.chkb_csv = uicheckbox(app.tab_loadData);
            app.chkb_csv.ValueChangedFcn = createCallbackFcn(app, @chkb_csvValueChanged, true);
            app.chkb_csv.Text = 'Lock';
            app.CSVFileLabel = uilabel(app.tab_loadData);
            app.CSVFileLabel.Text = 'CSV File';
            app.text_csvDataFile = uieditfield(app.tab_loadData, 'text');
            app.text_csvDataFile.Editable = 'off';
            app.button_browse = uibutton(app.tab_loadData, 'push');
            app.button_browse.ButtonPushedFcn = createCallbackFcn(app, @button_browsePushed, true);
            app.button_browse.Text = 'Browse';
            app.text_workingDir = uieditfield(app.tab_loadData, 'text');
            app.text_workingDir.ValueChangedFcn = createCallbackFcn(app, @text_workingDirValueChanged, true);
            app.text_workingDir.Editable = 'off';
            app.text_workingDir.Value = 'Select a Directory';
            app.StudyDirectoryListBoxLabel = uilabel(app.tab_loadData);
            app.StudyDirectoryListBoxLabel.Text = 'Study Directory';
            app.list_studyDir = uilistbox(app.tab_loadData);
            app.list_studyDir.Items = {};
            app.list_studyDir.ValueChangedFcn = createCallbackFcn(app, @list_studyDirValueChanged, true);
            app.list_studyDir.Value = {};
            app.button_nodeStandard = uibutton(app.tab_loadData, 'push');
            app.button_nodeStandard.ButtonPushedFcn = createCallbackFcn(app, @button_nodeStandardButtonPushed, true);
            app.button_nodeStandard.Enable = 'off';
            app.button_nodeStandard.Text = '<<';
            app.chkb_nodeStandard = uicheckbox(app.tab_loadData);
            app.chkb_nodeStandard.ValueChangedFcn = createCallbackFcn(app, @chkb_nodeStandardValueChanged, true);
            app.chkb_nodeStandard.Text = 'Lock';
            app.NodeStandardLabel = uilabel(app.tab_loadData);
            app.NodeStandardLabel.Text = 'Node Standard';
            app.text_nodeStandardFile = uieditfield(app.tab_loadData, 'text');
            app.text_nodeStandardFile.Editable = 'off';
            app.button_createNodeStd = uibutton(app.tab_loadData, 'push');
            app.button_createNodeStd.ButtonPushedFcn = createCallbackFcn(app, @button_createNodeStdPushed, true);
            app.button_createNodeStd.Text = 'Create Node Standard';
            app.button_loadFromFile = uibutton(app.tab_loadData, 'push');
            app.button_loadFromFile.Text = 'Load From File (0)';
            app.tab_identifyNodes = uitab(app.tab_group);
            app.tab_identifyNodes.Title = 'identifyNodes';
            app.button_popNodeTable = uibutton(app.tab_identifyNodes, 'push');
            app.button_popNodeTable.ButtonPushedFcn = createCallbackFcn(app, @button_popNodeTableButtonPushed, true);
            app.button_popNodeTable.Text = 'Populate Table';
            app.button_makeDiagonalTrue = uibutton(app.tab_identifyNodes, 'push');
            app.button_makeDiagonalTrue.ButtonPushedFcn = createCallbackFcn(app, @button_makeDiagonalTruePushed, true);
            app.button_makeDiagonalTrue.Enable = 'off';
            app.button_makeDiagonalTrue.Text = 'Make Diagonal True';
            app.table_csvHeaders = uitable(app.tab_identifyNodes);
            app.table_csvHeaders.ColumnName = {'Column 1'};
            app.table_csvHeaders.RowName = {};
            app.table_csvHeaders.CellSelectionCallback = createCallbackFcn(app, @table_csvHeadersCellSelection, true);
            app.table_nodeAssign = uitable(app.tab_identifyNodes);
            app.table_nodeAssign.ColumnName = {'Column 1'; 'Column 2'};
            app.table_nodeAssign.RowName = {};
            app.table_nodeAssign.CellSelectionCallback = createCallbackFcn(app, @table_nodeAssignCellSelection, true);
            app.button_addSelection = uibutton(app.tab_identifyNodes, 'push');
            app.button_addSelection.ButtonPushedFcn = createCallbackFcn(app, @button_addSelectionButtonPushed, true);
            app.button_addSelection.Text = 'Add Selection >>';
            app.button_DeleteMappedValue = uibutton(app.tab_identifyNodes, 'push');
            app.button_DeleteMappedValue.ButtonPushedFcn = createCallbackFcn(app, @button_DeleteMappedValueButtonPushed, true);
            app.button_DeleteMappedValue.Text = 'Delete Mapped Value';
            app.InsertEmptySpaceButton = uibutton(app.tab_identifyNodes, 'push');
            app.InsertEmptySpaceButton.ButtonPushedFcn = createCallbackFcn(app, @InsertEmptySpaceButtonPushed, true);
            app.InsertEmptySpaceButton.Text = {'Insert Empty'; 'Space >>'};
            app.button_prev = uibutton(app.ui_mapMotionCsvToNodeStd, 'push');
            app.button_prev.ButtonPushedFcn = createCallbackFcn(app, @button_prevButtonPushed, true);
            app.button_prev.Text = '<<Prev';
            app.button_next = uibutton(app.ui_mapMotionCsvToNodeStd, 'push');
            app.button_next.ButtonPushedFcn = createCallbackFcn(app, @button_nextButtonPushed, true);
            app.button_next.Text = 'Next>>';
            app.label_errorMsg = uilabel(app.ui_mapMotionCsvToNodeStd);
            app.label_errorMsg.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mapMotionCsvToNodeStd_before

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ui_mapMotionCsvToNodeStd)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ui_mapMotionCsvToNodeStd)
        end
    end
end