classdef template < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ui_template          matlab.ui.Figure
        tab_group            matlab.ui.container.TabGroup
        tab_one              matlab.ui.container.Tab
        panel_one            matlab.ui.container.Panel
        button_loadFromFile  matlab.ui.control.Button
        label_loadFromFile   matlab.ui.control.Label
        tab_two              matlab.ui.container.Tab
        panel_two            matlab.ui.container.Panel
        button_prev          matlab.ui.control.Button
        button_next          matlab.ui.control.Button
        label_errorMsg       matlab.ui.control.Label
    end


%---------------------------- CONSTANT PROPERTIES ---------------------------------%    
    
    properties (Access = private, Constant = true)
        % variables that control app's visibility workflow function
        isPanelChar = 'panel_';
        initPanelChar = 'panel_one';
        
        % number of initial steps, pair with startedFlag non constant property
        % change this according to the app's workflow before it unlocks the buton next
        numInitialSteps = 0;
        
        % relevant variables to save in savedVarName
        % savedAppName saves this plus everything else too
        %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
        varsToSaveinStd =  {'appName';
                            'appVersion';
                            'appAuthor';}
    end
    
    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'template';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
        % important/ relevant output from this app
        savedVarName = 'templatevars';
        % the full app, required to populate this app with a previous session
        % this contains ALL components, even those stored in savedVarName
        savedAppName = 'templateapp';
    end
    

%----------------------------- CLASS PROPERTIES ----------------------------------%
    
     properties (Access = private)
        % started flag to make sure there is name and directory
        startedFlag = 0;
        
        % tab control variable, start at first tab
        tc = 1;

        % this variable stores the working directory
        workingDir = '';
    end
    
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%
    
    methods (Access = private)
        
        function loadAppState(app)
            %TODO -- error checking here try catch?
            %TODO -- supress warnings when loading file for fig?
            [tfile, tpath] = uigetfile('*.mat');
            % get file but only the app section of it
            tmatfile = load(fullfile(tpath, tfile));
            loadedFile = tmatfile.(app.savedAppName);
            clear('tmatfile')
            
            if(~strcmp(class(loadedFile), app.appName))
                app.label_errorMsg = 'ERROR: not the right kind of file';
            
            else    
                % if load previous datafile, then populate all variables
                % app-defined user properties
                
                
            end%if file is correct
            
            % delete loaded instance, but first display the name of the standard selected
            app.label_loadFromFile.Text = fullfile(tpath, tfile);
            clear('loadedFile', 'tfile', 'tpath');
            
        end%load file function
        %---------------------------------------------------------------------------%
            
        function s = varsToSaveinStdFcn(app)
            %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
            for(i = 1:length(app.varsToSaveinStd))
                s.(app.varsToSaveinStd{i}) = app.(app.varsToSaveinStd{i});
            end                
        end%vars to save fnc
         %---------------------------------------------------------------------------%
    
        function v = incStartFlag(app, cnt)
            % increase or decrease the start flag with bound checking
            % this ommits the value of cnt and only uses its sign.
            if(cnt>0 && app.startedFlag<app.numInitialSteps)
                app.startedFlag = app.startedFlag + 1;
            elseif(cnt<0 && app.startedFlag>0)
                app.startedFlag = app.startedFlag - 1;
            end
            
            % determine if the start is done.
            if(app.startedFlag>=app.numInitialSteps)
                v = true;
            else
                v = false;
            end
        end%increase start flag
        %---------------------------------------------------------------------------%
        
    end%private methods


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %TODO -- a little hardcoded?
            % fist turn all tabs off
            % get names of all panels and indexes in app. computing here is slower but app may change?
            tnames  = fieldnames(app);
            tpanels = find(contains(tnames,app.isPanelChar));
            
            for(i=1: length(tpanels))
                app.(tnames{tpanels(i)}).Visible = 'off';
            end
            % the first pannel and tab controlled by class constants
            tpanel = find(contains(tnames,app.initPanelChar));
            app.(tnames{tpanel}).Visible = 'on';
            
            % activate appropiate tab. at init = 1
            app.tab_group.SelectedTab = app.tab_group.Children(app.tc);
            
        end

        % Button pushed function: button_next
        function button_nextButtonPushed(app, event)
            % make sure user cant proceed unless name and directory are changed.
            if(~incStartFlag(app,0))
                app.label_errorMsg.Text = 'ERROR: This section must be completed.';
            else
                % fist turn all tabs off
                % get names of all panels and indexes in app. computing here is slower but app may change?
                tnames  = fieldnames(app);
                tpanels = find(contains(tnames,app.isPanelChar));
                
                for(i=1: length(tpanels))
                    app.(tnames{tpanels(i)}).Visible = 'off';
                end
                
                % increment tab control if no bigger than size of tab
                if(app.tc < size(app.tab_group.Children,1))
                    app.tc = app.tc+1;
                end
                
                app.(tnames{tpanels(app.tc)}).Visible = 'on';
                
                % activate appropiate tab
                app.tab_group.SelectedTab = app.tab_group.Children(app.tc);
            end
        end

        % Button pushed function: button_prev
        function button_prevButtonPushed(app, event)
            % fist turn all tabs off
            % get names of all panels and indexes in app. computing here is slower but app may change?
            tnames  = fieldnames(app);
            tpanels = find(contains(tnames,app.isPanelChar));
            
            for(i=1: length(tpanels))
                app.(tnames{tpanels(i)}).Visible = 'off';
            end
            
            % change tab control if no smaller than one
            if(app.tc > 1)
                app.tc = app.tc-1;
            end
            
            app.(tnames{tpanels(app.tc)}).Visible = 'on';
            
            % activate appropiate tab
            app.tab_group.SelectedTab = app.tab_group.Children(app.tc);
        end

        % Button pushed function: button_loadFromFile
        function button_loadFromFileButtonPushed(app, event)
            % load from an existing file
            loadAppState(app);
            app.button_loadFromFile.Enable = 'off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ui_template = uifigure;
            app.ui_template.Name = 'UI Figure';
            app.tab_group = uitabgroup(app.ui_template);
            app.tab_one = uitab(app.tab_group);
            app.tab_one.Title = 'loadData ';
            app.panel_one = uipanel(app.tab_one);
            app.button_loadFromFile = uibutton(app.panel_one, 'push');
            app.button_loadFromFile.ButtonPushedFcn = createCallbackFcn(app, @button_loadFromFileButtonPushed, true);
            app.button_loadFromFile.Text = 'Load From File (0)';
            app.label_loadFromFile = uilabel(app.panel_one);
            app.label_loadFromFile.Text = '';
            app.tab_two = uitab(app.tab_group);
            app.tab_two.Title = 'identifyNodes';
            app.panel_two = uipanel(app.tab_two);
            app.button_prev = uibutton(app.ui_template, 'push');
            app.button_prev.ButtonPushedFcn = createCallbackFcn(app, @button_prevButtonPushed, true);
            app.button_prev.Text = '<<Prev';
            app.button_next = uibutton(app.ui_template, 'push');
            app.button_next.ButtonPushedFcn = createCallbackFcn(app, @button_nextButtonPushed, true);
            app.button_next.Text = 'Next>>';
            app.label_errorMsg = uilabel(app.ui_template);
            app.label_errorMsg.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = template

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ui_template)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ui_template)
        end
    end
end