classdef computeNullStateMechGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        computeNullStateMech      matlab.ui.Figure
        tabg_group                matlab.ui.container.TabGroup
        tab_loadData              matlab.ui.container.Tab
        panel_loadData            matlab.ui.container.Panel
        button_trialSelect        matlab.ui.control.Button
        chkB_trialLock            matlab.ui.control.CheckBox
        lablel_trialFile          matlab.ui.control.Label
        editF_trialFile           matlab.ui.control.EditField
        label_workingDir          matlab.ui.control.Label
        label_step1               matlab.ui.control.Label
        label_existingFile        matlab.ui.control.Label
        button_refreshWorkingDir  matlab.ui.control.Button
        label_step0               matlab.ui.control.Label
        label_trialsAvail         matlab.ui.control.Label
        listB_trialsAvail         matlab.ui.control.ListBox
        button_readTrial          matlab.ui.control.Button
        tab_filterStuff           matlab.ui.container.Tab
        panel_filterStuff         matlab.ui.container.Panel
        label_filterAs            matlab.ui.control.Label
        dropD_filterAs            matlab.ui.control.DropDown
        label_filterAsInstr       matlab.ui.control.Label
        button_readyForFilter     matlab.ui.control.Button
        tab_filterData            matlab.ui.container.Tab
        panel_filterData          matlab.ui.container.Panel
        label_varInWorkspace      matlab.ui.control.Label
        textA_varInWorkspace      matlab.ui.control.TextArea
        table_filterPts           matlab.ui.control.Table
        button_loadFunc           matlab.ui.control.Button
        button_copyToBase         matlab.ui.control.Button
        button_filterApp          matlab.ui.control.Button
        button_signalAn           matlab.ui.control.Button
        button_uglyDataApp        matlab.ui.control.Button
        button_doneFilter         matlab.ui.control.Button
        label_filterInstr         matlab.ui.control.Label
        button_plotPoint          matlab.ui.control.Button
        button_startOver          matlab.ui.control.Button
        label_dataProcessed       matlab.ui.control.Label
        lamp_dataProcessed        matlab.ui.control.Lamp
        tab_computeMech           matlab.ui.container.Tab
        panel_computeMech         matlab.ui.container.Panel
        tab_saveAll               matlab.ui.container.Tab
        panel_saveAll             matlab.ui.container.Panel
        button_saveAllAndExit     matlab.ui.control.Button
        label_savedAs             matlab.ui.control.Label
        button_copyToWorkspace    matlab.ui.control.Button
        label_copyToWorkspace     matlab.ui.control.Label
        editF_copyToWorkspace     matlab.ui.control.EditField
        button_repeatForAll       matlab.ui.control.Button
        button_prev               matlab.ui.control.Button
        button_next               matlab.ui.control.Button
        label_errorMsg            matlab.ui.control.Label
    end


%---------------------------- CONSTANT PROPERTIES ---------------------------------%    
    
    properties (Access = private, Constant = true)
        % variables that control app's visibility workflow function
        isTabChar = 'tab_';
        
        % number of initial steps, pair with startedFlag non constant property
        % change this according to the app's workflow before it unlocks the buton next
        numInitialSteps = 1;
    end
    
    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'computeNullStateMechGui';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
        
        % important/ relevant output from this app
        savedVarName = 'computeNullStateMech';
        
        % the full app, required to populate this app with a previous session
        % this contains ALL components, even those stored in savedVarName
        savedAppName = 'computeNullStateMechApp';
        
        % relevant variables to save in savedVarName
        % savedAppName saves this plus everything else too
        %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
        varsToSaveinStd =  {'motion';
                            'appVersion'};

        %character that appends variables to copy to workspace
        bodyChar  = 'b';
        worldChar = 'w';
        xChar     = '_x';
        yChar     = '_y';
        zChar     = '_z';
        
        %no function marker
        noFunc = {'none'};
          
    end
    

%---------------------------- VARIABLE PROPERTIES --------------------------------%
    
    properties (Access = private)
        % this variable stores the working directory
        workingDir = '';
        trialDir   = '';
        
        % started flag to make sure there is name and directory
        startedFlag = 0;
        
        % tab control variable, start at first tab
        tabControl = 1;
        
        % application tabs, used to move around with buttons, computed at startup
        applicationTabs;
        
        %paths to functions to get added (and removed at exit)
        funcPaths = cell('');
        funcAvail;
        
        %flag for batch save
        batchSaveFlag = false;
        functionNames;
        bodyWorldChar;
        pointName;
        
        %motion data if needs to be transformed
        localMotion;
        worldMotion;
        
        %selected cells on filter funciton
        filterPtsCellSel = 0;
        
    end
         
    properties (Access = public)
        % local copy of node standard imported and names of its elements
        %TODO -- this is a stupid name
        motion;
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%
    
    methods (Access = private)
        
        function setInitialGuiParam(app)
            % fist turn all panels off
            % get names of all tabs and indexes in app.
            tnames  = fieldnames(app);
            
            % tabs are in the order that they apear in the gui in sequence
            %TODO -- if tabs have multiple children this wont work
            app.applicationTabs = tnames(contains(tnames,app.isTabChar));
            
            % disable all panels
            for(i=1: length(app.applicationTabs))
                app.(app.applicationTabs{i}).Children.Visible = 'off';
            end
            
            % the first pannel and tab controlled by class tab control
            app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
            app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            
            %start funcion list with the empty car
            app.funcAvail = app.noFunc;
            
            % load the study directory and display files
            loadWorkingDirFiles(app);
            findAppInstance(app);
        end%setInitialGuiParam
%-----------------------------------------------------------------------------------%
        
        function loadWorkingDirFiles(app)   
                %TODO -- should trim this to show a ../../dir
                app.label_workingDir.Text = app.workingDir;

                % dont display the trials availiable
                tignorefiles = {'.'; '..'; '.git'};
                tmp = cellstr(ls(fullfile(app.workingDir, 'trial')));
                tmp = setdiff(tmp,tignorefiles);
                app.listB_trialsAvail.Items = tmp;
        end%loadWorkingDirFiles
%-----------------------------------------------------------------------------------%

        function findAppInstance(app)
            % see if there is an instance of this app in the working directory
            if(~(isfile(fullfile(app.trialDir, [biolocomotionMainVar.savedMotionVar '.mat']))))
                app.label_existingFile.Text = {['There is no ' biolocomotionMainVar.savedMotionVar ' File in the Working Directory.'] 'Saving will create a new one.'};
            else
                %tell the user this exist
                app.label_existingFile.Text = {['There is a ' biolocomotionMainVar.savedMotionVar ' File in the Working Directory.'] 'Saving will overwrite it.'};
            end
        end%findAppInstance
%-----------------------------------------------------------------------------------%

        function incStartFlag(app, cnt)
            % increase or decrease the start flag with bound checking
            % this ommits the value of cnt and only uses its sign.
            if(cnt>0 && app.startedFlag<app.numInitialSteps)
                app.startedFlag = app.startedFlag + 1;
            elseif(cnt<0 && app.startedFlag>0)
                app.startedFlag = app.startedFlag - 1;
            end
            
            % determine if the start is done and enable prev and next buttons
            if(app.startedFlag>=app.numInitialSteps)
                app.button_next.Enable = 'on';
                app.button_prev.Enable = 'on';
            else
                app.button_next.Enable = 'off';
                app.button_prev.Enable = 'off';
            end
        end%incStartFlag
%-----------------------------------------------------------------------------------%
        
        function advanceTab(app, cnt)
            % first check if cnt will request a tab out of range
            if((app.tabControl+cnt)>length(app.applicationTabs) || (app.tabControl+cnt)<1)
                dispMsg(app, 'e', 'tab change request out of range');
            else
                % clear errors if any
                dispMsg(app, 'c');
                
                % disable the current panel
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'off';
                
                % increase tab control and activate next panel and tab
                app.tabControl = app.tabControl+cnt;
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
                app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            end
        end%advanceTab
%-----------------------------------------------------------------------------------%

        function s = varsToSaveinStdFcn(app)
            % saves just the relevant variables that come out of this class not to save ui elements
            for(i = 1:length(app.varsToSaveinStd))
                s.(app.varsToSaveinStd{i}) = app.(app.varsToSaveinStd{i});
            end                
        end%varsToSaveinStdFcn
%-----------------------------------------------------------------------------------%   
         
        function loadTrialData(app)
            %TODO -- should i need try catch here to make sure that this is a standard node
            %TODO -- this should be a motion object but just load it like this for now
            %get the motion data and make a new copy because it will get
            %transformed after new coordinate systems
            tmp = fullfile(app.trialDir, [biolocomotionMainVar.savedMotionVar '.mat']);
            app.motion = load(tmp);
        end%loadTrialData
%-----------------------------------------------------------------------------------%

        function dispMsg(app, ttype, tmsg)
            % For log messaging blue
            if(strcmpi(ttype, 'L'))
                app.label_errorMsg.Text = ['LOG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.45,0.74];
                
            % for error messages red
            elseif(strcmpi(ttype, 'E'))
                app.label_errorMsg.Text = ['ERROR: ' tmsg];
                app.label_errorMsg.FontColor = [0.64,0.08,0.18];
                
            % general propose message black
            elseif(strcmpi(ttype, 'M'))
                app.label_errorMsg.Text = ['MSG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.00,0.00];
            
            % for clearing the mesage
            elseif(strcmpi(ttype, 'C'))
                app.label_errorMsg.Text = '';
            end
        end%dispMsg
%-----------------------------------------------------------------------------------%

    function applyFunction(app)
        %for each table line sweet trough and apply functions
        for(i = 1: size(app.pointName,1))
            tname = app.pointName{i};
            
            %skip if no function selected
            tfunc = app.functionNames{i};
            if(strcmp(tfunc, app.noFunc))  
                continue
            end
            tfunhan = str2func(tfunc);
            
            if(strcmp(app.bodyWorldChar(i), app.bodyChar))
                %make function handle and process
                tmp = app.localMotion.(tname);
                [tx, ty, tz] = tfunhan(tmp(:,1), tmp(:,2), tmp(:,3));
                app.localMotion.(tname) = [tx, ty, tz];
            else
                %make function handle and process
                tmp = app.worldMotion.(tname);
                [tx, ty, tz] = tfunhan(tmp(:,1), tmp(:,2), tmp(:,3));
                app.worldMotion.(tname) = [tx, ty, tz];
            end
            clear('tfunhan');
        end
        
        %not making mechanics so ploting now
        app.motion.bodyMotion  = app.localMotion;
        app.motion.worldMotion = app.worldMotion;

    end%applyFunction
    
%-----------------------------------------------------------------------------------%

    function closeApp(app)
        for(i = 1: length(app.funcPaths))
            rmpath(app.funcPaths{i});
        end
        delete(app)
    end%closeApp
%-----------------------------------------------------------------------------------%

    end%private methods

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tworkingDir)
            app.workingDir = tworkingDir;
            setInitialGuiParam(app);
        end

        % Button pushed function: button_next
        function button_nextButtonPushed(app, event)
            % move to next tab
            advanceTab(app, 1);
        end

        % Button pushed function: button_prev
        function button_prevButtonPushed(app, event)
            % move to previous tab
            advanceTab(app, -1);
        end

        % Value changed function: listB_trialsAvail
        function listB_trialsAvailValueChanged(app, event)
            value = app.listB_trialsAvail.Value;
            loadWorkingDirFiles(app);
        end

        % Button pushed function: button_refreshWorkingDir
        function button_refreshWorkingDirButtonPushed(app, event)
            loadWorkingDirFiles(app);
        end

        % Value changed function: chkB_trialLock
        function chkB_trialLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_trialLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.button_trialSelect.Enable = 'off';
                app.button_readTrial.Enable   = 'on';
                findAppInstance(app);
                incStartFlag(app, 1);
            else
                app.button_trialSelect.Enable = 'on';
                app.button_readTrial.Enable   = 'off';
                app.label_existingFile.Text   = {''};
                incStartFlag(app, -1);
            end
        end

        % Button pushed function: button_trialSelect
        function button_trialSelectButtonPushed(app, event)
            % assign value of current selected file to this field
            % make sure there is a selection
            if(~isempty(app.listB_trialsAvail.Value))
                app.editF_trialFile.Value = app.listB_trialsAvail.Value;
                
                % lock the value trough the checkbox
                app.chkB_trialLock.Value = 1;
                chkB_trialLockValueChanged(app);
            
                % clear errors if any
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'Select a file first');
            end 
        end

        % Button pushed function: button_readTrial
        function button_readTrialPushed(app, event)
            %disable change
            %TODO -- there is no error checking here
            app.chkB_trialLock.Enable = 'off';
            app.trialDir = fullfile(app.workingDir, 'trial', app.editF_trialFile.Value);
            loadTrialData(app);
            dispMsg(app, 'l', 'File read! proceed');  
            incStartFlag(app, 1);
        end

        % Button pushed function: button_readyForFilter
        function button_readyForFilterButtonPushed(app, event)
            %disable options first
            app.dropD_filterAs.Enable = 'off';
            
            %make a local copy to modify as needed, dont include sample num
            app.localMotion = app.motion.bodyMotion;
            app.worldMotion = app.motion.worldMotion;
            
            try
                %TODO -- this is dumb as shit, make that row numbername
                app.localMotion.(motionCsvToNodeStdMapGui.csvDataRowNumberName) = [];
                app.worldMotion.(motionCsvToNodeStdMapGui.csvDataRowNumberName) = [];
            catch
            end
            
            %transfrom data to world if asked to
            %TODO -- i am not doing this now
            
            
            %fill table with all points
            app.table_filterPts.ColumnFormat = {[], [], app.funcAvail};
            app.table_filterPts.ColumnEditable = [false, false, true];
            
            tbodyworld = [repmat({app.bodyChar},[length(app.localMotion.Properties.VariableNames) 1]); repmat({app.worldChar},[length(app.worldMotion.Properties.VariableNames) 1])];
            tnames     = [app.localMotion.Properties.VariableNames(:); app.worldMotion.Properties.VariableNames(:)];
            tfunc      = repmat(app.noFunc, [length(tnames), 1]);
            
            app.table_filterPts.Data = [tbodyworld, tnames, tfunc];
            
            dispMsg(app, 'l', 'Proceed!');
            
        end

        % Cell selection callback: table_filterPts
        function table_filterPtsCellSelection(app, event)
            indices = event.Indices;
            app.filterPtsCellSel = indices(1,1);
        end

        % Button pushed function: button_copyToBase
        function button_copyToBaseButtonPushed(app, event)
            % separate into variables and copy to workspace both body and world
            dispMsg(app, 'c');
            
            % find the variable to copy to workspace
            tname = app.table_filterPts.Data{app.filterPtsCellSel,2};
            
            if(strcmp(app.table_filterPts.Data(app.filterPtsCellSel,1), app.bodyChar))
                tmp = app.localMotion.(tname);
            else
                tmp = app.worldMotion.(tname);
            end
            assignin('base', [tname, app.xChar], tmp(:,1));
            assignin('base', [tname, app.yChar], tmp(:,2));
            assignin('base', [tname, app.zChar], tmp(:,3));
            
            dispMsg(app, 'l', 'Proceed! Variable copied to base workspace');
        end

        % Button pushed function: button_loadFunc
        function button_loadFuncButtonPushed(app, event)
            %get one function path at a time
            [tfile, tpath] = uigetfile('*.m','Titles in dialogs are overrated',app.workingDir);
            if isequal(tfile,0)
                dispMsg(app, 'e', 'User selected Cancel');
            else
                dispMsg(app, 'c');
                app.funcPaths = [app.funcPaths; {tpath}];
                [~,tname,~]  = fileparts(tfile);
                app.funcAvail = [app.funcAvail, {tname}];
                app.textA_varInWorkspace.Value  = app.funcAvail;
                app.table_filterPts.ColumnFormat = {[], [], app.funcAvail};
            end
        end

        % Button pushed function: button_plotPoint
        function button_plotPointPushed(app, event)
            % get the name of the point to plot
            % separate into variables and copy to workspace both body and world
            dispMsg(app, 'c');
            
            % find the variable to copy to workspace
            %TODO -- this wont work just like so if i do world transformations
            tname = app.table_filterPts.Data{app.filterPtsCellSel,2};
            
            if(strcmp(app.table_filterPts.Data(app.filterPtsCellSel,1), app.bodyChar))
                tfil = app.localMotion.(tname);
                tori = app.motion.bodyMotion.(tname);
            else
                tfil = app.worldMotion.(tname);
                tori = app.motion.worldMotion.(tname);
            end
            
            figure();
            plot(tori);
            hold('on');
            plot(tfil);
            hold('off');
            legend('original X','original Y','original Z','new X','new Y','new Z');
            grid('on');
            
            figure();
            plot3(tori(:,1), tori(:,2), tori(:,3));
            hold('on');
            plot3(tfil(:,1), tfil(:,2), tfil(:,3));
            hold('off');
            legend('original XYZ', 'new XYZ');
            xlabel('X', 'FontWeight', 'bold');
            ylabel('Y', 'FontWeight', 'bold');
            zlabel('Z', 'FontWeight', 'bold');
            %TODO -- make sure this package is loaded with the toolbox
            tptstart = tori(1,1:3);
            tptend   = tori(end,1:3);
            arrow(tptstart,tptend, 2);
            grid('on');
        end

        % Button pushed function: button_startOver
        function button_startOverPushed(app, event)
            %make a local copy to modify as needed, dont include sample num
            %TODO -- this is repeated code it can be made into a function
            app.localMotion = app.motion.bodyMotion;
            app.worldMotion = app.motion.worldMotion;
            
            try
                %TODO -- this is dumb as shit, make that row numbername
                app.localMotion.(motionCsvToNodeStdMapGui.csvDataRowNumberName) = [];
                app.worldMotion.(motionCsvToNodeStdMapGui.csvDataRowNumberName) = [];
            catch
            end
            dispMsg(app, 'l', 'Data is back to its original values');
            app.lamp_dataProcessed.Color = 'r';
        end

        % Button pushed function: button_uglyDataApp
        function button_uglyDataAppButtonPushed(app, event)
            try
                evalin('base', 'UglyDataApp');
            catch ME
                dispMsg(app, 'e', 'Either the UglyDataApp is not installed or its not added to path');
                display(ME.message);
            end
        end

        % Button pushed function: button_filterApp
        function button_filterAppPushed(app, event)
            try
                evalin('base', 'filterDesigner');
            catch ME
                dispMsg(app, 'e', 'Either the UglyDataApp is not installed or its not added to path');
                display(ME.message);
            end
        end

        % Button pushed function: button_signalAn
        function button_signalAnButtonPushed(app, event)
            try
                 evalin('base', 'signalAnalyzer');
            catch ME
                dispMsg(app, 'e', 'Either the UglyDataApp is not installed or its not added to path');
                display(ME.message);
            end
        end

        % Button pushed function: button_doneFilter
        function button_doneFilterButtonPushed(app, event)
            % add function paths
            dispMsg(app, 'l', 'Making transformations bip bop bip bop');
            for(i = 1: length(app.funcPaths))
                addpath(app.funcPaths{i});
            end
            
            %for each table line sweet trough and apply functions
            for(i = 1: size(app.table_filterPts.Data,1))
                tname = app.table_filterPts.Data{i,2};
                
                %skip if no function selected
                tfunc = app.table_filterPts.Data{i,3};
                if(strcmp(tfunc, app.noFunc))  
                    continue
                end
                tfunhan = str2func(tfunc);
                
                if(strcmp(app.table_filterPts.Data(i,1), app.bodyChar))
                    %make function handle and process
                    tmp = app.localMotion.(tname);
                    [tx, ty, tz] = tfunhan(tmp(:,1), tmp(:,2), tmp(:,3));
                    app.localMotion.(tname) = [tx, ty, tz];
                else
                    %make function handle and process
                    tmp = app.worldMotion.(tname);
                    [tx, ty, tz] = tfunhan(tmp(:,1), tmp(:,2), tmp(:,3));
                    app.worldMotion.(tname) = [tx, ty, tz];
                end
                %delete vfunction handle and variables if exist
                evalin('base', ['clear ', tname, app.xChar]);
                evalin('base', ['clear ', tname, app.yChar]);
                evalin('base', ['clear ', tname, app.zChar]);
                clear('tfunhan');
            end
            
            %make indicators
            dispMsg(app, 'l', 'donzo with this part');
            app.lamp_dataProcessed.Color = 'g';
            
            %not making mechanics so ploting now
            app.motion.bodyMotion  = app.localMotion;
            app.motion.worldMotion = app.worldMotion;
            
            %used for batch trials
            app.bodyWorldChar = app.table_filterPts.Data(:,1);
            app.pointName     = app.table_filterPts.Data(:,2);
            app.functionNames = app.table_filterPts.Data(:,3);

        end

        % Button pushed function: button_saveAllAndExit
        function button_saveAllAndExitButtonPushed(app, event)
            % disable wargings from saving stuff such as uia components
            %TODO -- just dont try to save them to prevent warning
            warning('off');
            
             %need temp structure to be saved
            tmp = app.motion;
            save(fullfile(app.trialDir, [biolocomotionMainVar.savedMotionVar '.mat']), '-struct', 'tmp');
            app.label_savedAs.Text = fullfile(app.trialDir, [biolocomotionMainVar.savedMotionVar '.mat']);
            
            
            if(~app.batchSaveFlag)
                % alert the user it is done
                tsel = uiconfirm(app.computeNullStateMech,{'All Saved!!!';'You can do this for all trials or close'},'You Are Amazing',...computeNullMech
                                                             'Options',{'Close app','Do more trials','Cancel'},'DefaultOption',2,'CancelOption',3);
                
                if(strcmp(tsel,'Close app'))
                    closeApp(app);
                elseif(strcmp(tsel,'Do more trials'))
                    app.button_repeatForAll.Enable   = 'on';
                    app.button_saveAllAndExit.Enable = 'off';
                end
            end
            
            % turn warnings back on
            warning('on');
            %TODO -- this is a matlab bug
            warning off MATLAB:subscripting:noSubscriptsSpecified;
        end

        % Button pushed function: button_repeatForAll
        function button_repeatForAllButtonPushed(app, event)
            %get the name of the trials to-do
            ttrials = app.listB_trialsAvail.Items(~strcmp(app.listB_trialsAvail.Items,app.editF_trialFile.Value));
            app.batchSaveFlag = true;

            for(i = 1: length(ttrials))
                app.trialDir = fullfile(app.workingDir, 'trial', ttrials{i});

                loadTrialData(app);
                button_readyForFilterButtonPushed(app);
                applyFunction(app);
                
                button_saveAllAndExitButtonPushed(app);
            end
            
            tsel = uiconfirm(app.computeNullStateMech,{'All Saved!!!';'all trials are done'},'You Are Amazing',...
                                                             'Options',{'Close app','Cancel'},'DefaultOption',1,'CancelOption',2);
                
            if(strcmp(tsel,'Close app'))
                closeApp(app);
            end
        end

        % Button pushed function: button_copyToWorkspace
        function button_copyToWorkspaceButtonPushed(app, event)
            % first make sure that the name is valid
            if(isvarname(app.editF_copyToWorkspace.Value))
                % create a struct of the saved variables
                tmp.(app.savedVarName) = varsToSaveinStdFcn(app);
                
                % assign value to base workspace in matlab
                assignin('base',app.editF_copyToWorkspace.Value,tmp.(app.savedVarName));
                
                % clear error if any
                dispMsg(app, 'l', 'Copied to base workspace');  
            else
                dispMsg(app, 'e', 'The variable in field must be a valid matlab name');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.computeNullStateMech = uifigure('Visible', 'off');
            app.computeNullStateMech.Name = 'Compute Motion Null Mechanics';
            app.tabg_group = uitabgroup(app.computeNullStateMech);
            app.tab_loadData = uitab(app.tabg_group);
            app.tab_loadData.Title = 'Load Data';
            app.panel_loadData = uipanel(app.tab_loadData);
            app.button_trialSelect = uibutton(app.panel_loadData, 'push');
            app.button_trialSelect.ButtonPushedFcn = createCallbackFcn(app, @button_trialSelectButtonPushed, true);
            app.button_trialSelect.Text = '<<';
            app.chkB_trialLock = uicheckbox(app.panel_loadData);
            app.chkB_trialLock.ValueChangedFcn = createCallbackFcn(app, @chkB_trialLockValueChanged, true);
            app.chkB_trialLock.Text = 'Lock';
            app.lablel_trialFile = uilabel(app.panel_loadData);
            app.lablel_trialFile.Text = 'Trial';
            app.editF_trialFile = uieditfield(app.panel_loadData, 'text');
            app.editF_trialFile.Editable = 'off';
            app.label_workingDir = uilabel(app.panel_loadData);
            app.label_workingDir.Text = '';
            app.label_step1 = uilabel(app.panel_loadData);
            app.label_step1.Text = '(1)';
            app.label_existingFile = uilabel(app.panel_loadData);
            app.label_existingFile.Text = '';
            app.button_refreshWorkingDir = uibutton(app.panel_loadData, 'push');
            app.button_refreshWorkingDir.ButtonPushedFcn = createCallbackFcn(app, @button_refreshWorkingDirButtonPushed, true);
            app.button_refreshWorkingDir.Text = 'Refresh';
            app.label_step0 = uilabel(app.panel_loadData);
            app.label_step0.Text = '(0)';
            app.label_trialsAvail = uilabel(app.panel_loadData);
            app.label_trialsAvail.Text = 'Trials';
            app.listB_trialsAvail = uilistbox(app.panel_loadData);
            app.listB_trialsAvail.Items = {};
            app.listB_trialsAvail.ValueChangedFcn = createCallbackFcn(app, @listB_trialsAvailValueChanged, true);
            app.listB_trialsAvail.Value = {};
            app.button_readTrial = uibutton(app.panel_loadData, 'push');
            app.button_readTrial.ButtonPushedFcn = createCallbackFcn(app, @button_readTrialPushed, true);
            app.button_readTrial.Enable = 'off';
            app.button_readTrial.Text = 'Read this file';
            app.tab_filterStuff = uitab(app.tabg_group);
            app.tab_filterStuff.Title = 'Filter Stuff';
            app.panel_filterStuff = uipanel(app.tab_filterStuff);
            app.label_filterAs = uilabel(app.panel_filterStuff);
            app.label_filterAs.Text = 'Filter as';
            app.dropD_filterAs = uidropdown(app.panel_filterStuff);
            app.dropD_filterAs.Items = {'Body', 'World'};
            app.dropD_filterAs.Value = 'Body';
            app.label_filterAsInstr = uilabel(app.panel_filterStuff);
            app.label_filterAsInstr.Text = {'- This depends on what you are doing.'; '- There is no right method as a rule.'; ''; '- World will:'; '   - Transform data to world coordiantes'; '   - Apply filter to those numbers'; '   - Then return to body coordiantes'; '- Body will:'; '    - Apply filter to body points in the body frame'; '    - Apply filter of body triad in the world frame'; ''; '- It really depends on what are you tracking, how'; '   you tracked it, and how is your error distribuited.'};
            app.button_readyForFilter = uibutton(app.panel_filterStuff, 'push');
            app.button_readyForFilter.ButtonPushedFcn = createCallbackFcn(app, @button_readyForFilterButtonPushed, true);
            app.button_readyForFilter.Text = 'Ready data for filtering';
            app.tab_filterData = uitab(app.tabg_group);
            app.tab_filterData.Title = 'Filter Data';
            app.panel_filterData = uipanel(app.tab_filterData);
            app.label_varInWorkspace = uilabel(app.panel_filterData);
            app.label_varInWorkspace.Text = 'Functions availiable';
            app.textA_varInWorkspace = uitextarea(app.panel_filterData);
            app.table_filterPts = uitable(app.panel_filterData);
            app.table_filterPts.ColumnName = {'b/w'; 'Name'; 'Function'};
            app.table_filterPts.RowName = {};
            app.table_filterPts.CellSelectionCallback = createCallbackFcn(app, @table_filterPtsCellSelection, true);
            app.button_loadFunc = uibutton(app.panel_filterData, 'push');
            app.button_loadFunc.ButtonPushedFcn = createCallbackFcn(app, @button_loadFuncButtonPushed, true);
            app.button_loadFunc.Text = 'Load function';
            app.button_copyToBase = uibutton(app.panel_filterData, 'push');
            app.button_copyToBase.ButtonPushedFcn = createCallbackFcn(app, @button_copyToBaseButtonPushed, true);
            app.button_copyToBase.Text = 'Copy to workspace';
            app.button_filterApp = uibutton(app.panel_filterData, 'push');
            app.button_filterApp.ButtonPushedFcn = createCallbackFcn(app, @button_filterAppPushed, true);
            app.button_filterApp.Text = 'Open filter app';
            app.button_signalAn = uibutton(app.panel_filterData, 'push');
            app.button_signalAn.ButtonPushedFcn = createCallbackFcn(app, @button_signalAnButtonPushed, true);
            app.button_signalAn.Text = 'Open Signal Analyzer';
            app.button_uglyDataApp = uibutton(app.panel_filterData, 'push');
            app.button_uglyDataApp.ButtonPushedFcn = createCallbackFcn(app, @button_uglyDataAppButtonPushed, true);
            app.button_uglyDataApp.Text = 'Open Ugly Data app';
            app.button_doneFilter = uibutton(app.panel_filterData, 'push');
            app.button_doneFilter.ButtonPushedFcn = createCallbackFcn(app, @button_doneFilterButtonPushed, true);
            app.button_doneFilter.Text = 'I am donzo';
            app.label_filterInstr = uilabel(app.panel_filterData);
            app.label_filterInstr.Text = {'- Why make code if there is good stuff out there.'; '- There are afew options that you can use depending '; '   on your data.'; '- If you copy to workspace it will get a xyz variable '; '   that you can use to test filters.'; '- The idea is that you make functions and then '; '   indicate which points will be treated with such '; '   funcitons.'; '- Functions must be of this form:'; '   [X, Y, Z] = yourFunc(x,y,z);'; '   depending on the output from the different apps'; '   to smooth and filters you may have to make a '; '   wrapper function.'; '- Ideally these extra functions should live in the '; '   /share dir.'; '- Any point with "none" or with an unchecked Done '; '   will just have the original data.'; '- Done will also remove all copied variables'; '  from your workspace and process all points.'; '- Click "done" when ready to move on.'; '- You can plot and copy before and after you say '; '   done to see the changes. if you dont like it,'; '   then click start over to reset the points back.'; '- Data is not saved until the very end of this app.'};
            app.button_plotPoint = uibutton(app.panel_filterData, 'push');
            app.button_plotPoint.ButtonPushedFcn = createCallbackFcn(app, @button_plotPointPushed, true);
            app.button_plotPoint.Text = 'Plot point';
            app.button_startOver = uibutton(app.panel_filterData, 'push');
            app.button_startOver.ButtonPushedFcn = createCallbackFcn(app, @button_startOverPushed, true);
            app.button_startOver.Text = 'Nopup, start over';
            app.label_dataProcessed = uilabel(app.panel_filterData);
            app.label_dataProcessed.Text = 'Data is processed';
            app.lamp_dataProcessed = uilamp(app.panel_filterData);
            app.tab_computeMech = uitab(app.tabg_group);
            app.tab_computeMech.Title = 'Compute Mechanics';
            app.panel_computeMech = uipanel(app.tab_computeMech);
            app.tab_saveAll = uitab(app.tabg_group);
            app.tab_saveAll.Title = 'Save All';
            app.panel_saveAll = uipanel(app.tab_saveAll);
            app.button_saveAllAndExit = uibutton(app.panel_saveAll, 'push');
            app.button_saveAllAndExit.ButtonPushedFcn = createCallbackFcn(app, @button_saveAllAndExitButtonPushed, true);
            app.button_saveAllAndExit.Text = 'SAVE ALL!!!!!!!';
            app.label_savedAs = uilabel(app.panel_saveAll);
            app.label_savedAs.Text = '';
            app.button_copyToWorkspace = uibutton(app.panel_saveAll, 'push');
            app.button_copyToWorkspace.ButtonPushedFcn = createCallbackFcn(app, @button_copyToWorkspaceButtonPushed, true);
            app.button_copyToWorkspace.Text = 'Copy results to workspace';
            app.label_copyToWorkspace = uilabel(app.panel_saveAll);
            app.label_copyToWorkspace.Text = 'Var Name';
            app.editF_copyToWorkspace = uieditfield(app.panel_saveAll, 'text');
            app.button_repeatForAll = uibutton(app.panel_saveAll, 'push');
            app.button_repeatForAll.ButtonPushedFcn = createCallbackFcn(app, @button_repeatForAllButtonPushed, true);
            app.button_repeatForAll.Enable = 'off';
            app.button_repeatForAll.Text = 'Repeat this for all trials (will also automatically save)';
            app.button_prev = uibutton(app.computeNullStateMech, 'push');
            app.button_prev.ButtonPushedFcn = createCallbackFcn(app, @button_prevButtonPushed, true);
            app.button_prev.Enable = 'off';
            app.button_prev.Text = '<<Prev';
            app.button_next = uibutton(app.computeNullStateMech, 'push');
            app.button_next.ButtonPushedFcn = createCallbackFcn(app, @button_nextButtonPushed, true);
            app.button_next.Enable = 'off';
            app.button_next.Text = 'Next>>';
            app.label_errorMsg = uilabel(app.computeNullStateMech);
            app.label_errorMsg.Text = '';
            % Show the figure after all components are created
            app.computeNullStateMech.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = computeNullStateMechGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.computeNullStateMech)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.computeNullStateMech)
        end
    end
end