classdef createBlankStudyArchitectureGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        createBlankStudyArchitecture  matlab.ui.Figure
        tabg_group                    matlab.ui.container.TabGroup
        tab_createStudy               matlab.ui.container.Tab
        panel_createStudy             matlab.ui.container.Panel
        label_saveName                matlab.ui.control.Label
        editF_saveName                matlab.ui.control.EditField
        button_browse                 matlab.ui.control.Button
        button_create                 matlab.ui.control.Button
        button_thisIsAStudy           matlab.ui.control.Button
        label_workingDir              matlab.ui.control.Label
        label_step1                   matlab.ui.control.Label
        label_step2                   matlab.ui.control.Label
        tab_copyStudyFiles            matlab.ui.container.Tab
        panel_copyStudyFiles          matlab.ui.container.Panel
        button_downloadRenameTool     matlab.ui.control.Button
        textA_excelToolInstructions   matlab.ui.control.TextArea
        label_step3                   matlab.ui.control.Label
        label_step4                   matlab.ui.control.Label
        tab_createTrials              matlab.ui.container.Tab
        panel_createTrials            matlab.ui.container.Panel
        button_generateTrials         matlab.ui.control.Button
        label_globalFiles             matlab.ui.control.Label
        listB_globalFiles             matlab.ui.control.ListBox
        label_currentTrials           matlab.ui.control.Label
        listB_currentTrials           matlab.ui.control.ListBox
        label_copyGlobalFiles         matlab.ui.control.Label
        button_refreshGlobal          matlab.ui.control.Button
        label_selectFileInstructions  matlab.ui.control.Label
        label_step5                   matlab.ui.control.Label
        label_step6                   matlab.ui.control.Label
        label_step7                   matlab.ui.control.Label
        tab_batchCopy                 matlab.ui.container.Tab
        panel_batchCopy               matlab.ui.container.Panel
        table_availiableTrials        matlab.ui.control.Table
        button_refreshResShare        matlab.ui.control.Button
        label_resAndSare              matlab.ui.control.Label
        listB_resAndSare              matlab.ui.control.ListBox
        button_batchCopyFiles         matlab.ui.control.Button
        label_step8                   matlab.ui.control.Label
        label_step9                   matlab.ui.control.Label
        button_prev                   matlab.ui.control.Button
        button_next                   matlab.ui.control.Button
        label_errorMsg                matlab.ui.control.Label
    end

%---------------------------- CONSTANT PROPERTIES ---------------------------------%    
    
    properties (Access = private, Constant = true)
        % variables that control app's visibility workflow function
        isTabChar = 'tab_';
        
        % number of initial steps, pair with startedFlag non constant property
        % change this according to the app's workflow before it unlocks the buton next
        numInitialSteps = 1;
    end
    
    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'createBlankStudyArchitectureGui';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
        
        % important/ relevant output from this app
        savedVarName = 'createBlankStudyArchitecture';
        
        % the full app, required to populate this app with a previous session
        % this contains ALL components, even those stored in savedVarName
        savedAppName = 'createBlankStudyArchitectureApp';
        
        % relevant variables to save in savedVarName
        % savedAppName saves this plus everything else too
        %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
        varsToSaveinStd =  {};
    end
    
%----------------------------- CLASS PROPERTIES ----------------------------------%
    
     properties (Access = private)
        % this variable stores the working directory
        workingDir = '';
        
        % started flag to make sure there is name and directory
        startedFlag = 0;
        
        % tab control variable, start at first tab
        tabControl = 1;
        
        % application tabs, used to move around with buttons, computed at startup
        applicationTabs;
        
        % global files serched recursively extended and compressed
        extendedGlobalFiles;
        globalFiles;
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%
    
    methods (Access = private)
            
        function setInitialGuiParam(app)
            % fist turn all panels off
            % get names of all tabs and indexes in app.
            tnames  = fieldnames(app);
            
            % tabs are in the order that they apear in the gui in sequence
            %TODO -- if tabs have multiple children this wont work
            app.applicationTabs = tnames(contains(tnames,app.isTabChar));
            
            % disable all panels
            for(i=1: length(app.applicationTabs))
                app.(app.applicationTabs{i}).Children.Visible = 'off';
            end
            
            % the first pannel and tab controlled by class tab control
            app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
            app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            
            % get the instructions from res folder
            %TODO -- point to root and res, hardcoded
            try
                app.textA_excelToolInstructions.Value = ...
                    fileread('C:\Users\alberto-bortoni\OneDrive\research\biolocomotion\res\excelAddins\installationInstructions.txt');
            catch
            end
            
        end%setInitialGuiParam
%-----------------------------------------------------------------------------------%

        function loadAppState(app, loadedFile)
            % if load previous datafile, then populate all variables
            % app-defined user properties
            % tab one, edit fields
            app.editF_nodeStandard.Value    = loadedFile.editF_nodeStandard.Value;
            app.editF_motionData.Value      = loadedFile.editF_motionData.Value;
            app.editF_cameraObject.Value    = loadedFile.editF_cameraObject.Value;
            app.listB_currentTrials.Items   = loadedFile.listB_currentTrials.Items;
            app.chkB_nodeStandardLock.Value = loadedFile.chkB_nodeStandardLock.Value;
            app.chkB_motionDataLock.Value   = loadedFile.chkB_motionDataLock.Value;
            app.chkB_cameraObjectLock.Value = loadedFile.chkB_cameraObjectLock.Value;
            % make sure checkbox enables/disables
            chkB_motionDataLockValueChanged(app);
            chkB_nodeStandardLockValueChanged(app);
            chkB_cameraObjectLockValueChanged(app);
            
            % tab two
            app.listB_videosToMap.Items   = loadedFile.listB_videosToMap.Items;
            app.table_cameraVideoMap.Data = loadedFile.table_cameraVideoMap.Data;
            
            % other public properties
            importObjectInstances(app);
            
            % move the user to the viewer
            advanceTab(app,2);
        end%loadAppState
%-----------------------------------------------------------------------------------%

        function incStartFlag(app, cnt)
            % increase or decrease the start flag with bound checking
            % this ommits the value of cnt and only uses its sign.
            if(cnt>0 && app.startedFlag<app.numInitialSteps)
                app.startedFlag = app.startedFlag + 1;
            elseif(cnt<0 && app.startedFlag>0)
                app.startedFlag = app.startedFlag - 1;
            end
            
            % determine if the start is done and enable prev and next buttons
            if(app.startedFlag>=app.numInitialSteps)
                app.button_next.Enable = 'on';
                app.button_prev.Enable = 'on';
            else
                app.button_next.Enable = 'off';
                app.button_prev.Enable = 'off';
            end
        end%incStartFlag
%-----------------------------------------------------------------------------------%
        
        function advanceTab(app, cnt)
            % first check if cnt will request a tab out of range
            if((app.tabControl+cnt)>length(app.applicationTabs) || (app.tabControl+cnt)<1)
                dispMsg(app, 'e', 'tab change request out of range');
            else
                % clear errors if any
                dispMsg(app, 'c');
                
                % disable the current panel
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'off';
                
                % increase tab control and activate next panel and tab
                app.tabControl = app.tabControl+cnt;
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
                app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            end
        end%advanceTab
%-----------------------------------------------------------------------------------%    

        function createDirectoryTree(app)
            % create study tree
            %TODO -- this does nto verify that is on a place that the user has write access
            app.workingDir = fullfile(app.workingDir, app.editF_saveName.Value);
            app.label_workingDir.Text = app.workingDir;
            
            mkdir(app.workingDir)
            
            %TODO -- should i be getting these names from an enaum or something?
            % generate the standard name of theirectories
            mkdir(fullfile(app.workingDir,'global'));
            mkdir(fullfile(app.workingDir,'res'));
            mkdir(fullfile(app.workingDir,'doc'));
            mkdir(fullfile(app.workingDir,'trial'));
            mkdir(fullfile(app.workingDir,'share'));
        end%createDirectoryTree
%-----------------------------------------------------------------------------------%

        function dispMsg(app, ttype, tmsg)
            % For log messaging blue
            if(strcmpi(ttype, 'L'))
                app.label_errorMsg.Text = ['LOG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.45,0.74];
                
            % for error messages red
            elseif(strcmpi(ttype, 'E'))
                app.label_errorMsg.Text = ['ERROR: ' tmsg];
                app.label_errorMsg.FontColor = [0.64,0.08,0.18];
                
            % general propose message black
            elseif(strcmpi(ttype, 'M'))
                app.label_errorMsg.Text = ['MSG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.00,0.00];
            
            % for clearing the mesage
            elseif(strcmpi(ttype, 'C'))
                app.label_errorMsg.Text = '';
            end
        end%dispMsg
%-----------------------------------------------------------------------------------%
        
    end%private methods

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setInitialGuiParam(app);
        end

        % Button pushed function: button_next
        function button_nextButtonPushed(app, event)
            % move to next tab
            advanceTab(app, 1);
        end

        % Button pushed function: button_prev
        function button_prevButtonPushed(app, event)
            % move to previous tab
            advanceTab(app, -1);
        end

        % Button pushed function: button_browse
        function button_browseButtonPushed(app, event)
            % get the new study directory
            %TODO -- i dont know if this will work in mac and linux
            [tpath] = uigetdir(getenv('HOMEPATH'),'Select directory where the Study will be created');
            
            if(tpath ~= 0)
                app.workingDir = tpath;
            
                % load the study directory
                app.label_workingDir.Text = app.workingDir;
                
                % enable all buttons
                app.button_create.Enable        = 'on';
                app.button_thisIsAStudy.Enable  = 'on';
                app.editF_saveName.Editable     = 'on';
            else
                dispMsg(app, 'That was not a valid directory');
            end
        end

        % Button pushed function: button_thisIsAStudy
        function button_thisIsAStudyButtonPushed(app, event)
            % if there are files with standard names in working dir, load them automatically
            % get the name of the working dir
            tmp = strfind(app.workingDir,'\');
            app.editF_saveName.Value = app.workingDir((tmp(end)+1):end);
            
            % disable all buttons
            app.button_browse.Enable        = 'off';
            app.button_create.Enable        = 'off';
            app.button_thisIsAStudy.Enable  = 'off';
            app.editF_saveName.Editable     = 'off';
            
            % enable controllers to move in tabs
            incStartFlag(app, 1);
            dispMsg(app, 'c');      
        end

        % Button pushed function: button_create
        function button_createButtonPushed(app, event)
            % make sure it is a valid name
            if(isvarname(app.editF_saveName.Value))
                if(~isfolder(fullfile(app.workingDir, app.editF_saveName.Value)))
                    % make directory in this location with this name
                    createDirectoryTree(app);             
                    
                    % disable all buttons
                    app.button_browse.Enable        = 'off';
                    app.button_create.Enable        = 'off';
                    app.button_thisIsAStudy.Enable  = 'off';
                    app.editF_saveName.Editable     = 'off';
                    
                    % enable controllers to move in tabs
                    incStartFlag(app, 1);
                    dispMsg(app, 'c');
                    
                % display message if it directory already here  
                else
                    dispMsg(app, 'e', 'There is a directory of that same name here already');
                end
            % display message if it is a worng name
            else
                dispMsg(app, 'e', 'That is not a valid name, use aA to zZ, 0 to 9, and _');
            end
        end

        % Button pushed function: button_downloadRenameTool
        function button_downloadRenameToolButtonPushed(app, event)
            %TODO -- implement this platform agnostic
            %TODO -- tool freaks out when there is only one sheet
            %TODO -- tool does not check for unique names
        end

        % Button pushed function: button_refreshGlobal
        function button_refreshGlobalButtonPushed(app, event)
            % get the global files
            % and display current trials and remove unwanted files/dir
            [app.extendedGlobalFiles, ~] = displayDirRecursively(fullfile(app.workingDir, 'global'));
            
            % make sure user has copied something to global
            if(~isempty(app.extendedGlobalFiles))
                app.globalFiles = compressPathNmes(app.extendedGlobalFiles);
                
                [~, ttrialfiles] = displayDirRecursively(fullfile(app.workingDir, 'trial'));
                
                % display to list box
                app.listB_globalFiles.Items   = app.globalFiles;
                app.listB_currentTrials.Items = ttrialfiles;
                
                % clear any errors
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'there are no files in the global dir');
            end
        end

        % Button pushed function: button_generateTrials
        function button_generateTrialsButtonPushed(app, event)
            % make sure there is a selection
            %TODO -- this should be wraped on a try catch
            if(~isempty(app.listB_globalFiles.Value))
                %TODO -- for some reason this line is not excecuted?
                dispMsg(app, 'l', 'Computing! This might take a minute');
                
                % get the selected files in its extended name form
                tmp = app.listB_globalFiles.Value;
                tindex = arrayfun(@(k) find(strcmp(tmp{k},app.globalFiles)), 1:length(tmp));
                ttrialfiles = app.extendedGlobalFiles(tindex);
        
                % for every file selected
                for(i = 1: length(ttrialfiles))
                    
                    tfilename = fullfile(app.workingDir, 'global',ttrialfiles{i});
                    [~,tsheetname] = xlsfinfo(tfilename);
                    
                    % for every sheet in this file
                    for(k = 1: length(tsheetname))
                        tnewtrialname = fullfile(app.workingDir, 'trial',tsheetname{k});
                        
                        % if directory is already here, then skip
                        %TODO -- this depends on excel for now
                        if(~isfolder(tnewtrialname))
                            traw = readtable(tfilename, 'Sheet', tsheetname{k});
                            mkdir(tnewtrialname);
                            writetable(traw,fullfile(tnewtrialname, [tsheetname{k}, '.csv']));
                        end
                    end
                end
                
                dispMsg(app, 'l', 'Done creating! refresh again to see results');
            else
                dispMsg(app, 'e', 'Select a cell frist');
            end
        end

        % Button pushed function: button_refreshResShare
        function button_refreshResShareButtonPushed(app, event)
            %TODO -- complete this section, display all files on res and
            %TODO -- share also pop all trials
        end

        % Button pushed function: button_batchCopyFiles
        function button_batchCopyFilesButtonPushed(app, event)
            %TODO -- complete this section
            %TODO -- all items selected batch copy to trials
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.createBlankStudyArchitecture = uifigure('Visible', 'off');
            app.createBlankStudyArchitecture.Name = 'Create A Blank Study Architecture';
            app.tabg_group = uitabgroup(app.createBlankStudyArchitecture);
            app.tab_createStudy = uitab(app.tabg_group);
            app.tab_createStudy.Title = 'Create Study';
            app.panel_createStudy = uipanel(app.tab_createStudy);
            app.label_saveName = uilabel(app.panel_createStudy);
            app.label_saveName.Text = 'Study Name';
            app.editF_saveName = uieditfield(app.panel_createStudy, 'text');
            app.editF_saveName.Editable = 'off';
            app.button_browse = uibutton(app.panel_createStudy, 'push');
            app.button_browse.ButtonPushedFcn = createCallbackFcn(app, @button_browseButtonPushed, true);
            app.button_browse.Text = 'Browse';
            app.button_create = uibutton(app.panel_createStudy, 'push');
            app.button_create.ButtonPushedFcn = createCallbackFcn(app, @button_createButtonPushed, true);
            app.button_create.Enable = 'off';
            app.button_create.Text = 'Create New';
            app.button_thisIsAStudy = uibutton(app.panel_createStudy, 'push');
            app.button_thisIsAStudy.ButtonPushedFcn = createCallbackFcn(app, @button_thisIsAStudyButtonPushed, true);
            app.button_thisIsAStudy.Enable = 'off';
            app.button_thisIsAStudy.Text = 'This is a Stidy';
            app.label_workingDir = uilabel(app.panel_createStudy);
            app.label_workingDir.Text = '';
            app.label_step1 = uilabel(app.panel_createStudy);
            app.label_step1.Text = '(1)';
            app.label_step2 = uilabel(app.panel_createStudy);
            app.label_step2.Text = '(2)';
            app.tab_copyStudyFiles = uitab(app.tabg_group);
            app.tab_copyStudyFiles.Title = 'Copy Study Files';
            app.panel_copyStudyFiles = uipanel(app.tab_copyStudyFiles);
            app.button_downloadRenameTool = uibutton(app.panel_copyStudyFiles, 'push');
            app.button_downloadRenameTool.ButtonPushedFcn = createCallbackFcn(app, @button_downloadRenameToolButtonPushed, true);
            app.button_downloadRenameTool.Text = 'Get Excel Rename Tool';
            app.textA_excelToolInstructions = uitextarea(app.panel_copyStudyFiles);
            app.label_step3 = uilabel(app.panel_copyStudyFiles);
            app.label_step3.Text = '(3)';
            app.label_step4 = uilabel(app.panel_copyStudyFiles);
            app.label_step4.Text = '(4)';
            app.tab_createTrials = uitab(app.tabg_group);
            app.tab_createTrials.Title = 'Create Trials';
            app.panel_createTrials = uipanel(app.tab_createTrials);
            app.button_generateTrials = uibutton(app.panel_createTrials, 'push');
            app.button_generateTrials.ButtonPushedFcn = createCallbackFcn(app, @button_generateTrialsButtonPushed, true);
            app.button_generateTrials.Text = 'Generate Trials';
            app.label_globalFiles = uilabel(app.panel_createTrials);
            app.label_globalFiles.Text = 'Global Files';
            app.listB_globalFiles = uilistbox(app.panel_createTrials);
            app.listB_globalFiles.Items = {};
            app.listB_globalFiles.Multiselect = 'on';
            app.listB_globalFiles.Value = {};
            app.label_currentTrials = uilabel(app.panel_createTrials);
            app.label_currentTrials.Text = 'Current Trails';
            app.listB_currentTrials = uilistbox(app.panel_createTrials);
            app.listB_currentTrials.Items = {};
            app.listB_currentTrials.Value = {};
            app.label_copyGlobalFiles = uilabel(app.panel_createTrials);
            app.label_copyGlobalFiles.Text = {'Before refreshing directory, copy all your experiment'; 'data, videos, exports, etc to the "global" directory'; 'that was just created in your study directory.'; 'This will serve as the local raw directory.'; ''; 'Note that this is not actually where you should keep'; 'your raw files. this is a local copy of raw files for the '; 'propose of computations in this toolbox.'; 'Keep your raw files in a different place in your system'; 'and make sure they have a repository backup too.'; ''; 'While I hope I manage raw data propperly, there is'; 'always the chanse of data corruption. So again, copy '; 'the data to "global".'};
            app.button_refreshGlobal = uibutton(app.panel_createTrials, 'push');
            app.button_refreshGlobal.ButtonPushedFcn = createCallbackFcn(app, @button_refreshGlobalButtonPushed, true);
            app.button_refreshGlobal.Text = 'Refresh All';
            app.label_selectFileInstructions = uilabel(app.panel_createTrials);
            app.label_selectFileInstructions.Text = {'Select the csv or excel files that contain the trials from this study.'; 'Each trial must be on a different worksheet.'; 'Each file selected here can have one or many worksheets (trials), '; 'they will all be exported as individual direcotries.'; 'Make sure all worksheet (trials) names are valid by using the excel'; ' tool in previous tab.'; 'If a file contains all valid names, all direcotries will be created'; 'if at least one already exist then the whole operation will stop '; 'for that file. So you can always come back and add more trials'};
            app.label_step5 = uilabel(app.panel_createTrials);
            app.label_step5.Text = '(5)';
            app.label_step6 = uilabel(app.panel_createTrials);
            app.label_step6.Text = '(6)';
            app.label_step7 = uilabel(app.panel_createTrials);
            app.label_step7.Text = '(7)';
            app.tab_batchCopy = uitab(app.tabg_group);
            app.tab_batchCopy.Title = 'Batch Copy';
            app.panel_batchCopy = uipanel(app.tab_batchCopy);
            app.table_availiableTrials = uitable(app.panel_batchCopy);
            app.table_availiableTrials.ColumnName = {'Trials'};
            app.table_availiableTrials.RowName = {};
            app.button_refreshResShare = uibutton(app.panel_batchCopy, 'push');
            app.button_refreshResShare.ButtonPushedFcn = createCallbackFcn(app, @button_refreshResShareButtonPushed, true);
            app.button_refreshResShare.Text = 'Refresh All';
            app.label_resAndSare = uilabel(app.panel_batchCopy);
            app.label_resAndSare.Text = 'Resources and Shared Data';
            app.listB_resAndSare = uilistbox(app.panel_batchCopy);
            app.listB_resAndSare.Items = {};
            app.listB_resAndSare.Multiselect = 'on';
            app.listB_resAndSare.Value = {};
            app.button_batchCopyFiles = uibutton(app.panel_batchCopy, 'push');
            app.button_batchCopyFiles.ButtonPushedFcn = createCallbackFcn(app, @button_batchCopyFilesButtonPushed, true);
            app.button_batchCopyFiles.Text = 'Batch Copy >>';
            app.label_step8 = uilabel(app.panel_batchCopy);
            app.label_step8.Text = '(8)';
            app.label_step9 = uilabel(app.panel_batchCopy);
            app.label_step9.Text = '(9)';
            app.button_prev = uibutton(app.createBlankStudyArchitecture, 'push');
            app.button_prev.ButtonPushedFcn = createCallbackFcn(app, @button_prevButtonPushed, true);
            app.button_prev.Enable = 'off';
            app.button_prev.Text = '<<Prev';
            app.button_next = uibutton(app.createBlankStudyArchitecture, 'push');
            app.button_next.ButtonPushedFcn = createCallbackFcn(app, @button_nextButtonPushed, true);
            app.button_next.Enable = 'off';
            app.button_next.Text = 'Next>>';
            app.label_errorMsg = uilabel(app.createBlankStudyArchitecture);
            app.label_errorMsg.Text = '';
            % Show the figure after all components are created
            app.createBlankStudyArchitecture.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = createBlankStudyArchitectureGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.createBlankStudyArchitecture)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.createBlankStudyArchitecture)
        end
    end
end