classdef createCameraObjGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ui_createCameraObj      matlab.ui.Figure
        label_existingCameras   matlab.ui.control.Label
        listB_existingCameras   matlab.ui.control.ListBox
        button_loadCamera       matlab.ui.control.Button
        button_saveCamera       matlab.ui.control.Button
        label_workingDir        matlab.ui.control.Label
        label_existingFile      matlab.ui.control.Label
        label_errorMsg          matlab.ui.control.Label
        label_name              matlab.ui.control.Label
        editF_name              matlab.ui.control.EditField
        label_firstPixel        matlab.ui.control.Label
        dropD_firstPixel        matlab.ui.control.DropDown
        textA_selectedDataInfo  matlab.ui.control.TextArea
        label_recordedMetadata  matlab.ui.control.Label
        dropD_recordedMetadata  matlab.ui.control.DropDown
        panel_enterNewMetadata  matlab.ui.container.Panel
        button_enterMetadata    matlab.ui.control.Button
        label_fieldInfo         matlab.ui.control.Label
        textA_fieldInfo         matlab.ui.control.TextArea
        label_newMetadata       matlab.ui.control.Label
        editF_newMetadata       matlab.ui.control.EditField
        label_divider           matlab.ui.control.Label
        label_divvider2         matlab.ui.control.Label
        label_otherSettings     matlab.ui.control.Label
        button_deleteCamera     matlab.ui.control.Button
        button_clearForm        matlab.ui.control.Button
        chkB_useDltParam        matlab.ui.control.CheckBox
        tabg_group              matlab.ui.container.TabGroup
        tab_extrIntr            matlab.ui.container.Tab
        panel_extrIntr          matlab.ui.container.Panel
        editF_r11               matlab.ui.control.NumericEditField
        editF_r12               matlab.ui.control.NumericEditField
        editF_r13               matlab.ui.control.NumericEditField
        label_translation       matlab.ui.control.Label
        label_rotation          matlab.ui.control.Label
        label_extrinsic         matlab.ui.control.Label
        label_intrinsic         matlab.ui.control.Label
        label_translationX      matlab.ui.control.Label
        editF_translationX      matlab.ui.control.NumericEditField
        label_translationY      matlab.ui.control.Label
        editF_translationY      matlab.ui.control.NumericEditField
        label_translationZ      matlab.ui.control.Label
        editF_translationZ      matlab.ui.control.NumericEditField
        label_alfaX             matlab.ui.control.Label
        editF_alftaX            matlab.ui.control.NumericEditField
        label_alftaY            matlab.ui.control.Label
        editF_alftaY            matlab.ui.control.NumericEditField
        label_u0                matlab.ui.control.Label
        editF_u0                matlab.ui.control.NumericEditField
        label_v0                matlab.ui.control.Label
        editF_v0                matlab.ui.control.NumericEditField
        label_gamma             matlab.ui.control.Label
        editF_gamma             matlab.ui.control.NumericEditField
        editF_r21               matlab.ui.control.NumericEditField
        editF_r22               matlab.ui.control.NumericEditField
        editF_r23               matlab.ui.control.NumericEditField
        editF_r31               matlab.ui.control.NumericEditField
        editF_r32               matlab.ui.control.NumericEditField
        editF_r33               matlab.ui.control.NumericEditField
        button_xmllabImport     matlab.ui.control.Button
        tab_dlt                 matlab.ui.container.Tab
        panel_dlt               matlab.ui.container.Panel
        table_dltParam          matlab.ui.control.Table
        button_addDlt           matlab.ui.control.Button
        label_copyPaste         matlab.ui.control.Label
        textA_copyPaste         matlab.ui.control.TextArea
        button_resetDltParam    matlab.ui.control.Button
        label_cameraTransf      matlab.ui.control.Label
        button_saveAlltoStudy   matlab.ui.control.Button
        button_removeField      matlab.ui.control.Button
        label_sensorSize        matlab.ui.control.Label
        editF_sensorSizeW       matlab.ui.control.NumericEditField
        chkB_firstPixelIndex    matlab.ui.control.CheckBox
        label_x                 matlab.ui.control.Label
        editF_sensorSizeH       matlab.ui.control.NumericEditField
    end

    
%---------------------------- CONSTANT PROPERTIES ---------------------------------%    
    properties (Access = private, Constant = true)
        % variables that control app's visibility workflow function
        isTabChar = 'tab_';
    end

    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'createCameraObjGui';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
        
        % important/ relevant output from this app
        savedVarName = 'createCameraObj';
        
        % the full app, required to populate this app with a previous session
        % this contains ALL components, even those stored in savedVarName
        savedAppName = 'createCameraObjApp';
        
        % relevant variables to save in savedVarName
        % savedAppName saves this plus everything else too
        %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
        varsToSaveinStd =  {'cameraObjs';
                            'appVersion'};
    end

%----------------------------- CLASS PROPERTIES ----------------------------------%
     properties (Access = private)
        % this variable stores the working directory
        workingDir = '';
        
        % tab control variable, start at first tab
        tabControl = 1;
        
        % application tabs, used to move around with buttons, computed at startup
        applicationTabs;
        
        % temp metadata holder before assigining to camera object
        metaData = struct();
        
        % file to import from xmllab id, to make sure it closes
        openFileIndx;
    end
    
    properties (Access = public)
        % camera object struct to save from, its contents are the only thing saved
        cameraObjs = struct();
    end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%

    methods (Access = private)
        
        function setInitialGuiParam(app)
            % fist turn all panels off
            % get names of all tabs and indexes in app.
            tnames  = fieldnames(app);
            
            % tabs are in the order that they apear in the gui in sequence
            %TODO -- if tabs have multiple children this wont work
            app.applicationTabs = tnames(contains(tnames,app.isTabChar));
            
            % disable all panels
            for(i=1: length(app.applicationTabs))
                app.(app.applicationTabs{i}).Children.Visible = 'off';
            end
            
            % the first pannel and tab controlled by class tab control
            app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
            app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            
            % load the study directory and display files
            loadWorkingDirFiles(app);
            
            % declare the table of dlt parameters as a numeric one
            app.table_dltParam.ColumnFormat = {'numeric'};
            
            % virtually push the clear form button to wipe fresh
            clearFormFields(app);
        end%setInitialGuiParam
%-----------------------------------------------------------------------------------%
        
        function loadWorkingDirFiles(app)   
                %TODO -- should trim this to show a ../../dir
                app.label_workingDir.Text = app.workingDir;
        end%loadWorkingDirFiles
%-----------------------------------------------------------------------------------%

        function findAppInstance(app)
            % see if there is an instance of this app in the working directory
            if(~(isfile(fullfile(app.workingDir, [app.savedAppName '.mat']))))
                app.label_existingFile.Text = {['There is no ' app.savedAppName ' File in the Working Directory.'] 'Saving will create a new one.'};
            else
                % try to open the file
                try
                    % just load the camera objects
                    tmp = fullfile(app.workingDir, [app.savedAppName '.mat']);
                    tstruct = load(tmp, createCameraObjGui.savedVarName);
                    app.cameraObjs = tstruct.(createCameraObjGui.savedVarName).(createCameraObjGui.varsToSaveinStd{1});

                % send a message and ask the user to take action 
                catch
                    msgbox({'There was an error loading the file, app wil close'; ...
                            'If probem persist you need to delete the saved mat file'}, 'Error','error');
                    delete(app);
                end
                % load variables to populate the instance of this app
                app.label_existingFile.Text = {['There is a ' app.savedAppName ' File in the Working Directory.'] 'Saving will overwrite it.'};
                reloadCameraList(app);
            end
        end%findAppInstance
%-----------------------------------------------------------------------------------%

        function reloadCameraList(app)
            app.listB_existingCameras.Items = fieldnames(app.cameraObjs);
            app.listB_existingCameras.ItemsData = fieldnames(app.cameraObjs);
        end%reloadCameraList
%-----------------------------------------------------------------------------------%

        function clearFormFields(app)   
            % clear the full form
            app.editF_name.Value = '';
            app.chkB_useDltParam.Value = 0;
            deleteDltParams(app);
            deleteExtIntParams(app);
            app.dropD_firstPixel.Value = app.dropD_firstPixel.Items{1};
            app.editF_sensorSizeW.Value = 0;
            app.editF_sensorSizeH.Value = 0;
            app.chkB_firstPixelIndex.Value = 1;
            app.dropD_recordedMetadata.Items = {};
            app.dropD_recordedMetadata.ItemsData = {};
            app.metaData = rmfield(app.metaData, char(fieldnames(app.metaData)));
            app.textA_selectedDataInfo.Value = {''};
            app.editF_newMetadata.Value = '';
            app.textA_fieldInfo.Value = {''};
            dispMsg(app, 'c');
        end%clearFormFields
%-----------------------------------------------------------------------------------%

        function deleteDltParams(app)
            % delete the information in the text area
            app.textA_copyPaste.Value = {''};
            
            % delete the information in the table
            app.table_dltParam.Data = nan(11,1);
        end%deleteDltParams
%-----------------------------------------------------------------------------------%
        
        function deleteExtIntParams(app)
            % set to zero all extrensic and intrinsic parameters
            app.editF_translationX.Value = 0;
            app.editF_translationY.Value = 0;
            app.editF_translationZ.Value = 0;
            app.editF_r11.Value = 0;
            app.editF_r12.Value = 0;
            app.editF_r13.Value = 0;
            app.editF_r21.Value = 0;
            app.editF_r22.Value = 0;
            app.editF_r23.Value = 0;
            app.editF_r31.Value = 0;
            app.editF_r32.Value = 0;
            app.editF_r33.Value = 0;
            app.editF_alftaX.Value = 0;
            app.editF_alftaY.Value = 0;
            app.editF_u0.Value = 0;
            app.editF_v0.Value = 0;
            app.editF_gamma.Value = 0;
        end%deleteExtIntParams
%-----------------------------------------------------------------------------------%
        
        function advanceTab(app, cnt)
            % first check if cnt will request a tab out of range
            if((app.tabControl+cnt)<=length(app.applicationTabs) && (app.tabControl+cnt)>1)
                % clear errors if any
                dispMsg(app, 'c');
                
                % disable the current panel
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'off';
                
                % increase tab control and activate next panel and tab
                app.tabControl = app.tabControl+cnt;
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
                app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            end
        end%advanceTab
%-----------------------------------------------------------------------------------%

        function makeDltCameraObject(app)
            % create a camera object from the form and update the list of availiable cameras
            % tdltpar has already being checked for 11 parameters all numeric, so skipping
            app.cameraObjs.(app.editF_name.Value) = cameraObj(app.editF_name.Value, cameraObj.transParam.dlt);
            
            %TODO -- this is not taking into account axis flip with pixel one location
            % ensure it is a column vector
            app.cameraObjs.(app.editF_name.Value).dltParam = app.table_dltParam.Data(:);
            
            % if first index is zero then add 1 to matrix correction, else assign zeros
            if(app.chkB_firstPixelIndex.Value==1)
                app.cameraObjs.(app.editF_name.Value).intrIndexCorr = [1;1];
            else
                app.cameraObjs.(app.editF_name.Value).intrIndexCorr = [0;0];
            end
            
            % compute correction for axis flip depending on sensor's pixel one position
            % this flips the axis with respect to the world coordiante system to be used by the camera
            if(app.dropD_firstPixel.Value == app.dropD_firstPixel.Items{1})
                app.cameraObjs.(app.editF_name.Value).intrAxisCorr = [1, 0; 0, -1];
            elseif(app.dropD_firstPixel.Value == app.dropD_firstPixel.Items{2})
                app.cameraObjs.(app.editF_name.Value).intrAxisCorr = [-1, 0; 0, -1];
            elseif(app.dropD_firstPixel.Value == app.dropD_firstPixel.Items{3})
                app.cameraObjs.(app.editF_name.Value).intrAxisCorr = [1, 0; 0, 1];
            elseif(app.dropD_firstPixel.Value == app.dropD_firstPixel.Items{4})
                app.cameraObjs.(app.editF_name.Value).intrAxisCorr = [-1, 0; 0, 1];
            end
            
            app.cameraObjs.(app.editF_name.Value).metadata = app.metaData;
            
            tsensorSize = [app.editF_sensorSizeW.Value; app.editF_sensorSizeW.Value];
            app.cameraObjs.(app.editF_name.Value).sensorSize = tsensorSize;
            
            % reload list of cameras
            reloadCameraList(app);
            
            % clear the form
            clearFormFields(app);
        end%makeDltCameraObject
%-----------------------------------------------------------------------------------%
        
        function makeExInCameraObject(app)
            % create a camera object from the form and update the list of availiable cameras
            app.cameraObjs.(app.editF_name.Value) = cameraObj(app.editF_name.Value, cameraObj.transParam.exin);
            
            tsensorSize = [app.editF_sensorSizeW.Value; app.editF_sensorSizeW.Value];
            app.cameraObjs.(app.editF_name.Value).sensorSize = tsensorSize;
            
            ttranslation = [app.editF_translationX.Value; app.editF_translationY.Value; app.editF_translationZ.Value];
            app.cameraObjs.(app.editF_name.Value).translation = ttranslation;
            
            trotation =[app.editF_r11.Value, app.editF_r12.Value, app.editF_r13.Value; ...
                        app.editF_r21.Value, app.editF_r22.Value, app.editF_r23.Value; ...
                        app.editF_r31.Value, app.editF_r32.Value, app.editF_r33.Value];
            app.cameraObjs.(app.editF_name.Value).rotation = trotation;
            
            tintrMat = [app.editF_alftaX.Value,  app.editF_gamma.Value, app.editF_u0.Value; ...
                                             0, app.editF_alftaY.Value, app.editF_v0.Value; ...
                                             0,                      0,                  1];
            app.cameraObjs.(app.editF_name.Value).intrMat = tintrMat;
            
            % if first index is zero then add 1 to matrix correction, else assign zeros
            if(app.chkB_firstPixelIndex.Value==1)
                app.cameraObjs.(app.editF_name.Value).intrIndexCorr = [1;1];
            else
                app.cameraObjs.(app.editF_name.Value).intrIndexCorr = [0;0];
            end
            
            % compute correction for axis flip depending on sensor's pixel one position
            % this flips the axis with respect to the world coordiante system to be used by the camera
            if(app.dropD_firstPixel.Value == app.dropD_firstPixel.Items{1})
                app.cameraObjs.(app.editF_name.Value).intrAxisCorr = [1, 0; 0, -1];
            elseif(app.dropD_firstPixel.Value == app.dropD_firstPixel.Items{2})
                app.cameraObjs.(app.editF_name.Value).intrAxisCorr = [-1, 0; 0, -1];
            elseif(app.dropD_firstPixel.Value == app.dropD_firstPixel.Items{3})
                app.cameraObjs.(app.editF_name.Value).intrAxisCorr = [1, 0; 0, 1];
            elseif(app.dropD_firstPixel.Value == app.dropD_firstPixel.Items{4})
                app.cameraObjs.(app.editF_name.Value).intrAxisCorr = [-1, 0; 0, 1];
            end
            
            % copy metadata
            app.cameraObjs.(app.editF_name.Value).metadata = app.metaData;
            
            % reload list of cameras
            reloadCameraList(app);
            
            % clear the form
            clearFormFields(app);
        end%makeExInCameraObject
%-----------------------------------------------------------------------------------%

        function dispMsg(app, ttype, tmsg)
            % For log messaging blue
            if(strcmpi(ttype, 'L'))
                app.label_errorMsg.Text = ['LOG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.45,0.74];
                
            % for error messages red
            elseif(strcmpi(ttype, 'E'))
                app.label_errorMsg.Text = ['ERROR: ' tmsg];
                app.label_errorMsg.FontColor = [0.64,0.08,0.18];
                
            % general propose message black
            elseif(strcmpi(ttype, 'M'))
                app.label_errorMsg.Text = ['MSG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.00,0.00];
            
            % for clearing the mesage
            elseif(strcmpi(ttype, 'C'))
                app.label_errorMsg.Text = '';
            end
        end%dispMsg
%-----------------------------------------------------------------------------------%

        function s = varsToSaveinStdFcn(app)
            % saves just the relevant variables that come out of this class not to save ui elements
            for(i = 1:length(app.varsToSaveinStd))
                s.(app.varsToSaveinStd{i}) = app.(app.varsToSaveinStd{i});
            end                
        end%varsToSaveinStdFcn
%-----------------------------------------------------------------------------------%   
        
    end%private methods

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tworkingDir)
            %TODO -- share and tree structure is hardcoded
            app.workingDir = fullfile(tworkingDir, 'share');
            setInitialGuiParam(app);
            findAppInstance(app);
        end

        % Button pushed function: button_loadCamera
        function button_loadCameraButtonPushed(app, event)
            % once the user selects a camera, then populate all the form
            % this will also delete all the current values in the form
            % first make sure there is a selection
            if(~isempty(app.listB_existingCameras.Value))
                
                % so first delete them by virtually pressing the clear form
                clearFormFields(app);
                % temp variable for shorthand
                tcamera = app.listB_existingCameras.Value;
                
                app.editF_name.Value = app.cameraObjs.(tcamera).name;
                if(strcmp(app.cameraObjs.(tcamera).transType, cameraObj.transParam.dlt))
                    % populate the dlt values, virtually change the check box
                    app.chkB_useDltParam.Value = 1;
                    chkB_useDltParamValueChanged(app);
                    app.table_dltParam.Data = app.cameraObjs.(tcamera).dltParam(:);
                else
                    % populate ext and int values, virtually change the check box
                    app.chkB_useDltParam.Value = 0;
                    chkB_useDltParamValueChanged(app);
                    
                    app.editF_translationX.Value = app.cameraObjs.(tcamera).translation(1,1);
                    app.editF_translationY.Value = app.cameraObjs.(tcamera).translation(2,1);
                    app.editF_translationZ.Value = app.cameraObjs.(tcamera).translation(3,1);
                    
                    app.editF_r11.Value = app.cameraObjs.(tcamera).rotation(1,1);
                    app.editF_r21.Value = app.cameraObjs.(tcamera).rotation(2,1);
                    app.editF_r31.Value = app.cameraObjs.(tcamera).rotation(3,1);
                    app.editF_r12.Value = app.cameraObjs.(tcamera).rotation(1,2);
                    app.editF_r22.Value = app.cameraObjs.(tcamera).rotation(2,2);
                    app.editF_r32.Value = app.cameraObjs.(tcamera).rotation(3,2);
                    app.editF_r13.Value = app.cameraObjs.(tcamera).rotation(1,3);
                    app.editF_r23.Value = app.cameraObjs.(tcamera).rotation(2,3);
                    app.editF_r33.Value = app.cameraObjs.(tcamera).rotation(3,3);

                    app.editF_alftaX.Value = app.cameraObjs.(tcamera).intrMat(1,1);
                    app.editF_alftaY.Value = app.cameraObjs.(tcamera).intrMat(2,2);
                    app.editF_u0.Value     = app.cameraObjs.(tcamera).intrMat(1,3);
                    app.editF_v0.Value     = app.cameraObjs.(tcamera).intrMat(2,3);
                    app.editF_gamma.Value  = app.cameraObjs.(tcamera).intrMat(1,2);
                end
                                
                app.editF_sensorSizeW.Value = app.cameraObjs.(tcamera).sensorSize(1,1);
                app.editF_sensorSizeH.Value = app.cameraObjs.(tcamera).sensorSize(2,1);
            
                % if first index is zero then add 1 to matrix correction, else assign zeros
                if(app.cameraObjs.(tcamera).intrIndexCorr == [1;1])
                    app.chkB_firstPixelIndex.Value = 1;
                else
                    app.chkB_firstPixelIndex.Value = 0;
                end
                
                % compute correction for axis flip depending on sensor's pixel one position
                % this flips the axis with respect to the world coordiante system to be used by the camera
                %TODO -- put these values on a structured array with the same names as the index drop down, its hardcoded now
                %TODO -- use isequal();
                if(app.cameraObjs.(tcamera).intrAxisCorr == [1, 0; 0, -1])
                    app.dropD_firstPixel.Value == app.dropD_firstPixel.Items{1};
                elseif(app.cameraObjs.(tcamera).intrAxisCorr == [-1, 0; 0, -1])
                    app.dropD_firstPixel.Value == app.dropD_firstPixel.Items{2};
                elseif(app.cameraObjs.(tcamera).intrAxisCorr == [1, 0; 0, 1])
                    app.dropD_firstPixel.Value == app.dropD_firstPixel.Items{3};
                elseif(app.cameraObjs.(tcamera).intrAxisCorr == [-1, 0; 0, 1])
                    app.dropD_firstPixel.Value == app.dropD_firstPixel.Items{4};
                end
                
                % get the metadata availiable, first local var then populate fields
                app.metaData = app.cameraObjs.(tcamera).metadata;
                app.dropD_recordedMetadata.Items = fieldnames(app.cameraObjs.(tcamera).metadata);
                app.dropD_recordedMetadata.ItemsData = fieldnames(app.cameraObjs.(tcamera).metadata);
                % then virtually change the drop down menu to update the variable
                dropD_recordedMetadataValueChanged(app);
            end
        end

        % Button pushed function: button_deleteCamera
        function button_deleteCameraButtonPushed(app, event)
            % make sure that the selection is not empty, if so send an error message
            if(~isempty(app.listB_existingCameras.Value))
                app.cameraObjs = rmfield(app.cameraObjs, app.listB_existingCameras.Value);
                
                % reload list to reflect the chantes
                reloadCameraList(app);
                
                % clear error if there is any
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'Select one camera to delete');
            end
        end

        % Button pushed function: button_saveAlltoStudy
        function button_saveAlltoStudyPushed(app, event)
            % disable wargings from saving stuff such as uia components
            %TODO -- just dont try to save them to prevent warning
            warning('off');
            
            % make a struct of what I want to save to that they keep those variable names
            tmp.(app.savedAppName) = app;
            tmp.(app.savedVarName) = varsToSaveinStdFcn(app);
            
            % save file
            %TODO -- does this needs a try catch?
            save(fullfile(app.workingDir, [app.savedAppName '.mat']), '-struct', 'tmp');
            app.label_existingFile.Text = {['There is a ' app.savedAppName ' File in the Working Directory.'] 'Saving will overwrite it.'};
            
            % alert the user it is done
            msgbox({'All Saved!!!';'You can keep modfying or just close the window'},'Success');
            
            % turn warnings back on
            warning('on');
            %TODO -- this is a matlab bug
            warning off MATLAB:subscripting:noSubscriptsSpecified;
        end

        % Button pushed function: button_saveCamera
        function button_saveCameraButtonPushed(app, event)
            % depending on which parametrs are to be used
            % for dlt parameters
            if(app.chkB_useDltParam.Value == 1)
                % make sure that at least name and all transformation parameters are entered
                if(~isvarname(app.textA_fieldInfo.Value) && length(app.table_dltParam.Data)==11 && ~any(isnan(app.table_dltParam.Data)))
                    makeDltCameraObject(app);
                else
                    dispMsg(app, 'e', 'A valid name and 11 DLT parameters must be set');
                end
                
            % for extrinsic and intrinsic parameters
            else
                % make sure that at least name is entered
                if(~isvarname(app.textA_fieldInfo.Value))
                    makeExInCameraObject(app);
                else
                    dispMsg(app, 'e', 'A valid name must be set');
                end
            end
        end

        % Button pushed function: button_clearForm
        function button_clearFormButtonPushed(app, event)
            clearFormFields(app);
        end

        % Value changed function: chkB_useDltParam
        function chkB_useDltParamValueChanged(app, event)
            % take the value and change tabs and disable panels so user can only enter one type of transformation
            value = app.chkB_useDltParam.Value;
            
            % first delete all the values in the form, both sides
            deleteDltParams(app);
            deleteExtIntParams(app);
            
            % if user is providing DLT (direct linear matrix) transformation move to next tab
            if(value == 1)
                advanceTab(app, 1);
            else
                advanceTab(app, -1);
            end
        end

        % Button pushed function: button_addDlt
        function button_addDltButtonPushed(app, event)
            % add all the dlt text to table
            % first remove all empty lines
            app.textA_copyPaste.Value = app.textA_copyPaste.Value(~strcmp(app.textA_copyPaste.Value,''));

            % make sure that there is space in the table, find the number of empty values
            if(size(app.textA_copyPaste.Value,1)>sum(isnan(app.table_dltParam.Data)))
                dispMsg(app, 'e', 'The total parameters must be 11');
            else
                % copy all the values from text area to table, then clear text area.
                tfrom = find(isnan(app.table_dltParam.Data),1);
                tto = tfrom + size(app.textA_copyPaste.Value,1)-1;
                
                % make sure the values that the user inputs are numeric
                try
                    app.table_dltParam.Data(tfrom:tto) = cellfun(@str2num,app.textA_copyPaste.Value);
                    %clear copy paste area and error if any
                    app.textA_copyPaste.Value = {''};
                    dispMsg(app, 'c');
                catch
                    dispMsg(app, 'e', 'Parameters need to be numbers');
                end
            end
        end

        % Value changed function: dropD_recordedMetadata
        function dropD_recordedMetadataValueChanged(app, event)
            % change the value of the text field with drop down menu
            % which reflects the contents of the struct metadata
            % first make sure it is not empy, otherwise just clear the text field
            value = app.dropD_recordedMetadata.Value;
            if(~isempty(app.dropD_recordedMetadata.Value))
                app.textA_selectedDataInfo.Value = app.metaData.(value);
            else
                app.textA_selectedDataInfo.Value = {''};
            end
        end

        % Button pushed function: button_enterMetadata
        function button_enterMetadataButtonPushed(app, event)
            % first make sure that both fields have information
            if(isempty(app.editF_newMetadata.Value) || isempty(app.textA_fieldInfo.Value{:}))
                % trow error
                dispMsg(app, 'e', 'To add new metadata Name and Desc. cannot be empty');
            elseif(~isvarname(app.editF_newMetadata.Value))
                % if the name on input is not a valid matlab name, trow error
                dispMsg(app, 'e', 'not a valid name for a metadata variable');
            else
                % clear error if exist
                dispMsg(app, 'c');
                
                % add to struct
                app.metaData.(app.editF_newMetadata.Value) = app.textA_fieldInfo.Value;
                
                % if it is the first item in the list the ValueCahnged method on drop down wont work, so hard display info
                if(isempty(app.dropD_recordedMetadata.Items))
                    app.textA_selectedDataInfo.Value = app.textA_fieldInfo.Value;
                end
                
                % update drop down menu for metadata
                app.dropD_recordedMetadata.Items = fieldnames(app.metaData);
                app.dropD_recordedMetadata.ItemsData = fieldnames(app.metaData);
                
                % clear the values on the fields
                app.editF_newMetadata.Value = '';
                app.textA_fieldInfo.Value = {''};
            end
        end

        % Button pushed function: button_resetDltParam
        function button_resetDltParamPushed(app, event)
            % reset the table in case of user mistake
            deleteDltParams(app);
        end

        % Button pushed function: button_removeField
        function button_removeFieldButtonPushed(app, event)
            % if there is no metadata, then do nothing
            if(~isempty(app.dropD_recordedMetadata.Value))
                app.metaData = rmfield(app.metaData, app.dropD_recordedMetadata.Value);
                
                % update drop down menu for metadata
                app.dropD_recordedMetadata.Items = fieldnames(app.metaData);
                app.dropD_recordedMetadata.ItemsData = fieldnames(app.metaData);
                
                % virtually change the drop down menu
                dropD_recordedMetadataValueChanged(app);
            end
        end

        % Button pushed function: button_xmllabImport
        function button_xmllabImportButtonPushed(app, event)
            % reads from an xmlab export file called mayacam 2.0
            % this needs some major try catch, if this is the wrong file it will crash and it may leave the file open?
            try
                [tfile,tpath] = uigetfile('*.txt','Select the text file output from XMLLAB Mayacam 2.0', app.workingDir);
                app.openFileIndx = fopen(fullfile(tpath,tfile), 'r');
                
                tfileread = {};
                for(i=1:17)
                   tline = fgetl(app.openFileIndx);
                   tfileread = [tfileread; tline];
                end
    
                %close the file after reading
                fclose(app.openFileIndx);
                
                %TODO -- is this hardcoded? its technically a standard file
                timagesize = textscan(tfileread{2}, '%f', 'delimiter',',');
                app.editF_sensorSizeW.Value = timagesize{:}(1);
                app.editF_sensorSizeH.Value = timagesize{:}(2);
    
                tintrMat = textscan(tfileread{4}, '%f', 'delimiter',',');
                app.editF_alftaX.Value = tintrMat{:}(1);
                app.editF_gamma.Value = tintrMat{:}(2);
                app.editF_u0.Value = tintrMat{:}(3);
                tintrMat = textscan(tfileread{5}, '%f', 'delimiter',',');
                app.editF_alftaY.Value = tintrMat{:}(2);
                app.editF_v0.Value = tintrMat{:}(3);
                
                trotation = textscan(tfileread{8}, '%f', 'delimiter',',');
                app.editF_r11.Value = trotation{:}(1);
                app.editF_r12.Value = trotation{:}(2);
                app.editF_r13.Value = trotation{:}(3);
                trotation = textscan(tfileread{9}, '%f', 'delimiter',',');
                app.editF_r21.Value = trotation{:}(1);
                app.editF_r22.Value = trotation{:}(2);
                app.editF_r23.Value = trotation{:}(3);
                trotation = textscan(tfileread{10}, '%f', 'delimiter',',');
                app.editF_r31.Value = trotation{:}(1);
                app.editF_r32.Value = trotation{:}(2);
                app.editF_r33.Value = trotation{:}(3);
                
                ttranslation = textscan(tfileread{12}, '%f', 'delimiter',',');
                app.editF_translationX.Value = ttranslation{:}(1);
                ttranslation = textscan(tfileread{13}, '%f', 'delimiter',',');
                app.editF_translationY.Value = ttranslation{:}(1);
                ttranslation = textscan(tfileread{14}, '%f', 'delimiter',',');
                app.editF_translationZ.Value = ttranslation{:}(1);
                
                dispMsg(app, 'c');
                
            catch
                dispMsg(app, 'e', 'Something went wrong reading the file');
                try
                    fclose(app.openFileIndx);
                catch
                    %TODO -- what do i do here? in case the file is already closed 
                end
            end
        end

        % Close request function: ui_createCameraObj
        function ui_createCameraObjCloseRequest(app, event)
            % make sure file is closed before terminating
            try
                fclose(app.openFileIndx);
            catch
               %TODO -- what do i do here? in case the file is already closed 
            end
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ui_createCameraObj = uifigure;
            app.ui_createCameraObj.Name = 'Create A Camera Object';
            app.ui_createCameraObj.CloseRequestFcn = createCallbackFcn(app, @ui_createCameraObjCloseRequest, true);
            app.label_existingCameras = uilabel(app.ui_createCameraObj);
            app.label_existingCameras.Text = 'Exsisting Cameras';
            app.listB_existingCameras = uilistbox(app.ui_createCameraObj);
            app.listB_existingCameras.Items = {};
            app.listB_existingCameras.Value = {};
            app.button_loadCamera = uibutton(app.ui_createCameraObj, 'push');
            app.button_loadCamera.ButtonPushedFcn = createCallbackFcn(app, @button_loadCameraButtonPushed, true);
            app.button_loadCamera.Text = 'Load Camera to Form';
            app.button_saveCamera = uibutton(app.ui_createCameraObj, 'push');
            app.button_saveCamera.ButtonPushedFcn = createCallbackFcn(app, @button_saveCameraButtonPushed, true);
            app.button_saveCamera.Text = 'Save to Study';
            app.label_workingDir = uilabel(app.ui_createCameraObj);
            app.label_workingDir.Text = '';
            app.label_existingFile = uilabel(app.ui_createCameraObj);
            app.label_existingFile.Text = '';
            app.label_errorMsg = uilabel(app.ui_createCameraObj);
            app.label_errorMsg.Text = '';
            app.label_name = uilabel(app.ui_createCameraObj);
            app.label_name.Text = 'Camera Name';
            app.editF_name = uieditfield(app.ui_createCameraObj, 'text');
            app.editF_name.Tooltip = {'Name requirements:'; '- Just aA to zZ and 0 to 9'; '- It cant start with a number'};
            app.label_firstPixel = uilabel(app.ui_createCameraObj);
            app.label_firstPixel.Text = 'First Pixel';
            app.dropD_firstPixel = uidropdown(app.ui_createCameraObj);
            app.dropD_firstPixel.Items = {'top-left', 'top-right', 'bottom-left', 'bottom-right'};
            app.dropD_firstPixel.ItemsData = {'top-left', 'top-right', 'bottom-left', 'bottom-right'};
            app.dropD_firstPixel.Tooltip = {'In reference to the camera''s focal origin'};
            app.dropD_firstPixel.Value = 'top-left';
            app.textA_selectedDataInfo = uitextarea(app.ui_createCameraObj);
            app.textA_selectedDataInfo.Editable = 'off';
            app.label_recordedMetadata = uilabel(app.ui_createCameraObj);
            app.label_recordedMetadata.Text = 'Metadata';
            app.dropD_recordedMetadata = uidropdown(app.ui_createCameraObj);
            app.dropD_recordedMetadata.Items = {};
            app.dropD_recordedMetadata.ValueChangedFcn = createCallbackFcn(app, @dropD_recordedMetadataValueChanged, true);
            app.dropD_recordedMetadata.Value = {};
            app.panel_enterNewMetadata = uipanel(app.ui_createCameraObj);
            app.panel_enterNewMetadata.Title = 'Enter New Metadata Field';
            app.button_enterMetadata = uibutton(app.panel_enterNewMetadata, 'push');
            app.button_enterMetadata.ButtonPushedFcn = createCallbackFcn(app, @button_enterMetadataButtonPushed, true);
            app.button_enterMetadata.Text = 'Enter New Field';
            app.label_fieldInfo = uilabel(app.panel_enterNewMetadata);
            app.label_fieldInfo.Text = 'Field Information';
            app.textA_fieldInfo = uitextarea(app.panel_enterNewMetadata);
            app.label_newMetadata = uilabel(app.panel_enterNewMetadata);
            app.label_newMetadata.Text = 'Name';
            app.editF_newMetadata = uieditfield(app.panel_enterNewMetadata, 'text');
            app.editF_newMetadata.Tooltip = {'Name requirements:'; '- Just aA to zZ and 0 to 9'; '- It cant start with a number'};
            app.label_divider = uilabel(app.ui_createCameraObj);
            app.label_divider.Text = '---------------------------------------------------------------------------------------------------------------';
            app.label_divvider2 = uilabel(app.ui_createCameraObj);
            app.label_divvider2.Text = '---------------------------------------------------------------------------------------------------------------';
            app.label_otherSettings = uilabel(app.ui_createCameraObj);
            app.label_otherSettings.Text = 'Other Settings';
            app.button_deleteCamera = uibutton(app.ui_createCameraObj, 'push');
            app.button_deleteCamera.ButtonPushedFcn = createCallbackFcn(app, @button_deleteCameraButtonPushed, true);
            app.button_deleteCamera.Text = 'Delete Camera From Study';
            app.button_clearForm = uibutton(app.ui_createCameraObj, 'push');
            app.button_clearForm.ButtonPushedFcn = createCallbackFcn(app, @button_clearFormButtonPushed, true);
            app.button_clearForm.Text = 'Clear Form';
            app.chkB_useDltParam = uicheckbox(app.ui_createCameraObj);
            app.chkB_useDltParam.ValueChangedFcn = createCallbackFcn(app, @chkB_useDltParamValueChanged, true);
            app.chkB_useDltParam.Text = 'Use DLT Parameters';
            app.tabg_group = uitabgroup(app.ui_createCameraObj);
            app.tab_extrIntr = uitab(app.tabg_group);
            app.tab_extrIntr.Title = 'Extr/Intr Param';
            app.panel_extrIntr = uipanel(app.tab_extrIntr);
            app.editF_r11 = uieditfield(app.panel_extrIntr, 'numeric');
            app.editF_r11.ValueDisplayFormat = '%11.15g';
            app.editF_r12 = uieditfield(app.panel_extrIntr, 'numeric');
            app.editF_r12.ValueDisplayFormat = '%11.15g';
            app.editF_r13 = uieditfield(app.panel_extrIntr, 'numeric');
            app.editF_r13.ValueDisplayFormat = '%11.15g';
            app.label_translation = uilabel(app.panel_extrIntr);
            app.label_translation.Text = 'Translation';
            app.label_rotation = uilabel(app.panel_extrIntr);
            app.label_rotation.Text = 'Rotation ';
            app.label_extrinsic = uilabel(app.panel_extrIntr);
            app.label_extrinsic.Text = 'World Origin to Camera''s Focal Point (Extrinsic)';
            app.label_intrinsic = uilabel(app.panel_extrIntr);
            app.label_intrinsic.Text = 'Focal Pont to Sensor Plane (Intrinsic)';
            app.label_translationX = uilabel(app.panel_extrIntr);
            app.label_translationX.Text = 'X';
            app.editF_translationX = uieditfield(app.panel_extrIntr, 'numeric');
            app.editF_translationX.ValueDisplayFormat = '%11.15g';
            app.label_translationY = uilabel(app.panel_extrIntr);
            app.label_translationY.Text = 'Y';
            app.editF_translationY = uieditfield(app.panel_extrIntr, 'numeric');
            app.editF_translationY.ValueDisplayFormat = '%11.15g';
            app.label_translationZ = uilabel(app.panel_extrIntr);
            app.label_translationZ.Text = 'Z';
            app.editF_translationZ = uieditfield(app.panel_extrIntr, 'numeric');
            app.editF_translationZ.ValueDisplayFormat = '%11.15g';
            app.label_alfaX = uilabel(app.panel_extrIntr);
            app.label_alfaX.Text = 'ax';
            app.editF_alftaX = uieditfield(app.panel_extrIntr, 'numeric');
            app.editF_alftaX.ValueDisplayFormat = '%11.15g';
            app.label_alftaY = uilabel(app.panel_extrIntr);
            app.label_alftaY.Text = 'ay';
            app.editF_alftaY = uieditfield(app.panel_extrIntr, 'numeric');
            app.editF_alftaY.ValueDisplayFormat = '%11.15g';
            app.label_u0 = uilabel(app.panel_extrIntr);
            app.label_u0.Text = 'u0';
            app.editF_u0 = uieditfield(app.panel_extrIntr, 'numeric');
            app.editF_u0.ValueDisplayFormat = '%11.15g';
            app.label_v0 = uilabel(app.panel_extrIntr);
            app.label_v0.Text = 'v0';
            app.editF_v0 = uieditfield(app.panel_extrIntr, 'numeric');
            app.editF_v0.ValueDisplayFormat = '%11.15g';
            app.label_gamma = uilabel(app.panel_extrIntr);
            app.label_gamma.Text = 'g';
            app.editF_gamma = uieditfield(app.panel_extrIntr, 'numeric');
            app.editF_gamma.ValueDisplayFormat = '%11.15g';
            app.editF_r21 = uieditfield(app.panel_extrIntr, 'numeric');
            app.editF_r21.ValueDisplayFormat = '%11.15g';
            app.editF_r22 = uieditfield(app.panel_extrIntr, 'numeric');
            app.editF_r22.ValueDisplayFormat = '%11.15g';
            app.editF_r23 = uieditfield(app.panel_extrIntr, 'numeric');
            app.editF_r23.ValueDisplayFormat = '%11.15g';
            app.editF_r31 = uieditfield(app.panel_extrIntr, 'numeric');
            app.editF_r31.ValueDisplayFormat = '%11.15g';
            app.editF_r32 = uieditfield(app.panel_extrIntr, 'numeric');
            app.editF_r32.ValueDisplayFormat = '%11.15g';
            app.editF_r33 = uieditfield(app.panel_extrIntr, 'numeric');
            app.editF_r33.ValueDisplayFormat = '%11.15g';
            app.button_xmllabImport = uibutton(app.panel_extrIntr, 'push');
            app.button_xmllabImport.ButtonPushedFcn = createCallbackFcn(app, @button_xmllabImportButtonPushed, true);
            app.button_xmllabImport.Text = 'xmllab import';
            app.tab_dlt = uitab(app.tabg_group);
            app.tab_dlt.Title = 'DLT Param';
            app.panel_dlt = uipanel(app.tab_dlt);
            app.table_dltParam = uitable(app.panel_dlt);
            app.table_dltParam.ColumnName = {'DLT Param'};
            app.table_dltParam.RowName = {'L(1)'; 'L(2)'; 'L(3)'; 'L(4)'; 'L(5)'; 'L(6)'; 'L(7)'; 'L(8)'; 'L(9)'; 'L(10)'; 'L(11)'};
            app.table_dltParam.ColumnEditable = false;
            app.button_addDlt = uibutton(app.panel_dlt, 'push');
            app.button_addDlt.ButtonPushedFcn = createCallbackFcn(app, @button_addDltButtonPushed, true);
            app.button_addDlt.Text = 'ADD>>';
            app.label_copyPaste = uilabel(app.panel_dlt);
            app.label_copyPaste.Text = 'Copy-Paste Here';
            app.textA_copyPaste = uitextarea(app.panel_dlt);
            app.button_resetDltParam = uibutton(app.panel_dlt, 'push');
            app.button_resetDltParam.ButtonPushedFcn = createCallbackFcn(app, @button_resetDltParamPushed, true);
            app.button_resetDltParam.Text = 'reset';
            app.label_cameraTransf = uilabel(app.ui_createCameraObj);
            app.label_cameraTransf.Text = 'Camera Transformations';
            app.button_saveAlltoStudy = uibutton(app.ui_createCameraObj, 'push');
            app.button_saveAlltoStudy.ButtonPushedFcn = createCallbackFcn(app, @button_saveAlltoStudyPushed, true);
            app.button_saveAlltoStudy.Text = 'Save All to Study File';
            app.button_removeField = uibutton(app.ui_createCameraObj, 'push');
            app.button_removeField.ButtonPushedFcn = createCallbackFcn(app, @button_removeFieldButtonPushed, true);
            app.button_removeField.Text = 'Remove Field';
            app.label_sensorSize = uilabel(app.ui_createCameraObj);
            app.label_sensorSize.Text = 'Size (px)';
            app.editF_sensorSizeW = uieditfield(app.ui_createCameraObj, 'numeric');
            app.editF_sensorSizeW.Limits = [0 Inf];
            app.editF_sensorSizeW.ValueDisplayFormat = '%.0f';
            app.editF_sensorSizeW.Tooltip = {'image Height'};
            app.chkB_firstPixelIndex = uicheckbox(app.ui_createCameraObj);
            app.chkB_firstPixelIndex.Text = 'First pixel index is 0';
            app.chkB_firstPixelIndex.Value = true;
            app.label_x = uilabel(app.ui_createCameraObj);
            app.label_x.Text = 'X';
            app.editF_sensorSizeH = uieditfield(app.ui_createCameraObj, 'numeric');
            app.editF_sensorSizeH.Limits = [0 Inf];
            app.editF_sensorSizeH.ValueDisplayFormat = '%.0f';
            app.editF_sensorSizeH.Tooltip = {'Image Width'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = createCameraObjGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ui_createCameraObj)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ui_createCameraObj)
        end
    end
end