classdef createNodeStandardGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ui_createNodeStandard          matlab.ui.Figure
        tabg_group                     matlab.ui.container.TabGroup
        tab_start                      matlab.ui.container.Tab
        panel_start                    matlab.ui.container.Panel
        label_workingDir               matlab.ui.control.Label
        button_create                  matlab.ui.control.Button
        button_changeDir               matlab.ui.control.Button
        editF_saveAsLabel              matlab.ui.control.Label
        editF_saveAs                   matlab.ui.control.EditField
        button_loadFromFile            matlab.ui.control.Button
        label_loadFromFile             matlab.ui.control.Label
        label_inShare                  matlab.ui.control.Label
        tab_nodes                      matlab.ui.container.Tab
        panel_nodes                    matlab.ui.container.Panel
        table_recordedNodes            matlab.ui.control.Table
        button_make                    matlab.ui.control.Button
        button_deleteSelection         matlab.ui.control.Button
        button_uploadImage             matlab.ui.control.Button
        uia_image                      matlab.ui.control.UIAxes
        button_openImage               matlab.ui.control.Button
        button_addRow                  matlab.ui.control.Button
        label_tableExplanation         matlab.ui.control.Label
        textA_nodeListLabel            matlab.ui.control.Label
        textA_nodeList                 matlab.ui.control.TextArea
        tab_nodeGraph                  matlab.ui.container.Tab
        panel_nodeGraphMap             matlab.ui.container.Panel
        table_adjacencyMatrix          matlab.ui.control.Table
        button_makeAdjacencyMatrix     matlab.ui.control.Button
        spinner_resizeColWidthsLabel   matlab.ui.control.Label
        spinner_resizeColWidths        matlab.ui.control.Spinner
        button_makeGraph               matlab.ui.control.Button
        button_openGraphvsImage        matlab.ui.control.Button
        label_adjMatrixNote            matlab.ui.control.Label
        tab_nameEdges                  matlab.ui.container.Tab
        panel_nameEdges                matlab.ui.container.Panel
        table_edgeNames                matlab.ui.control.Table
        table_nodeTypes                matlab.ui.control.Table
        button_populateEdgeNodeTables  matlab.ui.control.Button
        button_doneFillEmptySpaces     matlab.ui.control.Button
        tab_makeBodyKinematics         matlab.ui.container.Tab
        panel_makeBodyKinematics       matlab.ui.container.Panel
        button_createBodyKinematics    matlab.ui.control.Button
        button_saveAndFinish           matlab.ui.control.Button
        chkB_savePlotFigure            matlab.ui.control.CheckBox
        button_prev                    matlab.ui.control.Button
        button_next                    matlab.ui.control.Button
        label_errorMsg                 matlab.ui.control.Label
    end


%----------------------------- CONSTANT PROPERTIES ---------------------------------%
    properties (Access = private, Constant = true)
        % variables that control app's visibility workflow function
        isTabChar = 'tab_';
        
        % number of initial steps, pair with startedFlag non constant property
        % change this according to the app's workflow before it unlocks the buton next
        numInitialSteps = 1;
                        
        % used to append characters from user entry prior to storage in Node table
        emptyRowRecordedNodes = {'' false false}
        rightChar   =  'R';
        leftChar    =  'L';
    end
    
    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'createNodeStandardGui';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
        
        % important/ relevant output from this app
        savedVarName = 'createNodeStandard';
        
        % the full app, required to populate this app with a previous session
        % this contains ALL components, even those stored in savedVarName
        savedAppName = 'createNodeStandardApp';
        
        % relevant variables to save in savedVarName
        % savedAppName saves this plus everything else too
        %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
        varsToSaveinStd =  {'stdName';
                            'body';
                            'appVersion'};
    end
    
%----------------------------- VARIABLE PROPERTIES --------------------------------%
    properties (Access = private)
        % root drectory for Node Standards
        %TODO -- Change this to Desktop
        workingDir = '';
        
        % started flag to make sure there is name and directory
        startedFlag = 0;
        
        % tab control variable, start at first tab
        tabControl = 1;
        
        % application tabs, used to move around with buttons, computed at startup
        applicationTabs;
        
        % selected cells from csv header and mapped tables ISR updated pointer
        tableNodeSelCells = 0;
        
        % used before the creation of the graph to hold node values
        nodes;
    end
    
    properties (Access = public)
        % conectivty graph, cant name it graph because it is reserved
        conGraph;
        adjMatrix;
        
        % store values for Nodes and their coordinate expansions
        stdName;
        image;
        
        % kinematic body
        body;
    end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%

%------------------------------- PRIVATE METHODS -----------------------------------%   
    methods (Access = private)
    
        function setInitialGuiParam(app)
            % fist turn all panels off
            % get names of all tabs and indexes in app.
            tnames  = fieldnames(app);
            
            % tabs are in the order that they apear in the gui in sequence
            %TODO -- if tabs have multiple children this wont work
            app.applicationTabs = tnames(contains(tnames,app.isTabChar));
            
            % disable all panels
            for(i=1: length(app.applicationTabs))
                app.(app.applicationTabs{i}).Children.Visible = 'off';
            end
            
            % the first pannel and tab controlled by class tab control
            app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
            app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            
            % load the study directory and display files
            loadWorkingDirFiles(app);
            
            % start array with one empty line
            button_addRowButtonPushed(app);
        end%setInitialGuiParam
%-----------------------------------------------------------------------------------%
        
        function loadWorkingDirFiles(app)   
                %TODO -- should trim this to show a ../../dir
                app.label_workingDir.Text = app.workingDir;
        end%loadWorkingDirFiles
%-----------------------------------------------------------------------------------%

        function loadAppState(app, loadedFile)
            % if load previous datafile, then populate all variables, there seems not to be a better way to do this
            % app-defined user properties
            app.button_openImage.Enable       = loadedFile.button_openImage.Enable;
            app.button_saveAndFinish.Enable   = loadedFile.button_saveAndFinish.Enable;
            if(~isempty(loadedFile.label_errorMsg))
                app.label_errorMsg.Text           = loadedFile.label_errorMsg.Text;
            end
            app.spinner_resizeColWidths.Value = loadedFile.spinner_resizeColWidths.Value;
            app.table_adjacencyMatrix.ColumnName     = loadedFile.table_adjacencyMatrix.ColumnName;
            app.table_adjacencyMatrix.RowName        = loadedFile.table_adjacencyMatrix.RowName;
            app.table_adjacencyMatrix.ColumnEditable = loadedFile.table_adjacencyMatrix.ColumnEditable;
            app.table_adjacencyMatrix.ColumnFormat   = loadedFile.table_adjacencyMatrix.ColumnFormat;
            app.table_adjacencyMatrix.ColumnWidth    = loadedFile.table_adjacencyMatrix.ColumnWidth;
            app.table_adjacencyMatrix.Data           = loadedFile.table_adjacencyMatrix.Data;
            app.table_edgeNames.Data          = loadedFile.table_edgeNames.Data;
            app.table_nodeTypes.ColumnFormat  = loadedFile.table_nodeTypes.ColumnFormat;
            app.table_nodeTypes.Data          = loadedFile.table_nodeTypes.Data;
            app.table_recordedNodes.Data      = loadedFile.table_recordedNodes.Data;
            app.textA_nodeList.Value          = loadedFile.textA_nodeList.Value;
            
            % user-defined public properties
            app.conGraph  = loadedFile.conGraph;
            app.adjMatrix = loadedFile.adjMatrix;
            app.stdName   = loadedFile.stdName;
            app.image     = loadedFile.image;
            app.body      = loadedFile.body;

            % load other stuff
            imshow(app.image, 'parent', app.uia_image);
        end%loadAppState
%-----------------------------------------------------------------------------------%

        function incStartFlag(app, cnt)
            % increase or decrease the start flag with bound checking
            % this ommits the value of cnt and only uses its sign.
            if(cnt>0 && app.startedFlag<app.numInitialSteps)
                app.startedFlag = app.startedFlag + 1;
            elseif(cnt<0 && app.startedFlag>0)
                app.startedFlag = app.startedFlag - 1;
            end
            
            % determine if the start is done and enable prev and next buttons
            if(app.startedFlag>=app.numInitialSteps)
                app.button_next.Enable = 'on';
                app.button_prev.Enable = 'on';
            else
                app.button_next.Enable = 'off';
                app.button_prev.Enable = 'off';
            end
        end%incStartFlag
%-----------------------------------------------------------------------------------%
        
        function advanceTab(app, cnt)
            % first check if cnt will request a tab out of range
            if((app.tabControl+cnt)>length(app.applicationTabs) || (app.tabControl+cnt)<1)
                dispMsg(app, 'e', 'tab change request out of range');
            else
                % clear errors if any
                dispMsg(app, 'c');
                
                % disable the current panel
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'off';
                
                % increase tab control and activate next panel and tab
                app.tabControl = app.tabControl+cnt;
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
                app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            end
        end%advanceTab
%-----------------------------------------------------------------------------------%

        function s = varsToSaveinStdFcn(app)
            % saves just the relevant variables that come out of this class not to save ui elements
            %TODO -- this is horrible
            for(i = 1:length(app.varsToSaveinStd))
                if(isa(app.varsToSaveinStd{i},'bioLinkage'))
                    s.(app.varsToSaveinStd{i}) = bioLinkage.loadobj(saveobj(app.(app.varsToSaveinStd{i})));
                else
                    s.(app.varsToSaveinStd{i}) = app.(app.varsToSaveinStd{i});
                end
            end                
        end%varsToSaveinStdFcn
%-----------------------------------------------------------------------------------%   
         
        function readNodeStandard(app)
            %TODO -- should i need try catch here to make sure that this is a standard node
            % aquire the value of the node standard and place it in a private variable here (copy)
            tmp = fullfile(app.workingDir, app.editF_nodeStandardFile.Value);
            tstruct = load(tmp, createNodeStandard.savedVarName);
            %TODO -- is this hardcoded or pointless?
            app.nodeStandardNames = tstruct.(createNodeStandard.savedVarName).conGraph.Nodes.Name;
        end%readNodeStandard
%-----------------------------------------------------------------------------------%
    
        function readCsvFile(app)
            %TODO -- should i need try catch here to make sure that this is a standard node dir?
            % read fille with all experiment data to local var
            app.csvDataRaw = importdata(fullfile(app.workingDir, app.editF_csvDataFile.Value));
            app.csvDataRaw.fileName = app.editF_csvDataFile.Value;
        end%readCsvFile
%-----------------------------------------------------------------------------------%

        function loadVarsFromTemplate(app, ttemplate)
            % step 2 is indifferent so run it as-is
            button_popNodeTableButtonPushed(app);
            
            % technically colum 3 is already populated from step 2, whatever.
            % copy the node assignment from the template experiment
            app.table_nodeAssign.Data = ttemplate.table_nodeAssign.Data;
            
            % compute step 4 and five
            % the user can do this technically but it should be ready so do it automatically and save time
            button_computeMapMatrixPushed(app);
            button_populateTrimNanButtonPushed(app);
        end%loadVarsFromTemplate
%-----------------------------------------------------------------------------------%

        function dispMsg(app, ttype, tmsg)
            % For log messaging blue
            if(strcmpi(ttype, 'L'))
                app.label_errorMsg.Text = ['LOG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.45,0.74];
                
            % for error messages red
            elseif(strcmpi(ttype, 'E'))
                app.label_errorMsg.Text = ['ERROR: ' tmsg];
                app.label_errorMsg.FontColor = [0.64,0.08,0.18];
                
            % general propose message black
            elseif(strcmpi(ttype, 'M'))
                app.label_errorMsg.Text = ['MSG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.00,0.00];
            
            % for clearing the mesage
            elseif(strcmpi(ttype, 'C'))
                app.label_errorMsg.Text = '';
            end
        end%dispMsg
%-----------------------------------------------------------------------------------%
    end%private methods


%-------------------------------- PUBLIC METHODS -----------------------------------% 
    methods (Access = public)
            
        function plotConnectivityGraph(app, tsave)
            %TODO -- sure this is repeated but it can be accesed publicly
            %--------------- compute all graph's connectivity ---------------%
            % aquire the nodes, joints, and origin to be highlighted.
            %TODO -- this node and joint chars are hardcded
            tmp = strcmp(app.conGraph.Nodes.Type, 'node');
            tnodes = app.conGraph.Nodes.Name(tmp);
            tmp = strcmp(app.conGraph.Nodes.Type, 'joint');
            tjoints = app.conGraph.Nodes.Name(tmp);
            tmp = app.conGraph.Nodes.Origin;
            torigin = app.conGraph.Nodes.Name(tmp);
            toriginType = app.conGraph.Nodes.Type(tmp);
            
            % variables to make links later, preallocate size
            tnodeEdges = false(length(app.conGraph.Edges.EndNodes),1);
            toriginEdges = false(length(app.conGraph.Edges.EndNodes),1);
            
            % edges that connect node-to-node are driven, preallocate size
            tfrom = false(length(app.conGraph.Edges.EndNodes),1);
            tto = false(length(app.conGraph.Edges.EndNodes),1);
            
            % grab all edges that connect node to node, they are driven (non actuated)
            for i =1:length(tnodes)
                tto = strcmp(app.conGraph.Edges.EndNodes(:,2),tnodes(i))|tto;
                tfrom = strcmp(app.conGraph.Edges.EndNodes(:,1),tnodes(i))|tfrom;
            end
            tnodeEdges = tto&tfrom;
            
            % if origin is a node, then all edges that connect to and from it are driven
            if(strcmp(app.conGraph.Nodes.Type(app.conGraph.Nodes.Origin), 'node'))
                toriginEdges = strcmp(app.conGraph.Edges.EndNodes(:,1),app.conGraph.Nodes.Name(app.conGraph.Nodes.Origin)) | ...
                               strcmp(app.conGraph.Edges.EndNodes(:,2),app.conGraph.Nodes.Name(app.conGraph.Nodes.Origin));
            end
            
            %------------- create the plot with all information -------------%
            %TODO -- make sure that I save this as a fig and that it preserves the highlight commands
            %TODO -- add weight all == 1 to lines and plot with weights proportionally
            % plot the new and complete conectivity graph and save it to a fig variable
            tfigure = figure('Name',['Connectivity graph for Node Standard: ' app.editF_saveAs.Value],'NumberTitle','off');
            tgraphPlot = plot(app.conGraph,'NodeLabel',app.conGraph.Nodes.Name, 'EdgeLabel',app.conGraph.Edges.Name);
            
            % show nodes in red, joints in blue, edges that connect node-node in red, the rest black
            highlight(tgraphPlot,tnodes,'NodeColor','r');
            highlight(tgraphPlot,tjoints,'NodeColor','b');
            highlight(tgraphPlot,'Edges',true(size(app.conGraph.Edges,1),1),'EdgeColor','k');
            
            % make the origin bigger, then green
            highlight(tgraphPlot,torigin);
            highlight(tgraphPlot,torigin,'NodeColor','green');
            
            % all edges that connect node to node are driven
            highlight(tgraphPlot,'Edges',tnodeEdges,'EdgeColor','r');
            
            % if toriginEdges is a node, then it is fall and all edges that connect to and from it are driven
            if(any(toriginEdges))
                highlight(tgraphPlot,'Edges',toriginEdges,'EdgeColor','c');
            end 
            
            %------------------- save figure if requested -------------------%
            if(~exist('tsave','var'))
                tsave = 'n';
            end
            
            if(strcmp(tsave, 'y'))
               savefig(tfigure, fullfile(app.workingDir, [app.editF_saveAs.Value '.fig'])) 
            end
        end%plotConnectivityGraph
%-----------------------------------------------------------------------------------%
    end%public methods
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tworkingDir)
            app.workingDir = tworkingDir;
            setInitialGuiParam(app);
        end

        % Button pushed function: button_make
        function button_makeButtonPushed(app, event)
            % first delete empty cells
            tmp = strcmp(app.table_recordedNodes.Data(:,1), '');
            app.table_recordedNodes.Data = app.table_recordedNodes.Data(~tmp,:);
            
            % check that matrix is not empty
            if(isempty(app.table_recordedNodes.Data))
                % if it is empty then insert one space
                button_addRowButtonPushed(app);
                dispMsg(app, 'e', 'Marix is empty.');
                
            % then make sure all items are unique
            elseif(size(app.table_recordedNodes.Data(:,1),1) ~= size(unique(app.table_recordedNodes.Data(:,1)),1))
                dispMsg(app, 'e', 'values are not unique!');
            
            % check that every entry is a valid variable name
            elseif(~all(cellfun(@isvarname, app.table_recordedNodes.Data(:,1))))
                dispMsg(app, 'e', 'at least one of the entries is not a valid name');
                
            % if everyting is good, then proceed
            else
                % if there was an error, clear it
                dispMsg(app, 'c');
                    
                % assign values
                tnodes = app.table_recordedNodes.Data(:,1);
                tright = logical(cell2mat(app.table_recordedNodes.Data(:,2)));
                tleft  = logical(cell2mat(app.table_recordedNodes.Data(:,3)));
                
                % define as an empty cell array
                app.nodes = {};
                
                % first create the nodes, if R or L is selcted then create those
                % if neither R nor L is selected, then copy the vaue as-is
                for(i = 1: size(tnodes,1))
                    if(tright(i))
                        tmp = [tnodes{i} app.rightChar];
                        app.nodes = [app.nodes; tmp];
                    end
                    if(tleft(i))
                        tmp = [tnodes{i} app.leftChar];
                        app.nodes = [app.nodes; tmp];
                    end
                    if((~tright(i)) && (~tleft(i)))
                        app.nodes = [app.nodes; tnodes{i}];
                    end       
                end%for RL
                
                % display the results in the text area
                app.textA_nodeList.Value = app.nodes;
            end%if values are valid
        end

        % Button pushed function: button_deleteSelection
        function button_deleteSelectionPushed(app, event)
            % if nothing is selected then selected nodes is zero, that is not a valid index;
            % also checks if matrix is empty already
            % cells selected array comes from an ISR table_recordedNodesCellSelection()
            if(~isempty(app.tableNodeSelCells) && app.tableNodeSelCells ~= 0)
                dispMsg(app, 'c');
                app.table_recordedNodes.Data(app.tableNodeSelCells,:) = [];
                app.tableNodeSelCells = 0;
            else
                dispMsg(app, 'e', 'ERROR: Select at least one cell');
            end
        end

        % Cell selection callback: table_recordedNodes
        function table_recordedNodesCellSelection(app, event)
           % for some reason it does not like to be directly assigned to private var
           % indices is probably adefined attribute of this function.
           indices = event.Indices(:,1);
           app.tableNodeSelCells = unique(indices);
        end

        % Button pushed function: button_uploadImage
        function button_uploadImageButtonPushed(app, event)
            % select an image to stay with the node standard
            [tfile,tpath] = uigetfile('*.png', 'Select a PNG image (500kB max)', app.workingDir);
            
            % save image for standard
            try
                % try to read image in the file and clear error if any
                timage = imread(fullfile(tpath, tfile));
                ispMsg(app, 'c');
            catch
                dispMsg(app, 'e', 'Something went wrong reading that image file');
            end
            
            % make sure the image is small
            tmp = whos('timage');
            if(floor(tmp.bytes/1000)>1000)
                dispMsg(app, 'e', 'The file is too large to store it here');
            else 
                % assing to class variable
                app.image = timage;
                % enable button to see image on other window
                app.button_openImage.Enable = 'on';
            
                %display image on app and clear error if any
                imshow(app.image, 'parent', app.uia_image);
                dispMsg(app, 'c');
            end
        end

        % Button pushed function: button_create
        function button_createPushed(app, event)
            % ensure the parameters are set before moving on
            if(isempty(app.workingDir))
                dispMsg(app, 'e', 'A propper directory must be selected first!');
            elseif(~isvarname(app.editF_saveAs.Value))
                dispMsg(app, 'e', 'That is not a propper standard name!');
            elseif(~isempty(dir(fullfile(app.workingDir, [app.editF_saveAs.Value '.mat']))))
                dispMsg(app, 'e', 'There is a file with that name here already');
            else
                % disable and lock fields
                dispMsg(app, 'c');
                app.editF_saveAs.Enable         = 'off';
                app.button_create.Enable        = 'off';
                app.button_changeDir.Enable     = 'off';
                app.button_loadFromFile.Enable  = 'off';
                % name the app
                app.stdName = app.editF_saveAs.Value;
                
                % add one to the start flag and disable button for load file
                incStartFlag(app, 1);
                app.button_loadFromFile.Enable = 'off';
            end
        end

        % Button pushed function: button_changeDir
        function button_changeDirPushed(app, event)
            % change direcotry in case user wants
            %TODO -- start at a reasonable place working ir if exsist
            tmp = uigetdir('Select annother target directory');
            
            % if it is zero means the user did not selected a directory
            if(tmp ~= 0)
                app.workingDir = tmp;
                app.label_workingDir.Text = app.workingDir;
            else
                msgbox('You must select a directory!', 'Chaos!', 'warn');
            end
        end

        % Button pushed function: button_openImage
        function button_openImageButtonPushed(app, event)
            % the button is enabled by loading an image, then display this to zoom
            imshow(app.image);
        end

        % Button pushed function: button_addRow
        function button_addRowButtonPushed(app, event)
            app.table_recordedNodes.Data = [app.table_recordedNodes.Data; app.emptyRowRecordedNodes]; 
        end

        % Button pushed function: button_makeAdjacencyMatrix
        function button_makeAdjacencyMatrixButtonPushed(app, event)
            % pupulate the table with Node Standard Names with empty values
            % first get the values to name the columns and rows which equals app.nodes
            
            % initialize the table with empty values
            % TODO -- the second time it should OR whatever is there, more importantly if i load from file
            app.table_adjacencyMatrix.Data = false(size(app.nodes,1));
            
            % allow user to modify table
            app.table_adjacencyMatrix.ColumnEditable = true;
            
            % make table a checkbox one for every column which = size(nodes)
            tmpcell = cell(1, size(app.nodes,1));
            tmpcell(1:end) = {'logical'};
            app.table_adjacencyMatrix.ColumnFormat = tmpcell;
            
            % format width with the initial value of spinner
            % future rezising will happen on the spinner_..ValueChanged callback
            tcolsize = cell(1, size(app.nodes,1));
            tcolsize(1:end) = num2cell(app.spinner_resizeColWidths.Value);
            app.table_adjacencyMatrix.ColumnWidth = tcolsize;
            
            % include names of columns and rows
            app.table_adjacencyMatrix.ColumnName = app.nodes;
            app.table_adjacencyMatrix.RowName    = app.nodes;
        end

        % Value changed function: spinner_resizeColWidths
        function spinner_resizeColWidthsValueChanged(app, event)
            value = app.spinner_resizeColWidths.Value;
            
            % format width as value from spinner field
            % this applies to all columns in matrix
            tmp = cell(1, size(app.nodes,1));
            tmp(1:end) = num2cell(value);
            app.table_adjacencyMatrix.ColumnWidth = tmp;
        end

        % Button pushed function: button_makeGraph
        function button_makeGraphButtonPushed(app, event)
            % make a connectiviy graph from adj matrix
            %TODO -- if matrix is empty it may crash
            
            % first clear diagonal and upper half
            tmp = logical(tril(ones(size(app.nodes,1)))-eye(size(app.nodes,1)));
            app.table_adjacencyMatrix.Data = tmp&app.table_adjacencyMatrix.Data;
            
            % make a full adj matrix (symetric) by the following
            app.adjMatrix = app.table_adjacencyMatrix.Data | transpose(app.table_adjacencyMatrix.Data);
            
            % create the first viewing of the conectivity matrix using the graph set
            app.conGraph = graph(app.adjMatrix, app.nodes);
            
            % show on image, plot externally, for some reason uiaxes as of today does not import annotations
            plot(app.conGraph, 'NodeLabel',app.conGraph.Nodes.Name);
        end

        % Button pushed function: button_populateEdgeNodeTables
        function button_populateEdgeNodeTablesButtonPushed(app, event)
            % populate the table of edges.
            app.table_edgeNames.Data = app.conGraph.Edges.EndNodes(:,1);
            app.table_edgeNames.Data = [app.table_edgeNames.Data app.conGraph.Edges.EndNodes(:,2)];
            tmpcell = cell(size(app.table_edgeNames.Data,1),1);
            tmpcell(:) = {''};
            app.table_edgeNames.Data = [app.table_edgeNames.Data tmpcell];
            
            % populate the table of node types
            app.table_nodeTypes.Data = app.conGraph.Nodes.Name;
            % second colum is a drop down for type of node
            tmpcell = cell(size(app.table_nodeTypes.Data,1),1);
            tmpcell(:) = {'type:'};
            app.table_nodeTypes.Data = [app.table_nodeTypes.Data tmpcell];
            % thid column is logical to find who is the origin
            tmpcell = cell(size(app.table_nodeTypes.Data,1),1);
            tmpcell(:) = {false};
            app.table_nodeTypes.Data = [app.table_nodeTypes.Data tmpcell];
            
            % make table an option one, transpose to row array, get joint type from bioJoint class constant
            app.table_nodeTypes.ColumnFormat = {[] fieldnames(bioJoint.jointTypes)' 'logical'};
        end

        % Button pushed function: button_doneFillEmptySpaces
        function button_doneFillEmptySpacesPushed(app, event)
            %TODO -- all tables Data could be referenced by column name too! but with a struct
            % grab the cells that the user did not enter to auto populate with def name
            tempty = strcmp(app.table_edgeNames.Data(:,3), '');
            torigin = logical(cell2mat(app.table_nodeTypes.Data(:,3)));
            
            % make sure no type is left undeclared
            if(any(strcmp(app.table_nodeTypes.Data(:,2),'type:')))
                % then send a warning that all should be declared
                dispMsg(app, 'e', 'All items in Node Table should be declared');
            
            % make sure all the entries are valid variable names, unless user did not a    
            elseif(~all(cellfun(@isvarname, app.table_edgeNames.Data(~tempty,1))))
                dispMsg(app, 'e', 'At least one of the names entered is not valid');
            
            %if there are multiple origins
            elseif(sum(torigin)~=1)
                dispMsg(app, 'e', 'There can only (and must) be one origin');
            else
                % assign a default edge name to the ones not filled
                % a default of node1_node2
                app.table_edgeNames.Data(tempty,3) = strcat(app.table_edgeNames.Data(tempty,1), {'_'}, app.table_edgeNames.Data(tempty,2));
            
                % Then upadate the edges table variable with all this
                app.conGraph.Edges.Name = app.table_edgeNames.Data(:,3);
                
                % append to the variable nodes the names types and the origin
                app.conGraph.Nodes.Type = app.table_nodeTypes.Data(:,2);
                app.conGraph.Nodes.Origin = torigin;
            end%if joit is undeclared
        end

        % Button pushed function: button_createBodyKinematics
        function button_createBodyKinematicsButtonPushed(app, event)
            
            %--------------- compute all graph's connectivity ---------------%
            % aquire the nodes, joints, and origin to be highlighted.
            %TODO -- this node and joint chars are hardcded
            tmp = strcmp(app.conGraph.Nodes.Type, 'node');
            tnodes = app.conGraph.Nodes.Name(tmp);
            tmp = strcmp(app.conGraph.Nodes.Type, 'joint');
            tjoints = app.conGraph.Nodes.Name(tmp);
            tmp = app.conGraph.Nodes.Origin;
            torigin = app.conGraph.Nodes.Name(tmp);
            toriginType = app.conGraph.Nodes.Type(tmp);
            
            % variables to make links later, preallocate size
            tnodeEdges = false(length(app.conGraph.Edges.EndNodes),1);
            toriginEdges = false(length(app.conGraph.Edges.EndNodes),1);
            
            % edges that connect node-to-node are driven, preallocate size
            tfrom = false(length(app.conGraph.Edges.EndNodes),1);
            tto = false(length(app.conGraph.Edges.EndNodes),1);
            
            % grab all edges that connect node to node, they are driven (non actuated)
            for i =1:length(tnodes)
                tto = strcmp(app.conGraph.Edges.EndNodes(:,2),tnodes(i))|tto;
                tfrom = strcmp(app.conGraph.Edges.EndNodes(:,1),tnodes(i))|tfrom;
            end
            tnodeEdges = tto&tfrom;
            
            % if origin is a node, then all edges that connect to and from it are driven
            if(strcmp(app.conGraph.Nodes.Type(app.conGraph.Nodes.Origin), 'node'))
                toriginEdges = strcmp(app.conGraph.Edges.EndNodes(:,1),app.conGraph.Nodes.Name(app.conGraph.Nodes.Origin)) | ...
                               strcmp(app.conGraph.Edges.EndNodes(:,2),app.conGraph.Nodes.Name(app.conGraph.Nodes.Origin));
            end
            
            %-------------- Assign edge connectivity prperties --------------% 
            %TODO -- make sure that all joints must connect to at least one joint 
            tedgetype = cell(size(app.conGraph.Edges.Name,1),1);
            tedgetype(tnodeEdges|toriginEdges)    = {bioLink.linkTypes.driven};
            tedgetype(~(tnodeEdges|toriginEdges)) = {bioLink.linkTypes.actuated};
            % create new column on table for the type of link
            app.conGraph.Edges.Type = tedgetype;
            
            
            %------------- create the plot with all information -------------%
            if(app.chkB_savePlotFigure.Value == 1)
                plotConnectivityGraph(app, 'y');
            else
                plotConnectivityGraph(app, 'n');
            end
            
            %------------------- create body kinematics ---------------------%
            %TODO -- there is a correct way to transform from joint to joint, that is, not passing trough nodes
            %TODO -- make a graph of just the joints and make sure that all are connected and there are no orphans
            %TODO -- retrieve with biconncomp(G)?to nsure that nodes in joint-node-joint config are one link and not 2
            %TODO -- how to interpret links that are ligaments or non actuated joitns,, almost soft-joints?
            
            app.body = bioLinkage(app.stdName);
            
            %TODO -- this repeats the origin node!
            for(i =1:length(app.conGraph.Nodes.Name))
               app.body.addJoints(bioJoint(app.conGraph.Nodes.Name{i},app.conGraph.Nodes.Type{i}));
            end
            
            for(i =1:length(app.conGraph.Edges.Name))
               app.body.addLinks(bioLink(app.conGraph.Edges.Name{i},app.conGraph.Edges.Type{i}));
            end
            
            app.body.adjMatrix = app.adjMatrix;
            app.body.graph = app.conGraph;
            app.body.standard = app.stdName;
            
            %-------------------------- finish ------------------------------%
            % activate the next button to save all
            app.button_saveAndFinish.Enable = 'on';
        end

        % Button pushed function: button_next
        function button_nextButtonPushed(app, event)
            % move to next tab
            advanceTab(app, 1);
        end

        % Button pushed function: button_prev
        function button_prevButtonPushed(app, event)
            % move to previous tab
            advanceTab(app, -1);
        end

        % Button pushed function: button_saveAndFinish
        function button_saveAndFinishButtonPushed(app, event)
            % disable wargings from saving stuff such as uia components
            %TODO -- just dont try to save them to prevent warning
            warning('off');

            % make a struct of what I want to save to that they keep those variable names
            tmp.(app.savedAppName) = app;
            tmp.(app.savedVarName) = varsToSaveinStdFcn(app);
            
            %tsave = app.body;
            %linkage = bioLinkage.loadobj(saveobj(app.body));
            % save file
            %TODO -- does this needs a try catch?
            %save(fullfile(app.workingDir, [app.editF_saveAs.Value '.mat']), 'tsave','linkage');
            save(fullfile(app.workingDir, [app.editF_saveAs.Value '.mat']), '-struct', 'tmp');
            
            % alert the user it is done
            msgbox({'All Saved!!!';'You can keep modfying or just close the window'},'Success');
            
            % turn warnings back on
            warning('on');
            %TODO -- this is a matlab bug
            warning off MATLAB:subscripting:noSubscriptsSpecified;
        end

        % Button pushed function: button_openGraphvsImage
        function button_openGraphvsImageButtonPushed(app, event)
            % create figure in expternal window
            %TODO -- subplot should have both images near each other
            figure();
            subplot(1,2,1), imshow(app.image);
            subplot(1,2,2), plot(app.conGraph);
        end

        % Button pushed function: button_loadFromFile
        function button_loadFromFileButtonPushed(app, event)
            % pick file as a template
            [tfile,tpath] = uigetfile('*.mat', ['Select only a valid: ' app.savedAppName '.mat file'], app.workingDir);
            try
                tmatfile = load(fullfile(tpath,tfile));
                loadedFile = tmatfile.(app.savedAppName);
                app.label_loadFromFile.Text = fullfile(tpath,tfile);   
            catch
                % for some reason the file cant be opened
                msgbox('The file cant be read for some reason', 'Chaos!', 'warn');
            end
            
            % load all the varialbes to populate the app
            loadAppState(app, loadedFile);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ui_createNodeStandard = uifigure;
            app.ui_createNodeStandard.Name = 'Create Node Standard';
            app.tabg_group = uitabgroup(app.ui_createNodeStandard);
            app.tab_start = uitab(app.tabg_group);
            app.tab_start.Title = 'Start';
            app.panel_start = uipanel(app.tab_start);
            app.label_workingDir = uilabel(app.panel_start);
            app.label_workingDir.Tooltip = {'No spaces'; ' no symbols'; ' no underscores no extensions.'; 'Just use camelCase. There is no error checking.'};
            app.label_workingDir.Text = '';
            app.button_create = uibutton(app.panel_start, 'push');
            app.button_create.ButtonPushedFcn = createCallbackFcn(app, @button_createPushed, true);
            app.button_create.Text = 'Create (2)';
            app.button_changeDir = uibutton(app.panel_start, 'push');
            app.button_changeDir.ButtonPushedFcn = createCallbackFcn(app, @button_changeDirPushed, true);
            app.button_changeDir.Text = 'Change Directory (1)';
            app.editF_saveAsLabel = uilabel(app.panel_start);
            app.editF_saveAsLabel.Text = 'Save As:';
            app.editF_saveAs = uieditfield(app.panel_start, 'text');
            app.editF_saveAs.Tooltip = {'No spaces no symbols no underscores no extensions.'; 'Just use camelCase. There is no error checking.'};
            app.button_loadFromFile = uibutton(app.panel_start, 'push');
            app.button_loadFromFile.ButtonPushedFcn = createCallbackFcn(app, @button_loadFromFileButtonPushed, true);
            app.button_loadFromFile.Text = 'Load From File (0)';
            app.label_loadFromFile = uilabel(app.panel_start);
            app.label_loadFromFile.Text = '';
            app.label_inShare = uilabel(app.panel_start);
            app.label_inShare.Text = {'Consider saving the file in the "share"'; 'directory of a study'};
            app.tab_nodes = uitab(app.tabg_group);
            app.tab_nodes.Title = 'Node Creation and Definition';
            app.panel_nodes = uipanel(app.tab_nodes);
            app.table_recordedNodes = uitable(app.panel_nodes);
            app.table_recordedNodes.ColumnName = {'Nodes'; 'R'; 'L'};
            app.table_recordedNodes.RowName = {};
            app.table_recordedNodes.ColumnEditable = true;
            app.table_recordedNodes.CellSelectionCallback = createCallbackFcn(app, @table_recordedNodesCellSelection, true);
            app.button_make = uibutton(app.panel_nodes, 'push');
            app.button_make.ButtonPushedFcn = createCallbackFcn(app, @button_makeButtonPushed, true);
            app.button_make.Text = 'Make(4)>>';
            app.button_deleteSelection = uibutton(app.panel_nodes, 'push');
            app.button_deleteSelection.ButtonPushedFcn = createCallbackFcn(app, @button_deleteSelectionPushed, true);
            app.button_deleteSelection.Text = 'Delete Selection (3)';
            app.button_uploadImage = uibutton(app.panel_nodes, 'push');
            app.button_uploadImage.ButtonPushedFcn = createCallbackFcn(app, @button_uploadImageButtonPushed, true);
            app.button_uploadImage.Text = 'Upload Image (5)';
            app.uia_image = uiaxes(app.panel_nodes);
            app.button_openImage = uibutton(app.panel_nodes, 'push');
            app.button_openImage.ButtonPushedFcn = createCallbackFcn(app, @button_openImageButtonPushed, true);
            app.button_openImage.Enable = 'off';
            app.button_openImage.Text = 'Open Image in Window';
            app.button_addRow = uibutton(app.panel_nodes, 'push');
            app.button_addRow.ButtonPushedFcn = createCallbackFcn(app, @button_addRowButtonPushed, true);
            app.button_addRow.Text = 'Add Row (3)';
            app.label_tableExplanation = uilabel(app.panel_nodes);
            app.label_tableExplanation.Text = 'Symetry R/L make independent nodes. X/Y/Z are node components.';
            app.textA_nodeListLabel = uilabel(app.panel_nodes);
            app.textA_nodeListLabel.Text = 'Node Coordinates List';
            app.textA_nodeList = uitextarea(app.panel_nodes);
            app.textA_nodeList.Editable = 'off';
            app.tab_nodeGraph = uitab(app.tabg_group);
            app.tab_nodeGraph.Title = 'Node Graph Mapping';
            app.panel_nodeGraphMap = uipanel(app.tab_nodeGraph);
            app.table_adjacencyMatrix = uitable(app.panel_nodeGraphMap);
            app.table_adjacencyMatrix.ColumnName = {''};
            app.table_adjacencyMatrix.RowName = {};
            app.button_makeAdjacencyMatrix = uibutton(app.panel_nodeGraphMap, 'push');
            app.button_makeAdjacencyMatrix.ButtonPushedFcn = createCallbackFcn(app, @button_makeAdjacencyMatrixButtonPushed, true);
            app.button_makeAdjacencyMatrix.Text = 'Make Adjacency Matrix (6)';
            app.spinner_resizeColWidthsLabel = uilabel(app.panel_nodeGraphMap);
            app.spinner_resizeColWidthsLabel.Text = 'Resize Col Widths';
            app.spinner_resizeColWidths = uispinner(app.panel_nodeGraphMap);
            app.spinner_resizeColWidths.Step = 5;
            app.spinner_resizeColWidths.Limits = [1 400];
            app.spinner_resizeColWidths.ValueDisplayFormat = '%.0f';
            app.spinner_resizeColWidths.ValueChangedFcn = createCallbackFcn(app, @spinner_resizeColWidthsValueChanged, true);
            app.spinner_resizeColWidths.Value = 30;
            app.button_makeGraph = uibutton(app.panel_nodeGraphMap, 'push');
            app.button_makeGraph.ButtonPushedFcn = createCallbackFcn(app, @button_makeGraphButtonPushed, true);
            app.button_makeGraph.Text = 'Make Graph (7)';
            app.button_openGraphvsImage = uibutton(app.panel_nodeGraphMap, 'push');
            app.button_openGraphvsImage.ButtonPushedFcn = createCallbackFcn(app, @button_openGraphvsImageButtonPushed, true);
            app.button_openGraphvsImage.Text = 'Open Graph vs Image (8)';
            app.label_adjMatrixNote = uilabel(app.panel_nodeGraphMap);
            app.label_adjMatrixNote.Text = {'NOTE: Only mark the bottom left or ''lower'' triangle'; 'in the matrix. Diagonal is ignored too.'};
            app.tab_nameEdges = uitab(app.tabg_group);
            app.tab_nameEdges.Title = 'Name Edges And Node Types';
            app.panel_nameEdges = uipanel(app.tab_nameEdges);
            app.table_edgeNames = uitable(app.panel_nameEdges);
            app.table_edgeNames.ColumnName = {'Node n'; 'Node k'; 'Edge Name'};
            app.table_edgeNames.RowName = {};
            app.table_edgeNames.ColumnEditable = [false false true];
            app.table_nodeTypes = uitable(app.panel_nameEdges);
            app.table_nodeTypes.ColumnName = {'Name'; 'Node/Joint'; 'Origin?'};
            app.table_nodeTypes.RowName = {};
            app.table_nodeTypes.ColumnEditable = [false true true];
            app.button_populateEdgeNodeTables = uibutton(app.panel_nameEdges, 'push');
            app.button_populateEdgeNodeTables.ButtonPushedFcn = createCallbackFcn(app, @button_populateEdgeNodeTablesButtonPushed, true);
            app.button_populateEdgeNodeTables.Text = 'Populate Tables (9)';
            app.button_doneFillEmptySpaces = uibutton(app.panel_nameEdges, 'push');
            app.button_doneFillEmptySpaces.ButtonPushedFcn = createCallbackFcn(app, @button_doneFillEmptySpacesPushed, true);
            app.button_doneFillEmptySpaces.Text = 'Done / Fill Empty Spaces (10)';
            app.tab_makeBodyKinematics = uitab(app.tabg_group);
            app.tab_makeBodyKinematics.Title = 'Make Body Kinematics';
            app.panel_makeBodyKinematics = uipanel(app.tab_makeBodyKinematics);
            app.button_createBodyKinematics = uibutton(app.panel_makeBodyKinematics, 'push');
            app.button_createBodyKinematics.ButtonPushedFcn = createCallbackFcn(app, @button_createBodyKinematicsButtonPushed, true);
            app.button_createBodyKinematics.Text = 'Create Body Kinematics (11)';
            app.button_saveAndFinish = uibutton(app.panel_makeBodyKinematics, 'push');
            app.button_saveAndFinish.ButtonPushedFcn = createCallbackFcn(app, @button_saveAndFinishButtonPushed, true);
            app.button_saveAndFinish.Enable = 'off';
            app.button_saveAndFinish.Text = 'Save and Finish! (12)';
            app.chkB_savePlotFigure = uicheckbox(app.panel_makeBodyKinematics);
            app.chkB_savePlotFigure.Text = 'Save Figure';
            app.button_prev = uibutton(app.ui_createNodeStandard, 'push');
            app.button_prev.ButtonPushedFcn = createCallbackFcn(app, @button_prevButtonPushed, true);
            app.button_prev.Enable = 'off';
            app.button_prev.Text = '<<Prev';
            app.button_next = uibutton(app.ui_createNodeStandard, 'push');
            app.button_next.ButtonPushedFcn = createCallbackFcn(app, @button_nextButtonPushed, true);
            app.button_next.Enable = 'off';
            app.button_next.Text = 'Next>>';
            app.label_errorMsg = uilabel(app.ui_createNodeStandard);
            app.label_errorMsg.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = createNodeStandardGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ui_createNodeStandard)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ui_createNodeStandard)
        end
    end
end