classdef extractFramesFromVideoGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        extractFramesFromVideo  matlab.ui.Figure
        panel_videos            matlab.ui.container.Panel
        panel_controls          matlab.ui.container.Panel
        chkB_lock               matlab.ui.control.CheckBox
        button_selectVideos     matlab.ui.control.Button
        listB_frames            matlab.ui.control.ListBox
        button_pickFrame        matlab.ui.control.Button
        button_extractFinish    matlab.ui.control.Button
        button_showFrame        matlab.ui.control.Button
        text_totalFrames        matlab.ui.control.Label
        button_delete           matlab.ui.control.Button
        label_errorMsg          matlab.ui.control.Label
        label_numCameras        matlab.ui.control.Label
        spinner_numCameras      matlab.ui.control.Spinner
        label_frame             matlab.ui.control.Label
        spinner_frame           matlab.ui.control.Spinner
    end

  %-----------------------------------------------------------------------------------%
  % Original idea and code:                                                           %
  %               David Boerma 2019                                                   %
  %                                                                                   %
  %----------------------------- CONSTANT PROPERTIES ---------------------------------%
  
  properties (Access = private, Constant = true)
    plotchar   = 'p';
    videochar  = 'v';
    suffixchar = 'i';
    framechar  = 'f';
    newDirName = 'calibImgs';
  end
  
  properties (Access = public, Constant = true)
    % app descriptors
    appName = 'extractFramesFromVideoGui';
    appVersion = '0.04';
    appAuthor = 'Alberto Bortoni';
    
    % important/ relevant output from this app
    savedVarName = 'extractFramesFromVideo';
    
    % the full app, required to populate this app with a previous session
    % this contains ALL components, even those stored in savedVarName
    savedAppName = 'extractFramesFromVideoGuiApp';
    
    % relevant variables to save in savedVarName
    % savedAppName saves this plus everything else too
    %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
    varsToSaveinStd =  {'appVersion'};
  end
  
  %---------------------------- VARIABLE PROPERTIES --------------------------------%
  
  properties (Access = private)
    % this variable stores the working directory
    workingDir = '';
    numCameras;
    filenames;
    videos     = struct();
    vidPlots   = struct();
    nFrames    = 0;
    images     = struct();
    currimg    = struct();
    scaleApp   = true;
    dirFlag    = true;
  end
  
  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
  %                               METHODS SECTION                                     %
  %-----------------------------------------------------------------------------------%
  
  methods (Access = private)
    
    function closeApp(app,src,event)
      delete(app)
    end%setInitialGuiParam
    %-----------------------------------------------------------------------------------%
    
    function dirExsistFlag(app,src,event)
      display(src)
      display(event)
    end%setInitialGuiParam
    %-----------------------------------------------------------------------------------%
    
    function s = varsToSaveinStdFcn(app)
      % saves just the relevant variables that come out of this class not to save ui elements
      for(i = 1:length(app.varsToSaveinStd))
        s.(app.varsToSaveinStd{i}) = app.(app.varsToSaveinStd{i});
      end
    end%varsToSaveinStdFcn
    %-----------------------------------------------------------------------------------%
    
    function initPlots(app)
      %determine the num of rows
      trows = 1;
      if(app.numCameras>3)
        trows = 2;
      end
      
      %determine the number of col
      tcols = 3;
      switch(app.numCameras)
        case 1
          tcols = 1;
        case 2
        case 4
          tcols = 2;
        otherwise
          tcols = 3;
      end
      
      %initialize the subplots
      for(i = 1: app.numCameras)
        app.vidPlots.([app.plotchar num2str(i)]) = subplot(trows, tcols, i, 'Parent', app.panel_videos);
      end
    end%initPlots
    %-----------------------------------------------------------------------------------%
    
    function dispMsg(app, ttype, tmsg)
      % For log messaging blue
      if(strcmpi(ttype, 'L'))
        app.label_errorMsg.Text = ['LOG: ' tmsg];
        app.label_errorMsg.FontColor = [0.00,0.45,0.74];
        
        % for error messages red
      elseif(strcmpi(ttype, 'E'))
        app.label_errorMsg.Text = ['ERROR: ' tmsg];
        app.label_errorMsg.FontColor = [0.64,0.08,0.18];
        
        % general propose message black
      elseif(strcmpi(ttype, 'M'))
        app.label_errorMsg.Text = ['MSG: ' tmsg];
        app.label_errorMsg.FontColor = [0.00,0.00,0.00];
        
        % for clearing the mesage
      elseif(strcmpi(ttype, 'C'))
        app.label_errorMsg.Text = '';
      end
    end%dispMsg
    %-----------------------------------------------------------------------------------%
  end%private methods

    % Callbacks that handle component events
    methods (Access = private)

        % Size changed function: extractFramesFromVideo
        function extractFramesFromVideoSizeChanged(app, event)
          position = app.extractFramesFromVideo.Position;
          if(app.scaleApp)
            app.panel_controls.Position = [1,(position(4)-128),(position(3)),128];
            app.panel_videos.Position   = [1,1,(position(3)),(position(4)-132)];
          end
        end

        % Value changed function: chkB_lock
        function chkB_lockValueChanged(app, event)
          value = app.chkB_lock.Value;
          
          if(value == 1)
            %get the number of cameras
            app.numCameras = app.spinner_numCameras.Value;
            
            %continue with workflow
            app.spinner_numCameras.Enable  = 'off';
            app.button_selectVideos.Enable = 'on';
            
            %resize
            dispMsg(app, 'l', 'Resize this window before "select videos"')
          else
            %continue with workflow
            app.spinner_numCameras.Enable  = 'on';
            app.button_selectVideos.Enable = 'off';
          end
        end

        % Button pushed function: button_selectVideos
        function button_selectVideosButtonPushed(app, event)
          %prevent user from scaling the app
          app.scaleApp = false;
          
          %get the target directory
          [tfile, tpath] = uigetfile('*.avi','MultiSelect','on');
          dispMsg(app, 'l', 'Wait...');
          
          %make sure the selection is good
          if(~isnumeric(tfile) || ~isnumeric(tpath))
            %make it a col vector, if it is not cell then make it one
            if(iscell(tfile))
              tfile = tfile(:);
            else
              tfile = {tfile};
            end
            
            %make sure that only the right ammount of videos are selected
            if(size(tfile,1) == app.numCameras)
              %before plotting disable autoresize, it does not work with subplot.
              app.panel_videos.AutoResizeChildren = 'off';
              
              app.workingDir = tpath;
              app.filenames = tfile;
              
              %read videos
              for(i = 1: app.numCameras)
                app.videos.([app.videochar num2str(i)]) = VideoReader(fullfile(app.workingDir, app.filenames{i}));
                
                %get the number of frames
                tmp = floor(app.videos.([app.videochar num2str(i)]).Duration*app.videos.([app.videochar num2str(i)]).FrameRate);
                %then take the smallest number for frame
                if(app.nFrames == 0)
                  app.nFrames = tmp;
                elseif(tmp<app.nFrames)
                  app.nFrames = tmp;
                end
              end
              
              %mark the limits of the spinner
              app.spinner_frame.Limits = [1, app.nFrames];
              app.text_totalFrames.Text = ['Frames: ' num2str(app.nFrames)];
              
              %create the subplots
              initPlots(app);
              
              %enable/disable buttons
              app.button_selectVideos.Enable  = 'off';
              app.chkB_lock.Enable            = 'off';
              app.spinner_frame.Enable        = 'on';
              app.button_showFrame.Enable     = 'on';
              app.button_pickFrame.Enable     = 'on';
              app.listB_frames.Enable         = 'on';
              app.button_extractFinish.Enable = 'on';
              app.button_delete.Enable        = 'on';
              
              dispMsg(app, 'c');
            else
              dispMsg(app, 'e', 'the number of videos selected does not match');
            end
          else
            dispMsg(app, 'e', 'select a valid directory');
          end
        end

        % Button pushed function: button_showFrame
        function button_showFrameButtonPushed(app, event)
          %read videos then display on subplot
          for(i = 1: app.numCameras)
            dispMsg(app, 'l', 'Wait...');
            
            %get the time at which this frame happens and change it
            ttime = app.spinner_frame.Value/app.videos.([app.videochar num2str(i)]).FrameRate;
            app.videos.([app.videochar num2str(i)]).CurrentTime = ttime;
            
            %show it on the subplot and store it in current image
            tmp = imshow(app.videos.([app.videochar num2str(i)]).readFrame, 'Parent', app.vidPlots.([app.plotchar num2str(i)]));
            app.currimg.([app.videochar num2str(i)]) = tmp.CData;
            dispMsg(app, 'l', 'done!');
          end
        end

        % Button pushed function: button_pickFrame
        function button_pickFrameButtonPushed(app, event)
          %store the images in the struct
          if(~any(strcmp(app.listB_frames.Items,num2str(app.spinner_frame.Value))) || isempty(app.listB_frames.Items))
            for(i = 1: app.numCameras)
              dispMsg(app, 'l', 'Wait...');
              app.images.([app.videochar num2str(i)]).([app.framechar num2str(app.spinner_frame.Value)]) = app.currimg.([app.videochar num2str(i)]);
            end
            
            %add the item to the list
            app.listB_frames.Items = [app.listB_frames.Items, num2str(app.spinner_frame.Value)];
            dispMsg(app, 'l', 'frame stored');
          else
            dispMsg(app, 'e', 'that frame is already stored');
          end
        end

        % Value changed function: spinner_frame
        function spinner_frameValueChanged(app, event)
          value = app.spinner_frame.Value;
          dispMsg(app, 'c');
        end

        % Button pushed function: button_delete
        function button_deletePushed(app, event)
          %remove the frame from the list
          if(~isempty(app.listB_frames.Value))
            tframe = str2num(app.listB_frames.Value);
            app.listB_frames.Items(contains(app.listB_frames.Items, app.listB_frames.Value)) = [];
            
            %for every camera remove the struct
            for(i = 1: app.numCameras)
              app.images.([app.videochar num2str(i)]) = rmfield(app.images.([app.videochar num2str(i)]), [app.framechar num2str(tframe)]);
            end
            dispMsg(app, 'c');
          else
            dispMsg(app, 'e', 'You must select an item from the list');
          end
        end

        % Button pushed function: button_extractFinish
        function button_extractFinishButtonPushed(app, event)
          %if directory is there make sure its ok
          %TODO -- allow user to pick if change dir is needed
          %TODO -- select download format
          %TODO -- if i just hit pick and not show it wont save the right
          %image
          tsaveFlag = true;
          if(isfolder(fullfile(app.workingDir, app.newDirName)))
            tsaveFlag = false;
            %notify and exit
            tsel = uiconfirm(app.extractFramesFromVideo,{'Dir already exist:';fullfile(app.workingDir, app.newDirName);' '; 'it should be ok but I will let you decide'}, ...
              'Directory exist', 'Options', {'Proceed', 'Stop!'}); %, 'dirExsistFlag', @(src,event)dirExsistFlag(app,src,event));
            if(strcmp(tsel, 'Proceed'))
              tsaveFlag = true;
            end
          end
          
          %make a directory with images
          if(tsaveFlag)
            mkdir(fullfile(app.workingDir, app.newDirName));
            dispMsg(app, 'l', 'Wait, Saving all images...');
            
            %store the images in the drive
            for(i = 1: app.numCameras)
              %get the name and drop the extension
              tname = app.filenames{i};
              tmp = strfind(tname,'.');
              tname = tname(1:(tmp-1));
              
              %get all the frames to be saved
              %TODO -- this will cause an error if there are no images
              %selected
              tframes = fieldnames(app.images.([app.videochar num2str(i)]));
              
              for(j = 1: size(tframes,1))
                imwrite(app.images.([app.videochar num2str(i)]).(tframes{j}), fullfile(app.workingDir, app.newDirName, [tname, '_', tframes{j}, '.png']), ...
                  'png');
              end
            end
            
            %notify and exit
            uiconfirm(app.extractFramesFromVideo,{'Images saved in:';fullfile(app.workingDir, app.newDirName);' '; 'App will close now =)'},'Confirm Close','Options',{'OK'},'CloseFcn',@(src,event)closeApp(app,src,event));
          else
            dispMsg(app, 'e', ['Directory ', app.newDirName, ' already exsist']);
          end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.extractFramesFromVideo = uifigure('Visible', 'off');
            app.extractFramesFromVideo.Name = 'UI Figure';
            app.extractFramesFromVideo.SizeChangedFcn = createCallbackFcn(app, @extractFramesFromVideoSizeChanged, true);
            app.panel_videos = uipanel(app.extractFramesFromVideo);
            app.panel_controls = uipanel(app.extractFramesFromVideo);
            app.chkB_lock = uicheckbox(app.panel_controls);
            app.chkB_lock.ValueChangedFcn = createCallbackFcn(app, @chkB_lockValueChanged, true);
            app.chkB_lock.Text = 'Lock';
            app.button_selectVideos = uibutton(app.panel_controls, 'push');
            app.button_selectVideos.ButtonPushedFcn = createCallbackFcn(app, @button_selectVideosButtonPushed, true);
            app.button_selectVideos.Enable = 'off';
            app.button_selectVideos.Text = 'Select Videos';
            app.listB_frames = uilistbox(app.panel_controls);
            app.listB_frames.Items = {};
            app.listB_frames.Enable = 'off';
            app.listB_frames.Value = {};
            app.button_pickFrame = uibutton(app.panel_controls, 'push');
            app.button_pickFrame.ButtonPushedFcn = createCallbackFcn(app, @button_pickFrameButtonPushed, true);
            app.button_pickFrame.Enable = 'off';
            app.button_pickFrame.Text = 'Pick Frame';
            app.button_extractFinish = uibutton(app.panel_controls, 'push');
            app.button_extractFinish.ButtonPushedFcn = createCallbackFcn(app, @button_extractFinishButtonPushed, true);
            app.button_extractFinish.Enable = 'off';
            app.button_extractFinish.Text = {'Extract'; 'Finish'};
            app.button_showFrame = uibutton(app.panel_controls, 'push');
            app.button_showFrame.ButtonPushedFcn = createCallbackFcn(app, @button_showFrameButtonPushed, true);
            app.button_showFrame.Enable = 'off';
            app.button_showFrame.Text = 'Show Frame';
            app.text_totalFrames = uilabel(app.panel_controls);
            app.text_totalFrames.Text = 'Frames:';
            app.button_delete = uibutton(app.panel_controls, 'push');
            app.button_delete.ButtonPushedFcn = createCallbackFcn(app, @button_deletePushed, true);
            app.button_delete.Enable = 'off';
            app.button_delete.Text = 'delete';
            app.label_errorMsg = uilabel(app.panel_controls);
            app.label_errorMsg.Text = '';
            app.label_numCameras = uilabel(app.panel_controls);
            app.label_numCameras.Text = 'Num Cameras';
            app.spinner_numCameras = uispinner(app.panel_controls);
            app.spinner_numCameras.Limits = [1 6];
            app.spinner_numCameras.Value = 1;
            app.label_frame = uilabel(app.panel_controls);
            app.label_frame.Text = 'Frame';
            app.spinner_frame = uispinner(app.panel_controls);
            app.spinner_frame.Limits = [1 10];
            app.spinner_frame.ValueChangedFcn = createCallbackFcn(app, @spinner_frameValueChanged, true);
            app.spinner_frame.Enable = 'off';
            app.spinner_frame.Value = 1;
            % Show the figure after all components are created
            app.extractFramesFromVideo.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = extractFramesFromVideoGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.extractFramesFromVideo)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.extractFramesFromVideo)
        end
    end
end