classdef modifyCoordinateSystemGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        modifyCoordinateSystem    matlab.ui.Figure
        tabg_group                matlab.ui.container.TabGroup
        tab_loadData              matlab.ui.container.Tab
        panel_loadData            matlab.ui.container.Panel
        button_trialSelect        matlab.ui.control.Button
        chkB_trialLock            matlab.ui.control.CheckBox
        lablel_trialFile          matlab.ui.control.Label
        editF_trialFile           matlab.ui.control.EditField
        label_workingDir          matlab.ui.control.Label
        label_step1               matlab.ui.control.Label
        label_existingFile        matlab.ui.control.Label
        button_refreshWorkingDir  matlab.ui.control.Button
        label_step0               matlab.ui.control.Label
        label_trialsAvail         matlab.ui.control.Label
        listB_trialsAvail         matlab.ui.control.ListBox
        button_readTrial          matlab.ui.control.Button
        tab_triadFunctions        matlab.ui.container.Tab
        panel_triadFunctions      matlab.ui.container.Panel
        button_browseToWorld      matlab.ui.control.Button
        label_instructionsFun     matlab.ui.control.Label
        label_varName             matlab.ui.control.Label
        editF_varName             matlab.ui.control.EditField
        button_copyVarName        matlab.ui.control.Button
        label_functionLoc         matlab.ui.control.Label
        label_worldTranFunc       matlab.ui.control.Label
        editF_worldTranFunc       matlab.ui.control.EditField
        label_bodyTranFunc        matlab.ui.control.Label
        editF_bodyTranFunc        matlab.ui.control.EditField
        label_worldOrigin         matlab.ui.control.Label
        label_bodyOrigin          matlab.ui.control.Label
        button_browseToBody       matlab.ui.control.Button
        button_lockFunc           matlab.ui.control.Button
        tab_chCoordinate          matlab.ui.container.Tab
        panel_chCoordinate        matlab.ui.container.Panel
        button_chCoordinate       matlab.ui.control.Button
        tab_saveAll               matlab.ui.container.Tab
        panel_saveAll             matlab.ui.container.Panel
        button_saveAllAndExit     matlab.ui.control.Button
        label_savedAs             matlab.ui.control.Label
        button_copyToWorkspace    matlab.ui.control.Button
        label_copyToWorkspace     matlab.ui.control.Label
        editF_copyToWorkspace     matlab.ui.control.EditField
        button_repeatForAll       matlab.ui.control.Button
        button_prev               matlab.ui.control.Button
        button_next               matlab.ui.control.Button
        label_errorMsg            matlab.ui.control.Label
    end


%---------------------------- CONSTANT PROPERTIES ---------------------------------%    
    
    properties (Access = private, Constant = true)
        % variables that control app's visibility workflow function
        isTabChar = 'tab_';
        
        % number of initial steps, pair with startedFlag non constant property
        % change this according to the app's workflow before it unlocks the buton next
        numInitialSteps = 1;
    end
    
    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'modifyCoordinateSystemGui';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
        
        % important/ relevant output from this app
        savedVarName     = 'modifyCoordinateSystem';
        
        % the full app, required to populate this app with a previous session
        % this contains ALL components, even those stored in savedVarName
        savedAppName = 'modifyCoordinateSystempApp';
        
        % relevant variables to save in savedVarName
        % savedAppName saves this plus everything else too
        %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
        varsToSaveinStd =  {'motion';
                            'appVersion'};
          
    end
    

%---------------------------- VARIABLE PROPERTIES --------------------------------%
    
    properties (Access = private)
        % this variable stores the working directory
        workingDir = '';
        trialDir   = '';
        
        % started flag to make sure there is name and directory
        startedFlag = 0;
        
        % tab control variable, start at first tab
        tabControl = 1;
        
        % application tabs, used to move around with buttons, computed at startup
        applicationTabs;
        
        %paths to functions to get added (and removed at exit)
        funcPaths = cell('');
        
        %flag for batch save
        batchSaveFlag  = false;
        cameraSaveFlag = true;
    end
         
    properties (Access = public)
        % local copy of node standard imported and names of its elements
        %TODO -- this is a stupid name
        motion;
        camera;
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%
    
    methods (Access = private)
        
        function setInitialGuiParam(app)
            % fist turn all panels off
            % get names of all tabs and indexes in app.
            tnames  = fieldnames(app);
            
            % tabs are in the order that they apear in the gui in sequence
            %TODO -- if tabs have multiple children this wont work
            app.applicationTabs = tnames(contains(tnames,app.isTabChar));
            
            % disable all panels
            for(i=1: length(app.applicationTabs))
                app.(app.applicationTabs{i}).Children.Visible = 'off';
            end
            
            % the first pannel and tab controlled by class tab control
            app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
            app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            
            % load the study directory and display files
            loadWorkingDirFiles(app);
        end%setInitialGuiParam
%-----------------------------------------------------------------------------------%
        
        function loadWorkingDirFiles(app)   
                %TODO -- should trim this to show a ../../dir
                app.label_workingDir.Text = app.workingDir;

                % dont display the trials availiable
                tignorefiles = {'.'; '..'; '.git'};
                tmp = cellstr(ls(fullfile(app.workingDir, 'trial')));
                tmp = setdiff(tmp,tignorefiles);
                app.listB_trialsAvail.Items = tmp;
        end%loadWorkingDirFiles
%-----------------------------------------------------------------------------------%

        function findAppInstance(app)
            % see if there is an instance of this app in the working directory
            if(~(isfile(fullfile(app.trialDir, [biolocomotionMainVar.savedMotionVar '.mat']))))
                app.label_existingFile.Text = {['There is no ' biolocomotionMainVar.savedMotionVar ' File in the Working Directory.'] 'Saving will create a new one.'};
            else
                %tell the user this exist
                app.label_existingFile.Text = {['There is a ' biolocomotionMainVar.savedMotionVar ' File in the Working Directory.'] 'Saving will overwrite it.'};
            end
        end%findAppInstance
%-----------------------------------------------------------------------------------%

        function incStartFlag(app, cnt)
            % increase or decrease the start flag with bound checking
            % this ommits the value of cnt and only uses its sign.
            if(cnt>0 && app.startedFlag<app.numInitialSteps)
                app.startedFlag = app.startedFlag + 1;
            elseif(cnt<0 && app.startedFlag>0)
                app.startedFlag = app.startedFlag - 1;
            end
            
            % determine if the start is done and enable prev and next buttons
            if(app.startedFlag>=app.numInitialSteps)
                app.button_next.Enable = 'on';
                app.button_prev.Enable = 'on';
            else
                app.button_next.Enable = 'off';
                app.button_prev.Enable = 'off';
            end
        end%incStartFlag
%-----------------------------------------------------------------------------------%
        
        function advanceTab(app, cnt)
            % first check if cnt will request a tab out of range
            if((app.tabControl+cnt)>length(app.applicationTabs) || (app.tabControl+cnt)<1)
                dispMsg(app, 'e', 'tab change request out of range');
            else
                % clear errors if any
                dispMsg(app, 'c');
                
                % disable the current panel
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'off';
                
                % increase tab control and activate next panel and tab
                app.tabControl = app.tabControl+cnt;
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
                app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            end
        end%advanceTab
%-----------------------------------------------------------------------------------%

        function s = varsToSaveinStdFcn(app)
            % saves just the relevant variables that come out of this class not to save ui elements
            for(i = 1:length(app.varsToSaveinStd))
                s.(app.varsToSaveinStd{i}) = app.(app.varsToSaveinStd{i});
            end                
        end%varsToSaveinStdFcn
%-----------------------------------------------------------------------------------%   
         
        function loadTrialData(app)
            %TODO -- should i need try catch here to make sure that this is a standard node
            %get the motion data and make a new copy because it will get
            %transformed after new coordinate systems
            tmp = fullfile(app.trialDir, [motionCsvToNodeStdMapGui.savedAppName '.mat']);
            tstruct = load(tmp, motionCsvToNodeStdMapGui.savedVarName);
            app.motion = tstruct.(motionCsvToNodeStdMapGui.savedVarName);
            
            %get the camera objects to add a new transformation
            %TODO -- a little hardcodes, also change prural on cameraobjs
            tmp = fullfile(app.workingDir, 'share', [createCameraObjGui.savedAppName '.mat']);
            tstruct = load(tmp, createCameraObjGui.savedVarName);
            app.camera = tstruct.(createCameraObjGui.savedVarName).('camera');
            
            %point out what are the origins of world and body
            app.label_worldOrigin.Text = ['The world origin is at point: ', app.motion.originName];
            app.label_bodyOrigin.Text  = ['The world origin is at point: ', app.motion.nodeStd.body.originName];
            
        end%loadTrialData
%-----------------------------------------------------------------------------------%

        function dispMsg(app, ttype, tmsg)
            % For log messaging blue
            if(strcmpi(ttype, 'L'))
                app.label_errorMsg.Text = ['LOG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.45,0.74];
                
            % for error messages red
            elseif(strcmpi(ttype, 'E'))
                app.label_errorMsg.Text = ['ERROR: ' tmsg];
                app.label_errorMsg.FontColor = [0.64,0.08,0.18];
                
            % general propose message black
            elseif(strcmpi(ttype, 'M'))
                app.label_errorMsg.Text = ['MSG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.00,0.00];
            
            % for clearing the mesage
            elseif(strcmpi(ttype, 'C'))
                app.label_errorMsg.Text = '';
            end
        end%dispMsg
%-----------------------------------------------------------------------------------%

    function closeApp(app)
        for(i = 1: length(app.funcPaths))
            rmpath(app.funcPaths{i});
        end
        delete(app)
    end%closeApp
    %-----------------------------------------------------------------------------------%

    end%private methods

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tworkingDir)
            app.workingDir = tworkingDir;
            setInitialGuiParam(app);
        end

        % Button pushed function: button_next
        function button_nextButtonPushed(app, event)
            % move to next tab
            advanceTab(app, 1);
        end

        % Button pushed function: button_prev
        function button_prevButtonPushed(app, event)
            % move to previous tab
            advanceTab(app, -1);
        end

        % Value changed function: listB_trialsAvail
        function listB_trialsAvailValueChanged(app, event)
            value = app.listB_trialsAvail.Value;
            loadWorkingDirFiles(app);
        end

        % Button pushed function: button_refreshWorkingDir
        function button_refreshWorkingDirButtonPushed(app, event)
            loadWorkingDirFiles(app);
        end

        % Value changed function: chkB_trialLock
        function chkB_trialLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_trialLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.button_trialSelect.Enable = 'off';
                app.button_readTrial.Enable   = 'on';
                findAppInstance(app);
                incStartFlag(app, 1);
            else
                app.button_trialSelect.Enable = 'on';
                app.button_readTrial.Enable   = 'off';
                app.label_existingFile.Text   = {''};
                incStartFlag(app, -1);
            end
        end

        % Button pushed function: button_trialSelect
        function button_trialSelectButtonPushed(app, event)
            % assign value of current selected file to this field
            % make sure there is a selection
            if(~isempty(app.listB_trialsAvail.Value))
                app.editF_trialFile.Value = app.listB_trialsAvail.Value;
                
                % lock the value trough the checkbox
                app.chkB_trialLock.Value = 1;
                chkB_trialLockValueChanged(app);
            
                % clear errors if any
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'Select a file first');
            end 
        end

        % Button pushed function: button_readTrial
        function button_readTrialPushed(app, event)
            %disable change
            app.chkB_trialLock.Enable = 'off';
            app.trialDir = fullfile(app.workingDir, 'trial', app.editF_trialFile.Value);
            loadTrialData(app);
            dispMsg(app, 'l', 'File read! proceed');  
            incStartFlag(app, 1);
        end

        % Button pushed function: button_copyVarName
        function button_copyVarNameButtonPushed(app, event)
            % first make sure that the name is valid
            if(isvarname(app.editF_varName.Value))
                % create a struct of the saved variables
                tmp.(app.savedVarName) = varsToSaveinStdFcn(app);
                
                % assign value to base workspace in matlab
                assignin('base',app.editF_varName.Value,tmp.(app.savedVarName));
                
                % clear error if any
                dispMsg(app, 'l', 'Copied to base workspace');  
            else
                dispMsg(app, 'e', 'The variable in field must be a valid matlab name');
            end
        end

        % Button pushed function: button_browseToWorld
        function button_browseToWorldButtonPushed(app, event)
            %get one function path at a time
            [tfile, tpath] = uigetfile('*.m','Titles in dialogs are overrated',app.workingDir);
            if isequal(tfile,0)
               dispMsg(app, 'e', 'User selected Cancel');
            else
               app.funcPaths = [app.funcPaths; {tpath}];
               app.editF_worldTranFunc.Value  = tfile;
               app.button_browseToBody.Enable = 'on';
            end
        end

        % Button pushed function: button_browseToBody
        function button_browseToBodyPushed(app, event)
            %get one function path at a time
            [tfile, tpath] = uigetfile('*.m','Titles in dialogs are overrated',app.workingDir);
            if isequal(tfile,0)
               dispMsg(app, 'l', 'User selected Cancel');
            else
               app.funcPaths = [app.funcPaths; {tpath}];
               app.editF_bodyTranFunc.Value  = tfile;
               app.button_lockFunc.Enable    = 'on';
            end
        end

        % Button pushed function: button_lockFunc
        function button_lockFuncButtonPushed(app, event)
            %add function dirs to matlab path
            for(i = 1: length(app.funcPaths))
                addpath(app.funcPaths{i});
            end
            %disable the rest
            app.button_browseToBody.Enable  = 'off';
            app.button_browseToWorld.Enable = 'off';
            app.button_chCoordinate.Enable = 'on';
        end

        % Button pushed function: button_chCoordinate
        function button_chCoordinateButtonPushed(app, event)
            %now start to change firs the world coordinate
            %this says b for bat but really applies to it all
            %so b equals body!
            dispMsg(app, 'l', 'Making transformations bip bop bip bop');
            
            %put names remove sample number
            tnamepts = app.motion.motionData.Properties.VariableNames(:);
            tnamepts = tnamepts(~strcmp(motionCsvToNodeStdMapGui.csvDataRowNumberName,tnamepts));
            tnameref = fieldnames(app.motion.refPts);
            torigna  = app.motion.originName;
            tborigna = app.motion.nodeStd.body.originName;
            
            %make world triads
            %[tx, ty, tz] = getWorldOrigin(nvar);
            [~,tname,~]  = fileparts(app.editF_worldTranFunc.Value);
            tworldfunhan = str2func(tname);
            [tx, ty, tz] = tworldfunhan(app.motion);
            torig        = app.motion.refPts.(torigna);
            newbase      = [tx',ty',tz'];
            
            % transform ref points and shift to make new origin [0,0,0]
            for(i = 1:length(tnameref))
              app.motion.refPts.(tnameref{i}) = (newbase\((app.motion.refPts.(tnameref{i})-torig)'))';
            end
            
            % transform bat points with reference to that point as well
            for(k = 1: length(tnamepts))
              for(i = 1:size(app.motion.motionData,1))
                app.motion.motionData.(tnamepts{k})(i,:) = (newbase\((app.motion.motionData.(tnamepts{k})(i,:)-torig)'))';
              end
            end
            
            %TODO -- camera transformations need to happen here
            %transform all camera objects ext-int parameters


            %new world origin
            [tx, ty, tz] = getWorldOrigin(app.motion);
            tx = [1,0,0];
            ty = [0,1,0];
            tz = [0,0,1];
            torig = [0,0,0];
            
            %% change bat points to bat origin
            %get bat triads
            [~,tname,~]  = fileparts(app.editF_bodyTranFunc.Value);
            tbatfunhan   = str2func(tname);
            [bx, by, bz] = tbatfunhan(app.motion);
            borig        = app.motion.motionData.(tborigna);
            app.motion.bodyMotion = app.motion.motionData;
            
            % transform bat points
            for(k = 1: length(tnamepts))
              for(i = 1:size(app.motion.bodyMotion,1))
                batbase = [bx(i,:)',by(i,:)',bz(i,:)'];
                app.motion.bodyMotion.(tnamepts{k})(i,:) = (batbase\((app.motion.motionData.(tnamepts{k})(i,:)-borig(i,:))'))';
              end
            end
            
            %create a table with the moqion of the body with ref to the world
            app.motion.worldMotion = table(app.motion.motionData.(motionCsvToNodeStdMapGui.csvDataRowNumberName),app.motion.motionData.(tborigna),bx,by,bz);
            app.motion.worldMotion.Properties.VariableNames = {motionCsvToNodeStdMapGui.csvDataRowNumberName,tborigna,'bx','by','bz'};
            %new world origin stays the same for world data in motionData
            %bat local origin will be 000 at 3x3 identy triad
            
            %now remove old motion data
            app.motion = rmfield(app.motion,'motionData');
            app.motion = rmfield(app.motion,'appVersion');
            app.motion.body = app.motion.nodeStd.body;
            %TODO -- ideally this should be deleted in motiontocsv and not here
            app.motion = rmfield(app.motion,'nodeStd');
            
            dispMsg(app, 'l', 'Donzo with the transformations, proceed bip bop bip bop');
        end

        % Button pushed function: button_copyToWorkspace
        function button_copyToWorkspaceButtonPushed(app, event)
            % first make sure that the name is valid
            if(isvarname(app.editF_copyToWorkspace.Value))
                % create a struct of the saved variables
                tmp.(app.savedVarName) = varsToSaveinStdFcn(app);
                
                % assign value to base workspace in matlab
                assignin('base',app.editF_copyToWorkspace.Value,tmp.(app.savedVarName));
                
                % clear error if any
                dispMsg(app, 'l', 'Copied to base workspace');  
            else
                dispMsg(app, 'e', 'The variable in field must be a valid matlab name');
            end
        end

        % Button pushed function: button_saveAllAndExit
        function button_saveAllAndExitButtonPushed(app, event)
            % disable wargings from saving stuff such as uia components
            %TODO -- just dont try to save them to prevent warning
            warning('off');
            
            %first overwrride camera transformation
            %TODO -- save cameras here with new perspective
            if(app.cameraSaveFlag)
                
                app.cameraSaveFlag = false;
            end
            
            %TODO -- this is not nice but for now do this
%             % make a struct of what I want to save to that they keep those variable names
%             tmp.(app.savedVarName) = varsToSaveinStdFcn(app);
%             
%             % save file
%             %TODO -- does this needs a try catch?
%             save(fullfile(app.trialDir, [app.savedAppName '.mat']), '-struct', 'tmp');
%             app.label_savedAs.Text = fullfile(app.trialDir, [app.savedAppName '.mat']);
            
            %need temp structure to be saved
            tmp = app.motion;
            save(fullfile(app.trialDir, [biolocomotionMainVar.savedMotionVar '.mat']), '-struct', 'tmp');
            app.label_savedAs.Text = fullfile(app.trialDir, [biolocomotionMainVar.savedMotionVar '.mat']);
            
    
            if(~app.batchSaveFlag)
                % alert the user it is done
                tsel = uiconfirm(app.modifyCoordinateSystem,{'All Saved!!!';'You can do this for all trials or close'},'You Are Amazing',...
                                                             'Options',{'Close app','Do more trials','Cancel'},'DefaultOption',2,'CancelOption',3);
                
                if(strcmp(tsel,'Close app'))
                    closeApp(app);
                elseif(strcmp(tsel,'Do more trials'))
                    app.button_repeatForAll.Enable   = 'on';
                    app.button_saveAllAndExit.Enable = 'off';
                end
            end
            
            % turn warnings back on
            warning('on');
            %TODO -- this is a matlab bug
            warning off MATLAB:subscripting:noSubscriptsSpecified;
        end

        % Button pushed function: button_repeatForAll
        function button_repeatForAllButtonPushed(app, event)
            %get the name of the trials to-do
            ttrials = app.listB_trialsAvail.Items(~strcmp(app.listB_trialsAvail.Items,app.editF_trialFile.Value));
            app.batchSaveFlag = true;

            for(i = 1: length(ttrials))
                app.trialDir = fullfile(app.workingDir, 'trial', ttrials{i});
                loadTrialData(app);
                button_chCoordinateButtonPushed(app);
                button_saveAllAndExitButtonPushed(app);
            end
            
            tsel = uiconfirm(app.modifyCoordinateSystem,{'All Saved!!!';'all trials are done'},'You Are Amazing',...
                                                             'Options',{'Close app','Cancel'},'DefaultOption',1,'CancelOption',2);
                
            if(strcmp(tsel,'Close app'))
                closeApp(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.modifyCoordinateSystem = uifigure('Visible', 'off');
            app.modifyCoordinateSystem.Name = 'Motion CSV Data To Node Standard Mapping';
            app.tabg_group = uitabgroup(app.modifyCoordinateSystem);
            app.tab_loadData = uitab(app.tabg_group);
            app.tab_loadData.Title = 'Load Data';
            app.panel_loadData = uipanel(app.tab_loadData);
            app.button_trialSelect = uibutton(app.panel_loadData, 'push');
            app.button_trialSelect.ButtonPushedFcn = createCallbackFcn(app, @button_trialSelectButtonPushed, true);
            app.button_trialSelect.Text = '<<';
            app.chkB_trialLock = uicheckbox(app.panel_loadData);
            app.chkB_trialLock.ValueChangedFcn = createCallbackFcn(app, @chkB_trialLockValueChanged, true);
            app.chkB_trialLock.Text = 'Lock';
            app.lablel_trialFile = uilabel(app.panel_loadData);
            app.lablel_trialFile.Text = 'Trial';
            app.editF_trialFile = uieditfield(app.panel_loadData, 'text');
            app.editF_trialFile.Editable = 'off';
            app.label_workingDir = uilabel(app.panel_loadData);
            app.label_workingDir.Text = '';
            app.label_step1 = uilabel(app.panel_loadData);
            app.label_step1.Text = '(1)';
            app.label_existingFile = uilabel(app.panel_loadData);
            app.label_existingFile.Text = '';
            app.button_refreshWorkingDir = uibutton(app.panel_loadData, 'push');
            app.button_refreshWorkingDir.ButtonPushedFcn = createCallbackFcn(app, @button_refreshWorkingDirButtonPushed, true);
            app.button_refreshWorkingDir.Text = 'Refresh';
            app.label_step0 = uilabel(app.panel_loadData);
            app.label_step0.Text = '(0)';
            app.label_trialsAvail = uilabel(app.panel_loadData);
            app.label_trialsAvail.Text = 'Trials';
            app.listB_trialsAvail = uilistbox(app.panel_loadData);
            app.listB_trialsAvail.Items = {};
            app.listB_trialsAvail.ValueChangedFcn = createCallbackFcn(app, @listB_trialsAvailValueChanged, true);
            app.listB_trialsAvail.Value = {};
            app.button_readTrial = uibutton(app.panel_loadData, 'push');
            app.button_readTrial.ButtonPushedFcn = createCallbackFcn(app, @button_readTrialPushed, true);
            app.button_readTrial.Enable = 'off';
            app.button_readTrial.Text = 'Read this file';
            app.tab_triadFunctions = uitab(app.tabg_group);
            app.tab_triadFunctions.Title = 'Triad Functions';
            app.panel_triadFunctions = uipanel(app.tab_triadFunctions);
            app.button_browseToWorld = uibutton(app.panel_triadFunctions, 'push');
            app.button_browseToWorld.ButtonPushedFcn = createCallbackFcn(app, @button_browseToWorldButtonPushed, true);
            app.button_browseToWorld.Text = 'Browse';
            app.label_instructionsFun = uilabel(app.panel_triadFunctions);
            app.label_instructionsFun.Text = {'- Make your own fuctions that generte triads from the motion.'; '- Copy this trial''s data to your workspace to build them.'; '- Ideally you should place these function in ''/global'' or ''/share'''; '- What will get copied to the workspace should be the only input '; '   to your funcion.'; '- You must return 3 unitary row [0,0,0] vectors in the order'; '   x,y, and z that represent the triad of the point you want.'};
            app.label_varName = uilabel(app.panel_triadFunctions);
            app.label_varName.Text = 'Variable Name';
            app.editF_varName = uieditfield(app.panel_triadFunctions, 'text');
            app.editF_varName.Value = 'var';
            app.button_copyVarName = uibutton(app.panel_triadFunctions, 'push');
            app.button_copyVarName.ButtonPushedFcn = createCallbackFcn(app, @button_copyVarNameButtonPushed, true);
            app.button_copyVarName.Text = 'Copy to workspace';
            app.label_functionLoc = uilabel(app.panel_triadFunctions);
            app.label_functionLoc.Text = {'Ideally these fuctions are in /share'; 'because it will temp. be added to $path'};
            app.label_worldTranFunc = uilabel(app.panel_triadFunctions);
            app.label_worldTranFunc.Text = 'World origin transofm';
            app.editF_worldTranFunc = uieditfield(app.panel_triadFunctions, 'text');
            app.label_bodyTranFunc = uilabel(app.panel_triadFunctions);
            app.label_bodyTranFunc.Text = 'Body origin transform';
            app.editF_bodyTranFunc = uieditfield(app.panel_triadFunctions, 'text');
            app.label_worldOrigin = uilabel(app.panel_triadFunctions);
            app.label_worldOrigin.Text = '';
            app.label_bodyOrigin = uilabel(app.panel_triadFunctions);
            app.label_bodyOrigin.Text = '';
            app.button_browseToBody = uibutton(app.panel_triadFunctions, 'push');
            app.button_browseToBody.ButtonPushedFcn = createCallbackFcn(app, @button_browseToBodyPushed, true);
            app.button_browseToBody.Enable = 'off';
            app.button_browseToBody.Text = 'Browse';
            app.button_lockFunc = uibutton(app.panel_triadFunctions, 'push');
            app.button_lockFunc.ButtonPushedFcn = createCallbackFcn(app, @button_lockFuncButtonPushed, true);
            app.button_lockFunc.Text = 'lock them functions up!';
            app.tab_chCoordinate = uitab(app.tabg_group);
            app.tab_chCoordinate.Title = 'Change Coordiante';
            app.panel_chCoordinate = uipanel(app.tab_chCoordinate);
            app.button_chCoordinate = uibutton(app.panel_chCoordinate, 'push');
            app.button_chCoordinate.ButtonPushedFcn = createCallbackFcn(app, @button_chCoordinateButtonPushed, true);
            app.button_chCoordinate.Enable = 'off';
            app.button_chCoordinate.Text = {'Change them coordinates with an unnecessarily big button'; ''};
            app.tab_saveAll = uitab(app.tabg_group);
            app.tab_saveAll.Title = 'Save All';
            app.panel_saveAll = uipanel(app.tab_saveAll);
            app.button_saveAllAndExit = uibutton(app.panel_saveAll, 'push');
            app.button_saveAllAndExit.ButtonPushedFcn = createCallbackFcn(app, @button_saveAllAndExitButtonPushed, true);
            app.button_saveAllAndExit.Text = {'SAVE ALL TRIALS!!!!!!!'; '(will also transform camera objects)'};
            app.label_savedAs = uilabel(app.panel_saveAll);
            app.label_savedAs.Text = '';
            app.button_copyToWorkspace = uibutton(app.panel_saveAll, 'push');
            app.button_copyToWorkspace.ButtonPushedFcn = createCallbackFcn(app, @button_copyToWorkspaceButtonPushed, true);
            app.button_copyToWorkspace.Text = 'Copy results to workspace';
            app.label_copyToWorkspace = uilabel(app.panel_saveAll);
            app.label_copyToWorkspace.Text = 'Var Name';
            app.editF_copyToWorkspace = uieditfield(app.panel_saveAll, 'text');
            app.button_repeatForAll = uibutton(app.panel_saveAll, 'push');
            app.button_repeatForAll.ButtonPushedFcn = createCallbackFcn(app, @button_repeatForAllButtonPushed, true);
            app.button_repeatForAll.Enable = 'off';
            app.button_repeatForAll.Text = 'Repeat this for all trials (will also automatically save)';
            app.button_prev = uibutton(app.modifyCoordinateSystem, 'push');
            app.button_prev.ButtonPushedFcn = createCallbackFcn(app, @button_prevButtonPushed, true);
            app.button_prev.Enable = 'off';
            app.button_prev.Text = '<<Prev';
            app.button_next = uibutton(app.modifyCoordinateSystem, 'push');
            app.button_next.ButtonPushedFcn = createCallbackFcn(app, @button_nextButtonPushed, true);
            app.button_next.Enable = 'off';
            app.button_next.Text = 'Next>>';
            app.label_errorMsg = uilabel(app.modifyCoordinateSystem);
            app.label_errorMsg.Text = '';
            % Show the figure after all components are created
            app.modifyCoordinateSystem.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modifyCoordinateSystemGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.modifyCoordinateSystem)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.modifyCoordinateSystem)
        end
    end
end