classdef motionAnimationViewerGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ui_motionAnimationViewer    matlab.ui.Figure
        tabg_group                  matlab.ui.container.TabGroup
        tab_loadData                matlab.ui.container.Tab
        panel_loadData              matlab.ui.container.Panel
        button_motionDataSelect     matlab.ui.control.Button
        chkB_motionDataLock         matlab.ui.control.CheckBox
        button_nodeStandard         matlab.ui.control.Button
        chkB_nodeStandardLock       matlab.ui.control.CheckBox
        label_step1                 matlab.ui.control.Label
        label_filesAvail            matlab.ui.control.Label
        listB_filesAvail            matlab.ui.control.ListBox
        label_motionData            matlab.ui.control.Label
        editF_motionData            matlab.ui.control.EditField
        label_nodeStandard          matlab.ui.control.Label
        editF_nodeStandard          matlab.ui.control.EditField
        button_videoSelect          matlab.ui.control.Button
        button_refresh              matlab.ui.control.Button
        button_cameraObject         matlab.ui.control.Button
        chkB_cameraObjectLock       matlab.ui.control.CheckBox
        label_cameraObject          matlab.ui.control.Label
        editF_cameraObject          matlab.ui.control.EditField
        label_existingFile          matlab.ui.control.Label
        button_deleteVideo          matlab.ui.control.Button
        label_videoFiles            matlab.ui.control.Label
        listB_videoFiles            matlab.ui.control.ListBox
        label_trialsAvail           matlab.ui.control.Label
        listB_trialsAvail           matlab.ui.control.ListBox
        label_workingDir            matlab.ui.control.Label
        chkB_lockTrial              matlab.ui.control.CheckBox
        label_step0                 matlab.ui.control.Label
        tab_makeAndSave             matlab.ui.container.Tab
        panel_makeAndSave           matlab.ui.container.Panel
        button_saveAll              matlab.ui.control.Button
        label_videosToMap           matlab.ui.control.Label
        listB_videosToMap           matlab.ui.control.ListBox
        button_add                  matlab.ui.control.Button
        table_cameraVideoMap        matlab.ui.control.Table
        button_populateTable        matlab.ui.control.Button
        label_addVideoInstructions  matlab.ui.control.Label
        tab_animationView           matlab.ui.container.Tab
        panel_animationView         matlab.ui.container.Panel
        button_prev                 matlab.ui.control.Button
        button_next                 matlab.ui.control.Button
        label_errorMsg              matlab.ui.control.Label
    end

 %TODO -- this file should create AnimationView objects and Animation view should have a class and a gui
 %TODO -- this so that any type of data can create an animation view object and be displaied on a general plot gui
 %TODO -- for now just skipping that and just plotting superimposed here
 %TODO -- ideally this will export a view object for every kind: super imposed? sidebyside? etc

%---------------------------- CONSTANT PROPERTIES ---------------------------------%    
    
    properties (Access = private, Constant = true)
        % variables that control app's visibility workflow function
        isTabChar = 'tab_';
        
        % number of initial steps, pair with startedFlag non constant property
        % change this according to the app's workflow before it unlocks the buton next
        numInitialSteps = 3;
    end
    
    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'motionAnimationViewerGui';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
        
        % important/ relevant output from this app
        savedVarName = 'motionAnimationViewer';
        
        % the full app, required to populate this app with a previous session
        % this contains ALL components, even those stored in savedVarName
        savedAppName = 'motionAnimationViewerApp';
        
        % relevant variables to save in savedVarName
        % savedAppName saves this plus everything else too
        %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
        varsToSaveinStd =  {'cameraObjs';
                            'motionData';
                            'nodeStd';
                            'appVersion'};
    end
    

%----------------------------- CLASS PROPERTIES ----------------------------------%
    
     properties (Access = private)
        % this variable stores the working directory
        workingDir = '';
        trialDir   = '';
        
        % started flag to make sure there is name and directory
        startedFlag = 0;
        
        % tab control variable, start at first tab
        tabControl = 1;
        
        % application tabs, used to move around with buttons, computed at startup
        applicationTabs;
        
        % selected cells for table video and cameras
        videoSelCell = 0;
        
        % files to use in directory
        filesAvailiable;
        filesAvailiableDisplay;

    end
    
    properties (Access = public)
        % camera object struct to save from, its contents are the only thing saved
        cameraObjs = struct();
        
        % object to save the motion data standard form
        motionData;
        
        % object to save the motion data standard form
        nodeStd;
    end
    
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%
    
    methods (Access = private)
            
        function setInitialGuiParam(app)
            % fist turn all panels off
            % get names of all tabs and indexes in app.
            tnames  = fieldnames(app);
            
            % tabs are in the order that they apear in the gui in sequence
            %TODO -- if tabs have multiple children this wont work
            app.applicationTabs = tnames(contains(tnames,app.isTabChar));
            
            % disable all panels
            for(i=1: length(app.applicationTabs))
                app.(app.applicationTabs{i}).Children.Visible = 'off';
            end
            
            % the first pannel and tab controlled by class tab control
            app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
            app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            
            % load the study directory and display files
            loadWorkingDirFiles(app);
            
        end%setInitialGuiParam
%-----------------------------------------------------------------------------------%
        
        function loadWorkingDirFiles(app)   
                %TODO -- should trim this to show a ../../dir
                app.label_workingDir.Text = app.workingDir;

                % dont display the trials availiable
                tignorefiles = {'.'; '..'; '.git'};
                tmp = cellstr(ls(fullfile(app.workingDir, 'trial')));
                tmp = setdiff(tmp,tignorefiles);
                app.listB_trialsAvail.Items = tmp;
                
                % make sure a trial is selected
                if(~isempty(app.listB_trialsAvail.Value))
                    % dont display the useless ones
                    tres        = displayDirRecursively(fullfile(app.workingDir, '\res'));
                    tres        = fullfile('\res',tres);
                    tshare      = displayDirRecursively(fullfile(app.workingDir, '\share'));
                    tshare      = fullfile('\share',tshare);
                    ttrialfiles = displayDirRecursively(fullfile(app.workingDir, '\trial', app.listB_trialsAvail.Value));
                    ttrialfiles = fullfile('\trial',app.listB_trialsAvail.Value,ttrialfiles);
                    
                    % append and remove empty ones
                    tmp = [tres; tshare; ttrialfiles];
                    app.filesAvailiable = tmp(~strcmp(tmp, ''));
                    app.filesAvailiableDisplay = compressPathNmes(app.filesAvailiable);
                    app.listB_filesAvail.Items = app.filesAvailiableDisplay;
                end
        end%loadWorkingDirFiles
%-----------------------------------------------------------------------------------%

        function findAppInstance(app)
            % see if there is an instance of this app in the working directory
            if(~(isfile(fullfile(app.trialDir, [app.savedAppName '.mat']))))
                app.label_existingFile.Text = {['There is no ' app.savedAppName ' File in the Working Directory.'] 'Saving will create a new one.'};
                loadEmptyAppState(app);
            else
                % try to open the file
                try
                    tmatfile = load(fullfile(app.trialDir, [app.savedAppName '.mat']));
                    loadedFile = tmatfile.(app.savedAppName);
                % send a message and ask the user to take action 
                catch
                    msgbox({'There was an error loading the file, app wil close'; ...
                            'If probem persist you need to delete the saved mat file'}, 'Error','error');
                    delete(app);
                end
                % load variables to populate the instance of this app
                app.label_existingFile.Text = {['There is a ' app.savedAppName ' File in the Working Directory.'] 'Saving will overwrite it.'};
                loadAppState(app, loadedFile);
            end
        end%findAppInstance
%-----------------------------------------------------------------------------------%

        function loadEmptyAppState(app)
            % if load previous datafile, then populate all variables
            % app-defined user properties
            % tab one, edit fields
            app.editF_nodeStandard.Value    = '';
            app.editF_motionData.Value      = '';
            app.editF_cameraObject.Value    = '';
            app.listB_videoFiles.Items      = {};
            app.chkB_nodeStandardLock.Value = 0;
            app.chkB_motionDataLock.Value   = 0;
            app.chkB_cameraObjectLock.Value = 0;
            
            % make sure checkbox enables/disables
            chkB_motionDataLockValueChanged(app);
            chkB_nodeStandardLockValueChanged(app);
            chkB_cameraObjectLockValueChanged(app);
            
            % tab two
            app.listB_videosToMap.Items     = {};
            app.table_cameraVideoMap.Data   = {};
            
            % other public properties
            app.cameraObjs = struct();
            app.motionData = [];
            app.nodeStd    = [];
        end%loadAppState
%-----------------------------------------------------------------------------------%

        function loadAppState(app, loadedFile)
            % if load previous datafile, then populate all variables
            % app-defined user properties
            % tab one, edit fields
            app.editF_nodeStandard.Value    = loadedFile.editF_nodeStandard.Value;
            app.editF_motionData.Value      = loadedFile.editF_motionData.Value;
            app.editF_cameraObject.Value    = loadedFile.editF_cameraObject.Value;
            app.listB_videoFiles.Items      = loadedFile.listB_videoFiles.Items;
            app.chkB_nodeStandardLock.Value = loadedFile.chkB_nodeStandardLock.Value;
            app.chkB_motionDataLock.Value   = loadedFile.chkB_motionDataLock.Value;
            app.chkB_cameraObjectLock.Value = loadedFile.chkB_cameraObjectLock.Value;
            
            % make sure checkbox enables/disables
            chkB_motionDataLockValueChanged(app);
            chkB_nodeStandardLockValueChanged(app);
            chkB_cameraObjectLockValueChanged(app);
            
            % tab two
            app.listB_videosToMap.Items     = loadedFile.listB_videosToMap.Items;
            app.table_cameraVideoMap.Data   = loadedFile.table_cameraVideoMap.Data;
            
            % other public properties
            importObjectInstances(app);
            
            % move the user to the viewer
            advanceTab(app,2);
        end%loadAppState
%-----------------------------------------------------------------------------------%

        function incStartFlag(app, cnt)
            % increase or decrease the start flag with bound checking
            % this ommits the value of cnt and only uses its sign.
            if(cnt>0 && app.startedFlag<app.numInitialSteps)
                app.startedFlag = app.startedFlag + 1;
            elseif(cnt<0 && app.startedFlag>0)
                app.startedFlag = app.startedFlag - 1;
            end
            
            % determine if the start is done and enable prev and next buttons
            if(app.startedFlag>=app.numInitialSteps)
                app.button_next.Enable = 'on';
                app.button_prev.Enable = 'on';
            else
                app.button_next.Enable = 'off';
                app.button_prev.Enable = 'off';
            end
        end%incStartFlag
%-----------------------------------------------------------------------------------%
        
        function advanceTab(app, cnt)
            % first check if cnt will request a tab out of range
            if((app.tabControl+cnt)>length(app.applicationTabs) || (app.tabControl+cnt)<1)
                dispMsg(app, 'e', 'tab change request out of range');
            else
                % clear errors if any
                dispMsg(app, 'c');
                
                % disable the current panel
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'off';
                
                % increase tab control and activate next panel and tab
                app.tabControl = app.tabControl+cnt;
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
                app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            end
        end%advanceTab
%-----------------------------------------------------------------------------------%

        function s = varsToSaveinStdFcn(app)
            % saves just the relevant variables that come out of this class not to save ui elements
            for(i = 1:length(app.varsToSaveinStd))
                s.(app.varsToSaveinStd{i}) = app.(app.varsToSaveinStd{i});
            end                
        end%varsToSaveinStdFcn
%-----------------------------------------------------------------------------------%      

        function importObjectInstances(app)
            readNodeStandardObject(app);
            readMotionDataObject(app);
            readCameraObject(app);
            readVideoObject(app);
        end%importObjectInstances
%-----------------------------------------------------------------------------------%  

        function readNodeStandardObject(app)
            %TODO -- should i need try catch here to make sure that this is a standard node
            % aquire the value of the node standard and place it in a private variable here (copy)
            tmp = fullfile(app.workingDir, app.editF_nodeStandard.Value);
            tstruct = load(tmp, createNodeStandardGui.savedVarName);
            app.nodeStd = tstruct.(createNodeStandardGui.savedVarName);
        end%readNodeStandardObject
%-----------------------------------------------------------------------------------%
    
        function readMotionDataObject(app)
            %TODO -- should i need try catch here to make sure that this is a standard motion
            %TODO -- this is kinda hardcoded, the number 1
            % aquire the value of the node standard and place it in a private variable here (copy)
            tmp = fullfile(app.workingDir, app.editF_motionData.Value);
            tstruct = load(tmp, motionCsvToNodeStdMapGui.savedVarName);
            app.motionData = tstruct.(motionCsvToNodeStdMapGui.savedVarName).(motionCsvToNodeStdMapGui.varsToSaveinStd{1});
        end%readMotionDataObject
%-----------------------------------------------------------------------------------%
    
        function readCameraObject(app)
            %TODO -- should i need try catch here to make sure that this is a standard camera
            %TODO -- this is kinda hardcoded, the number 4
            % aquire the value of the node standard and place it in a private variable here (copy)
            tmp = fullfile(app.workingDir, app.editF_cameraObject.Value);
            tstruct = load(tmp, createCameraObjGui.savedVarName);
            app.cameraObjs = tstruct.(createCameraObjGui.savedVarName).(createCameraObjGui.varsToSaveinStd{1});
        end%readCameraObject
%-----------------------------------------------------------------------------------%

        function readVideoObject(app)
            %TODO -- this is not ok, but for now
        end%readVideoObject
%-----------------------------------------------------------------------------------%

        function loadVarsFromTemplate(app, ttemplate)
            % step 2 is indifferent so run it as-is
            button_popNodeTableButtonPushed(app);
            
            % technically colum 3 is already populated from step 2, whatever.
            % copy the node assignment from the template experiment
            app.table_nodeAssign.Data = ttemplate.table_nodeAssign.Data;
            
            % compute step 4 and five
            % the user can do this technically but it should be ready so do it automatically and save time
            button_computeMapMatrixPushed(app);
            button_populateTrimNanButtonPushed(app);
        end%loadVarsFromTemplate
%-----------------------------------------------------------------------------------%

        function dispMsg(app, ttype, tmsg)
            % For log messaging blue
            if(strcmpi(ttype, 'L'))
                app.label_errorMsg.Text = ['LOG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.45,0.74];
                
            % for error messages red
            elseif(strcmpi(ttype, 'E'))
                app.label_errorMsg.Text = ['ERROR: ' tmsg];
                app.label_errorMsg.FontColor = [0.64,0.08,0.18];
                
            % general propose message black
            elseif(strcmpi(ttype, 'M'))
                app.label_errorMsg.Text = ['MSG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.00,0.00];
            
            % for clearing the mesage
            elseif(strcmpi(ttype, 'C'))
                app.label_errorMsg.Text = '';
            end
        end%dispMsg
%-----------------------------------------------------------------------------------%

    end%private methods

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tworkingDir)
            app.workingDir = tworkingDir;
            setInitialGuiParam(app);
        end

        % Button pushed function: button_next
        function button_nextButtonPushed(app, event)
            % move to next tab
            advanceTab(app, 1);
        end

        % Button pushed function: button_prev
        function button_prevButtonPushed(app, event)
            % move to previous tab
            advanceTab(app, -1);
        end

        % Value changed function: chkB_motionDataLock
        function chkB_motionDataLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_motionDataLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.button_motionDataSelect.Enable = 'off';
                incStartFlag(app, 1);
            else
                app.button_motionDataSelect.Enable = 'on';
                incStartFlag(app, -1);
            end
            
        end

        % Value changed function: chkB_nodeStandardLock
        function chkB_nodeStandardLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_nodeStandardLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.button_nodeStandard.Enable = 'off';
                incStartFlag(app, 1);
            else
                app.button_nodeStandard.Enable = 'on';
                incStartFlag(app, -1);
            end
            
        end

        % Button pushed function: button_motionDataSelect
        function button_motionDataSelectButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that ta csv is actually selected
            % if more than one file is selected, then trow error
            if(length(app.listB_filesAvail.Value)>1)
                dispMsg(app, 'e', 'that field only accepts one value.');
            else
                % clear the error if any
                dispMsg(app, 'c');
                
                % get the selected files in its extended name form
                tmp = app.listB_filesAvail.Value;
                tindex = arrayfun(@(k) find(strcmp(tmp{k},app.filesAvailiableDisplay)), 1:length(tmp));
                app.editF_motionData.Value = app.filesAvailiable{tindex};
                
                %TODO -- check that this file is a motiondata File
                % lock the value trough the checkbox
                app.chkB_motionDataLock.Value = 1;
                chkB_motionDataLockValueChanged(app, event);
            end
        end

        % Button pushed function: button_nodeStandard
        function button_nodeStandardButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that ta csv is actually selected
            % if more than one file is selected, then trow error
            if(length(app.listB_filesAvail.Value)>1)
                dispMsg(app, 'e', 'that field only accepts one value.');
            else
                % clear the error if any
                dispMsg(app, 'c');
                
                % get the selected files in its extended name form
                tmp = app.listB_filesAvail.Value;
                tindex = arrayfun(@(k) find(strcmp(tmp{k},app.filesAvailiableDisplay)), 1:length(tmp));
                app.editF_nodeStandard.Value = app.filesAvailiable{tindex};
                
                %TODO -- check that this file is a nodestd File
                % lock the value trough the checkbox
                app.chkB_nodeStandardLock.Value = 1;
                chkB_nodeStandardLockValueChanged(app, event);
            end
        end

        % Button pushed function: button_videoSelect
        function button_videoSelectButtonPushed(app, event)
            % assign value of all current selected files to this field, append current ones
            % only take the ones taht are unique
            %TODO -- this needs to be verified that that video is actually selected
            
            % get the selected files in its extended name form
            tmp = app.listB_filesAvail.Value;
            tindex = arrayfun(@(k) find(strcmp(tmp{k},app.filesAvailiableDisplay)), 1:length(tmp));
            
            app.listB_videoFiles.Items = unique([app.listB_videoFiles.Items app.filesAvailiable(tindex)]);
        end

        % Button pushed function: button_refresh
        function button_refreshButtonPushed(app, event)
            loadWorkingDirFiles(app);
        end

        % Value changed function: chkB_cameraObjectLock
        function chkB_cameraObjectLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_cameraObjectLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.button_cameraObject.Enable = 'off';
                incStartFlag(app, 1);
            else
                app.button_cameraObject.Enable = 'on';
                incStartFlag(app, -1);
            end
        end

        % Button pushed function: button_cameraObject
        function button_cameraObjectButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that corret is actually selected
            % if more than one file is selected, then trow error
            if(length(app.listB_filesAvail.Value)>1)
                ispMsg(app, 'e', 'that field only accepts one value.');
            else
                % clear the error if any
                dispMsg(app, 'c');
                
                % get the selected files in its extended name form
                tmp = app.listB_filesAvail.Value;
                tindex = arrayfun(@(k) find(strcmp(tmp{k},app.filesAvailiableDisplay)), 1:length(tmp));
                app.editF_cameraObject.Value = app.filesAvailiable{tindex};
                
                %TODO -- check that this file is a correct File
                % lock the value trough the checkbox
                app.chkB_cameraObjectLock.Value = 1;
                chkB_cameraObjectLockValueChanged(app, event);
            end
        end

        % Button pushed function: button_deleteVideo
        function button_deleteVideoPushed(app, event)
            if(~isempty(app.listB_videoFiles.Value))
               app.listB_videoFiles.Items(strcmp(app.listB_videoFiles.Items', app.listB_videoFiles.Value)) =  [];
            end
        end

        % Button pushed function: button_populateTable
        function button_populateTableButtonPushed(app, event)
            % first import objects from selected fields into app vars
            importObjectInstances(app);
            
            % first copy the contents of listbox one into listbox 2
            %TODO -- make this come from the vide object holder or struct
            app.listB_videosToMap.Items = app.listB_videoFiles.Items;

            % populate the cameras on the table, first make an emptu first column
            app.table_cameraVideoMap.Data = cell(length(fieldnames(app.cameraObjs)),1);
            app.table_cameraVideoMap.Data = [app.table_cameraVideoMap.Data, fieldnames(app.cameraObjs)];
        end

        % Button pushed function: button_add
        function button_addButtonPushed(app, event)
            % first make sure that both a video and a cell on the table are selected
            if(app.videoSelCell~=0 & isscalar(app.videoSelCell) & ~isempty(app.listB_videosToMap.Value))
                % clear error if any   
                dispMsg(app, 'c');
                
                % assign this value to table
                app.table_cameraVideoMap.Data(app.videoSelCell,1) = {app.listB_videosToMap.Value};
                
                % delete this element from availiable cameras
                app.listB_videosToMap.Items(strcmp(app.listB_videosToMap.Items', app.listB_videosToMap.Value)) =  [];
                
            else
                % trow error
                dispMsg(app, 'e', 'need to select one cell of table and one video');
            end
        end

        % Cell selection callback: table_cameraVideoMap
        function table_cameraVideoMapCellSelection(app, event)
            % for some reason it does not like to be directly assigned to private var
            % indices is probably adefined attribute of this function.
            indices = event.Indices(:,1);
            app.videoSelCell = unique(indices);
        end

        % Button pushed function: button_saveAll
        function button_saveAllButtonPushed(app, event)
            % disable wargings from saving stuff such as uia components
            %TODO -- just dont try to save them to prevent warning
            warning('off');
            
            % make a struct of what I want to save to that they keep those variable names
            tmp.(app.savedAppName) = app;
            tmp.(app.savedVarName) = varsToSaveinStdFcn(app);
            
            % save file
            %TODO -- does this needs a try catch?
            save(fullfile(app.trialDir, [app.savedAppName '.mat']), '-struct', 'tmp');
            app.label_existingFile.Text = {['There is a ' app.savedAppName ' File in the Working Directory.'] 'Saving will overwrite it.'};
            loadWorkingDirFiles(app);
            
            % alert the user it is done
            msgbox({'All Saved!!!';'You can keep modfying or just close the window'},'Success');
            
            % turn warnings back on
            warning('on');
            %TODO -- this is a matlab bug
            warning off MATLAB:subscripting:noSubscriptsSpecified;
        end

        % Value changed function: listB_trialsAvail
        function listB_trialsAvailValueChanged(app, event)
            value = app.listB_trialsAvail.Value;
            
            loadWorkingDirFiles(app);
        end

        % Value changed function: chkB_lockTrial
        function chkB_lockTrialValueChanged(app, event)
            value = app.chkB_lockTrial.Value;
            % if the box is checked then update the trial data
            if(value == 1)
                % get the directory path of this trial, check for previous instance
                app.trialDir = fullfile(app.workingDir,'trial', app.listB_trialsAvail.Value);
                findAppInstance(app);
                
                % enable the selection of trials in list box
                app.listB_trialsAvail.Enable  = 'off';
                
                % then enable all interactivity
                app.editF_cameraObject.Enable = 'on';
                app.editF_motionData.Enable   = 'on';
                app.editF_nodeStandard.Enable = 'on';
                app.listB_videoFiles.Enable   = 'on';
                
                app.button_nodeStandard.Enable     = 'on';
                app.button_motionDataSelect.Enable = 'on';
                app.button_cameraObject.Enable     = 'on';
                app.button_videoSelect.Enable      = 'on';
                app.button_deleteVideo.Enable      = 'on';
                
                app.chkB_nodeStandardLock.Enable = 'on';
                app.chkB_motionDataLock.Enable   = 'on';
                app.chkB_cameraObjectLock.Enable = 'on';
            else
                % disable the selection of trials in list box
                app.listB_trialsAvail.Enable  = 'on';
                
                % clear this text 
                app.label_existingFile.Text = '';
                
                % then disable all interactivity
                app.editF_cameraObject.Enable = 'off';
                app.editF_motionData.Enable   = 'off';
                app.editF_nodeStandard.Enable = 'off';
                app.listB_videoFiles.Enable   = 'off';
                
                app.button_nodeStandard.Enable     = 'off';
                app.button_motionDataSelect.Enable = 'off';
                app.button_cameraObject.Enable     = 'off';
                app.button_videoSelect.Enable      = 'off';
                app.button_deleteVideo.Enable      = 'off';
                
                app.chkB_nodeStandardLock.Enable = 'off';
                app.chkB_motionDataLock.Enable   = 'off';
                app.chkB_cameraObjectLock.Enable = 'off';
                
                % clear all mapped information
                app.editF_cameraObject.Value = '';
                app.editF_motionData.Value   = '';
                app.editF_nodeStandard.Value = '';
                app.listB_videoFiles.Items   = {};
                
                app.chkB_nodeStandardLock.Value = 0;
                app.chkB_motionDataLock.Value   = 0;
                app.chkB_cameraObjectLock.Value = 0;
                
                % clear all progres to move forward
                app.startedFlag = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ui_motionAnimationViewer = uifigure('Visible', 'off');
            app.ui_motionAnimationViewer.Name = 'Motion Animation Viewer';
            app.tabg_group = uitabgroup(app.ui_motionAnimationViewer);
            app.tab_loadData = uitab(app.tabg_group);
            app.tab_loadData.Title = 'Load Data';
            app.panel_loadData = uipanel(app.tab_loadData);
            app.button_motionDataSelect = uibutton(app.panel_loadData, 'push');
            app.button_motionDataSelect.ButtonPushedFcn = createCallbackFcn(app, @button_motionDataSelectButtonPushed, true);
            app.button_motionDataSelect.Enable = 'off';
            app.button_motionDataSelect.Text = '<<';
            app.chkB_motionDataLock = uicheckbox(app.panel_loadData);
            app.chkB_motionDataLock.ValueChangedFcn = createCallbackFcn(app, @chkB_motionDataLockValueChanged, true);
            app.chkB_motionDataLock.Enable = 'off';
            app.chkB_motionDataLock.Text = 'Lock';
            app.button_nodeStandard = uibutton(app.panel_loadData, 'push');
            app.button_nodeStandard.ButtonPushedFcn = createCallbackFcn(app, @button_nodeStandardButtonPushed, true);
            app.button_nodeStandard.Enable = 'off';
            app.button_nodeStandard.Text = '<<';
            app.chkB_nodeStandardLock = uicheckbox(app.panel_loadData);
            app.chkB_nodeStandardLock.ValueChangedFcn = createCallbackFcn(app, @chkB_nodeStandardLockValueChanged, true);
            app.chkB_nodeStandardLock.Enable = 'off';
            app.chkB_nodeStandardLock.Text = 'Lock';
            app.label_step1 = uilabel(app.panel_loadData);
            app.label_step1.Text = '(1)';
            app.label_filesAvail = uilabel(app.panel_loadData);
            app.label_filesAvail.Text = 'Displaying files in Trial selected plus /share and /res';
            app.listB_filesAvail = uilistbox(app.panel_loadData);
            app.listB_filesAvail.Items = {};
            app.listB_filesAvail.Multiselect = 'on';
            app.listB_filesAvail.Value = {};
            app.label_motionData = uilabel(app.panel_loadData);
            app.label_motionData.Text = 'Motion Data';
            app.editF_motionData = uieditfield(app.panel_loadData, 'text');
            app.editF_motionData.Editable = 'off';
            app.editF_motionData.Enable = 'off';
            app.label_nodeStandard = uilabel(app.panel_loadData);
            app.label_nodeStandard.Text = 'Node Standard';
            app.editF_nodeStandard = uieditfield(app.panel_loadData, 'text');
            app.editF_nodeStandard.Editable = 'off';
            app.editF_nodeStandard.Enable = 'off';
            app.button_videoSelect = uibutton(app.panel_loadData, 'push');
            app.button_videoSelect.ButtonPushedFcn = createCallbackFcn(app, @button_videoSelectButtonPushed, true);
            app.button_videoSelect.Enable = 'off';
            app.button_videoSelect.Text = '<<';
            app.button_refresh = uibutton(app.panel_loadData, 'push');
            app.button_refresh.ButtonPushedFcn = createCallbackFcn(app, @button_refreshButtonPushed, true);
            app.button_refresh.Text = 'Refresh';
            app.button_cameraObject = uibutton(app.panel_loadData, 'push');
            app.button_cameraObject.ButtonPushedFcn = createCallbackFcn(app, @button_cameraObjectButtonPushed, true);
            app.button_cameraObject.Enable = 'off';
            app.button_cameraObject.Text = '<<';
            app.chkB_cameraObjectLock = uicheckbox(app.panel_loadData);
            app.chkB_cameraObjectLock.ValueChangedFcn = createCallbackFcn(app, @chkB_cameraObjectLockValueChanged, true);
            app.chkB_cameraObjectLock.Enable = 'off';
            app.chkB_cameraObjectLock.Text = 'Lock';
            app.label_cameraObject = uilabel(app.panel_loadData);
            app.label_cameraObject.Text = 'Camera Objects';
            app.editF_cameraObject = uieditfield(app.panel_loadData, 'text');
            app.editF_cameraObject.Editable = 'off';
            app.editF_cameraObject.Enable = 'off';
            app.label_existingFile = uilabel(app.panel_loadData);
            app.label_existingFile.Text = '';
            app.button_deleteVideo = uibutton(app.panel_loadData, 'push');
            app.button_deleteVideo.ButtonPushedFcn = createCallbackFcn(app, @button_deleteVideoPushed, true);
            app.button_deleteVideo.Enable = 'off';
            app.button_deleteVideo.Text = 'Delete Selection';
            app.label_videoFiles = uilabel(app.panel_loadData);
            app.label_videoFiles.Text = 'Video File(s)';
            app.listB_videoFiles = uilistbox(app.panel_loadData);
            app.listB_videoFiles.Items = {};
            app.listB_videoFiles.Multiselect = 'on';
            app.listB_videoFiles.Enable = 'off';
            app.listB_videoFiles.Value = {};
            app.label_trialsAvail = uilabel(app.panel_loadData);
            app.label_trialsAvail.Text = 'Trials';
            app.listB_trialsAvail = uilistbox(app.panel_loadData);
            app.listB_trialsAvail.Items = {};
            app.listB_trialsAvail.ValueChangedFcn = createCallbackFcn(app, @listB_trialsAvailValueChanged, true);
            app.listB_trialsAvail.Value = {};
            app.label_workingDir = uilabel(app.panel_loadData);
            app.label_workingDir.Text = '';
            app.chkB_lockTrial = uicheckbox(app.panel_loadData);
            app.chkB_lockTrial.ValueChangedFcn = createCallbackFcn(app, @chkB_lockTrialValueChanged, true);
            app.chkB_lockTrial.Text = 'Lock';
            app.label_step0 = uilabel(app.panel_loadData);
            app.label_step0.Text = '(0)';
            app.tab_makeAndSave = uitab(app.tabg_group);
            app.tab_makeAndSave.Title = 'Make and Save';
            app.panel_makeAndSave = uipanel(app.tab_makeAndSave);
            app.button_saveAll = uibutton(app.panel_makeAndSave, 'push');
            app.button_saveAll.ButtonPushedFcn = createCallbackFcn(app, @button_saveAllButtonPushed, true);
            app.button_saveAll.Text = 'Save All';
            app.label_videosToMap = uilabel(app.panel_makeAndSave);
            app.label_videosToMap.Text = 'Videos';
            app.listB_videosToMap = uilistbox(app.panel_makeAndSave);
            app.listB_videosToMap.Items = {};
            app.listB_videosToMap.Value = {};
            app.button_add = uibutton(app.panel_makeAndSave, 'push');
            app.button_add.ButtonPushedFcn = createCallbackFcn(app, @button_addButtonPushed, true);
            app.button_add.Text = 'ADD>>';
            app.table_cameraVideoMap = uitable(app.panel_makeAndSave);
            app.table_cameraVideoMap.ColumnName = {'Video'; 'Camera'};
            app.table_cameraVideoMap.RowName = {};
            app.table_cameraVideoMap.CellSelectionCallback = createCallbackFcn(app, @table_cameraVideoMapCellSelection, true);
            app.button_populateTable = uibutton(app.panel_makeAndSave, 'push');
            app.button_populateTable.ButtonPushedFcn = createCallbackFcn(app, @button_populateTableButtonPushed, true);
            app.button_populateTable.Text = 'Populate Table';
            app.label_addVideoInstructions = uilabel(app.panel_makeAndSave);
            app.label_addVideoInstructions.Text = {'Select a Video in the list, '; 'The appropiate cell on the table,'; 'Then Click ADD'};
            app.tab_animationView = uitab(app.tabg_group);
            app.tab_animationView.Title = 'Animation View';
            app.panel_animationView = uipanel(app.tab_animationView);
            app.button_prev = uibutton(app.ui_motionAnimationViewer, 'push');
            app.button_prev.ButtonPushedFcn = createCallbackFcn(app, @button_prevButtonPushed, true);
            app.button_prev.Enable = 'off';
            app.button_prev.Text = '<<Prev';
            app.button_next = uibutton(app.ui_motionAnimationViewer, 'push');
            app.button_next.ButtonPushedFcn = createCallbackFcn(app, @button_nextButtonPushed, true);
            app.button_next.Enable = 'off';
            app.button_next.Text = 'Next>>';
            app.label_errorMsg = uilabel(app.ui_motionAnimationViewer);
            app.label_errorMsg.Text = '';
            % Show the figure after all components are created
            app.ui_motionAnimationViewer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = motionAnimationViewerGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ui_motionAnimationViewer)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ui_motionAnimationViewer)
        end
    end
end