classdef motionCsvToNodeStdMapGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        motionCsvToNodeStdMap        matlab.ui.Figure
        tabg_group                   matlab.ui.container.TabGroup
        tab_loadData                 matlab.ui.container.Tab
        panel_loadData               matlab.ui.container.Panel
        label_loadFromFile           matlab.ui.control.Label
        button_csvSelect             matlab.ui.control.Button
        chkB_csvLock                 matlab.ui.control.CheckBox
        button_nodeStandard          matlab.ui.control.Button
        chkB_nodeStandardLock        matlab.ui.control.CheckBox
        label_filesAvail             matlab.ui.control.Label
        listB_filesAvail             matlab.ui.control.ListBox
        label_csvFile                matlab.ui.control.Label
        editF_csvDataFile            matlab.ui.control.EditField
        label_nodeStandard           matlab.ui.control.Label
        editF_nodeStandardFile       matlab.ui.control.EditField
        label_workingDir             matlab.ui.control.Label
        label_step1                  matlab.ui.control.Label
        button_browseToTemplateFile  matlab.ui.control.Button
        label_divider                matlab.ui.control.Label
        label_existingFile           matlab.ui.control.Label
        button_refreshWorkingDir     matlab.ui.control.Button
        chkB_lockTrial               matlab.ui.control.CheckBox
        label_step0                  matlab.ui.control.Label
        label_trialsAvail            matlab.ui.control.Label
        listB_trialsAvail            matlab.ui.control.ListBox
        button_mapTemplate           matlab.ui.control.Button
        chkB_mapTemplate             matlab.ui.control.CheckBox
        label_mapTemplate            matlab.ui.control.Label
        editF_mapTemplate            matlab.ui.control.EditField
        tab_identifyNodes            matlab.ui.container.Tab
        panel_identifyNodes          matlab.ui.container.Panel
        button_popNodeTable          matlab.ui.control.Button
        table_nodeAssign             matlab.ui.control.Table
        table_csvHeaders             matlab.ui.control.Table
        button_addSelection          matlab.ui.control.Button
        button_deleteMappedValue     matlab.ui.control.Button
        button_computeMapMatrix      matlab.ui.control.Button
        button_insertZero            matlab.ui.control.Button
        label_step3                  matlab.ui.control.Label
        button_referencePts          matlab.ui.control.Button
        table_referencePts           matlab.ui.control.Table
        tab_refPts                   matlab.ui.container.Tab
        panel_refPts                 matlab.ui.container.Panel
        table_refPtsToMap            matlab.ui.control.Table
        table_refPtsData             matlab.ui.control.Table
        button_popRefPts             matlab.ui.control.Button
        button_selectRefPts          matlab.ui.control.Button
        label_worldOrigin            matlab.ui.control.Label
        dropD_worldOrigin            matlab.ui.control.DropDown
        tab_trimData                 matlab.ui.container.Tab
        panel_trimData               matlab.ui.container.Panel
        table_trimData               matlab.ui.control.Table
        label_headNan                matlab.ui.control.Label
        editF_headNan                matlab.ui.control.NumericEditField
        label_tailNan                matlab.ui.control.Label
        editF_tailNan                matlab.ui.control.NumericEditField
        spinner_headTrim             matlab.ui.control.Spinner
        label_samplesLeft            matlab.ui.control.Label
        editF_samplesLeft            matlab.ui.control.NumericEditField
        spinner_tailTrim             matlab.ui.control.Spinner
        label_percentLeft            matlab.ui.control.Label
        editF_percentLeft            matlab.ui.control.NumericEditField
        label_nonNanRow              matlab.ui.control.Label
        label_trimExtra              matlab.ui.control.Label
        button_populateTrimNan       matlab.ui.control.Button
        button_trimData              matlab.ui.control.Button
        label_numbRows               matlab.ui.control.Label
        editF_numbRows               matlab.ui.control.NumericEditField
        label_divider1               matlab.ui.control.Label
        label_rawData                matlab.ui.control.Label
        label_trimmedCopiedData      matlab.ui.control.Label
        tab_refineData               matlab.ui.container.Tab
        panel_refineData             matlab.ui.container.Panel
        table_fillGaps               matlab.ui.control.Table
        button_plotSelection         matlab.ui.control.Button
        button_populateNodeGap       matlab.ui.control.Button
        button_closeGaps             matlab.ui.control.Button
        label_polynomialDegree       matlab.ui.control.Label
        knob_polynomialDegree        matlab.ui.control.DiscreteKnob
        button_unitConversion        matlab.ui.control.Button
        label_convertFrom            matlab.ui.control.Label
        dropD_convertFrom            matlab.ui.control.DropDown
        label_convertTo              matlab.ui.control.Label
        dropD_convertTo              matlab.ui.control.DropDown
        button_displaySummary        matlab.ui.control.Button
        label_customConvInstr        matlab.ui.control.Label
        label_customUnitInstr        matlab.ui.control.Label
        editF_customDataIn           matlab.ui.control.NumericEditField
        editF_customConvertTo        matlab.ui.control.EditField
        label_convertInstr           matlab.ui.control.Label
        CurrentUntsEditFieldLabel    matlab.ui.control.Label
        editF_currentUnits           matlab.ui.control.EditField
        tab_saveAll                  matlab.ui.container.Tab
        panel_saveAll                matlab.ui.container.Panel
        button_saveAllAndExit        matlab.ui.control.Button
        label_savedAs                matlab.ui.control.Label
        button_saveTemplate          matlab.ui.control.Button
        label_saveTemplateName       matlab.ui.control.Label
        button_copyToWorkspace       matlab.ui.control.Button
        label_copyToWorkspace        matlab.ui.control.Label
        editF_copyToWorkspace        matlab.ui.control.EditField
        button_prev                  matlab.ui.control.Button
        button_next                  matlab.ui.control.Button
        label_errorMsg               matlab.ui.control.Label
    end


%---------------------------- CONSTANT PROPERTIES ---------------------------------%    
    
    properties (Access = private, Constant = true)
        % variables that control app's visibility workflow function
        isTabChar = 'tab_';
        
        % number of initial steps, pair with startedFlag non constant property
        % change this according to the app's workflow before it unlocks the buton next
        numInitialSteps = 2;
    end
    
    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'motionCsvToNodeStdMapGui';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
        
        % important/ relevant output from this app
        savedVarName = 'motionCsvToNodeStdMap';
        
        % the full app, required to populate this app with a previous session
        % this contains ALL components, even those stored in savedVarName
        savedAppName = 'motionCsvToNodeStdMapApp';
        savedAppTemplateName = 'motionCsvToNodeStdMapTemplateApp';
        
        % relevant variables to save in savedVarName
        % savedAppName saves this plus everything else too
        %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
        varsToSaveinStd =  {'motionData';
                            'body';
                            'refPts';
                            'originName';
                            'units';
                            'appVersion'};
          
        % constants used to expand the nodes into XYZ coordinates
        xCoorSuffix = '_X';
        yCoorSuffix = '_Y';
        zCoorSuffix = '_Z';
        notRecordedLabel = 'NR';
        notRecordedIndex = '0';
        customUnitChar   = 'xxx';
        
        % name of the first column, the independent variable
        csvDataRowNumberName = 'sampleNum';
    end
    

%---------------------------- VARIABLE PROPERTIES --------------------------------%
    
    properties (Access = private)
        % this variable stores the working directory
        workingDir = '';
        trialDir   = '';
        
        % started flag to make sure there is name and directory
        startedFlag = 0;
        
        % tab control variable, start at first tab
        tabControl = 1;
        
        % application tabs, used to move around with buttons, computed at startup
        applicationTabs;
        
        % selected cells from csv header and mapped tables ISR updated pointer
        csvHeadersSelCells = 0;
        mappedNodeSelCells = 0;
        fillGapsSelCells   = 0;
        refPtsToMapCellSel = 0;
        refPtsDataCellSel  = 0;
        
        % files to use in directory
        filesAvailiable;
        filesAvailiableDisplay;
    end
         
    properties (Access = public)
        % where all csv data is saved
        csvDataRaw;
        motionData;
        refPts;
        units;
        originName = '';
       
        % csv to node standard map
        cvsToNodeStdMap;
        cvsToRefPtsMap;
       
        % index used for polinomial fit gap filling and it correspoinds to the rawData
        isNanIndex = struct();
       
        % local copy of node standard imported and names of its elements
        bodyNames;
        body;
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%
    
    methods (Access = private)
        
        function setInitialGuiParam(app)
            % fist turn all panels off
            % get names of all tabs and indexes in app.
            tnames  = fieldnames(app);
            
            % tabs are in the order that they apear in the gui in sequence
            %TODO -- if tabs have multiple children this wont work
            app.applicationTabs = tnames(contains(tnames,app.isTabChar));
            
            % disable all panels
            for(i=1: length(app.applicationTabs))
                app.(app.applicationTabs{i}).Children.Visible = 'off';
            end
            
            % the first pannel and tab controlled by class tab control
            app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
            app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            
            % load the study directory and display files
            loadWorkingDirFiles(app);
            
            %set defaalt units
            app.units = app.customUnitChar;
        end%setInitialGuiParam
%-----------------------------------------------------------------------------------%
        
        function loadWorkingDirFiles(app)   
                %TODO -- should trim this to show a ../../dir
                app.label_workingDir.Text = app.workingDir;

                % dont display the trials availiable
                tignorefiles = {'.'; '..'; '.git'};
                tmp = cellstr(ls(fullfile(app.workingDir, 'trial')));
                tmp = setdiff(tmp,tignorefiles);
                app.listB_trialsAvail.Items = tmp;
                
                % make sure a trial is selected
                if(~isempty(app.listB_trialsAvail.Value))
                    % dont display the useless ones
                    tres        = displayDirRecursively(fullfile(app.workingDir, '\res'));
                    tres        = fullfile('\res',tres);
                    tshare      = displayDirRecursively(fullfile(app.workingDir, '\share'));
                    tshare      = fullfile('\share',tshare);
                    ttrialfiles = displayDirRecursively(fullfile(app.workingDir, '\trial', app.listB_trialsAvail.Value));
                    ttrialfiles = fullfile('\trial',app.listB_trialsAvail.Value,ttrialfiles);
                    
                    % append and remove empty ones
                    tmp = [tres; tshare; ttrialfiles];
                    app.filesAvailiable = tmp(~strcmp(tmp, ''));
                    app.filesAvailiableDisplay = compressPathNmes(app.filesAvailiable);
                    app.listB_filesAvail.Items = app.filesAvailiableDisplay;
                end
        end%loadWorkingDirFiles
%-----------------------------------------------------------------------------------%

        function findAppInstance(app)
            % see if there is an instance of this app in the working directory
            if(~(isfile(fullfile(app.trialDir, [app.savedAppName '.mat']))))
                app.label_existingFile.Text = {['There is no ' app.savedAppName ' File in the Working Directory.'] 'Saving will create a new one.'};
            else
                % try to open the file
                try
                    % disable wargings from saving stuff such as uia components
                    warning('off');
                    tmatfile = load(fullfile(app.trialDir, [app.savedAppName '.mat']));
                    loadedFile = tmatfile.(app.savedAppName);
                    
                    % turn warnings back on
                    warning('on');
                    %TODO -- this is a matlab bug
                    warning off MATLAB:subscripting:noSubscriptsSpecified;
                    
                % send a message and ask the user to take action 
                catch
                    msgbox({'There was an error loading the file, app wil close'; ...
                            'If probem persist you need to delete the saved mat file'}, 'Error','error');
                    delete(app);
                end
                % load variables to populate the instance of this app
                app.label_existingFile.Text = {['There is a ' app.savedAppName ' File in the Working Directory.'] 'Saving will overwrite it.'};
            end
        end%findAppInstance
%-----------------------------------------------------------------------------------%

        function incStartFlag(app, cnt)
            % increase or decrease the start flag with bound checking
            % this ommits the value of cnt and only uses its sign.
            if(cnt>0 && app.startedFlag<app.numInitialSteps)
                app.startedFlag = app.startedFlag + 1;
            elseif(cnt<0 && app.startedFlag>0)
                app.startedFlag = app.startedFlag - 1;
            end
            
            % determine if the start is done and enable prev and next buttons
            if(app.startedFlag>=app.numInitialSteps)
                app.button_next.Enable = 'on';
                app.button_prev.Enable = 'on';
                app.button_browseToTemplateFile.Enable = 'on';
            else
                app.button_next.Enable = 'off';
                app.button_prev.Enable = 'off';
                app.button_browseToTemplateFile.Enable = 'off';
            end
        end%incStartFlag
%-----------------------------------------------------------------------------------%
        
        function advanceTab(app, cnt)
            % first check if cnt will request a tab out of range
            if((app.tabControl+cnt)>length(app.applicationTabs) || (app.tabControl+cnt)<1)
                dispMsg(app, 'e', 'tab change request out of range');
            else
                % clear errors if any
                dispMsg(app, 'c');
                
                % disable the current panel
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'off';
                
                % increase tab control and activate next panel and tab
                app.tabControl = app.tabControl+cnt;
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
                app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            end
        end%advanceTab
%-----------------------------------------------------------------------------------%

        function s = varsToSaveinStdFcn(app)
            % saves just the relevant variables that come out of this class not to save ui elements
            for(i = 1:length(app.varsToSaveinStd))
                s.(app.varsToSaveinStd{i}) = app.(app.varsToSaveinStd{i});
            end                
        end%varsToSaveinStdFcn
%-----------------------------------------------------------------------------------%   
         
        function readNodeStandard(app)
            %TODO -- should i need try catch here to make sure that this is a standard node
            % aquire the value of the node standard and place it in a private variable here (copy)
            tmp = fullfile(app.workingDir, app.editF_nodeStandardFile.Value);
            tstruct = load(tmp, createNodeStandardGui.savedVarName);
            %TODO -- is this hardcoded or pointless ".congraph.nod...?
            % body is not really used but it will be saved with this app so it can be referenced
            app.body      = tstruct.(createNodeStandardGui.savedVarName).body;
            app.bodyNames = tstruct.(createNodeStandardGui.savedVarName).body.graph.Nodes.Name;
        end%readNodeStandard
%-----------------------------------------------------------------------------------%
    
        function readCsvFile(app)
            %TODO -- should i need try catch here to make sure that this is a standard node dir?
            % read fille with all experiment data to local var
            topts = detectImportOptions(fullfile(app.workingDir, app.editF_csvDataFile.Value));
 
            app.csvDataRaw.data = readmatrix(fullfile(app.workingDir, app.editF_csvDataFile.Value));
            
            [~,tname,text] = fileparts(app.editF_csvDataFile.Value);
            app.csvDataRaw.fileName   = [tname, text];
            
            app.csvDataRaw.colheaders = topts.VariableNames;
        end%readCsvFile
%-----------------------------------------------------------------------------------%

        function loadVarsFromTemplate(app, ttemplate)
            % step 2 is indifferent so run it as-is
            button_popNodeTableButtonPushed(app);
            
            % technically colum 3 is already populated from step 2, whatever.
            % copy the node assignment from the template experiment
            app.table_nodeAssign.Data(:,1)   = num2cell(ttemplate.nodeAssign);
            app.table_nodeAssign.Data(:,2)   = app.table_csvHeaders.Data(ttemplate.nodeAssign,1);
            %this is different because the table is empty by default
            tolddata  = get(app.table_referencePts,'Data');
            tnewdata  = [num2cell(ttemplate.referencePts), cellstr(app.table_csvHeaders.Data(ttemplate.referencePts,1))];
            tnewtable = [tolddata; tnewdata];
            set(app.table_referencePts,'Data',tnewtable);
            
            % compute step 4 and five
            % the user can do this technically but it should be ready so do it automatically and save time
            button_computeMapMatrixPushed(app);
            button_popRefPtsButtonPushed(app);
        end%loadVarsFromTemplate
%-----------------------------------------------------------------------------------%

        function dispMsg(app, ttype, tmsg)
            % For log messaging blue
            if(strcmpi(ttype, 'L'))
                app.label_errorMsg.Text = ['LOG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.45,0.74];
                
            % for error messages red
            elseif(strcmpi(ttype, 'E'))
                app.label_errorMsg.Text = ['ERROR: ' tmsg];
                app.label_errorMsg.FontColor = [0.64,0.08,0.18];
                
            % general propose message black
            elseif(strcmpi(ttype, 'M'))
                app.label_errorMsg.Text = ['MSG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.00,0.00];
            
            % for clearing the mesage
            elseif(strcmpi(ttype, 'C'))
                app.label_errorMsg.Text = '';
            end
        end%dispMsg
%-----------------------------------------------------------------------------------%

    end%private methods

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tworkingDir)
            app.workingDir = tworkingDir;
            setInitialGuiParam(app);
        end

        % Button pushed function: button_next
        function button_nextButtonPushed(app, event)
            % move to next tab
            advanceTab(app, 1);
        end

        % Button pushed function: button_prev
        function button_prevButtonPushed(app, event)
            % move to previous tab
            advanceTab(app, -1);
        end

        % Value changed function: listB_trialsAvail
        function listB_trialsAvailValueChanged(app, event)
            value = app.listB_trialsAvail.Value;
            loadWorkingDirFiles(app);
        end

        % Value changed function: chkB_lockTrial
        function chkB_lockTrialValueChanged(app, event)
            value = app.chkB_lockTrial.Value;
            % if the box is checked then update the trial data
            if(value == 1)
                % get the directory path of this trial, check for previous instance
                app.trialDir = fullfile(app.workingDir,'trial', app.listB_trialsAvail.Value);
                findAppInstance(app);
                
                % enable the selection of trials in list box
                app.listB_trialsAvail.Enable  = 'off';
                
                % then enable all interactivity
                app.editF_csvDataFile.Enable      = 'on';
                app.editF_nodeStandardFile.Enable = 'on';
                app.editF_mapTemplate.Enable      = 'on';
                
                app.button_csvSelect.Enable       = 'on';
                app.button_nodeStandard.Enable    = 'on';
                app.button_mapTemplate.Enable     = 'on';
                
                app.chkB_csvLock.Enable          = 'on';
                app.chkB_nodeStandardLock.Enable = 'on';
                app.chkB_mapTemplate.Enable      = 'on';
            else
                % disable the selection of trials in list box
                app.listB_trialsAvail.Enable  = 'on';
                
                % clear existing file text
                app.label_existingFile.Text = '';
                
                % then enable all interactivity
                app.editF_csvDataFile.Enable      = 'off';
                app.editF_nodeStandardFile.Enable = 'off';
                app.editF_mapTemplate.Enable      = 'off';
                
                app.button_csvSelect.Enable       = 'off';
                app.button_nodeStandard.Enable    = 'off';
                app.button_mapTemplate.Enable     = 'off';
                
                app.chkB_csvLock.Enable          = 'off';
                app.chkB_nodeStandardLock.Enable = 'off';
                app.chkB_mapTemplate.Enable      = 'off';
                
                % clear all mapped information
                app.editF_csvDataFile.Value      = '';
                app.editF_nodeStandardFile.Value = '';
                app.editF_mapTemplate.Value      = '';
                
                app.chkB_csvLock.Value          = 0;
                app.chkB_nodeStandardLock.Value = 0;
                app.chkB_mapTemplate.Value      = 0;
                
                % clear all progres to move forward
                app.startedFlag = 0;
            end
        end

        % Button pushed function: button_refreshWorkingDir
        function button_refreshWorkingDirButtonPushed(app, event)
            loadWorkingDirFiles(app);
        end

        % Value changed function: chkB_csvLock
        function chkB_csvLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_csvLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.button_csvSelect.Enable = 'off';
                incStartFlag(app, 1);
            else
                app.button_csvSelect.Enable = 'on';
                incStartFlag(app, -1);
            end
        end

        % Button pushed function: button_csvSelect
        function button_csvSelectButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that ta csv is actually selected
            % make sure there is a selection
            if(~isempty(app.listB_filesAvail.Value))
                % get the selected files in its extended name form
                tmp = app.listB_filesAvail.Value;
                tindex = arrayfun(@(k) find(strcmp(tmp,app.filesAvailiableDisplay)), 1:length(tmp));
                app.editF_csvDataFile.Value = app.filesAvailiable{tindex};
                
                % lock the value trough the checkbox
                app.chkB_csvLock.Value = 1;
                chkB_csvLockValueChanged(app);
            
                % clear errors if any
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'Select a file first');
            end 
        end

        % Value changed function: chkB_nodeStandardLock
        function chkB_nodeStandardLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_nodeStandardLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.button_nodeStandard.Enable = 'off';
                incStartFlag(app, 1);
            else
                app.button_nodeStandard.Enable = 'on';
                incStartFlag(app, -1);
            end
        end

        % Button pushed function: button_nodeStandard
        function button_nodeStandardButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that node standard is actually selected
            % make sure there is a selection
            if(~isempty(app.listB_filesAvail.Value))
                % get the selected files in its extended name form
                tmp = app.listB_filesAvail.Value;
                tindex = arrayfun(@(k) find(strcmp(tmp,app.filesAvailiableDisplay)), 1:length(tmp));
                app.editF_nodeStandardFile.Value = app.filesAvailiable{tindex};
                
                % lock the value trough the checkbox
                app.chkB_nodeStandardLock.Value = 1;
                chkB_nodeStandardLockValueChanged(app); 
                
                % clear errors if any
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'Select a file first');
            end
        end

        % Value changed function: chkB_mapTemplate
        function chkB_mapTemplateValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_mapTemplate.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            if(value == 1)
                app.button_mapTemplate.Enable = 'off';
            else
                app.button_mapTemplate.Enable = 'on';
            end
        end

        % Button pushed function: button_mapTemplate
        function button_mapTemplateButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that node standard is actually selected
            % make sure there is a selection
            if(~isempty(app.listB_filesAvail.Value))
                % get the selected files in its extended name form
                tmp = app.listB_filesAvail.Value;
                tindex = arrayfun(@(k) find(strcmp(tmp,app.filesAvailiableDisplay)), 1:length(tmp));
                app.editF_mapTemplate.Value = app.filesAvailiable{tindex};
                
                % lock the value trough the checkbox
                app.chkB_mapTemplate.Value = 1;
                chkB_mapTemplateValueChanged(app); 
                
                % clear errors if any
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'Select a file first');
            end
        end

        % Button pushed function: button_browseToTemplateFile
        function button_browseToTemplateFileButtonPushed(app, event)
            % make sure the field is not empty
            if(~isempty(app.editF_mapTemplate))
                dispMsg(app,'c');
                try
                    tmatfile = load(fullfile(app.workingDir,app.editF_mapTemplate.Value));
                    ttemplate = tmatfile.(app.savedAppTemplateName);
                    
                catch
                    % for some reason the file cant be opened
                    msgbox('The file cant be read for some reason', 'Chaos!', 'warn');
                end
                
                % load what it is needed from the template file
                    loadVarsFromTemplate(app, ttemplate);
                    
                    % jump user to step 6 trim data tab
                    advanceTab(app, 2);
                    
                    %send a message to user saying that it should skip the frist steps that assign data
                    uiconfirm(app.motionCsvToNodeStdMap,{'All Good!!!';...
                            'Now you should skip the fist steps and go to excecute step six (6)';...
                            'consider that if the file selected does not match the CSV columns of this study Exactly';...
                            'crazy stuff will happen'},'You Are Amazing');
            else
                dispMsg(app, 'e', 'First select a file.');
            end
        end

        % Button pushed function: button_popNodeTable
        function button_popNodeTableButtonPushed(app, event)
            % frist aquire the values from node standard and csv experiment
            readNodeStandard(app);
            readCsvFile(app);
            
            % get the column headers from csv file and assign to table, make it into a col array
            app.table_csvHeaders.Data = app.csvDataRaw.colheaders(:);
            
            % expand the node coordiante into its components
            %TODO -- i should have these on checkmarks and also allow user to enter their own suffixes?
            %TODO -- knowing the above, then initialize array empty, preallocating increases speed
            tnodeExpanded = {};
            for(i = 1: length(app.bodyNames))
                tnodeExpanded = [tnodeExpanded; {[app.bodyNames{i}, app.xCoorSuffix]}; ...
                                                {[app.bodyNames{i}, app.yCoorSuffix]}; ...
                                                {[app.bodyNames{i}, app.zCoorSuffix]}];
            end
            
            % frist and second columns are empty
            tmp = cell(length(tnodeExpanded),1);
            tmp(:) = {''};
            app.table_nodeAssign.Data = [tmp, tmp];
            
            % second column gets node standard names with XYZ expansion
            app.table_nodeAssign.Data = [app.table_nodeAssign.Data tnodeExpanded];
            
            % enable buttons
            app.button_addSelection.Enable      = 'on';
            app.button_deleteMappedValue.Enable = 'on';
            app.button_insertZero.Enable        = 'on';
            app.button_computeMapMatrix.Enable  = 'on';
            app.button_referencePts.Enable      = 'on';
        end

        % Cell selection callback: table_csvHeaders
        function table_csvHeadersCellSelection(app, event)
            % for some reason it does not like to be directly assigned to private var
            % assign value to private variable (array) on a selection event
            indices = event.Indices(:,1);
            app.csvHeadersSelCells = unique(indices);
        end

        % Cell selection callback: table_nodeAssign
        function table_nodeAssignCellSelection(app, event)
            % for some reason it does not like to be directly assigned to private var
            % assign value to private variable (array) on a selection event
            indices = event.Indices(:,1);
            app.mappedNodeSelCells = unique(indices);
        end

        % Button pushed function: button_addSelection
        function button_addSelectionButtonPushed(app, event)
            % add selected CSV header to the table that will be the map to node std
            % make sure there is an actual selection
            if(~isempty(app.csvHeadersSelCells) & app.csvHeadersSelCells~=0)
                % the selected cells to be copied
                tcells = app.table_csvHeaders.Data(app.csvHeadersSelCells,1);
                
                % take the index of the first entry that is empty
                tindex = find(strcmp(app.table_nodeAssign.Data(:,1),''),1);
                
                % make sure the assignment does not exceed the table expected row n
                % assign the value of the node name and the row number. that correspond to csv col number
                if((tindex+length(tcells)-1)<=length(app.table_nodeAssign.Data))
                    app.table_nodeAssign.Data(tindex:(tindex+length(tcells)-1),2) = tcells;
                    app.table_nodeAssign.Data(tindex:(tindex+length(tcells)-1),1) = num2cell(app.csvHeadersSelCells);
                    dispMsg(app, 'c');
                else
                    dispMsg(app, 'e', 'selected cells will make array bigger than it must');
                end
                % clear the selected cell pointer
                app.csvHeadersSelCells = 0;
            else
                dispMsg(app, 'e', 'you must select a cell from the CSV Header table');
            end
        end

        % Button pushed function: button_deleteMappedValue
        function button_deleteMappedValueButtonPushed(app, event)
            % cherry-pick values from table and remove them
            % user needs to make a selection first, then press
            % cells selected array comes from an ISR table_nodeAssignCellSelection()
            if(~isempty(app.mappedNodeSelCells) & app.mappedNodeSelCells ~= 0)
                % deletes cell content, not cell row for csv col number and header name 
                app.table_nodeAssign.Data(app.mappedNodeSelCells,1:2) = {''};
                % clear the selected cell selected pointer, clear error if any
                app.mappedNodeSelCells = 0;
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'you must select a cell from the mapped node table');
            end
        end

        % Button pushed function: button_insertZero
        function button_insertZeroPushed(app, event)
            % Add "not recorded" or NR
            % take the index of the first entry that is empty
            tindex = find(strcmp(app.table_nodeAssign.Data(:,1),''),1);
            
            %TODO -- this works now but it will make the polyfit crash because it still expects to xyz
            %TODO -- need to fill with zeros for this entry but not all components of a node can be zero if so new node std needed
            % assign a not recorded value and an index of zero
            app.table_nodeAssign.Data(tindex,1) = {app.notRecordedIndex};
            app.table_nodeAssign.Data(tindex,2) = {app.notRecordedLabel};
        end

        % Button pushed function: button_referencePts
        function button_referencePtsButtonPushed(app, event)
            % add selected CSV header to the table that will be the map to node std
            % make sure there is an actual selection
            if(~isempty(app.csvHeadersSelCells) & app.csvHeadersSelCells~=0)
                % the selected cells to be copied
                tolddata  = get(app.table_referencePts,'Data');
                tnewdata  = [num2cell(app.csvHeadersSelCells(:,1)), cellstr(app.table_csvHeaders.Data(app.csvHeadersSelCells,1))];
                tnewtable = [tolddata; tnewdata];
                set(app.table_referencePts,'Data',tnewtable);
                
                % clear the selected cell pointer
                app.csvHeadersSelCells = 0;
            else
                dispMsg(app, 'e', 'you must select a cell from the CSV Header table');
            end
        end

        % Button pushed function: button_computeMapMatrix
        function button_computeMapMatrixPushed(app, event)
            %TODO -- this does not check that all components are 2d or 3d only
            %TODO -- a 1d is useless and untrue and not populating a node requires a change in node standard
            %TODO -- you can insert NR but i am not sure what it will do for now
            %TODO -- make sure that each node is mapped only once
            
            %TODO -- if statement here
            % first make sure that all the table is full
            
            %TODO -- if statement here
            % then make sure that after removing the notRecordedLabel zeros, all items are unique
            
            % make a map
            % pass data as-is
            tmap = app.table_nodeAssign.Data;
            
            % create empty column for the suffix
            tmp = cell(length(tmap),1);
            tmp(:) = {''};
            % column append
            tmap = [tmap, tmp];
            
            %TODO -- this is not expandable to just 2d or more user-defined dimensions
            %TODO -- is it better to do table of chars or table of cells of chars
            % for items containing X suffix, make new column then remove suffix from col 2
            trows = find(contains(tmap(:,3), app.xCoorSuffix));
            tmap(trows,4) = {app.xCoorSuffix};
            tmap(trows,3) = cellfun(@(tvar) tvar(1:end-length(app.xCoorSuffix)), tmap(trows,3), 'Uniform', 0);
            
            % for items containing Y suffix, make new column then remove suffix from col 2
            trows = find(contains(tmap(:,3), app.yCoorSuffix));
            tmap(trows,4) = {app.yCoorSuffix};
            tmap(trows,3) = cellfun(@(tvar) tvar(1:end-length(app.yCoorSuffix)), tmap(trows,3), 'Uniform', 0);
            
            % for items containing Z suffix, make new column then remove suffix from col 2
            trows = find(contains(tmap(:,3), app.zCoorSuffix));
            tmap(trows,4) = {app.zCoorSuffix};
            tmap(trows,3) = cellfun(@(tvar) tvar(1:end-length(app.zCoorSuffix)), tmap(trows,3), 'Uniform', 0);

            % make array into table
            app.cvsToNodeStdMap = cell2table(tmap, 'VariableNames', {'csvColNum' 'csvHead' 'nodes' 'coordinate'});
            
            
            %do the same but now for reference points
            % make a map
            % pass data as-is
            tmap = app.table_referencePts.Data;
            
            % create empty column for the suffix
            tmp = cell(length(tmap),1);
            tmp(:) = {''};
            % column append
            tmap = [tmap, tmap(:,2), tmp];
            
            % for items containing X suffix, make new column then remove suffix from col 2
            trows = find(contains(tmap(:,3), app.xCoorSuffix));
            tmap(trows,4) = {app.xCoorSuffix};
            tmap(trows,3) = cellfun(@(tvar) tvar(1:end-length(app.xCoorSuffix)), tmap(trows,3), 'Uniform', 0);
            
            % for items containing Y suffix, make new column then remove suffix from col 2
            trows = find(contains(tmap(:,3), app.yCoorSuffix));
            tmap(trows,4) = {app.yCoorSuffix};
            tmap(trows,3) = cellfun(@(tvar) tvar(1:end-length(app.yCoorSuffix)), tmap(trows,3), 'Uniform', 0);
            
            % for items containing Z suffix, make new column then remove suffix from col 2
            trows = find(contains(tmap(:,3), app.zCoorSuffix));
            tmap(trows,4) = {app.zCoorSuffix};
            tmap(trows,3) = cellfun(@(tvar) tvar(1:end-length(app.zCoorSuffix)), tmap(trows,3), 'Uniform', 0);

            % make array into table
            app.cvsToRefPtsMap = cell2table(tmap, 'VariableNames', {'csvColNum' 'csvHead' 'nodes' 'coordinate'});
            
            % send a message that the process is completed
            dispMsg(app, 'l', 'Matrix was succesfully computed!!');
        end

        % Button pushed function: button_popRefPts
        function button_popRefPtsButtonPushed(app, event)
            % take Raw csv Data and compute the Head and Tail #nans
            % find the index of first row (any, 2) in data that is non NaN
            % create empty column for the suffix
            tmp = cell(length(unique(app.cvsToRefPtsMap.nodes)),1);
            tmp(:) = {''};
            %display the name f that node, the second col is editable
            app.table_refPtsToMap.Data = [unique(app.cvsToRefPtsMap.nodes), unique(app.cvsToRefPtsMap.nodes), tmp];
            app.table_refPtsToMap.ColumnEditable = [false, true, false];
            
            app.dropD_worldOrigin.Items = ['none';unique(app.cvsToRefPtsMap.nodes)];
            app.dropD_worldOrigin.ItemsData = 0:length(unique(app.cvsToRefPtsMap.nodes));
            
            %enable interactivity
            app.button_selectRefPts.Enable = 'on';
            dispMsg(app, 'l', 'you can edit the name before storing it with "select"');
        end

        % Cell selection callback: table_refPtsToMap
        function table_refPtsToMapCellSelection(app, event)
            indices = event.Indices;
            app.refPtsToMapCellSel = indices(1,1);
            %get all the information from that point
            %first the the columns that match the point
            %TODO -- this is assuming that xyz are ordered as such
            tidx = find(contains(app.cvsToRefPtsMap.nodes, app.table_refPtsToMap.Data(app.refPtsToMapCellSel,1)),1);
            tnotnanrow = find(~any(isnan(app.csvDataRaw.data(:,app.cvsToRefPtsMap.csvColNum(tidx:tidx+2))),2));
            
            %display the data
            app.table_refPtsData.Data = [tnotnanrow, app.csvDataRaw.data(tnotnanrow, app.cvsToRefPtsMap.csvColNum(tidx:tidx+2))];
            
            app.refPtsDataCellSel = 0;
             
        end

        % Cell selection callback: table_refPtsData
        function table_refPtsDataCellSelection(app, event)
            indices = event.Indices;
            app.refPtsDataCellSel = indices(1,1);
        end

        % Button pushed function: button_selectRefPts
        function button_selectRefPtsButtonPushed(app, event)
            %TODO -- you can change this multiple times for the same point
            %TODO -- that is only if you change the name
            if(app.refPtsDataCellSel~=0 && app.refPtsToMapCellSel~=0)
                if(isvarname(app.table_refPtsToMap.Data{app.refPtsToMapCellSel,2})) 
                    tname = app.table_refPtsToMap.Data{app.refPtsToMapCellSel,2};
                    tvect = [app.table_refPtsData.Data(app.refPtsDataCellSel,2), app.table_refPtsData.Data(app.refPtsDataCellSel,3), app.table_refPtsData.Data(app.refPtsDataCellSel,4)];
                    app.refPts.(tname) = tvect;
                    assignin('base','ref',app.refPts);
                    app.table_refPtsToMap.Data(app.refPtsToMapCellSel,3) = {true};
                    app.refPtsToMapCellSel = 0;
                    app.refPtsDataCellSel  = 0;
                    dispMsg(app, 'l', 'you can edit the name before storing it with "select"');
                else
                    dispMsg(app, 'e', 'the variable name used is not a valit Matlab one');
                end
            else
                 dispMsg(app, 'e', 'you must select a cell from each table');
            end
        end

        % Value changed function: dropD_worldOrigin
        function dropD_worldOriginValueChanged(app, event)
            value = app.dropD_worldOrigin.Value;
            if(value==0)
                app.originName = '';
            else
                app.originName = app.table_refPtsToMap.Data{value,2};
            end
        end

        % Button pushed function: button_populateTrimNan
        function button_populateTrimNanButtonPushed(app, event)
            % take Raw csv Data and compute the Head and Tail #nans
            % find the index of first row (any, 2) in data that is non NaN
            tnotnanrow = find(~any(isnan(app.csvDataRaw.data(:,app.cvsToNodeStdMap.csvColNum)),2));
            
            % initialize with zeros in case there are no nans on head or tail
            tnansumhead = zeros(size(app.csvDataRaw.data,2),1);
            tnansumtail = zeros(size(app.csvDataRaw.data,2),1);
            
            if(~isempty(tnotnanrow))
                % populate the values 
                app.editF_headNan.Value  = tnotnanrow(1);
                app.editF_tailNan.Value  = tnotnanrow(end);
                app.editF_numbRows.Value = size(app.csvDataRaw.data,1);
                
                % compute each node for Head and tail Nans, then grab every 3rd element for XYZ triplet and make col vector
                % using Z because it is the third and therefore can be divided by 3
                % then rearrange all the elements to match the node standard and not the raw data
                %TODO -- i am assuming that each node has the same nans in XYZ, if not I should grab the largest
                tnansumhead = tnansumhead + sum(isnan(app.csvDataRaw.data(1:tnotnanrow(1),:)))';
                tnansumhead = tnansumhead(3:3:end);
                tnansumhead = tnansumhead(app.cvsToNodeStdMap.csvColNum(3:3:end)/3);
                
                tnansumtail = tnansumtail + sum(isnan(app.csvDataRaw.data(tnotnanrow(end):end,:)))';
                tnansumtail = tnansumtail(3:3:end);
                tnansumtail = tnansumtail(app.cvsToNodeStdMap.csvColNum(3:3:end)/3);
            end 
            
            %TODO -- do I need a number of dimensions per node constant in case there is other data aside from video?
            % populate table with findings, use app.cvsToNodeStdMap.csvColNum to make sure it is in the right order
            app.table_trimData.Data = app.bodyNames;
            app.table_trimData.Data = [app.table_trimData.Data num2cell(tnansumhead(:))];
            app.table_trimData.Data = [app.table_trimData.Data num2cell(tnansumtail(:))];
            
            % enable trim button
            app.button_trimData.Enable = 'on';
        end

        % Button pushed function: button_trimData
        function button_trimDataButtonPushed(app, event)
            % make a copy of the data and trim edges with head and tail values plus user input
            % first enssure that user input does not create a bad index for array
            %TODO -- make this if

            % grab de index the computed and the added user
            thead = app.editF_headNan.Value + app.spinner_headTrim.Value;
            ttail = app.editF_tailNan.Value + app.spinner_tailTrim.Value;
            
            % first initialize the table, fill first column with the original sample numbers
            % this can relate to a time series, frame number, etc.
            app.motionData = table();
            app.motionData.(app.csvDataRowNumberName) = (thead:ttail)';
            
            for(i = 1: length(app.bodyNames))
                % first find the column, just the first instance as it goes XYZ
                tmp = find(contains(app.cvsToNodeStdMap.nodes, app.bodyNames{i}),1);
                % then get the XYZ col number, not needed but otherwise too long
                tcoridx = app.cvsToNodeStdMap.csvColNum(tmp:tmp+2);
                % append the data to the table
                app.motionData.(app.bodyNames{i}) = app.csvDataRaw.data(thead:ttail,tcoridx);
            end
            
            % populate result values, use inclusive sum
            app.editF_percentLeft.Value = 100*(ttail-thead)/app.editF_numbRows.Value;
            app.editF_samplesLeft.Value = ttail-thead+1;
        end

        % Button pushed function: button_populateNodeGap
        function button_populateNodeGapButtonPushed(app, event)
            % compute the statistics of all the gaps in data
            % first clear the table
            app.table_fillGaps.Data = [];
            
            for tnames = app.motionData.Properties.VariableNames
                % change to an array of chars for the use in the for, loop tnames
                tnameschar = char(tnames);
                % skipping first index because it is the index number of the csvraw data
                if(strcmp(tnameschar, app.csvDataRowNumberName))
                    continue;
                end
                
                % find the nan data and OR its XYZ columns, take the largest
                % append false at the end to see if there is a sequence at start or end (should not because is trimmed)
                % transpose because row vector needed for strcmp
                tmp = [false; any(isnan(app.motionData.(tnameschar)),2); false];
                tstarts = strfind(tmp',[0 1])';
                % take the last nan, no the first not-nan
                tends   = strfind(tmp',[1 0])'-1;
                % get the start-end for each gap, will be used in polyfit
                app.isNanIndex.(tnameschar) = [tstarts tends];
                
                % the number of NaN's in a row, inclusive sum, diff every 1 on columns(2) so: col(2)-col(1)+1
                tnans = diff(app.isNanIndex.(tnameschar),1,2)+1;
                
                tnumgaps = size(tnans,1);
                % if there are no gaps then this is all useless
                if(tnumgaps == 0)
                    tmax    = 0;
                    tmean   = 0;
                    tstddev = 0;
                else
                    tmax    = max(tnans);
                    tmean   = round(mean(tnans),2);
                    tstddev = round(std(tnans),2);
                end
                % update table with trimmed data
                app.table_fillGaps.Data = [app.table_fillGaps.Data; {tnameschar tnumgaps tmean tstddev tmax}];
            end

            % enable plot and close gap buttons
            app.button_plotSelection.Enable  = 'on';
            app.button_closeGaps.Enable      = 'on';
            app.button_unitConversion.Enable = 'on';
            app.button_displaySummary.Enable = 'on';
            app.button_unitConversion.Enable = 'on';
            app.editF_currentUnits.Value     = app.units;
            
            %TODO -- add labels at the bottom to see how many gaps sum all are there still to fix, also grand max and mean
        end

        % Cell selection callback: table_fillGaps
        function table_fillGapsCellSelection(app, event)
            % for some reason it does not like to be directly assigned to private var
            % assign value to private variable (array) on a selection event
            indices = event.Indices(:,1);
            app.fillGapsSelCells = unique(indices);
        end

        % Button pushed function: button_closeGaps
        function button_closeGapsButtonPushed(app, event)
            %TODO -- this uses a n degree polynomial to fit the data, for many reasons this may be not ok (like wave data)
            %TODO -- offer a list of possible algorithms, not just least square
            %TODO -- is taking a fit for x,y,z different than a fit for xyz?
            %TODO -- this does not take into account the fact that a point is part of a linkage
            %TODO -- ideally each node could be computed separatelly, for now apply to all data
            %TODO -- this hshoudl be in annother file in the utils folder
            %TODO -- These gaps were interpolated using an over-constrained polynomial fitting algorithm (Riskin et al 2008)
            
            % take the user's input for polynomial degree
            tdegree = str2num(app.knob_polynomialDegree.Value);
            
            % take the polynomial with n+1 points at either end of the gap
            %TODO -- add annother slider for the user to increment this number even further?
            tnumpoints = tdegree+1;
            
            for(tnames = app.motionData.Properties.VariableNames)
                % change to an array of chars for the use in the for, loop tnames
                tnameschar = char(tnames);
                
                % skipping first index because it is the index number of the csvraw data
                if(strcmp(tnameschar, app.csvDataRowNumberName))
                    continue;
                end
                
                % if that data column has no gaps, then skip to next one
                if(isempty(app.isNanIndex.(tnameschar)))
                   continue; 
                end
                
                % for each straight gap on each column
                %TODO -- this is not checking how many points are availiable on either side of the gap
                %TODO -- if data is 5 samples long and you pick a 5th poly then there is no enough data to compute
                for(i = 1: size(app.isNanIndex.(tnameschar),1))
                    % make the number of points before gap's HEAD until number of points after gap's TAIL
                    trange = (app.isNanIndex.(tnameschar)(i,1)-tnumpoints):(app.isNanIndex.(tnameschar)(i,2)+tnumpoints);
                    
                    % compensate this range if it contains invalid indexes by shifting the range and keeping the number of pts
                    %TODO -- if the data set is too small this is not doing anything if range is bigger than data set
                    %TODO -- this does not check if shift is too large there should be at least 1?2?3? pts on either end?
                    %TODO -- if gap is too large, user should really go back and trim this data instead
                    if(~isempty(find(trange==0,1)))
                        trange = trange + find(trange<1,1,'last');
                    % now check the tail end    
                    elseif(~isempty(find(trange==size(app.motionData,1),1)))
                        trange = trange - find(trange>size(app.motionData,1),1,'last');
                    end
                    
                    % query points is the same as trange but extracting HEAD:TAIL because they are NAN, this is used for polifit
                    tquerypts = trange(~ismember(trange,(app.isNanIndex.(tnameschar)(i,1):app.isNanIndex.(tnameschar)(i,2))))';
                    
                    % query points is also the independent variable of the polinomial because it represents time in samples/s
                    % do a polinomial fit for X, Y, Z
                    [tpx, tSx, tmux] = polyfit(tquerypts, app.motionData.(tnameschar)(tquerypts,1), tdegree);
                    [tpy, tSy, tmuy] = polyfit(tquerypts, app.motionData.(tnameschar)(tquerypts,2), tdegree);
                    [tpz, tSz, tmuz] = polyfit(tquerypts, app.motionData.(tnameschar)(tquerypts,3), tdegree);
                    
                    % now obtain the values evaluating the polynomial at from gaps HEAD:TAIL
                    tevalpts = (app.isNanIndex.(tnameschar)(i,1):app.isNanIndex.(tnameschar)(i,2))';
                    
                    % evaluate polynomails at shuch points and fill the gaps in the main matrix
                    app.motionData.(tnameschar)(tevalpts,1) = polyval(tpx, tevalpts, [], tmux);
                    app.motionData.(tnameschar)(tevalpts,2) = polyval(tpy, tevalpts, [], tmuy);
                    app.motionData.(tnameschar)(tevalpts,3) = polyval(tpz, tevalpts, [], tmuz);
                end%for each gap
            end%for each node
            
            % send message that polynomials are done
            dispMsg(app, 'l', 'polynomials computed, table is now updated too.');
            % re-populate the table by virtually pressing the button
            button_populateNodeGapButtonPushed(app, event);
        end

        % Button pushed function: button_plotSelection
        function button_plotSelectionButtonPushed(app, event)
            %TODO -- this is hardcoded xyz
            %TODO -- offer more plotting options
            %TODO -- ideally point or indicate to the gaps if any
            %TODO -- mange plot handles propperly
            
            % user needs to make a selection first, then press
            % cells selected array comes from an ISR table_nodeAssignCellSelection()
            if(~isempty(app.fillGapsSelCells) & app.fillGapsSelCells~= 0)
                % make a time series with independedt var as sample number
                ttime = app.motionData.(app.csvDataRowNumberName);
                tnodename = char(app.table_fillGaps.Data(app.fillGapsSelCells,1));

                % create subplot with X, Y, Z, XYZ plots
                figure();
                subplot(3,2,1);
                plot(ttime, app.motionData.(tnodename)(:,1), 'Color','b');
                title('X');
                subplot(3,2,3);
                plot(ttime, app.motionData.(tnodename)(:,2), 'Color','r');
                title('Y');
                subplot(3,2,5);
                plot(ttime, app.motionData.(tnodename)(:,3), 'Color','g');
                title('Z');
                subplot(3,2,[2,4,6]);
                plot3(app.motionData.(tnodename)(:,1), app.motionData.(tnodename)(:,2), app.motionData.(tnodename)(:,3), 'Color','m');
                title('XYZ');
                xlabel('X', 'FontWeight', 'bold');
                ylabel('Y', 'FontWeight', 'bold');
                zlabel('Z', 'FontWeight', 'bold');
                %TODO -- make sure this package is loaded with the toolbox
                tptstart = app.motionData.(tnodename)(1,1:3);
                tptend   = app.motionData.(tnodename)(end,1:3);
                arrow(tptstart,tptend, 2);
                
                sgtitle(['Plot for Node: ' tnodename]);
                
                % clear errors if any
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'you must select a cell from the table');
            end
        end

        % Button pushed function: button_displaySummary
        function button_displaySummaryButtonPushed(app, event)
            summary(app.motionData, 1:5);
        end

        % Value changed function: dropD_convertFrom
        function dropD_convertFromValueChanged(app, event)
            value = app.dropD_convertFrom.Value;
            %if entering a custom value make editF and label visible
            if(strcmp(value, app.customUnitChar))
                app.label_customConvInstr.Visible = 'on';
                app.editF_customDataIn.Visible    = 'on';
            else
                app.label_customConvInstr.Visible = 'off';
                app.editF_customDataIn.Visible    = 'off';
                app.editF_customDataIn.Value      = 0;
            end
        end

        % Value changed function: dropD_convertTo
        function dropD_convertToValueChanged(app, event)
            value = app.dropD_convertTo.Value;
            if(strcmp(value, app.customUnitChar))
                app.label_customUnitInstr.Visible = 'on';
                app.editF_customConvertTo.Visible = 'on';
            else
                app.label_customUnitInstr.Visible = 'off';
                app.editF_customConvertTo.Visible = 'off';
                app.editF_customConvertTo.Value   = '';
            end
        end

        % Button pushed function: button_unitConversion
        function button_unitConversionButtonPushed(app, event)
            %TODO -- this does not disciminates each column
            % get temprorary variable to exclude sample number
            tmotiondata = app.motionData.Variables;
            %get all reference points
            tnames  = fieldnames(app.refPts);
            trefpts = zeros(length(tnames),3);
            for(i = 1: length(tnames))
                trefpts(i,:) = app.refPts.(tnames{i});
            end
            
            %if it is a custom conversion do it here
            %otherwise use the conversion table
            if(strcmp(app.dropD_convertFrom.Value, app.customUnitChar))
                tmp  = tmotiondata(:, 2:end)/app.editF_customDataIn.Value;
                tmp2 = trefpts/app.editF_customDataIn.Value;
            else
                % dont include the sample numbers
                tmp  = convertLenghtUnits(tmotiondata(:, 2:end), app.dropD_convertFrom.Value, app.dropD_convertTo.Value);
                tmp2 = convertLenghtUnits(trefpts, app.dropD_convertFrom.Value, app.dropD_convertTo.Value);
            end
            
            %put data back
            app.motionData.Variables = [tmotiondata(:,1), tmp];
            for(i = 1: length(tnames))
                app.refPts.(tnames{i}) = tmp2(i,:);
            end
            
            
            %savve units of he data for fuure reference
            if(strcmp(app.dropD_convertTo.Value, app.customUnitChar))
                if(isvarname(app.editF_customConvertTo.Value))
                    app.units = app.editF_customConvertTo.Value;
                else
                    dispMsg(app, 'e', 'that is not a valid Matlab variable name'); 
                end
            else
                app.units = app.dropD_convertTo.Value;
            end
            
            app.editF_currentUnits.Value = app.units;
            dispMsg(app, 'l', 'Conversion completed');  
        end

        % Button pushed function: button_copyToWorkspace
        function button_copyToWorkspaceButtonPushed(app, event)
            % first make sure that the name is valid
            if(isvarname(app.editF_copyToWorkspace.Value))
                % create a struct of the saved variables
                tmp.(app.savedVarName) = varsToSaveinStdFcn(app);
                
                % assign value to base workspace in matlab
                assignin('base',app.editF_copyToWorkspace.Value,tmp.(app.savedVarName));
                
                % clear error if any
                dispMsg(app, 'l', 'Copied to base workspace');  
            else
                dispMsg(app, 'e', 'The variable in field must be a valid matlab name');
            end
        end

        % Button pushed function: button_saveTemplate
        function button_saveTemplateButtonPushed(app, event)
            % disable wargings from saving stuff such as uia components
            %TODO -- just dont try to save them to prevent warning
            warning('off');
            
            % make a struct of what I want to save to that they keep those variable names
            %TODO -- this is a little hardcoded
            %jsut get the column order to map
            tmp.(app.savedAppTemplateName).nodeAssign   = cell2mat(app.table_nodeAssign.Data(:,1));
            tmp.(app.savedAppTemplateName).referencePts = cell2mat(app.table_referencePts.Data(:,1));
            
            % save file
            %TODO -- does this needs a try catch?
            save(fullfile(app.workingDir, 'share', [app.savedAppTemplateName '.mat']), '-struct', 'tmp');
            app.label_saveTemplateName.Text = fullfile(app.workingDir, 'share', [app.savedAppTemplateName '.mat']);
            loadWorkingDirFiles(app);
            
            % turn warnings back on
            warning('on');
            %TODO -- this is a matlab bug
            warning off MATLAB:subscripting:noSubscriptsSpecified;
        end

        % Button pushed function: button_saveAllAndExit
        function button_saveAllAndExitButtonPushed(app, event)
            % disable wargings from saving stuff such as uia components
            %TODO -- just dont try to save them to prevent warning
            warning('off');
            
            % make a struct of what I want to save to that they keep those variable names
            tmp.(app.savedAppName) = app;
            tmp.(app.savedVarName) = varsToSaveinStdFcn(app);
            
            % save file
            %TODO -- does this needs a try catch?
            save(fullfile(app.trialDir, [app.savedAppName '.mat']), '-struct', 'tmp');
            app.label_savedAs.Text = fullfile(app.trialDir, [app.savedAppName '.mat']);
            app.label_existingFile.Text = {['There is a ' app.savedAppName ' File in the Working Directory.'] 'Saving will overwrite it.'};
            loadWorkingDirFiles(app);
            
            % alert the user it is done
            uiconfirm(app.motionCsvToNodeStdMap,{'All Saved!!!';'You can keep modfying or just close the window'},'You Are Amazing');
            
            % turn warnings back on
            warning('on');
            %TODO -- this is a matlab bug
            warning off MATLAB:subscripting:noSubscriptsSpecified;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.motionCsvToNodeStdMap = uifigure('Visible', 'off');
            app.motionCsvToNodeStdMap.Name = 'Motion CSV Data To Node Standard Mapping';
            app.tabg_group = uitabgroup(app.motionCsvToNodeStdMap);
            app.tab_loadData = uitab(app.tabg_group);
            app.tab_loadData.Title = 'Load Data';
            app.panel_loadData = uipanel(app.tab_loadData);
            app.label_loadFromFile = uilabel(app.panel_loadData);
            app.label_loadFromFile.Text = '';
            app.button_csvSelect = uibutton(app.panel_loadData, 'push');
            app.button_csvSelect.ButtonPushedFcn = createCallbackFcn(app, @button_csvSelectButtonPushed, true);
            app.button_csvSelect.Enable = 'off';
            app.button_csvSelect.Text = '<<';
            app.chkB_csvLock = uicheckbox(app.panel_loadData);
            app.chkB_csvLock.ValueChangedFcn = createCallbackFcn(app, @chkB_csvLockValueChanged, true);
            app.chkB_csvLock.Enable = 'off';
            app.chkB_csvLock.Text = 'Lock';
            app.button_nodeStandard = uibutton(app.panel_loadData, 'push');
            app.button_nodeStandard.ButtonPushedFcn = createCallbackFcn(app, @button_nodeStandardButtonPushed, true);
            app.button_nodeStandard.Enable = 'off';
            app.button_nodeStandard.Text = '<<';
            app.chkB_nodeStandardLock = uicheckbox(app.panel_loadData);
            app.chkB_nodeStandardLock.ValueChangedFcn = createCallbackFcn(app, @chkB_nodeStandardLockValueChanged, true);
            app.chkB_nodeStandardLock.Enable = 'off';
            app.chkB_nodeStandardLock.Text = 'Lock';
            app.label_filesAvail = uilabel(app.panel_loadData);
            app.label_filesAvail.Text = 'Displaying files in Trial selected plus /share and /res';
            app.listB_filesAvail = uilistbox(app.panel_loadData);
            app.listB_filesAvail.Items = {};
            app.listB_filesAvail.Value = {};
            app.label_csvFile = uilabel(app.panel_loadData);
            app.label_csvFile.Text = 'CSV File';
            app.editF_csvDataFile = uieditfield(app.panel_loadData, 'text');
            app.editF_csvDataFile.Editable = 'off';
            app.editF_csvDataFile.Enable = 'off';
            app.label_nodeStandard = uilabel(app.panel_loadData);
            app.label_nodeStandard.Text = 'Node Standard';
            app.editF_nodeStandardFile = uieditfield(app.panel_loadData, 'text');
            app.editF_nodeStandardFile.Editable = 'off';
            app.editF_nodeStandardFile.Enable = 'off';
            app.label_workingDir = uilabel(app.panel_loadData);
            app.label_workingDir.Text = '';
            app.label_step1 = uilabel(app.panel_loadData);
            app.label_step1.Text = '(1)';
            app.button_browseToTemplateFile = uibutton(app.panel_loadData, 'push');
            app.button_browseToTemplateFile.ButtonPushedFcn = createCallbackFcn(app, @button_browseToTemplateFileButtonPushed, true);
            app.button_browseToTemplateFile.Enable = 'off';
            app.button_browseToTemplateFile.Text = {'Load Template'; 'Populate Fields'};
            app.label_divider = uilabel(app.panel_loadData);
            app.label_divider.Text = '----------------------------------------------------------------------------------';
            app.label_existingFile = uilabel(app.panel_loadData);
            app.label_existingFile.Text = '';
            app.button_refreshWorkingDir = uibutton(app.panel_loadData, 'push');
            app.button_refreshWorkingDir.ButtonPushedFcn = createCallbackFcn(app, @button_refreshWorkingDirButtonPushed, true);
            app.button_refreshWorkingDir.Text = 'Refresh';
            app.chkB_lockTrial = uicheckbox(app.panel_loadData);
            app.chkB_lockTrial.ValueChangedFcn = createCallbackFcn(app, @chkB_lockTrialValueChanged, true);
            app.chkB_lockTrial.Text = 'Lock';
            app.label_step0 = uilabel(app.panel_loadData);
            app.label_step0.Text = '(0)';
            app.label_trialsAvail = uilabel(app.panel_loadData);
            app.label_trialsAvail.Text = 'Trials';
            app.listB_trialsAvail = uilistbox(app.panel_loadData);
            app.listB_trialsAvail.Items = {};
            app.listB_trialsAvail.ValueChangedFcn = createCallbackFcn(app, @listB_trialsAvailValueChanged, true);
            app.listB_trialsAvail.Value = {};
            app.button_mapTemplate = uibutton(app.panel_loadData, 'push');
            app.button_mapTemplate.ButtonPushedFcn = createCallbackFcn(app, @button_mapTemplateButtonPushed, true);
            app.button_mapTemplate.Enable = 'off';
            app.button_mapTemplate.Text = '<<';
            app.chkB_mapTemplate = uicheckbox(app.panel_loadData);
            app.chkB_mapTemplate.ValueChangedFcn = createCallbackFcn(app, @chkB_mapTemplateValueChanged, true);
            app.chkB_mapTemplate.Enable = 'off';
            app.chkB_mapTemplate.Text = 'Lock';
            app.label_mapTemplate = uilabel(app.panel_loadData);
            app.label_mapTemplate.Text = 'Template';
            app.editF_mapTemplate = uieditfield(app.panel_loadData, 'text');
            app.editF_mapTemplate.Editable = 'off';
            app.editF_mapTemplate.Enable = 'off';
            app.tab_identifyNodes = uitab(app.tabg_group);
            app.tab_identifyNodes.Title = 'Identify Nodes';
            app.panel_identifyNodes = uipanel(app.tab_identifyNodes);
            app.button_popNodeTable = uibutton(app.panel_identifyNodes, 'push');
            app.button_popNodeTable.ButtonPushedFcn = createCallbackFcn(app, @button_popNodeTableButtonPushed, true);
            app.button_popNodeTable.Text = 'Populate Table (2)';
            app.table_nodeAssign = uitable(app.panel_identifyNodes);
            app.table_nodeAssign.ColumnName = {'Col#'; 'CSV Headers'; 'Node Standard'};
            app.table_nodeAssign.RowName = {};
            app.table_nodeAssign.ColumnEditable = false;
            app.table_nodeAssign.CellSelectionCallback = createCallbackFcn(app, @table_nodeAssignCellSelection, true);
            app.table_csvHeaders = uitable(app.panel_identifyNodes);
            app.table_csvHeaders.ColumnName = {'CSV Headers'};
            app.table_csvHeaders.RowName = {};
            app.table_csvHeaders.ColumnEditable = false;
            app.table_csvHeaders.CellSelectionCallback = createCallbackFcn(app, @table_csvHeadersCellSelection, true);
            app.button_addSelection = uibutton(app.panel_identifyNodes, 'push');
            app.button_addSelection.ButtonPushedFcn = createCallbackFcn(app, @button_addSelectionButtonPushed, true);
            app.button_addSelection.Enable = 'off';
            app.button_addSelection.Text = 'Add Selection >>';
            app.button_deleteMappedValue = uibutton(app.panel_identifyNodes, 'push');
            app.button_deleteMappedValue.ButtonPushedFcn = createCallbackFcn(app, @button_deleteMappedValueButtonPushed, true);
            app.button_deleteMappedValue.Enable = 'off';
            app.button_deleteMappedValue.Text = 'Delete Mapped Value';
            app.button_computeMapMatrix = uibutton(app.panel_identifyNodes, 'push');
            app.button_computeMapMatrix.ButtonPushedFcn = createCallbackFcn(app, @button_computeMapMatrixPushed, true);
            app.button_computeMapMatrix.Enable = 'off';
            app.button_computeMapMatrix.Text = 'Compute Map Matrix (4)';
            app.button_insertZero = uibutton(app.panel_identifyNodes, 'push');
            app.button_insertZero.ButtonPushedFcn = createCallbackFcn(app, @button_insertZeroPushed, true);
            app.button_insertZero.Enable = 'off';
            app.button_insertZero.Text = 'Insert Zero >>';
            app.label_step3 = uilabel(app.panel_identifyNodes);
            app.label_step3.Text = '(3)';
            app.button_referencePts = uibutton(app.panel_identifyNodes, 'push');
            app.button_referencePts.ButtonPushedFcn = createCallbackFcn(app, @button_referencePtsButtonPushed, true);
            app.button_referencePts.Enable = 'off';
            app.button_referencePts.Text = 'Reference Point';
            app.table_referencePts = uitable(app.panel_identifyNodes);
            app.table_referencePts.ColumnName = {'idx'; 'RefPts'};
            app.table_referencePts.RowName = {};
            app.tab_refPts = uitab(app.tabg_group);
            app.tab_refPts.Title = 'Reference Points';
            app.panel_refPts = uipanel(app.tab_refPts);
            app.table_refPtsToMap = uitable(app.panel_refPts);
            app.table_refPtsToMap.ColumnName = {'Point'; 'New Name'; 'Done'};
            app.table_refPtsToMap.RowName = {};
            app.table_refPtsToMap.CellSelectionCallback = createCallbackFcn(app, @table_refPtsToMapCellSelection, true);
            app.table_refPtsData = uitable(app.panel_refPts);
            app.table_refPtsData.ColumnName = {'idx'; 'X'; 'Y'; 'Z'};
            app.table_refPtsData.RowName = {};
            app.table_refPtsData.CellSelectionCallback = createCallbackFcn(app, @table_refPtsDataCellSelection, true);
            app.button_popRefPts = uibutton(app.panel_refPts, 'push');
            app.button_popRefPts.ButtonPushedFcn = createCallbackFcn(app, @button_popRefPtsButtonPushed, true);
            app.button_popRefPts.Text = 'Populate';
            app.button_selectRefPts = uibutton(app.panel_refPts, 'push');
            app.button_selectRefPts.ButtonPushedFcn = createCallbackFcn(app, @button_selectRefPtsButtonPushed, true);
            app.button_selectRefPts.Enable = 'off';
            app.button_selectRefPts.Text = 'Select This as Data';
            app.label_worldOrigin = uilabel(app.panel_refPts);
            app.label_worldOrigin.Text = 'Select the world Origin';
            app.dropD_worldOrigin = uidropdown(app.panel_refPts);
            app.dropD_worldOrigin.Items = {};
            app.dropD_worldOrigin.ValueChangedFcn = createCallbackFcn(app, @dropD_worldOriginValueChanged, true);
            app.dropD_worldOrigin.Value = {};
            app.tab_trimData = uitab(app.tabg_group);
            app.tab_trimData.Title = 'Trim Data';
            app.panel_trimData = uipanel(app.tab_trimData);
            app.table_trimData = uitable(app.panel_trimData);
            app.table_trimData.ColumnName = {'Node'; '# Head NaN''s'; '# Tail NaN''s'};
            app.table_trimData.RowName = {};
            app.label_headNan = uilabel(app.panel_trimData);
            app.label_headNan.Text = 'HEAD';
            app.editF_headNan = uieditfield(app.panel_trimData, 'numeric');
            app.editF_headNan.ValueDisplayFormat = '%.0f';
            app.editF_headNan.Editable = 'off';
            app.label_tailNan = uilabel(app.panel_trimData);
            app.label_tailNan.Text = 'TAIL';
            app.editF_tailNan = uieditfield(app.panel_trimData, 'numeric');
            app.editF_tailNan.ValueDisplayFormat = '%.0f';
            app.editF_tailNan.Editable = 'off';
            app.spinner_headTrim = uispinner(app.panel_trimData);
            app.spinner_headTrim.ValueDisplayFormat = '%.0f';
            app.label_samplesLeft = uilabel(app.panel_trimData);
            app.label_samplesLeft.Text = 'Samples Left';
            app.editF_samplesLeft = uieditfield(app.panel_trimData, 'numeric');
            app.editF_samplesLeft.ValueDisplayFormat = '%.0f';
            app.editF_samplesLeft.Editable = 'off';
            app.spinner_tailTrim = uispinner(app.panel_trimData);
            app.spinner_tailTrim.ValueDisplayFormat = '%.0f';
            app.label_percentLeft = uilabel(app.panel_trimData);
            app.label_percentLeft.Text = 'Percent Left';
            app.editF_percentLeft = uieditfield(app.panel_trimData, 'numeric');
            app.editF_percentLeft.Editable = 'off';
            app.label_nonNanRow = uilabel(app.panel_trimData);
            app.label_nonNanRow.Text = 'non-NaN Row';
            app.label_trimExtra = uilabel(app.panel_trimData);
            app.label_trimExtra.Text = 'Trim An Extra # Samples';
            app.button_populateTrimNan = uibutton(app.panel_trimData, 'push');
            app.button_populateTrimNan.ButtonPushedFcn = createCallbackFcn(app, @button_populateTrimNanButtonPushed, true);
            app.button_populateTrimNan.Text = 'Populate All (5)';
            app.button_trimData = uibutton(app.panel_trimData, 'push');
            app.button_trimData.ButtonPushedFcn = createCallbackFcn(app, @button_trimDataButtonPushed, true);
            app.button_trimData.Enable = 'off';
            app.button_trimData.Text = 'Trim Data (6)';
            app.label_numbRows = uilabel(app.panel_trimData);
            app.label_numbRows.Text = '# Rows Raw Data';
            app.editF_numbRows = uieditfield(app.panel_trimData, 'numeric');
            app.editF_numbRows.ValueDisplayFormat = '%.0f';
            app.editF_numbRows.Editable = 'off';
            app.label_divider1 = uilabel(app.panel_trimData);
            app.label_divider1.Text = '----------------------------------------------------------------------------------------------------------------------------------------------------------';
            app.label_rawData = uilabel(app.panel_trimData);
            app.label_rawData.Text = 'RAW DATA';
            app.label_trimmedCopiedData = uilabel(app.panel_trimData);
            app.label_trimmedCopiedData.Text = 'TRIMMED COPIED DATA';
            app.tab_refineData = uitab(app.tabg_group);
            app.tab_refineData.Title = 'Refine Data';
            app.panel_refineData = uipanel(app.tab_refineData);
            app.table_fillGaps = uitable(app.panel_refineData);
            app.table_fillGaps.ColumnName = {'Node'; '# Gaps'; 'Mean Gap'; 'StdDev Gap'; 'Max Gap'};
            app.table_fillGaps.RowName = {};
            app.table_fillGaps.CellSelectionCallback = createCallbackFcn(app, @table_fillGapsCellSelection, true);
            app.button_plotSelection = uibutton(app.panel_refineData, 'push');
            app.button_plotSelection.ButtonPushedFcn = createCallbackFcn(app, @button_plotSelectionButtonPushed, true);
            app.button_plotSelection.Enable = 'off';
            app.button_plotSelection.Text = 'Plot Selection';
            app.button_populateNodeGap = uibutton(app.panel_refineData, 'push');
            app.button_populateNodeGap.ButtonPushedFcn = createCallbackFcn(app, @button_populateNodeGapButtonPushed, true);
            app.button_populateNodeGap.Text = 'Populate Data (7)';
            app.button_closeGaps = uibutton(app.panel_refineData, 'push');
            app.button_closeGaps.ButtonPushedFcn = createCallbackFcn(app, @button_closeGapsButtonPushed, true);
            app.button_closeGaps.Enable = 'off';
            app.button_closeGaps.Text = 'Close Gaps (9)';
            app.label_polynomialDegree = uilabel(app.panel_refineData);
            app.label_polynomialDegree.Text = 'Polynomial Degree';
            app.knob_polynomialDegree = uiknob(app.panel_refineData, 'discrete');
            app.knob_polynomialDegree.Items = {'1', '2', '3', '4', '5'};
            app.knob_polynomialDegree.ItemsData = {'1', '2', '3', '4', '5'};
            app.knob_polynomialDegree.Value = '3';
            app.button_unitConversion = uibutton(app.panel_refineData, 'push');
            app.button_unitConversion.ButtonPushedFcn = createCallbackFcn(app, @button_unitConversionButtonPushed, true);
            app.button_unitConversion.Enable = 'off';
            app.button_unitConversion.Text = 'Unit Conversion (8)';
            app.label_convertFrom = uilabel(app.panel_refineData);
            app.label_convertFrom.Text = 'Data in:';
            app.dropD_convertFrom = uidropdown(app.panel_refineData);
            app.dropD_convertFrom.Items = {'meters', 'centimeters', 'milimiters', 'micrometers', 'inches', 'feet', 'custom'};
            app.dropD_convertFrom.ItemsData = {'m', 'cm', 'mm', 'um', 'in', 'ft', 'xxx'};
            app.dropD_convertFrom.ValueChangedFcn = createCallbackFcn(app, @dropD_convertFromValueChanged, true);
            app.dropD_convertFrom.Value = 'm';
            app.label_convertTo = uilabel(app.panel_refineData);
            app.label_convertTo.Text = 'Convert to:';
            app.dropD_convertTo = uidropdown(app.panel_refineData);
            app.dropD_convertTo.Items = {'meters', 'centimeters', 'milimiters', 'micrometers', 'inches', 'feet', 'custom'};
            app.dropD_convertTo.ItemsData = {'m', 'cm', 'mm', 'um', 'in', 'ft', 'xxx'};
            app.dropD_convertTo.ValueChangedFcn = createCallbackFcn(app, @dropD_convertToValueChanged, true);
            app.dropD_convertTo.Value = 'm';
            app.button_displaySummary = uibutton(app.panel_refineData, 'push');
            app.button_displaySummary.ButtonPushedFcn = createCallbackFcn(app, @button_displaySummaryButtonPushed, true);
            app.button_displaySummary.Enable = 'off';
            app.button_displaySummary.Text = 'Display Table Summary (col 1:5)';
            app.label_customConvInstr = uilabel(app.panel_refineData);
            app.label_customConvInstr.Visible = 'off';
            app.label_customConvInstr.Text = '- how many of ''Data in'' units make 1 of ''Convert to''?';
            app.label_customUnitInstr = uilabel(app.panel_refineData);
            app.label_customUnitInstr.Visible = 'off';
            app.label_customUnitInstr.Text = '- enter a char that defines this unit (eg ''pixel'', ''ang'')';
            app.editF_customDataIn = uieditfield(app.panel_refineData, 'numeric');
            app.editF_customDataIn.Visible = 'off';
            app.editF_customConvertTo = uieditfield(app.panel_refineData, 'text');
            app.editF_customConvertTo.Visible = 'off';
            app.label_convertInstr = uilabel(app.panel_refineData);
            app.label_convertInstr.Text = {'Data is by def. saved unitless'; 'make a cnverssin (even if it is a 1:1)'; 'to assign a unit.'};
            app.CurrentUntsEditFieldLabel = uilabel(app.panel_refineData);
            app.CurrentUntsEditFieldLabel.Text = 'Current Unts';
            app.editF_currentUnits = uieditfield(app.panel_refineData, 'text');
            app.editF_currentUnits.Editable = 'off';
            app.tab_saveAll = uitab(app.tabg_group);
            app.tab_saveAll.Title = 'Save All';
            app.panel_saveAll = uipanel(app.tab_saveAll);
            app.button_saveAllAndExit = uibutton(app.panel_saveAll, 'push');
            app.button_saveAllAndExit.ButtonPushedFcn = createCallbackFcn(app, @button_saveAllAndExitButtonPushed, true);
            app.button_saveAllAndExit.Text = 'SAVE ALL AND EXIT!!!!!!!';
            app.label_savedAs = uilabel(app.panel_saveAll);
            app.label_savedAs.Text = '';
            app.button_saveTemplate = uibutton(app.panel_saveAll, 'push');
            app.button_saveTemplate.ButtonPushedFcn = createCallbackFcn(app, @button_saveTemplateButtonPushed, true);
            app.button_saveTemplate.Text = 'Save as a Template in /share';
            app.label_saveTemplateName = uilabel(app.panel_saveAll);
            app.label_saveTemplateName.Text = '';
            app.button_copyToWorkspace = uibutton(app.panel_saveAll, 'push');
            app.button_copyToWorkspace.ButtonPushedFcn = createCallbackFcn(app, @button_copyToWorkspaceButtonPushed, true);
            app.button_copyToWorkspace.Text = 'Copy results to workspace';
            app.label_copyToWorkspace = uilabel(app.panel_saveAll);
            app.label_copyToWorkspace.Text = 'Var Name';
            app.editF_copyToWorkspace = uieditfield(app.panel_saveAll, 'text');
            app.button_prev = uibutton(app.motionCsvToNodeStdMap, 'push');
            app.button_prev.ButtonPushedFcn = createCallbackFcn(app, @button_prevButtonPushed, true);
            app.button_prev.Enable = 'off';
            app.button_prev.Text = '<<Prev';
            app.button_next = uibutton(app.motionCsvToNodeStdMap, 'push');
            app.button_next.ButtonPushedFcn = createCallbackFcn(app, @button_nextButtonPushed, true);
            app.button_next.Enable = 'off';
            app.button_next.Text = 'Next>>';
            app.label_errorMsg = uilabel(app.motionCsvToNodeStdMap);
            app.label_errorMsg.Text = '';
            % Show the figure after all components are created
            app.motionCsvToNodeStdMap.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = motionCsvToNodeStdMapGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.motionCsvToNodeStdMap)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.motionCsvToNodeStdMap)
        end
    end
end