classdef renameVesperFilesGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        renameVesperFiles    matlab.ui.Figure
        editF_vesperDir      matlab.ui.control.EditField
        chkB_vesperDirLock   matlab.ui.control.CheckBox
        label_expDate        matlab.ui.control.Label
        dateP_expDate        matlab.ui.control.DatePicker
        label_hour           matlab.ui.control.Label
        spinner_hour         matlab.ui.control.Spinner
        label_minute         matlab.ui.control.Label
        spinner_minute       matlab.ui.control.Spinner
        listB_filesAvail     matlab.ui.control.ListBox
        button_refreshFiles  matlab.ui.control.Button
        listB_newFiles       matlab.ui.control.ListBox
        button_changeTime    matlab.ui.control.Button
        button_renameFiles   matlab.ui.control.Button
        label_seconds        matlab.ui.control.Label
        spinner_seconds      matlab.ui.control.Spinner
        label_miliseconds    matlab.ui.control.Label
        editF_miliseconds    matlab.ui.control.NumericEditField
        button_selectDir     matlab.ui.control.Button
        label_errorMsg       matlab.ui.control.Label
        label_dateNote       matlab.ui.control.Label
        chkB_timeLock        matlab.ui.control.CheckBox
    end

%-----------------------------------------------------------------------------------%
% Original code:                                                                    %
%               Ellen Yan 2019                                                      %
%                                                                                   %
%----------------------------- CONSTANT PROPERTIES ---------------------------------%  

    properties (Access = private, Constant = true)

    end
    
    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'renameVesperFilesGui';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
        
        % important/ relevant output from this app
        savedVarName = 'renameVesperFiles';
        
        % the full app, required to populate this app with a previous session
        % this contains ALL components, even those stored in savedVarName
        savedAppName = 'renameVesperFilesApp';
        
        % relevant variables to save in savedVarName
        % savedAppName saves this plus everything else too
        %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
        varsToSaveinStd =  {'appVersion'};
    end

%---------------------------- VARIABLE PROPERTIES --------------------------------%
    properties (Access = private)
        % this variable stores the working directory
        workingDir  = ''; 

        %values subject to change and needed in calculation
        vesperDate;
        hour;
        minute;
        second;
        millisecond;
        
        %main table
        vesperFiles = table();
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%  
    methods (Access = private)
        
        function closeApp(app,src,event)
            delete(app);
        end%closeApp
%-----------------------------------------------------------------------------------%
        
        function dispMsg(app, ttype, tmsg)
            % For log messaging blue
            if(strcmpi(ttype, 'L'))
                app.label_errorMsg.Text = ['LOG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.45,0.74];
                
            % for error messages red
            elseif(strcmpi(ttype, 'E'))
                app.label_errorMsg.Text = ['ERROR: ' tmsg];
                app.label_errorMsg.FontColor = [0.64,0.08,0.18];
                
            % general propose message black
            elseif(strcmpi(ttype, 'M'))
                app.label_errorMsg.Text = ['MSG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.00,0.00];
            
            % for clearing the mesage
            elseif(strcmpi(ttype, 'C'))
                app.label_errorMsg.Text = '';
            end
        end%dispMsg
%-----------------------------------------------------------------------------------%
    end%private methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %prevent default value 0 and empty vector case
            app.hour        = 0;
            app.minute      = 0;
            app.second      = 0;
            app.millisecond = 0;

        end

        % Button pushed function: button_selectDir
        function button_selectDirButtonPushed(app, event)
            %get working directory
            [tpath] = uigetdir(getenv('HOMEPATH'),'Select directory where the Study will be created');
            
            %make sure its valid
            if(~isnumeric(tpath))
                app.workingDir = tpath;
                app.editF_vesperDir.Value = tpath;
                
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'You must select a valid directory');
            end
        end

        % Value changed function: chkB_vesperDirLock
        function chkB_vesperDirLockValueChanged(app, event)
            value = app.chkB_vesperDirLock.Value;
            % for some reason the var value needs to exsist, Abstract defined?
 
            % check and uncheck the box enables/disables the button to asign new values
            if(value == 1)
                app.button_selectDir.Enable      = 'off';
                app.button_refreshFiles.Enable = 'on';
                app.chkB_timeLock.Enable       = 'on';
                chkB_timeLockValueChanged(app);
            else
                app.button_selectDir.Enable      = 'on';
                app.button_refreshFiles.Enable = 'off';
                app.chkB_timeLock.Enable       = 'off';
            end
        end

        % Value changed function: chkB_timeLock
        function chkB_timeLockValueChanged(app, event)
            value = app.chkB_timeLock.Value;
            
            % check and uncheck the box enables/disables the button to asign new values
            if(value == 1)
                app.dateP_expDate.Enable     = 'off';
                app.spinner_hour.Enable      = 'off';
                app.spinner_minute.Enable    = 'off';
                app.spinner_seconds.Enable   = 'off';
                app.editF_miliseconds.Enable = 'off';
                app.button_refreshFiles.Enable = 'on';
                
                %assign values
                app.vesperDate  = app.dateP_expDate.Value;
                app.hour        = app.spinner_hour.Value;
                app.minute      = app.spinner_minute.Value;
                app.second      = app.spinner_seconds.Value;
                app.millisecond = app.editF_miliseconds.Value;
            else
                app.dateP_expDate.Enable     = 'on';
                app.spinner_hour.Enable      = 'on';
                app.spinner_minute.Enable    = 'on';
                app.spinner_seconds.Enable   = 'on';
                app.editF_miliseconds.Enable = 'on';
                app.button_refreshFiles.Enable = 'off';
            end
        end

        % Button pushed function: button_refreshFiles
        function button_refreshFilesButtonPushed(app, event)
            tvesperfiles = cellstr(ls(app.workingDir));
            tvesperfiles = tvesperfiles(3:end);
            tvesperfiles = sort(tvesperfiles);
            
            tdate   = duration(zeros(length(tvesperfiles), 3));
            tstop   = duration(zeros(length(tvesperfiles), 3));
            
            %retrieve the date, start and stop times          
            for i = 1: length(tvesperfiles)
                tmp = tvesperfiles{i};
                tidx = strfind(tmp, '_')';
                
                tyy = str2num(['20' tmp(2:tidx(1)-1)]);
                tmm = str2num(tmp(tidx(1)+1:tidx(2)-1));
                tdd = str2num(tmp(tidx(2)+1:tidx(3)-1));
                
                thhdt = str2num(tmp(tidx(3)+1:tidx(4)-1));
                tmidt = str2num(tmp(tidx(4)+1:tidx(5)-1));
                tssdt = floor(str2num(tmp(tidx(5)+1:tidx(6)-1)));
                tmsdt = ((str2num(tmp(tidx(5)+1:tidx(6)-1)) - tssdt)*1000);
               
                thhst = str2num(tmp(tidx(9)+1:tidx(10)-1));
                tmist = str2num(tmp(tidx(10)+1:tidx(11)-1));
                tssst = floor(str2num(tmp(tidx(11)+1:tidx(12)-1)));
                tmsst = ((str2num(tmp(tidx(11)+1:tidx(12)-1)) - tssst)*1000);
                
                tdate(i) = duration(thhdt, tmidt, tssdt, tmsdt);
                tstop(i) = duration(thhst, tmist, tssst, tmsst);
            end
            
            %make a table with files
            app.vesperFiles.files  = tvesperfiles;
            app.vesperFiles.startT = tdate;
            app.vesperFiles.stopT  = tstop;
    
            %populate the availiable trials
            app.listB_filesAvail.Items = tvesperfiles;
            
            %clear the computed names if exist
            if(ismember('newFiles', app.vesperFiles.Properties.VariableNames))
                app.vesperFiles.newFiles  = [];
                app.listB_newFiles.Items = {''};
            end
            
            app.button_refreshFiles.Enable = 'off';
            app.chkB_timeLock.Enable       = 'off';
            app.button_changeTime.Enable   = 'on';
        end

        % Button pushed function: button_changeTime
        function button_changeTimePushed(app, event)
            %first get the differences in start time
            tstartdiff = [0; diff(app.vesperFiles.startT)];
            tduration  = app.vesperFiles.stopT - app.vesperFiles.startT;
            
            ttypes  = cellfun(@(tvar) tvar(1),app.vesperFiles.files,'UniformOutput',false);
            temgidx = contains(ttypes,'e','IgnoreCase',true);
            tmicidx = contains(ttypes,'u','IgnoreCase',true);
            
            %now compute the new times
            tinittime = duration(app.hour, app.minute, app.second, app.millisecond);
            
            %do a separate init for emg and mic files
            tnewstart = duration(zeros(size(app.vesperFiles,1), 3));
            tnewstart(temgidx) = app.vesperFiles.startT(temgidx) - app.vesperFiles.startT(find(temgidx,1)) + tinittime;
            tnewstart(tmicidx) = app.vesperFiles.startT(tmicidx) - app.vesperFiles.startT(find(tmicidx,1)) + tinittime;
            tnewstop  = tnewstart + tduration;
            assignin('base', 'ves',app.vesperFiles);
            
            %copy the names as-is first
            app.vesperFiles.newFiles = app.vesperFiles.files;
            
            %for each entry, change the time
            %retrieve the date, start and stop times          
            for(i = 1: size(app.vesperFiles,1))
                tmp = app.vesperFiles.newFiles{i};
                tidx = strfind(tmp, '_')';
                
                %get the individual numers and make into char
                thhdt = num2str(floor(hours(tnewstart(i))));
                tmidt = num2str(floor(minutes(tnewstart(i))-floor(hours(tnewstart(i)))*60));
                tssdt = num2str(floor(seconds(tnewstart(i))-floor(minutes(tnewstart(i)))*60));
                tmsdt = num2str(floor(milliseconds(tnewstart(i))-floor(seconds(tnewstart(i)))*1000));
               
                thhst = num2str(floor(hours(tnewstop(i))));
                tmist = num2str(floor(minutes(tnewstop(i))-floor(hours(tnewstop(i)))*60));
                tssst = num2str(floor(seconds(tnewstop(i))-floor(minutes(tnewstop(i)))*60));
                tmsst = num2str(floor(milliseconds(tnewstop(i))-floor(seconds(tnewstop(i)))*1000));
                
                %concatenate zeros
                if length(thhdt)==1  thhdt = ['0',thhdt];  end
                if length(tmidt)==1  tmidt = ['0',tmidt];  end
                if length(tssdt)==1  tssdt = ['0',tssdt];  end
                if length(tmsdt)==1  tmsdt = ['00',tmsdt]; elseif length(tmsdt)==2 tmsdt = ['0',tmsdt]; end
                if length(thhst)==1  thhst = ['0',thhst];  end
                if length(tmist)==1  tmist = ['0',tmist];  end
                if length(tssst)==1  tssst = ['0',tssst];  end
                if length(tmsst)==1  tmsst = ['00',tmsst]; elseif length(tmsst)==2 tmsst = ['0',tmsst]; end
                
                %do the same for date
                tyy = num2str(app.dateP_expDate.Value.Year);
                tmm = num2str(app.dateP_expDate.Value.Month);
                tdd = num2str(app.dateP_expDate.Value.Day);
                
                %concatenate zeros
                tyy = tyy(3:4);
                if length(tmm)==1  tmm = ['0',tmm]; end
                if length(tdd)==1  tdd = ['0',tdd]; end
                
                %replace the start and end times, vesper does not append 0 on seconds, forcing it
                tmp(tidx(3)+1:tidx(4)-1) = thhdt;
                tmp(tidx(4)+1:tidx(5)-1) = tmidt;
                tmp = [tmp(1:tidx(5)), tssdt, '.', tmsdt, tmp(tidx(6):end)];
                tidx = strfind(tmp, '_')';
                
                tmp(tidx(9)+1:tidx(10)-1)  = thhst;
                tmp(tidx(10)+1:tidx(11)-1) = tmist;
                tmp = [tmp(1:tidx(11)), tssst, '.', tmsst, tmp(tidx(12):end)];
                tidx = strfind(tmp, '_')';
                
                %replace the date for start and end
                tmp(2:tidx(1)-1) = tyy;
                tmp(tidx(7)-2:tidx(7)-1) = tyy;
                tmp(tidx(1)+1:tidx(2)-1) = tmm;
                tmp(tidx(7)+1:tidx(8)-1) = tmm;
                tmp(tidx(2)+1:tidx(3)-1) = tdd;
                tmp(tidx(8)+1:tidx(9)-1) = tdd;
                
                app.vesperFiles.newFiles{i} = tmp;
            end
            
            %display the new names in the new listbox listB_results
            app.listB_newFiles.Items = app.vesperFiles.newFiles;
            %turn saving button on
            app.button_changeTime.Enable  = 'off';
            app.button_renameFiles.Enable = 'on';
        end

        % Button pushed function: button_renameFiles
        function button_renameFilesPushed(app, event)
            %rename files in new location then close
            for(i = 1: size(app.vesperFiles,1))
                movefile(fullfile(app.workingDir,app.vesperFiles.files{i}),fullfile(app.workingDir,app.vesperFiles.newFiles{i}));%rename in the old folder 
            end
            
            %notify and exit
            uiconfirm(app.renameVesperFiles,{'Vesper files renamed in: ';fullfile(app.workingDir);' '; 'App will close now =)'},'Confirm Close','Options',{'OK'},'CloseFcn',@(src,event)closeApp(app,src,event));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.renameVesperFiles = uifigure('Visible', 'off');
            app.renameVesperFiles.Name = 'UI Figure';
            app.editF_vesperDir = uieditfield(app.renameVesperFiles, 'text');
            app.editF_vesperDir.Editable = 'off';
            app.chkB_vesperDirLock = uicheckbox(app.renameVesperFiles);
            app.chkB_vesperDirLock.ValueChangedFcn = createCallbackFcn(app, @chkB_vesperDirLockValueChanged, true);
            app.chkB_vesperDirLock.Text = 'Lock';
            app.label_expDate = uilabel(app.renameVesperFiles);
            app.label_expDate.Text = 'Date';
            app.dateP_expDate = uidatepicker(app.renameVesperFiles);
            app.dateP_expDate.Enable = 'off';
            app.label_hour = uilabel(app.renameVesperFiles);
            app.label_hour.Text = 'Hour';
            app.spinner_hour = uispinner(app.renameVesperFiles);
            app.spinner_hour.Limits = [0 23];
            app.spinner_hour.Enable = 'off';
            app.label_minute = uilabel(app.renameVesperFiles);
            app.label_minute.Text = 'Minute';
            app.spinner_minute = uispinner(app.renameVesperFiles);
            app.spinner_minute.Limits = [0 59];
            app.spinner_minute.Enable = 'off';
            app.listB_filesAvail = uilistbox(app.renameVesperFiles);
            app.listB_filesAvail.Items = {};
            app.listB_filesAvail.Value = {};
            app.button_refreshFiles = uibutton(app.renameVesperFiles, 'push');
            app.button_refreshFiles.ButtonPushedFcn = createCallbackFcn(app, @button_refreshFilesButtonPushed, true);
            app.button_refreshFiles.Enable = 'off';
            app.button_refreshFiles.Text = 'Refresh vesper files';
            app.listB_newFiles = uilistbox(app.renameVesperFiles);
            app.listB_newFiles.Items = {};
            app.listB_newFiles.Value = {};
            app.button_changeTime = uibutton(app.renameVesperFiles, 'push');
            app.button_changeTime.ButtonPushedFcn = createCallbackFcn(app, @button_changeTimePushed, true);
            app.button_changeTime.Enable = 'off';
            app.button_changeTime.Text = 'Change time';
            app.button_renameFiles = uibutton(app.renameVesperFiles, 'push');
            app.button_renameFiles.ButtonPushedFcn = createCallbackFcn(app, @button_renameFilesPushed, true);
            app.button_renameFiles.Enable = 'off';
            app.button_renameFiles.Text = 'Rename files';
            app.label_seconds = uilabel(app.renameVesperFiles);
            app.label_seconds.Text = 'Sec';
            app.spinner_seconds = uispinner(app.renameVesperFiles);
            app.spinner_seconds.Limits = [0 59];
            app.spinner_seconds.Enable = 'off';
            app.label_miliseconds = uilabel(app.renameVesperFiles);
            app.label_miliseconds.Text = 'Milisec';
            app.editF_miliseconds = uieditfield(app.renameVesperFiles, 'numeric');
            app.editF_miliseconds.Limits = [0 999];
            app.editF_miliseconds.Enable = 'off';
            app.button_selectDir = uibutton(app.renameVesperFiles, 'push');
            app.button_selectDir.ButtonPushedFcn = createCallbackFcn(app, @button_selectDirButtonPushed, true);
            app.button_selectDir.Text = 'Select directory';
            app.label_errorMsg = uilabel(app.renameVesperFiles);
            app.label_errorMsg.Text = '';
            app.label_dateNote = uilabel(app.renameVesperFiles);
            app.label_dateNote.Text = {'Select the date and time of the first file -- when you gave the magnet signal. Vesper starts at 00:00hrs when removed from dock or after boot. '; 'Make sure this directory only contains vesper files'};
            app.chkB_timeLock = uicheckbox(app.renameVesperFiles);
            app.chkB_timeLock.ValueChangedFcn = createCallbackFcn(app, @chkB_timeLockValueChanged, true);
            app.chkB_timeLock.Text = 'Lock';
            % Show the figure after all components are created
            app.renameVesperFiles.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = renameVesperFilesGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.renameVesperFiles)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.renameVesperFiles)
        end
    end
end