classdef vesperDataImportGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        vesperDataImport              matlab.ui.Figure
        tabg_group                    matlab.ui.container.TabGroup
        tab_loadData                  matlab.ui.container.Tab
        panel_loadData                matlab.ui.container.Panel
        button_vesperDirSelect        matlab.ui.control.Button
        chkB_vesperDirLock            matlab.ui.control.CheckBox
        DisplayingfilesinglobalLabel  matlab.ui.control.Label
        listB_dirsAvail               matlab.ui.control.ListBox
        label_vesperDir               matlab.ui.control.Label
        editF_vesperDir               matlab.ui.control.EditField
        label_step1                   matlab.ui.control.Label
        button_refreshWorkingDir      matlab.ui.control.Button
        label_step0                   matlab.ui.control.Label
        label_cameraObj               matlab.ui.control.Label
        dropD_cameraObj               matlab.ui.control.DropDown
        label_emgFreq                 matlab.ui.control.Label
        dropD_emgFreq                 matlab.ui.control.DropDown
        label_micFreq                 matlab.ui.control.Label
        editF_micFreq                 matlab.ui.control.NumericEditField
        label_emgGain                 matlab.ui.control.Label
        dropD_emgGain                 matlab.ui.control.DropDown
        label_workingDir              matlab.ui.control.Label
        chkB_cameraObjLock            matlab.ui.control.CheckBox
        chkB_emgGainLock              matlab.ui.control.CheckBox
        chkB_emgFreqLock              matlab.ui.control.CheckBox
        chkB_micFreqLock              matlab.ui.control.CheckBox
        button_roi                    matlab.ui.control.Button
        uia_videoRoi                  matlab.ui.control.UIAxes
        tab_pairFiles                 matlab.ui.container.Tab
        panel_pairFiles               matlab.ui.container.Panel
        table_emgTimes                matlab.ui.control.Table
        table_micTimes                matlab.ui.control.Table
        table_vidTimes                matlab.ui.control.Table
        button_popPairTable           matlab.ui.control.Button
        button_pairData               matlab.ui.control.Button
        button_extractData            matlab.ui.control.Button
        label_step3                   matlab.ui.control.Label
        button_plotMic                matlab.ui.control.Button
        button_plotEmg                matlab.ui.control.Button
        tab_videoSync                 matlab.ui.container.Tab
        panel_videoSync               matlab.ui.container.Panel
        table_trialsMapped            matlab.ui.control.Table
        button_popTrials              matlab.ui.control.Button
        button_genWave                matlab.ui.control.Button
        uiA_syncSignal                matlab.ui.control.UIAxes
        label_vesperTimeMs            matlab.ui.control.Label
        editF_vesperTimeMs            matlab.ui.control.NumericEditField
        label_vesperTime              matlab.ui.control.Label
        slider_vesperTime             matlab.ui.control.Slider
        chkB_lockTrial                matlab.ui.control.CheckBox
        button_resetAxes              matlab.ui.control.Button
        label_divide                  matlab.ui.control.Label
        label_audioTime               matlab.ui.control.Label
        editF_audioTime               matlab.ui.control.NumericEditField
        label_audioSpeed              matlab.ui.control.Label
        spinner_audioSpeed            matlab.ui.control.Spinner
        button_playAudio              matlab.ui.control.Button
        button_stopAudio              matlab.ui.control.Button
        button_pauseAudio             matlab.ui.control.StateButton
        button_bandFilter             matlab.ui.control.Button
        editF_trimFreq                matlab.ui.control.NumericEditField
        label_zeroReset               matlab.ui.control.Label
        label_gustTime                matlab.ui.control.Label
        editF_gustTime                matlab.ui.control.NumericEditField
        label_gustDur                 matlab.ui.control.Label
        editF_gustDur                 matlab.ui.control.NumericEditField
        button_trimToVideo            matlab.ui.control.Button
        button_doneSync               matlab.ui.control.Button
        tab_emgSync                   matlab.ui.container.Tab
        panel_emgSync                 matlab.ui.container.Panel
        table_trialsMapped2           matlab.ui.control.Table
        button_popTrials2             matlab.ui.control.Button
        button_genWave2               matlab.ui.control.Button
        uiA_syncSignal2               matlab.ui.control.UIAxes
        button_trimToVideo2           matlab.ui.control.Button
        button_doneSync2              matlab.ui.control.Button
        label_vesperTimeMs2           matlab.ui.control.Label
        editF_vesperTimeMs2           matlab.ui.control.NumericEditField
        label_vesperTime2             matlab.ui.control.Label
        slider_vesperTime2            matlab.ui.control.Slider
        chkB_lockTrial2               matlab.ui.control.CheckBox
        button_resetAxes2             matlab.ui.control.Button
        tab_filterData                matlab.ui.container.Tab
        panel_filterData              matlab.ui.container.Panel
        table_trialsMapped3           matlab.ui.control.Table
        uiA_syncSignal3               matlab.ui.control.UIAxes
        button_popTrials3             matlab.ui.control.Button
        button_genWave3               matlab.ui.control.Button
        chkB_lockTrial3               matlab.ui.control.CheckBox
        chkB_plotBoth                 matlab.ui.control.CheckBox
        chkB_invertCh2                matlab.ui.control.CheckBox
        chkB_absolute                 matlab.ui.control.CheckBox
        chkB_scaleMv                  matlab.ui.control.CheckBox
        button_applyFilter            matlab.ui.control.Button
        button_finishTrial            matlab.ui.control.Button
        label_lowPass                 matlab.ui.control.Label
        editF_lowPass                 matlab.ui.control.NumericEditField
        label_highPass                matlab.ui.control.Label
        editF_highPass                matlab.ui.control.NumericEditField
        label_noFilter                matlab.ui.control.Label
        tab_saveAll                   matlab.ui.container.Tab
        panel_saveAll                 matlab.ui.container.Panel
        button_saveAllAndExit         matlab.ui.control.Button
        button_prev                   matlab.ui.control.Button
        button_next                   matlab.ui.control.Button
        label_errorMsg                matlab.ui.control.Label
    end


%---------------------------- CONSTANT PROPERTIES ---------------------------------%    
    
    properties (Access = private, Constant = true)
        % variables that control app's visibility workflow function
        isTabChar = 'tab_';
        
        % number of initial steps, pair with startedFlag non constant property
        % change this according to the app's workflow before it unlocks the buton next
        numInitialSteps = 5;
        
        %name indicating no camera object is selected
        nullCameraChar = 'N/A';
        
        %vesper hardware constants
        vesperEmgRes  = 2^16;
        vesperPreGain = 10;
        vesperVref    = 2.42;
        %Vmuscle (mv) = (VeperOut*vesperVref*1000)/(vesperEmgRes*vesperPreGain*emgGain)
        %speed-up calculations 
        %Vmuscle (mv) = VeperOut*vesperScale/emgGain
        %vesperScale  = (vesperVref*1000)/(vesperEmgRes*vesperPreGain)
        vesperScale   = 0.00369262;
        
    end
    
    properties (Access = public, Constant = true)
        % app descriptors
        appName    = 'vesperDataImportGui';
        appVersion = '0.04';
        appAuthor  = 'Alberto Bortoni';
        
        % important/ relevant output from this app
        savedVarName = 'vesperDataImport';
        
        % the full app, required to populate this app with a previous session
        % this contains ALL components, even those stored in savedVarName
        savedAppName = 'vesperDataImportApp';
        
        % relevant variables to save in savedVarName
        % savedAppName saves this plus everything else too
        %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
        varsToSaveinStd =  {'emgFreq';
                            'emgGain';
                            'micFreq';
                            'vesperData';
                            'appVersion'};
        
    end
    

%---------------------------- VARIABLE PROPERTIES --------------------------------%
    
    properties (Access = private)
        % this variable stores the working directory
        workingDir = '';
        trialDir   = '';
        vesperDir  = '';
        
        % started flag to make sure there is name and directory
        startedFlag = 0;
        
        % tab control variable, start at first tab
        tabControl = 1;
        
        % application tabs, used to move around with buttons, computed at startup
        applicationTabs;
        
        %camera objects
        videoHandle;
        videoRepo;
        
        %camera selected for roi
        roi;
        
        %sorting table index
        vidTimesSelCell = 0;
        emgTimesSelCell = 0;
        micTimesSelCell = 0;
        trialsMappedSelCell  = 0;
        trialsMappedSelCell2 = 0;
        trialsMappedSelCell3 = 0;
        
        %plot time series array
        videoTimeAxis;
        micTimeAxis;
        emgTimeAxis;
        
        %trial selected to work with
        targetTrial;
        
        %used to update data dynamically
        micPlotHandle;
        emgPlotHandle;
        emgPlotHandle2;
        vidPlotHandle;
        
        %holds the repository files before assigmnet
        emgFiles = table();
        micFiles = table();
        
        %audio player handle for mic files
        currMicPlay;
        
        %vertical line handles
        vlineHan = 0;
        vTextHan = 0;
        vlineHan2 = 0;
        vTextHan2 = 0;
        vlineHan3 = 0;
        vTextHan3 = 0;
        
    end
         
    properties (Access = public)
        %vesper properties
        emgFreq;
        emgGain;
        micFreq;
        vesperData = table();
        
        %holds value of time diff between devices
        micSyncAxis;
        emgSyncAxis;
        videoSyncAxis;
        
        %holds data of veper device while being modified
        currVideo;
        currMic;
        currEmg;
        currEmg2;
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%
    
    methods (Access = private)
        
        function closeApp(app,src,event)
            delete(app)
        end%setInitialGuiParam
%-----------------------------------------------------------------------------------%
        
        function setInitialGuiParam(app)
            % fist turn all panels off
            % get names of all tabs and indexes in app.
            tnames  = fieldnames(app);
            
            % tabs are in the order that they apear in the gui in sequence
            %TODO -- if tabs have multiple children this wont work
            app.applicationTabs = tnames(contains(tnames,app.isTabChar));
            
            % disable all panels
            for(i=1: length(app.applicationTabs))
                app.(app.applicationTabs{i}).Children.Visible = 'off';
            end
            
            % the first pannel and tab controlled by class tab control
            app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
            app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            
            % load the study directory and display files
            loadGlobalFiles(app);
            loadVideoHandlers(app);
            
            %remove axis from ROI image
            axis(app.uia_videoRoi,'off');
        end%setInitialGuiParam
%-----------------------------------------------------------------------------------%
        
        function loadGlobalFiles(app)   
                %TODO -- should trim this to show a ../../dir
                app.label_workingDir.Text = app.workingDir;

                % dont display the trials availiable
                [~, tmp] = displayDirRecursively(fullfile(app.workingDir, 'global'));
                app.listB_dirsAvail.Items = tmp;
        end%loadGlobalFiles
%-----------------------------------------------------------------------------------%
        
        function resetSyncPlot(app)
            xlabel(app.uiA_syncSignal, 'Time (s)')
            ylabel(app.uiA_syncSignal, 'Norm Amplitude')
            app.uiA_syncSignal.YLim = [0 1.1];
            app.uiA_syncSignal.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1];
            app.uiA_syncSignal.YTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'; '1.1'};
            app.uiA_syncSignal.Position = [298 11 725 345];
            app.uiA_syncSignal.XLimMode = 'auto';
        end%resetSyncPlot
%-----------------------------------------------------------------------------------%

        function resetSyncPlot2(app)
            xlabel(app.uiA_syncSignal2, 'Time (s)')
            ylabel(app.uiA_syncSignal2, 'Norm Amplitude')
            app.uiA_syncSignal2.YLim = [0 1.1];
            app.uiA_syncSignal2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1];
            app.uiA_syncSignal2.YTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'; '1.1'};
            app.uiA_syncSignal2.Position = [298 11 725 345];
            app.uiA_syncSignal2.XLimMode = 'auto';
        end%resetSyncPlot2
%-----------------------------------------------------------------------------------%

        function loadVideoHandlers(app)    
            % see if there is an instance of this app in the working directory
            if(~(isfile(fullfile(app.workingDir, 'share', [videoTrialHandlerGui.savedAppName '.mat']))))
                 msgbox({['There is no ' videoTrialHandlerGui.savedAppName ' File in the share Working Directory.'] 'You must create video handle  first'}, 'Error','error');
            else
                % try to open the file
                try
                    % just load the camera objects
                    tmp = fullfile(app.workingDir, 'share', [videoTrialHandlerGui.savedAppName '.mat']);
                    tstruct = load(tmp, videoTrialHandlerGui.savedVarName);

                    app.videoHandle = tstruct.(videoTrialHandlerGui.savedVarName).(videoTrialHandlerGui.varsToSaveinStd{1});
                    app.videoRepo   = tstruct.(videoTrialHandlerGui.savedVarName).(videoTrialHandlerGui.varsToSaveinStd{2});

                % send a message and ask the user to take action 
                catch
                    msgbox({'There was an error loading the file, app wil close'; ...
                            'If probem persist you need to delete the saved mat file'}, 'Error','error');
                    delete(app);
                end
                % load variables to populate the instance of this app
                %TODO -- thisis kinda hard coded too
                app.dropD_cameraObj.Items = unique(app.videoHandle.cameraObjName);
            end
        end%loadVideoHandlers
%-----------------------------------------------------------------------------------%

        function incStartFlag(app, cnt)
            % increase or decrease the start flag with bound checking
            % this ommits the value of cnt and only uses its sign.
            if(cnt>0 && app.startedFlag<app.numInitialSteps)
                app.startedFlag = app.startedFlag + 1;
            elseif(cnt<0 && app.startedFlag>0)
                app.startedFlag = app.startedFlag - 1;
            end
            
            % determine if the start is done and enable prev and next buttons
            if(app.startedFlag>=app.numInitialSteps)
                app.button_next.Enable = 'on';
                app.button_prev.Enable = 'on';
            else
                app.button_next.Enable = 'off';
                app.button_prev.Enable = 'off';
            end
        end%incStartFlag
%-----------------------------------------------------------------------------------%
        
        function advanceTab(app, cnt)
            % first check if cnt will request a tab out of range
            if((app.tabControl+cnt)>length(app.applicationTabs) || (app.tabControl+cnt)<1)
                dispMsg(app, 'e', 'tab change request out of range');
            else
                % clear errors if any
                dispMsg(app, 'c');
                
                % disable the current panel
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'off';
                
                % increase tab control and activate next panel and tab
                app.tabControl = app.tabControl+cnt;
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
                app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            end
        end%advanceTab
%-----------------------------------------------------------------------------------%

        function s = varsToSaveinStdFcn(app)
            % saves just the relevant variables that come out of this class not to save ui elements
            for(i = 1:length(app.varsToSaveinStd))
                s.(app.varsToSaveinStd{i}) = app.(app.varsToSaveinStd{i});
            end                
        end%varsToSaveinStdFcn
%-----------------------------------------------------------------------------------%

        function dispMsg(app, ttype, tmsg)
            % For log messaging blue
            if(strcmpi(ttype, 'L'))
                app.label_errorMsg.Text = ['LOG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.45,0.74];
                
            % for error messages red
            elseif(strcmpi(ttype, 'E'))
                app.label_errorMsg.Text = ['ERROR: ' tmsg];
                app.label_errorMsg.FontColor = [0.64,0.08,0.18];
                
            % general propose message black
            elseif(strcmpi(ttype, 'M'))
                app.label_errorMsg.Text = ['MSG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.00,0.00];
            
            % for clearing the mesage
            elseif(strcmpi(ttype, 'C'))
                app.label_errorMsg.Text = '';
            end
        end%dispMsg
%-----------------------------------------------------------------------------------%

    end%private methods

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tworkingDir)
            app.workingDir = tworkingDir;
            setInitialGuiParam(app);
        end

        % Button pushed function: button_next
        function button_nextButtonPushed(app, event)
            % move to next tab
            advanceTab(app, 1);
        end

        % Button pushed function: button_prev
        function button_prevButtonPushed(app, event)
            % move to previous tab
            advanceTab(app, -1);
        end

        % Button pushed function: button_refreshWorkingDir
        function button_refreshWorkingDirButtonPushed(app, event)
            loadGlobalFiles(app);
        end

        % Value changed function: chkB_vesperDirLock
        function chkB_vesperDirLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_vesperDirLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.button_vesperDirSelect.Enable = 'off';
                incStartFlag(app, 1);
            else
                app.button_vesperDirSelect.Enable = 'on';
                incStartFlag(app, -1);
            end
        end

        % Button pushed function: button_vesperDirSelect
        function button_vesperDirSelectButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that ta csv is actually selected
            % make sure there is a selection
            if(~isempty(app.listB_dirsAvail.Value))
                % get the selected files in its extended name form
                app.editF_vesperDir.Value = app.listB_dirsAvail.Value;
                app.vesperDir = fullfile(app.workingDir, 'global', app.editF_vesperDir.Value);
                
                % lock the value trough the checkbox
                app.chkB_vesperDirLock.Value = 1;
                chkB_vesperDirLockValueChanged(app);
            
                % clear errors if any
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'Select a file first');
            end 
        end

        % Value changed function: dropD_cameraObj
        function dropD_cameraObjValueChanged(app, event)
%             value = app.dropD_cameraObj.Value;
%             % when a camera object is selected, lock it
%             if(~strcmp(value,app.nullCameraChar))
%                 app.chkB_cameraObjLock.Value = 1;
%                 chkB_cameraObjLockValueChanged(app)
%             end
        end

        % Value changed function: chkB_cameraObjLock
        function chkB_cameraObjLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_cameraObjLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.dropD_cameraObj.Enable = 'off';
                app.button_roi.Enable      = 'off';
                incStartFlag(app, 1);
            else
                app.dropD_cameraObj.Enable = 'on';
                app.button_roi.Enable      = 'on';
                incStartFlag(app, -1);
            end
        end

        % Button pushed function: button_roi
        function button_roiButtonPushed(app, event)
            %select a random video taken with the selected camera
            tcamidx = contains(app.videoHandle.cameraObjName, app.dropD_cameraObj.Value);
            tvidlist = app.videoHandle.filename(tcamidx);

            % read video file
            tvideoRaw = VideoReader(fullfile(app.videoRepo, tvidlist{floor(length(tvidlist)*rand(1,1))+1}));
            tvideoRaw.CurrentTime = tvideoRaw.Duration*rand(1,1);
            
            % make a figure
            thandl = figure();
            currAxes = axes(thandl);

            vidFrame = readFrame(tvideoRaw);
            imshow(vidFrame, 'Parent', currAxes);
            axis equal;
            
            %allow user do dra the ROI
            troi = drawrectangle('Parent', currAxes, 'Rotatable', false, 'Color',[1 0 0]);
            app.roi = floor(troi.Position);
            close(thandl);
            
            %show image from ROI
            k=imcrop(vidFrame,app.roi);
            imshow(k,'Parent', app.uia_videoRoi);
            
            %disable button
            app.chkB_cameraObjLock.Value = 1;
            chkB_cameraObjLockValueChanged(app);
            
        end

        % Value changed function: dropD_emgGain
        function dropD_emgGainValueChanged(app, event)
            value = app.dropD_emgGain.Value;
            % when a emg gain is selected, lock it
            app.chkB_emgGainLock.Value = 1;
            chkB_emgGainLockValueChangedd(app)
        end

        % Value changed function: chkB_emgGainLock
        function chkB_emgGainLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_emgGainLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.dropD_emgGain.Enable = 'off';
                app.emgGain = str2num(app.dropD_emgGain.Value);
                incStartFlag(app, 1);
            else
                app.dropD_emgGain.Enable = 'on';
                incStartFlag(app, -1);
            end
        end

        % Value changed function: dropD_emgFreq
        function dropD_emgFreqValueChanged(app, event)
            value = app.dropD_emgFreq.Value;
            % when a emg frew is selected, lock it
            app.chkB_emgFreqLock.Value = 1;
            chkB_emgGainLockValueChangedd(app)
        end

        % Value changed function: chkB_emgFreqLock
        function chkB_emgFreqLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_emgFreqLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.dropD_emgFreq.Enable = 'off';
                app.emgFreq = str2num(app.dropD_emgFreq.Value);
                incStartFlag(app, 1);
            else
                app.dropD_emgFreq.Enable = 'on';
                incStartFlag(app, -1);
            end
        end

        % Value changed function: chkB_micFreqLock
        function chkB_micFreqLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_micFreqLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.editF_micFreq.Enable = 'off';
                app.micFreq = app.editF_micFreq.Value;
                incStartFlag(app, 1);
            else
                app.editF_micFreq.Enable = 'on';
                incStartFlag(app, -1);
            end
        end

        % Button pushed function: button_popPairTable
        function button_popPairTableButtonPushed(app, event)
            %first drop all cameras except the one selected as roi    
            tcamidx = contains(app.videoHandle.cameraObjName, app.dropD_cameraObj.Value);
            app.videoHandle(~tcamidx,:) = [];
            
            %parse the names of the video files
            tvidtimesdt = strcat(cellstr(num2str(app.videoHandle.startDate.Hour)),':',cellstr(num2str(app.videoHandle.startDate.Minute)),':',cellstr(num2str(app.videoHandle.startDate.Second)));
            tmp = app.videoHandle.startDate + seconds(app.videoHandle.duration);
            tvidtimesst = strcat(cellstr(num2str(tmp.Hour)),':',cellstr(num2str(tmp.Minute)),':',cellstr(num2str(tmp.Second)));
            tmp = cell(length(tvidtimesst),1);
            tmp(:) = {''};
            app.table_vidTimes.Data = [tvidtimesdt, tvidtimesst, tmp];
            
            %now parse vesper files
            tvesperfiles = cellstr(ls(app.vesperDir));
            tvesperfiles = tvesperfiles(3:end);

            tdate   = NaT(length(tvesperfiles), 1);
            tstop   = NaT(length(tvesperfiles), 1);
            tdur    = zeros(length(tvesperfiles), 1);

            %retrieve the date, start and stop times          
            for(i = 1: length(tvesperfiles))
                tmp = tvesperfiles{i};
                tidx = findstr(tmp, '_')';
                
                tyy = str2num(['20' tmp(2:tidx(1)-1)]);
                tmm = str2num(tmp(tidx(1)+1:tidx(2)-1));
                tdd = str2num(tmp(tidx(2)+1:tidx(3)-1));
                
                thhdt = str2num(tmp(tidx(3)+1:tidx(4)-1));
                tmidt = str2num(tmp(tidx(4)+1:tidx(5)-1));
                tssdt = floor(str2num(tmp(tidx(5)+1:tidx(6)-1)));
                tmsdt = floor((str2num(tmp(tidx(5)+1:tidx(6)-1)) - tssdt)*1000);
                
                thhst = str2num(tmp(tidx(9)+1:tidx(10)-1));
                tmist = str2num(tmp(tidx(10)+1:tidx(11)-1));
                tssst = floor(str2num(tmp(tidx(11)+1:tidx(12)-1)));
                tmsst = floor((str2num(tmp(tidx(11)+1:tidx(12)-1)) - tssst)*1000);
                
                tdate(i) = datetime(tyy, tmm, tdd, thhdt, tmidt, tssdt, tmsdt);
                tstop(i) = datetime(tyy, tmm, tdd, thhst, tmist, tssst, tmsst);
                tdur(i)  = thhdt*(60*60*1000)+tmidt*(60*1000)+tssdt*(1000)+tmsdt;
            end
            
            %order the table by start time
            [~, tindx] = sort(tdate);
            
            tdate  = tdate(tindx);
            tstop  = tstop(tindx);
            tdur   = tdur(tindx);
            tvesperfiles = tvesperfiles(tindx);
            
            temgfilesidx = find(contains(tvesperfiles,'e','IgnoreCase',true));
            tmicfilesidx = find(contains(tvesperfiles,'u','IgnoreCase',true));
            
            %make tables for both mic and emg data
            app.emgFiles.filename = tvesperfiles(temgfilesidx);
            app.emgFiles.start    = tdate(temgfilesidx);
            app.emgFiles.stop     = tstop(temgfilesidx);
            app.emgFiles.duration = tdur(temgfilesidx); 
            
            app.micFiles.filename = tvesperfiles(tmicfilesidx);
            app.micFiles.start    = tdate(tmicfilesidx);
            app.micFiles.stop     = tstop(tmicfilesidx);
            app.micFiles.duration = tdur(tmicfilesidx);
            
            %fill the tables with start times
            temgtimesdt = strcat(cellstr(num2str(app.emgFiles.start.Hour)),':',cellstr(num2str(app.emgFiles.start.Minute)),':',cellstr(num2str(app.emgFiles.start.Second)));
            temgtimesst = strcat(cellstr(num2str(app.emgFiles.stop.Hour)),':',cellstr(num2str(app.emgFiles.stop.Minute)),':',cellstr(num2str(app.emgFiles.stop.Second)));
            app.table_emgTimes.Data = [temgtimesdt, temgtimesst];
            
            tmictimesdt = strcat(cellstr(num2str(app.micFiles.start.Hour)),':',cellstr(num2str(app.micFiles.start.Minute)),':',cellstr(num2str(app.micFiles.start.Second)));
            tmictimesst = strcat(cellstr(num2str(app.micFiles.stop.Hour)),':',cellstr(num2str(app.micFiles.stop.Minute)),':',cellstr(num2str(app.micFiles.stop.Second)));
            app.table_micTimes.Data = [tmictimesdt, tmictimesst];
            
            %enable buttons
            app.button_pairData.Enable = 'on';
            app.button_plotEmg.Enable  = 'on';
            app.button_plotMic.Enable  = 'on';
            
            %display msg with instructions
            dispMsg(app,'l','select the files for each video file')
            
        end

        % Cell selection callback: table_vidTimes
        function table_vidTimesCellSelection(app, event)
            indices = event.Indices;
            app.vidTimesSelCell = indices(1,1);
        end

        % Cell selection callback: table_emgTimes
        function table_emgTimesCellSelection(app, event)
            % for some reason it does not like to be directly assigned to private var
            indices = event.Indices(:,1);
            app.emgTimesSelCell = indices(1,1);
        end

        % Cell selection callback: table_micTimes
        function table_micTimesCellSelection(app, event)
            indices = event.Indices;
            app.micTimesSelCell = indices(1,1);
        end

        % Button pushed function: button_plotMic
        function button_plotMicButtonPushed(app, event)
            %just plot the data to make sure there is activity    
            if(app.micTimesSelCell~=0)
                [tmp, ~] = audioread(fullfile(app.vesperDir, app.micFiles.filename{app.micTimesSelCell}));
                plot(tmp);
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'first select a cell to plot');
            end
        end

        % Button pushed function: button_plotEmg
        function button_plotEmgButtonPushed(app, event)
            %just plot the data to make sure there is activity
            if(app.emgTimesSelCell~=0)
                tmp = readtable(fullfile(app.vesperDir, app.emgFiles.filename{app.emgTimesSelCell}), 'ReadVariableNames', true);
                plot(tmp.(tmp.Properties.VariableNames{1}));
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'first select a cell to plot');
            end
        end

        % Button pushed function: button_pairData
        function button_pairDataButtonPushed(app, event)
            %add selection to pair to vesper
            %TODO -- you can have repeated entries in this table for now
            %TODO -- this only allows one 1:1:1 file mapping
            if(app.vidTimesSelCell~=0 && app.emgTimesSelCell~=0 && app.micTimesSelCell~=0)
                % assign value to table
                %check if table has been declared before
                if(~ismember('trial', app.vesperData.Properties.VariableNames))
                    app.vesperData.trial   = app.videoHandle.trial(app.vidTimesSelCell);
                    app.vesperData.emgFile = app.emgFiles.filename(app.emgTimesSelCell);
                    app.vesperData.micFile = app.micFiles.filename(app.micTimesSelCell);
                else
                    tmp = {app.videoHandle.trial(app.vidTimesSelCell), app.emgFiles.filename(app.emgTimesSelCell), app.micFiles.filename(app.micTimesSelCell)};
                    app.vesperData = [app.vesperData; tmp];
                end
                %reflect this on the video table
                app.table_vidTimes.Data(app.vidTimesSelCell,3) = {'ok'};
                
                %reset cell selections
                app.vidTimesSelCell = 0;
                app.emgTimesSelCell = 0;
                app.micTimesSelCell = 0;
                
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'select one element from each table');
            end
        
            %disable buttons
            app.button_popPairTable.Enable = 'off';
            app.button_extractData.Enable  = 'on'; 
        end

        % Button pushed function: button_extractData
        function button_extractDataButtonPushed(app, event)
            %get data from files and store here
            %make struct containers
            dispMsg(app, 'l', 'Extracting, wait.');
            tdata = struct();
            tmp   = struct();
            
            %get raw data for all fields
            for i = 1: size(app.vesperData,1)
                tmp.emg      = readtable(fullfile(app.vesperDir, app.vesperData.emgFile{i}), 'ReadVariableNames', true);
                [tmp.mic, ~] = audioread(fullfile(app.vesperDir, app.vesperData.micFile{i}));
                
                %check if table has been declared before
                if(~isfield(tdata, 'emg'))
                    tdata = tmp; 
                else
                    tdata = [tdata; tmp];
                end
            end
            app.vesperData.dataRaw = tdata;
            
            app.button_pairData.Enable    = 'off';
            app.button_extractData.Enable = 'off';
            app.button_plotEmg.Enable     = 'off';
            app.button_plotMic.Enable     = 'off';
            app.button_popTrials.Enable   = 'on';
            dispMsg(app, 'l', 'Done extracting');
        end

        % Button pushed function: button_popTrials
        function button_popTrialsButtonPushed(app, event)
            %populate table
            ttrials = app.vesperData.trial;
            tmp = cell(length(ttrials),1);
            tmp(:) = {''};
            app.table_trialsMapped.Data = [ttrials, tmp];
            
            %fill structure for where data will be
            %need to place in a struct because data is of diff lenght
            tdata = struct();
            tmp   = struct();
            for i = 1: size(app.vesperData,1)
                tmp.emg       = [];
                tmp.emgTime   = [];
                tmp.mic       = [];
                tmp.micTime   = [];
                tmp.vidTime   = [];
                tmp.vidTimeF  = [];
                tmp.vidSync   = [];
                tmp.gustStart = 0;
                tmp.gustStop  = 0;
                
                %check if table has been declared before
                if(~isfield(tdata, 'emg'))
                    tdata = tmp; 
                else
                    tdata = [tdata; tmp];
                end
            end
            app.vesperData.dataSync = tdata;
            
            %get filter bounds
            app.editF_trimFreq.Limits = [0, floor(app.micFreq/2)];
            
            %enable buttons
            app.button_popTrials.Enable   = 'off';
            app.chkB_lockTrial.Enable     = 'on';
            app.table_trialsMapped.Enable = 'on';
        end

        % Cell selection callback: table_trialsMapped
        function table_trialsMappedCellSelection(app, event)
            indices = event.Indices;
            app.trialsMappedSelCell = indices(1,1);
        end

        % Value changed function: chkB_lockTrial
        function chkB_lockTrialValueChanged(app, event)
            value = app.chkB_lockTrial.Value;
            
            if(app.trialsMappedSelCell~=0 && value)
                app.targetTrial = app.table_trialsMapped.Data(app.trialsMappedSelCell,1);
                %enable buttons
                app.button_genWave.Enable     = 'on';
                app.button_trimToVideo.Enable = 'on';
                app.button_doneSync.Enable    = 'on';
                app.table_trialsMapped.Enable = 'off';
                app.editF_vesperTimeMs.Enable = 'on';
                app.slider_vesperTime.Enable  = 'on';
                app.button_resetAxes.Enable   = 'on';
                app.editF_gustTime.Enable     = 'on';
                app.editF_gustDur.Enable      = 'on';
                
                dispMsg(app, 'c');
                
            elseif(~value)
                app.button_genWave.Enable     = 'off';
                app.button_trimToVideo.Enable = 'off';
                app.table_trialsMapped.Enable = 'on';
                app.editF_vesperTimeMs.Enable = 'off';
                app.slider_vesperTime.Enable  = 'off';
                app.button_resetAxes.Enable   = 'off';
                app.editF_gustTime.Enable     = 'off';
                app.editF_gustDur.Enable      = 'off';
                
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'select a target cell on table');
                app.chkB_lockTrial.Value = 0;
            end 
        end

        % Button pushed function: button_genWave
        function button_genWaveButtonPushed(app, event)
            %display the wave audio/mic for this trial
            dispMsg(app, 'c');
            dispMsg(app, 'l', 'Thinking, hold on');
            
            %get the habdle of this video on this trail and on selected camera
            tcamidx  = contains(app.videoHandle.cameraObjName, app.dropD_cameraObj.Value);
            tcamidx  = (contains(app.videoHandle.trial, app.targetTrial) & tcamidx);
            tvidlist = app.videoHandle(tcamidx,:);
            tmicidx  = contains(app.vesperData.trial, app.targetTrial);
            
            
            %tmake sure that there is only video that matches otherwise video handle was not done correctly
            %generate a wave from the light sync device from video ROI
            if(size(tvidlist,1)~= 1)
                dispMsg(app, 'e', 'there is more than one video matching, not possible. Redo video handler gui');
            else
                tvideoRaw = VideoReader(fullfile(app.videoRepo, char(tvidlist.filename)));
                %read frames and compute the mean value of pixels
                tmp = readFrame(tvideoRaw);
                tframe = mean(imcrop(tmp,app.roi), 'all'); 
            
                while hasFrame(tvideoRaw)
                    tmp = readFrame(tvideoRaw);
                    tframe = [tframe; mean(imcrop(tmp,app.roi), 'all')];
                end
                %take a crude average point then cap to 0 or 1
                %TODO -- how good is this?
                tidx = tframe>(floor(median(tframe)+0.13*range(tframe)));
                tframe(~tidx) = 0;
                tframe( tidx) = 1;
                
                %hold values on variables for corss-function
                app.currVideo     = tframe;
                app.currMic       = normalize(app.vesperData.dataRaw(tmicidx).mic, 'range');
                app.videoTimeAxis = linspace(0, length(app.currVideo)/tvidlist.frameRate, length(app.currVideo));
                app.videoSyncAxis = app.videoTimeAxis;
                app.micTimeAxis   = linspace(0, length(app.currMic)/app.micFreq, length(app.currMic));
                app.micSyncAxis   = app.micTimeAxis;
                
                %display on graph
                cla(app.uiA_syncSignal,'reset');
                resetSyncPlot(app);
                app.micPlotHandle = plot(app.uiA_syncSignal, app.micSyncAxis, app.currMic);
                app.micPlotHandle.XDataSource = 'app.micSyncAxis';
                app.micPlotHandle.YDataSource = 'app.currMic';
                hold(app.uiA_syncSignal, 'on');
                app.vidPlotHandle = plot(app.uiA_syncSignal, app.videoSyncAxis, app.currVideo);
                app.vidPlotHandle.XDataSource = 'app.videoSyncAxis';
                app.vidPlotHandle.YDataSource = 'app.currVideo';
                hold(app.uiA_syncSignal, 'off');
                
                %reset time sync tools
                app.editF_vesperTimeMs.Value = 0;
                app.slider_vesperTime.Value  = 0;
                app.editF_gustTime.Value     = 0;
                app.editF_gustTime.Limits    = [app.micSyncAxis(1), app.micSyncAxis(end)];
                editF_gustTimeValueChanged(app);
                
                
                %generate limits for playback time axis, exclude 0?
                app.editF_audioTime.Limits   = [app.micTimeAxis(2), app.micTimeAxis(end)];
                app.spinner_audioSpeed.Value = 1;
                
                %enable play buttons
                app.button_playAudio.Enable   = 'on';
                app.button_stopAudio.Enable   = 'on';
                app.spinner_audioSpeed.Enable = 'on';
                app.editF_audioTime.Enable    = 'on';
                app.editF_trimFreq.Enable     = 'on';
                app.button_bandFilter.Enable  = 'on';
                
                dispMsg(app, 'l', 'Wave generated, move the times, then trim');
            end
        end

        % Button pushed function: button_bandFilter
        function button_bandFilterButtonPushed(app, event)
            %band pass filter and refresh plot
            if(app.editF_trimFreq.Value~=0)
                app.currMic = normalize(bandpass(app.currMic, [app.editF_trimFreq.Value-5, app.editF_trimFreq.Value+5], app.editF_micFreq.Value), 'range');
            else
                tmicidx  = contains(app.vesperData.trial, app.targetTrial);
                app.currMic = normalize(app.vesperData.dataRaw(tmicidx).mic, 'range');
            end
            refreshdata(app.micPlotHandle, 'caller');
        end

        % Value changed function: slider_vesperTime
        function slider_vesperTimeValueChanged(app, event)
            value = app.slider_vesperTime.Value;
            app.videoSyncAxis = app.videoTimeAxis + ((app.micTimeAxis(end)*app.slider_vesperTime.Value/100) + app.editF_vesperTimeMs.Value/1000);
            refreshdata(app.vidPlotHandle, 'caller');
            %update vline
            app.editF_gustTime.Value  = ((app.micTimeAxis(end)*app.slider_vesperTime.Value/100) + app.editF_vesperTimeMs.Value/1000);
            editF_gustTimeValueChanged(app);
            app.editF_gustTime.Limits = [app.micSyncAxis(1), app.micSyncAxis(end)];
        end

        % Value changed function: editF_vesperTimeMs
        function editF_vesperTimeMsValueChanged(app, event)
            value = app.editF_vesperTimeMs.Value;
            app.videoSyncAxis = app.videoTimeAxis + ((app.micTimeAxis(end)*app.slider_vesperTime.Value/100) + app.editF_vesperTimeMs.Value/1000);
            refreshdata(app.vidPlotHandle, 'caller');
            %update vline
            app.editF_gustTime.Limits = [app.micSyncAxis(1), app.micSyncAxis(end)];
        end

        % Button pushed function: button_playAudio
        function button_playAudioButtonPushed(app, event)
            %create a player handle for this mic with selected parameters
            ttime = floor(app.editF_audioTime.Value*app.micFreq);
            tspeed = 1;
            if(app.spinner_audioSpeed.Value>0)
                tspeed = app.spinner_audioSpeed.Value;
            else
                tspeed = abs(1/app.spinner_audioSpeed.Value);
            end

            %create an aduiplayer ahndle and start playing
            app.currMicPlay = audioplayer(app.currMic(ttime:end),floor(app.micFreq*tspeed));
            play(app.currMicPlay);
            
            %disable jump and playback speed
            app.spinner_audioSpeed.Enable = 'off';
            app.editF_audioTime.Enable    = 'off';
            app.editF_trimFreq.Enable     = 'off';
            app.button_bandFilter.Enable  = 'off';
            app.button_pauseAudio.Enable  = 'on';
            app.button_pauseAudio.Value   = 0; 
            
            %create vertical line and draw on start time
            %TODO -- this is kinda of a patch, though it works
            if(app.vlineHan~=0)
                lhan = app.vlineHan;
                than = app.vTextHan;
                delete(lhan);
                delete(than);
            end
            [app.vlineHan, app.vTextHan] = vline([0, app.editF_audioTime.Value], {'m', 'LineWidth', 0.5}, [], [], [], app.uiA_syncSignal, [], []);
        end

        % Value changed function: editF_gustTime
        function editF_gustTimeValueChanged(app, event)
            value = app.editF_gustTime.Value;
            %create vertical line and draw on start time
            %TODO -- this is kinda of a patch, though it works
            if(app.vlineHan2~=0)
                lhan = app.vlineHan2;
                than = app.vTextHan2;
                delete(lhan);
                delete(than);
            end
            [app.vlineHan2, app.vTextHan2] = vline([0, app.editF_gustTime.Value], {'r', 'LineWidth', 0.5}, [], [], [], app.uiA_syncSignal, [], []);
            editF_gustDurValueChanged(app)
        end

        % Value changed function: editF_gustDur
        function editF_gustDurValueChanged(app, event)
            value = app.editF_gustDur.Value;
            %create vertical line and draw on start time
            %TODO -- this is kinda of a patch, though it works
            if(app.vlineHan3~=0)
                lhan = app.vlineHan3;
                than = app.vTextHan3;
                delete(lhan);
                delete(than);
            end
            [app.vlineHan3, app.vTextHan3] = vline([0, (app.editF_gustTime.Value+app.editF_gustDur.Value)], {'r', 'LineWidth', 0.5}, [], [], [], app.uiA_syncSignal, [], []);
        end

        % Value changed function: button_pauseAudio
        function button_pauseAudioValueChanged(app, event)
            value = app.button_pauseAudio.Value;
            %toogle pause and resume 
            if(value)
                app.currMicPlay.pause;
                app.spinner_audioSpeed.Enable = 'on';
            else
                app.currMicPlay.resume;
                app.spinner_audioSpeed.Enable = 'off';
            end
        end

        % Button pushed function: button_stopAudio
        function button_stopAudioPushed(app, event)
            stop(app.currMicPlay);
            app.button_pauseAudio.Value   = 0; 
            app.button_pauseAudio.Enable  = 'off';
            app.spinner_audioSpeed.Enable = 'on';
            app.editF_audioTime.Enable    = 'on';
            app.editF_trimFreq.Enable     = 'on';
            app.button_bandFilter.Enable  = 'on';
        end

        % Button pushed function: button_trimToVideo
        function button_trimToVideoButtonPushed(app, event)
            dispMsg(app, 'c');
            
            %get the indices of mic time within the video times    
            ttrialidx = contains(app.vesperData.trial, app.targetTrial);
            tmicspace = 1:length(app.micTimeAxis);
            tmicidx   = tmicspace(app.micTimeAxis>=app.videoSyncAxis(1) & app.micTimeAxis<=app.videoSyncAxis(end));
            
            %take the croped mic data and video time axis
            app.vesperData.dataSync(ttrialidx).mic       = app.vesperData.dataRaw(ttrialidx).mic(tmicidx);
            tmp = app.micTimeAxis(tmicidx);
            app.vesperData.dataSync(ttrialidx).micTime   = tmp(:);
            app.vesperData.dataSync(ttrialidx).vidTime   = app.videoSyncAxis(:);
            app.vesperData.dataSync(ttrialidx).vidTimeF  = (1:length(app.currVideo))';
            app.vesperData.dataSync(ttrialidx).vidSync   = app.currVideo;
            if(app.editF_gustDur.Value==0)
                app.vesperData.dataSync(ttrialidx).gustStart = 0;
                app.vesperData.dataSync(ttrialidx).gustStop  = 0;
            else
                app.vesperData.dataSync(ttrialidx).gustStart = app.editF_gustTime.Value;
                app.vesperData.dataSync(ttrialidx).gustStop  = app.editF_gustTime.Value + app.editF_gustDur.Value;
            end
            
            %return to new triel to start checkig
            app.table_trialsMapped.Data(app.trialsMappedSelCell,2) = {true};
            
            cla(app.uiA_syncSignal,'reset');
            resetSyncPlot(app);
            app.editF_vesperTimeMs.Value = 0;
            app.slider_vesperTime.Value  = 0;
            
            app.chkB_lockTrial.Value = 0;
            chkB_lockTrialValueChanged(app);
            
            app.trialsMappedSelCell  = 0;
            
            dispMsg(app, 'l', 'Trimmed, select done and get a new trial');
        end

        % Button pushed function: button_doneSync
        function button_doneSyncButtonPushed(app, event)
            %TODO -- check ot make sure that all cellsaare 1
            %enable next button 
            app.button_popTrials2.Enable = 'on';
            dispMsg(app, 'l', 'Proceed to EMG sync');
        end

        % Button pushed function: button_resetAxes
        function button_resetAxesButtonPushed(app, event)
            resetSyncPlot(app);
        end

        % Button pushed function: button_popTrials2
        function button_popTrials2ButtonPushed(app, event)
            %populate table
            ttrials = app.vesperData.trial;
            tmp = cell(length(ttrials),1);
            tmp(:) = {''};
            app.table_trialsMapped2.Data = [ttrials, tmp];
            
            %enable buttons
            app.button_popTrials2.Enable   = 'off';
            app.chkB_lockTrial2.Enable     = 'on';
            app.table_trialsMapped2.Enable = 'on';
        end

        % Cell selection callback: table_trialsMapped2
        function table_trialsMapped2CellSelection(app, event)
            indices = event.Indices;
            app.trialsMappedSelCell2 = indices(1,1);
        end

        % Value changed function: chkB_lockTrial2
        function chkB_lockTrial2ValueChanged(app, event)
            value = app.chkB_lockTrial2.Value;
            
            if(app.trialsMappedSelCell2~=0 && value)
                app.targetTrial = app.table_trialsMapped2.Data(app.trialsMappedSelCell2,1);
                %enable buttons
                app.button_genWave2.Enable     = 'on';
                app.button_trimToVideo2.Enable = 'on';
                app.button_doneSync2.Enable    = 'on';
                app.table_trialsMapped2.Enable = 'off';
                app.editF_vesperTimeMs2.Enable = 'on';
                app.slider_vesperTime2.Enable  = 'on';
                app.button_resetAxes2.Enable   = 'on';
                
                dispMsg(app, 'c');
                
            elseif(~value)
                app.button_genWave2.Enable     = 'off';
                app.button_trimToVideo2.Enable = 'off';
                app.table_trialsMapped2.Enable = 'on';
                app.editF_vesperTimeMs2.Enable = 'off';
                app.slider_vesperTime2.Enable  = 'off';
                app.button_resetAxes2.Enable   = 'off';
                
                dispMsg(app, 'c');
                
            else
                dispMsg(app, 'e', 'select a target cell on table');
                app.chkB_lockTrial2.Value = 0;
            end 
        end

        % Button pushed function: button_genWave2
        function button_genWave2ButtonPushed(app, event)
            %display the wave audio/mic for this trial
            dispMsg(app, 'c');
            dispMsg(app, 'l', 'Thinking, hold on');
            tvesidx  = contains(app.vesperData.trial, app.targetTrial);
            
            %hold values on variables for corss-function, plot only one emg
            app.currMic       = normalize(app.vesperData.dataRaw(tvesidx).mic, 'range');
            tcolname          = app.vesperData.dataRaw(tvesidx).emg.Properties.VariableNames{1};
            app.currEmg       = normalize(app.vesperData.dataRaw(tvesidx).emg.(tcolname), 'range');
            app.emgTimeAxis   = linspace(0, length(app.currEmg)/app.emgFreq, length(app.currEmg));
            app.micTimeAxis   = linspace(0, length(app.currMic)/app.micFreq, length(app.currMic));
            app.micSyncAxis   = app.micTimeAxis;
            
            %display on graph
            cla(app.uiA_syncSignal2,'reset');
            resetSyncPlot2(app);
            app.micPlotHandle = plot(app.uiA_syncSignal2, app.micSyncAxis, app.currMic);
            app.micPlotHandle.XDataSource = 'app.micSyncAxis';
            app.micPlotHandle.YDataSource = 'app.currMic';
            hold(app.uiA_syncSignal2, 'on');
            app.emgPlotHandle = plot(app.uiA_syncSignal2, app.emgTimeAxis, app.currEmg);
            app.emgPlotHandle.XDataSource = 'app.emgTimeAxis';
            app.emgPlotHandle.YDataSource = 'app.currEmg';
            hold(app.uiA_syncSignal2, 'off');
            
            %reset time sync tools
            app.editF_vesperTimeMs2.Value = 0;
            app.slider_vesperTime2.Value  = 0;
            
            dispMsg(app, 'l', 'Wave generated, move the times, then trim');

        end

        % Value changed function: slider_vesperTime2
        function slider_vesperTime2ValueChanged(app, event)
            value = app.slider_vesperTime2.Value;
            app.micSyncAxis = app.micTimeAxis + ((app.emgTimeAxis(end)*app.slider_vesperTime2.Value/100) + app.editF_vesperTimeMs2.Value/1000);
            refreshdata(app.micPlotHandle, 'caller');
        end

        % Value changed function: editF_vesperTimeMs2
        function editF_vesperTimeMs2ValueChanged(app, event)
            value = app.editF_vesperTimeMs2.Value;
            app.micSyncAxis = app.micTimeAxis + ((app.emgTimeAxis(end)*app.slider_vesperTime2.Value/100) + app.editF_vesperTimeMs2.Value/1000);
            refreshdata(app.micPlotHandle, 'caller');
        end

        % Button pushed function: button_trimToVideo2
        function button_trimToVideo2ButtonPushed(app, event)
            dispMsg(app, 'c');
            
            %get the indices of mic time within the video times
            ttrialidx = contains(app.vesperData.trial, app.targetTrial);
            %this compares the offset in this screen with the prev (vid syc)
            tstart = app.micSyncAxis(1) + app.vesperData.dataSync(ttrialidx).micTime(1);
            tend   = app.micSyncAxis(1) + app.vesperData.dataSync(ttrialidx).micTime(end);
            
            temgspace = 1:length(app.emgTimeAxis);
            temgidx   = temgspace(app.emgTimeAxis>=tstart & app.emgTimeAxis<=tend);
            
            %take the croped emg data
            app.vesperData.dataSync(ttrialidx).emg = app.vesperData.dataRaw(ttrialidx).emg(temgidx,:);
            tmp = app.emgTimeAxis(temgidx);
            app.vesperData.dataSync(ttrialidx).emgTime = tmp(:);
            
            %now make sure that all times start at zero using the mic
            app.vesperData.dataSync(ttrialidx).emgTime   = app.vesperData.dataSync(ttrialidx).emgTime   - tstart;
            app.vesperData.dataSync(ttrialidx).vidTime   = app.vesperData.dataSync(ttrialidx).vidTime   - app.vesperData.dataSync(ttrialidx).micTime(1);
            % for unperturbed trials both == 0
            if(~(app.vesperData.dataSync(ttrialidx).gustStart==0 && app.vesperData.dataSync(ttrialidx).gustStop==0))
                app.vesperData.dataSync(ttrialidx).gustStart = app.vesperData.dataSync(ttrialidx).gustStart - app.vesperData.dataSync(ttrialidx).micTime(1);
                app.vesperData.dataSync(ttrialidx).gustStop  = app.vesperData.dataSync(ttrialidx).gustStop  - app.vesperData.dataSync(ttrialidx).micTime(1);
            end
            
            app.vesperData.dataSync(ttrialidx).micTime   = app.vesperData.dataSync(ttrialidx).micTime   - app.vesperData.dataSync(ttrialidx).micTime(1);
            
            %return to new triel to start checkig
            app.table_trialsMapped2.Data(app.trialsMappedSelCell2,2) = {true};
            
            cla(app.uiA_syncSignal2,'reset');
            resetSyncPlot2(app);
            app.editF_vesperTimeMs2.Value = 0;
            app.slider_vesperTime2.Value  = 0;
            app.editF_gustTime.Value      = 0;
            
            app.chkB_lockTrial2.Value = 0;
            chkB_lockTrial2ValueChanged(app);
            
            app.trialsMappedSelCell2 = 0;

            dispMsg(app, 'l', 'Trimmed, select done and get a new trial');
        
        end

        % Button pushed function: button_doneSync2
        function button_doneSync2ButtonPushed(app, event)
            app.button_popTrials3.Enable = 'on';
            %this is silly but reset the Vline handles for later use 
            app.vlineHan  = 0;
            app.vTextHan  = 0;
            app.vlineHan2 = 0;
            app.vTextHan2 = 0;
        end

        % Button pushed function: button_resetAxes2
        function button_resetAxes2ButtonPushed(app, event)
            resetSyncPlot2(app);
        end

        % Button pushed function: button_popTrials3
        function button_popTrials3ButtonPushed(app, event)
            %populate table
            ttrials = app.vesperData.trial;
            tmp = cell(length(ttrials),1);
            tmp(:) = {''};
            app.table_trialsMapped3.Data = [ttrials, tmp];
            
            %fill structure for where data will be
            %need to place in a struct because data is of diff lenght
            tdata = struct();
            tmp   = struct();
            for i = 1: size(app.vesperData,1)
                tmp.emgCh1    = [];
                tmp.emgCh2    = [];
                tmp.emgTime   = [];
                tmp.vidTime   = [];
                tmp.vidTimeF  = [];
                tmp.vidSync   = [];
                tmp.gustStart = 0;
                tmp.gustStop  = 0;
                
                %check if table has been declared before
                if(~isfield(tdata, 'emgCh1'))
                    tdata = tmp; 
                else
                    tdata = [tdata; tmp];
                end
            end
            app.vesperData.data = tdata;
            
            %enable buttons
            app.button_popTrials3.Enable   = 'off';
            app.chkB_lockTrial3.Enable     = 'on';
            app.table_trialsMapped3.Enable = 'on';
            
            %put limits on filter values
            app.editF_lowPass.Limits  = [0 floor(app.emgFreq/2)];
            app.editF_highPass.Limits = [0 floor(app.emgFreq/2)];
        end

        % Cell selection callback: table_trialsMapped3
        function table_trialsMapped3CellSelection(app, event)
            indices = event.Indices;
            app.trialsMappedSelCell3 = indices(1,1);
        end

        % Value changed function: chkB_lockTrial3
        function chkB_lockTrial3ValueChanged(app, event)
            value = app.chkB_lockTrial3.Value;
            if(app.trialsMappedSelCell3~=0 && value)
                app.targetTrial = app.table_trialsMapped3.Data(app.trialsMappedSelCell3,1);
                %enable buttons
                app.button_genWave3.Enable     = 'on';
                app.table_trialsMapped3.Enable = 'off';
                
                dispMsg(app, 'c');
                
            elseif(~value)
                app.button_genWave3.Enable     = 'off';
                app.table_trialsMapped3.Enable = 'on';
                app.editF_lowPass.Enable       = 'off';
                app.editF_highPass.Enable      = 'off';
                app.button_applyFilter.Enable  = 'off';
                app.button_finishTrial.Enable  = 'off';
                app.chkB_plotBoth.Enable       = 'off';
                app.chkB_invertCh2.Enable      = 'off';
                app.chkB_absolute.Enable       = 'off';
                app.chkB_scaleMv.Enable        = 'off';
                
                dispMsg(app, 'c');
                
            else
                dispMsg(app, 'e', 'select a target cell on table');
                app.chkB_lockTrial3.Value = 0;
            end 
        end

        % Button pushed function: button_genWave3
        function button_genWave3ButtonPushed(app, event)
            %display the wave audio/mic for this trial
            dispMsg(app, 'c');
            dispMsg(app, 'l', 'Thinking, hold on');
            tvesidx  = contains(app.vesperData.trial, app.targetTrial);
            
            %hold values on variables for corss-function, plot only one
            %emg, but hold both
            tcolname        = app.vesperData.dataSync(tvesidx).emg.Properties.VariableNames;
            app.currEmg     = app.vesperData.dataSync(tvesidx).emg.(tcolname{1});
            app.currEmg2    = app.vesperData.dataSync(tvesidx).emg.(tcolname{2});
            app.emgSyncAxis = app.vesperData.dataSync(tvesidx).emgTime;

            %display on graph
            cla(app.uiA_syncSignal3,'reset');
            
            app.emgPlotHandle = plot(app.uiA_syncSignal3, app.emgSyncAxis, app.currEmg);
            app.emgPlotHandle.XDataSource  = 'app.emgSyncAxis';
            app.emgPlotHandle.YDataSource  = 'app.currEmg';
            hold(app.uiA_syncSignal3, 'on');
            app.emgPlotHandle2 = plot(app.uiA_syncSignal3, app.emgSyncAxis, app.currEmg2);
            app.emgPlotHandle2.XDataSource = 'app.emgSyncAxis';
            app.emgPlotHandle2.YDataSource = 'app.currEmg2';
            hold(app.uiA_syncSignal3, 'off');
            chkB_scaleMvValueChanged(app);
            
            %enable graph manipulation
            app.editF_lowPass.Enable      = 'on';
            app.editF_highPass.Enable     = 'on';
            app.button_applyFilter.Enable = 'on';
            app.button_finishTrial.Enable = 'on';
            app.chkB_plotBoth.Enable      = 'on';
            app.chkB_invertCh2.Enable     = 'on';
            app.chkB_absolute.Enable      = 'on';
            app.chkB_scaleMv.Enable       = 'on';
                
            dispMsg(app, 'l', 'Wave generated, move the times, then trim');
        end

        % Value changed function: chkB_plotBoth
        function chkB_plotBothValueChanged(app, event)
            value = app.chkB_plotBoth.Value;
            if(value)
                app.emgPlotHandle2.Visible = 'on'; 
            else
                app.emgPlotHandle2.Visible = 'off';
            end
        end

        % Value changed function: chkB_invertCh2
        function chkB_invertCh2ValueChanged(app, event)
            value = app.chkB_invertCh2.Value;
            if(value)
                app.currEmg2 = app.currEmg2*-1;
            else
                %copy data again
                tvesidx      = contains(app.vesperData.trial, app.targetTrial);
                tcolname     = app.vesperData.dataSync(tvesidx).emg.Properties.VariableNames;
                app.currEmg  = app.vesperData.dataSync(tvesidx).emg.(tcolname{1});
                app.currEmg2 = app.vesperData.dataSync(tvesidx).emg.(tcolname{2});
                chkB_scaleMvValueChanged(app);
                
                %reset boxes
                app.chkB_absolute.Value  = 0;
                
            end
            refreshdata(app.emgPlotHandle2, 'caller');
        end

        % Value changed function: chkB_absolute
        function chkB_absoluteValueChanged(app, event)
            value = app.chkB_absolute.Value;
            if(value)
                app.currEmg  = abs(app.currEmg);
                app.currEmg2 = abs(app.currEmg2);
            else
                %copy data again
                tvesidx      = contains(app.vesperData.trial, app.targetTrial);
                tcolname     = app.vesperData.dataSync(tvesidx).emg.Properties.VariableNames;
                app.currEmg  = app.vesperData.dataSync(tvesidx).emg.(tcolname{1});
                app.currEmg2 = app.vesperData.dataSync(tvesidx).emg.(tcolname{2});
                chkB_scaleMvValueChanged(app);
                
                %reset boxes
                app.chkB_invertCh2.Value = 0;
               
            end
            refreshdata(app.emgPlotHandle , 'caller');
            refreshdata(app.emgPlotHandle2, 'caller');
        end

        % Value changed function: chkB_scaleMv
        function chkB_scaleMvValueChanged(app, event)
            value = app.chkB_scaleMv.Value;
            %Vmuscle (mv) = VeperOut*vesperScale/emgGain
            if(value)
                app.currEmg  = app.currEmg *app.vesperScale/app.emgGain;
                app.currEmg2 = app.currEmg2*app.vesperScale/app.emgGain;
            else
                %copy data again
                tvesidx      = contains(app.vesperData.trial, app.targetTrial);
                tcolname     = app.vesperData.dataSync(tvesidx).emg.Properties.VariableNames;
                app.currEmg  = app.vesperData.dataSync(tvesidx).emg.(tcolname{1});
                app.currEmg2 = app.vesperData.dataSync(tvesidx).emg.(tcolname{2});
                
                %reset boxes
                app.chkB_invertCh2.Value = 0;
                app.chkB_absolute.Value  = 0;
            end
            refreshdata(app.emgPlotHandle , 'caller');
            refreshdata(app.emgPlotHandle2, 'caller');
        end

        % Button pushed function: button_applyFilter
        function button_applyFilterButtonPushed(app, event)
            %make sure the bounds make sense
            if(app.editF_highPass.Value<app.editF_lowPass.Value)
                dispMsg(app, 'c');
                % make sure a filter is needed and filter away
                if(app.editF_lowPass.Value~=0)
                    app.currEmg  = lowpass(app.currEmg , app.editF_lowPass.Value, app.emgFreq);
                    app.currEmg2 = lowpass(app.currEmg2, app.editF_lowPass.Value, app.emgFreq);
                end
                if(app.editF_highPass.Value~=0)
                    app.currEmg  = highpass(app.currEmg , app.editF_highPass.Value, app.emgFreq);
                    app.currEmg2 = highpass(app.currEmg2, app.editF_highPass.Value, app.emgFreq);
                end
                
                app.chkB_invertCh2.Enable = 'off';
                app.chkB_absolute.Enable  = 'off';
                app.chkB_scaleMv.Enable   = 'off';
                
                dispMsg(app, 'l', 'cant use checkboxes now, you can hit generate again to start');
                
            else
                dispMsg(app, 'e', 'them limits make no sense');
            end
            refreshdata(app.emgPlotHandle , 'caller');
            refreshdata(app.emgPlotHandle2, 'caller');
        end

        % Button pushed function: button_finishTrial
        function button_finishTrialButtonPushed(app, event)
            %save the data
            tvesidx  = contains(app.vesperData.trial, app.targetTrial);
            app.vesperData.data(tvesidx).gustStart = app.vesperData.dataSync(tvesidx).gustStart;
            app.vesperData.data(tvesidx).gustStop  = app.vesperData.dataSync(tvesidx).gustStop;
            app.vesperData.data(tvesidx).vidTime   = app.vesperData.dataSync(tvesidx).vidTime;
            app.vesperData.data(tvesidx).vidTimeF  = app.vesperData.dataSync(tvesidx).vidTimeF;
            app.vesperData.data(tvesidx).vidSync   = app.vesperData.dataSync(tvesidx).vidSync;
            app.vesperData.data(tvesidx).emgTime   = app.vesperData.dataSync(tvesidx).emgTime;
            app.vesperData.data(tvesidx).emgCh1    = app.currEmg;
            app.vesperData.data(tvesidx).emgCh2    = app.currEmg2;
            
            %return to new triel to start checkig
            app.table_trialsMapped3.Data(app.trialsMappedSelCell3,2) = {true};
            
            %clear variables
            app.chkB_lockTrial3.Value = 0;
            chkB_lockTrial3ValueChanged(app);
            app.trialsMappedSelCell3 = 0;
 
            cla(app.uiA_syncSignal3,'reset');

            dispMsg(app, 'l', 'Stored, select done and get a new trial');
        end

        % Button pushed function: button_saveAllAndExit
        function button_saveAllAndExitButtonPushed(app, event)
            % disable wargings from saving stuff such as uia components
            dispMsg(app, 'l', 'hold on a little until i save this stuff');
            %TODO -- just dont try to save them to prevent warning
            warning('off');
            
            %get trials that were assigned
            ttrial = app.vesperData.trial;
            
            %first save all trial information in combined file
            % make a struct of what I want to save to that they keep those variable names
            tmp.(app.savedAppName) = app;
            tmp.(app.savedVarName) = varsToSaveinStdFcn(app);
            
            % save file with all information for this trial
            %TODO -- does this needs a try catch?
            save(fullfile(app.workingDir, 'share', [app.savedAppName '.mat']), '-struct', 'tmp');
            
            %then save individual files on each trial
            clear('tmp');
            for(i = 1: size(ttrial,1))
                % make a struct of what I want to save to that they keep those variable names
                tvesidx      = contains(app.vesperData.trial, ttrial{i});
                
                tmp.(app.savedAppName).trial      = ttrial{i};
                tmp.(app.savedAppName).appVersion = app.appVersion;
                tmp.(app.savedAppName).emgFreq    = app.emgFreq;
                tmp.(app.savedAppName).emgGain    = app.emgGain;
                tmp.(app.savedAppName).emgTime    = app.vesperData.data(tvesidx).emgTime;
                tmp.(app.savedAppName).emgCh1     = app.vesperData.data(tvesidx).emgCh1;
                tmp.(app.savedAppName).emgCh2     = app.vesperData.data(tvesidx).emgCh2;
                tmp.(app.savedAppName).vidTime    = app.vesperData.data(tvesidx).vidTime;
                tmp.(app.savedAppName).vidTimeF   = app.vesperData.data(tvesidx).vidTimeF;
                tmp.(app.savedAppName).vidSync    = app.vesperData.data(tvesidx).vidSync;
                tmp.(app.savedAppName).gustStart  = app.vesperData.data(tvesidx).gustStart;
                tmp.(app.savedAppName).gustStop   = app.vesperData.data(tvesidx).gustStop;
                
                % save file with all information for this trial
                %TODO -- does this needs a try catch?
                save(fullfile(app.workingDir, 'trial', ttrial{i}, [app.savedAppName '.mat']), '-struct', 'tmp');
            end
            
            % turn warnings back on
            warning('on');
            %TODO -- this is a matlab bug
            warning off MATLAB:subscripting:noSubscriptsSpecified;
            
            % alert the user it is done
            dispMsg(app, 'l', ['a "' app.savedAppName '" file was created in each trial with all vesper resources']);
            uiconfirm(app.vesperDataImport,{'All Saved, You made it!!!';' '; 'App will close now =)'},'Confirm Close','Options',{'OK'},'CloseFcn',@(src,event)closeApp(app,src,event));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.vesperDataImport = uifigure('Visible', 'off');
            app.vesperDataImport.Name = 'Vesper Data Import Tool';
            app.tabg_group = uitabgroup(app.vesperDataImport);
            app.tab_loadData = uitab(app.tabg_group);
            app.tab_loadData.Title = 'Load Data';
            app.panel_loadData = uipanel(app.tab_loadData);
            app.button_vesperDirSelect = uibutton(app.panel_loadData, 'push');
            app.button_vesperDirSelect.ButtonPushedFcn = createCallbackFcn(app, @button_vesperDirSelectButtonPushed, true);
            app.button_vesperDirSelect.Text = '<<';
            app.chkB_vesperDirLock = uicheckbox(app.panel_loadData);
            app.chkB_vesperDirLock.ValueChangedFcn = createCallbackFcn(app, @chkB_vesperDirLockValueChanged, true);
            app.chkB_vesperDirLock.Text = 'Lock';
            app.DisplayingfilesinglobalLabel = uilabel(app.panel_loadData);
            app.DisplayingfilesinglobalLabel.Text = 'Displaying files in /global';
            app.listB_dirsAvail = uilistbox(app.panel_loadData);
            app.listB_dirsAvail.Items = {};
            app.listB_dirsAvail.Value = {};
            app.label_vesperDir = uilabel(app.panel_loadData);
            app.label_vesperDir.Text = 'Vesper Raw Dir';
            app.editF_vesperDir = uieditfield(app.panel_loadData, 'text');
            app.editF_vesperDir.Editable = 'off';
            app.label_step1 = uilabel(app.panel_loadData);
            app.label_step1.Text = '(1)';
            app.button_refreshWorkingDir = uibutton(app.panel_loadData, 'push');
            app.button_refreshWorkingDir.ButtonPushedFcn = createCallbackFcn(app, @button_refreshWorkingDirButtonPushed, true);
            app.button_refreshWorkingDir.Text = 'Refresh';
            app.label_step0 = uilabel(app.panel_loadData);
            app.label_step0.Text = '(0)';
            app.label_cameraObj = uilabel(app.panel_loadData);
            app.label_cameraObj.Text = 'Camera Obj';
            app.dropD_cameraObj = uidropdown(app.panel_loadData);
            app.dropD_cameraObj.Items = {};
            app.dropD_cameraObj.ValueChangedFcn = createCallbackFcn(app, @dropD_cameraObjValueChanged, true);
            app.dropD_cameraObj.Value = {};
            app.label_emgFreq = uilabel(app.panel_loadData);
            app.label_emgFreq.Text = 'EMG Freq';
            app.dropD_emgFreq = uidropdown(app.panel_loadData);
            app.dropD_emgFreq.Items = {'125Hz', '250Hz', '500Hz', '1kHz', '2kHz'};
            app.dropD_emgFreq.ItemsData = {'125', '250', '500', '1000', '2000', ''};
            app.dropD_emgFreq.ValueChangedFcn = createCallbackFcn(app, @dropD_emgFreqValueChanged, true);
            app.dropD_emgFreq.Value = '2000';
            app.label_micFreq = uilabel(app.panel_loadData);
            app.label_micFreq.Text = 'Mic Freq';
            app.editF_micFreq = uieditfield(app.panel_loadData, 'numeric');
            app.editF_micFreq.Limits = [0 80000];
            app.editF_micFreq.ValueDisplayFormat = '%.0f';
            app.editF_micFreq.Value = 65536;
            app.label_emgGain = uilabel(app.panel_loadData);
            app.label_emgGain.Text = {'EMG Gain'; ''};
            app.dropD_emgGain = uidropdown(app.panel_loadData);
            app.dropD_emgGain.Items = {'x1', 'x2', 'x3', 'x4', 'x6', 'x8', 'x12'};
            app.dropD_emgGain.ItemsData = {'1', '2', '3', '4', '6', '8', '12'};
            app.dropD_emgGain.ValueChangedFcn = createCallbackFcn(app, @dropD_emgGainValueChanged, true);
            app.dropD_emgGain.Value = '6';
            app.label_workingDir = uilabel(app.panel_loadData);
            app.label_workingDir.Text = '';
            app.chkB_cameraObjLock = uicheckbox(app.panel_loadData);
            app.chkB_cameraObjLock.ValueChangedFcn = createCallbackFcn(app, @chkB_cameraObjLockValueChanged, true);
            app.chkB_cameraObjLock.Text = 'Lock';
            app.chkB_emgGainLock = uicheckbox(app.panel_loadData);
            app.chkB_emgGainLock.ValueChangedFcn = createCallbackFcn(app, @chkB_emgGainLockValueChanged, true);
            app.chkB_emgGainLock.Text = 'Lock';
            app.chkB_emgFreqLock = uicheckbox(app.panel_loadData);
            app.chkB_emgFreqLock.ValueChangedFcn = createCallbackFcn(app, @chkB_emgFreqLockValueChanged, true);
            app.chkB_emgFreqLock.Text = 'Lock';
            app.chkB_micFreqLock = uicheckbox(app.panel_loadData);
            app.chkB_micFreqLock.ValueChangedFcn = createCallbackFcn(app, @chkB_micFreqLockValueChanged, true);
            app.chkB_micFreqLock.Text = 'Lock';
            app.button_roi = uibutton(app.panel_loadData, 'push');
            app.button_roi.ButtonPushedFcn = createCallbackFcn(app, @button_roiButtonPushed, true);
            app.button_roi.Text = 'ROI';
            app.uia_videoRoi = uiaxes(app.panel_loadData);
            title(app.uia_videoRoi, 'ROI Selected')
            xlabel(app.uia_videoRoi, 'X')
            ylabel(app.uia_videoRoi, 'Y')
            app.tab_pairFiles = uitab(app.tabg_group);
            app.tab_pairFiles.Title = 'Pair Files';
            app.panel_pairFiles = uipanel(app.tab_pairFiles);
            app.table_emgTimes = uitable(app.panel_pairFiles);
            app.table_emgTimes.ColumnName = {'Emg Start'; 'Emg End'};
            app.table_emgTimes.RowName = {};
            app.table_emgTimes.ColumnEditable = false;
            app.table_emgTimes.CellSelectionCallback = createCallbackFcn(app, @table_emgTimesCellSelection, true);
            app.table_micTimes = uitable(app.panel_pairFiles);
            app.table_micTimes.ColumnName = {'Mic Start'; 'Mic End'};
            app.table_micTimes.RowName = {};
            app.table_micTimes.ColumnEditable = false;
            app.table_micTimes.CellSelectionCallback = createCallbackFcn(app, @table_micTimesCellSelection, true);
            app.table_vidTimes = uitable(app.panel_pairFiles);
            app.table_vidTimes.ColumnName = {'Vid Start'; 'Vid End'; 'status'};
            app.table_vidTimes.RowName = {};
            app.table_vidTimes.ColumnEditable = false;
            app.table_vidTimes.CellSelectionCallback = createCallbackFcn(app, @table_vidTimesCellSelection, true);
            app.button_popPairTable = uibutton(app.panel_pairFiles, 'push');
            app.button_popPairTable.ButtonPushedFcn = createCallbackFcn(app, @button_popPairTableButtonPushed, true);
            app.button_popPairTable.Text = 'Populate Table (2)';
            app.button_pairData = uibutton(app.panel_pairFiles, 'push');
            app.button_pairData.ButtonPushedFcn = createCallbackFcn(app, @button_pairDataButtonPushed, true);
            app.button_pairData.Enable = 'off';
            app.button_pairData.Text = 'Pair data';
            app.button_extractData = uibutton(app.panel_pairFiles, 'push');
            app.button_extractData.ButtonPushedFcn = createCallbackFcn(app, @button_extractDataButtonPushed, true);
            app.button_extractData.Enable = 'off';
            app.button_extractData.Text = 'Extract data';
            app.label_step3 = uilabel(app.panel_pairFiles);
            app.label_step3.Text = '(3)';
            app.button_plotMic = uibutton(app.panel_pairFiles, 'push');
            app.button_plotMic.ButtonPushedFcn = createCallbackFcn(app, @button_plotMicButtonPushed, true);
            app.button_plotMic.Enable = 'off';
            app.button_plotMic.Text = 'Plot mic';
            app.button_plotEmg = uibutton(app.panel_pairFiles, 'push');
            app.button_plotEmg.ButtonPushedFcn = createCallbackFcn(app, @button_plotEmgButtonPushed, true);
            app.button_plotEmg.Enable = 'off';
            app.button_plotEmg.Text = 'Plot Emg';
            app.tab_videoSync = uitab(app.tabg_group);
            app.tab_videoSync.Title = 'Video Sync';
            app.panel_videoSync = uipanel(app.tab_videoSync);
            app.table_trialsMapped = uitable(app.panel_videoSync);
            app.table_trialsMapped.ColumnName = {'Trial'; 'Done'};
            app.table_trialsMapped.RowName = {};
            app.table_trialsMapped.CellSelectionCallback = createCallbackFcn(app, @table_trialsMappedCellSelection, true);
            app.table_trialsMapped.Enable = 'off';
            app.button_popTrials = uibutton(app.panel_videoSync, 'push');
            app.button_popTrials.ButtonPushedFcn = createCallbackFcn(app, @button_popTrialsButtonPushed, true);
            app.button_popTrials.Enable = 'off';
            app.button_popTrials.Text = 'Populate Trials';
            app.button_genWave = uibutton(app.panel_videoSync, 'push');
            app.button_genWave.ButtonPushedFcn = createCallbackFcn(app, @button_genWaveButtonPushed, true);
            app.button_genWave.Enable = 'off';
            app.button_genWave.Text = 'Generate Wave';
            app.uiA_syncSignal = uiaxes(app.panel_videoSync);
            xlabel(app.uiA_syncSignal, 'Time (s)')
            ylabel(app.uiA_syncSignal, 'Norm Amplitude')
            app.uiA_syncSignal.YLim = [0 1.1];
            app.uiA_syncSignal.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1];
            app.uiA_syncSignal.YTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'; '1.1'};
            app.label_vesperTimeMs = uilabel(app.panel_videoSync);
            app.label_vesperTimeMs.Text = 'Vesper Time (ms)';
            app.editF_vesperTimeMs = uieditfield(app.panel_videoSync, 'numeric');
            app.editF_vesperTimeMs.Limits = [-5000 5000];
            app.editF_vesperTimeMs.ValueDisplayFormat = '%.0f';
            app.editF_vesperTimeMs.ValueChangedFcn = createCallbackFcn(app, @editF_vesperTimeMsValueChanged, true);
            app.editF_vesperTimeMs.Enable = 'off';
            app.label_vesperTime = uilabel(app.panel_videoSync);
            app.label_vesperTime.Text = 'Vesper Time (%)';
            app.slider_vesperTime = uislider(app.panel_videoSync);
            app.slider_vesperTime.ValueChangedFcn = createCallbackFcn(app, @slider_vesperTimeValueChanged, true);
            app.slider_vesperTime.Enable = 'off';
            app.chkB_lockTrial = uicheckbox(app.panel_videoSync);
            app.chkB_lockTrial.ValueChangedFcn = createCallbackFcn(app, @chkB_lockTrialValueChanged, true);
            app.chkB_lockTrial.Enable = 'off';
            app.chkB_lockTrial.Text = 'Lock Trial';
            app.button_resetAxes = uibutton(app.panel_videoSync, 'push');
            app.button_resetAxes.ButtonPushedFcn = createCallbackFcn(app, @button_resetAxesButtonPushed, true);
            app.button_resetAxes.Enable = 'off';
            app.button_resetAxes.Text = 'reset axes';
            app.label_divide = uilabel(app.panel_videoSync);
            app.label_divide.Text = '----------------------------';
            app.label_audioTime = uilabel(app.panel_videoSync);
            app.label_audioTime.Text = 'time';
            app.editF_audioTime = uieditfield(app.panel_videoSync, 'numeric');
            app.editF_audioTime.Limits = [1 Inf];
            app.editF_audioTime.Enable = 'off';
            app.editF_audioTime.Value = 1;
            app.label_audioSpeed = uilabel(app.panel_videoSync);
            app.label_audioSpeed.Text = 'speed';
            app.spinner_audioSpeed = uispinner(app.panel_videoSync);
            app.spinner_audioSpeed.Step = 0.5;
            app.spinner_audioSpeed.Limits = [-4 4];
            app.spinner_audioSpeed.Enable = 'off';
            app.spinner_audioSpeed.Value = 1;
            app.button_playAudio = uibutton(app.panel_videoSync, 'push');
            app.button_playAudio.ButtonPushedFcn = createCallbackFcn(app, @button_playAudioButtonPushed, true);
            app.button_playAudio.Enable = 'off';
            app.button_playAudio.Text = 'play';
            app.button_stopAudio = uibutton(app.panel_videoSync, 'push');
            app.button_stopAudio.ButtonPushedFcn = createCallbackFcn(app, @button_stopAudioPushed, true);
            app.button_stopAudio.Enable = 'off';
            app.button_stopAudio.Text = 'stop';
            app.button_pauseAudio = uibutton(app.panel_videoSync, 'state');
            app.button_pauseAudio.ValueChangedFcn = createCallbackFcn(app, @button_pauseAudioValueChanged, true);
            app.button_pauseAudio.Enable = 'off';
            app.button_pauseAudio.Text = 'II';
            app.button_bandFilter = uibutton(app.panel_videoSync, 'push');
            app.button_bandFilter.ButtonPushedFcn = createCallbackFcn(app, @button_bandFilterButtonPushed, true);
            app.button_bandFilter.Enable = 'off';
            app.button_bandFilter.Text = 'band filter';
            app.editF_trimFreq = uieditfield(app.panel_videoSync, 'numeric');
            app.editF_trimFreq.Limits = [0 10000];
            app.editF_trimFreq.ValueDisplayFormat = '%.0f';
            app.editF_trimFreq.Enable = 'off';
            app.editF_trimFreq.Value = 2650;
            app.label_zeroReset = uilabel(app.panel_videoSync);
            app.label_zeroReset.Text = '0 = reset, 2650 default';
            app.label_gustTime = uilabel(app.panel_videoSync);
            app.label_gustTime.Text = 'Gust Time (s)';
            app.editF_gustTime = uieditfield(app.panel_videoSync, 'numeric');
            app.editF_gustTime.Limits = [0 Inf];
            app.editF_gustTime.ValueDisplayFormat = '%.3f';
            app.editF_gustTime.ValueChangedFcn = createCallbackFcn(app, @editF_gustTimeValueChanged, true);
            app.editF_gustTime.Enable = 'off';
            app.label_gustDur = uilabel(app.panel_videoSync);
            app.label_gustDur.Text = 'Gust Dur (s)';
            app.editF_gustDur = uieditfield(app.panel_videoSync, 'numeric');
            app.editF_gustDur.Limits = [0 10];
            app.editF_gustDur.ValueDisplayFormat = '%.3f';
            app.editF_gustDur.ValueChangedFcn = createCallbackFcn(app, @editF_gustDurValueChanged, true);
            app.editF_gustDur.Enable = 'off';
            app.editF_gustDur.Value = 0.3;
            app.button_trimToVideo = uibutton(app.panel_videoSync, 'push');
            app.button_trimToVideo.ButtonPushedFcn = createCallbackFcn(app, @button_trimToVideoButtonPushed, true);
            app.button_trimToVideo.Enable = 'off';
            app.button_trimToVideo.Text = 'Trim to Video';
            app.button_doneSync = uibutton(app.panel_videoSync, 'push');
            app.button_doneSync.ButtonPushedFcn = createCallbackFcn(app, @button_doneSyncButtonPushed, true);
            app.button_doneSync.Enable = 'off';
            app.button_doneSync.Text = 'Done';
            app.tab_emgSync = uitab(app.tabg_group);
            app.tab_emgSync.Title = 'Emg Sync';
            app.panel_emgSync = uipanel(app.tab_emgSync);
            app.table_trialsMapped2 = uitable(app.panel_emgSync);
            app.table_trialsMapped2.ColumnName = {'Trial'; 'Done'};
            app.table_trialsMapped2.RowName = {};
            app.table_trialsMapped2.CellSelectionCallback = createCallbackFcn(app, @table_trialsMapped2CellSelection, true);
            app.table_trialsMapped2.Enable = 'off';
            app.button_popTrials2 = uibutton(app.panel_emgSync, 'push');
            app.button_popTrials2.ButtonPushedFcn = createCallbackFcn(app, @button_popTrials2ButtonPushed, true);
            app.button_popTrials2.Enable = 'off';
            app.button_popTrials2.Text = 'Populate Trials';
            app.button_genWave2 = uibutton(app.panel_emgSync, 'push');
            app.button_genWave2.ButtonPushedFcn = createCallbackFcn(app, @button_genWave2ButtonPushed, true);
            app.button_genWave2.Enable = 'off';
            app.button_genWave2.Text = 'Generate Wave';
            app.uiA_syncSignal2 = uiaxes(app.panel_emgSync);
            xlabel(app.uiA_syncSignal2, 'Time (s)')
            ylabel(app.uiA_syncSignal2, 'Norm Amplitude')
            app.uiA_syncSignal2.YLim = [0 1.1];
            app.uiA_syncSignal2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1];
            app.uiA_syncSignal2.YTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'; '1.1'};
            app.button_trimToVideo2 = uibutton(app.panel_emgSync, 'push');
            app.button_trimToVideo2.ButtonPushedFcn = createCallbackFcn(app, @button_trimToVideo2ButtonPushed, true);
            app.button_trimToVideo2.Enable = 'off';
            app.button_trimToVideo2.Text = 'Trim to Video';
            app.button_doneSync2 = uibutton(app.panel_emgSync, 'push');
            app.button_doneSync2.ButtonPushedFcn = createCallbackFcn(app, @button_doneSync2ButtonPushed, true);
            app.button_doneSync2.Enable = 'off';
            app.button_doneSync2.Text = 'Done';
            app.label_vesperTimeMs2 = uilabel(app.panel_emgSync);
            app.label_vesperTimeMs2.Text = 'Vesper Time (ms)';
            app.editF_vesperTimeMs2 = uieditfield(app.panel_emgSync, 'numeric');
            app.editF_vesperTimeMs2.Limits = [-5000 5000];
            app.editF_vesperTimeMs2.ValueDisplayFormat = '%.0f';
            app.editF_vesperTimeMs2.ValueChangedFcn = createCallbackFcn(app, @editF_vesperTimeMs2ValueChanged, true);
            app.editF_vesperTimeMs2.Enable = 'off';
            app.label_vesperTime2 = uilabel(app.panel_emgSync);
            app.label_vesperTime2.Text = 'Vesper Time (%)';
            app.slider_vesperTime2 = uislider(app.panel_emgSync);
            app.slider_vesperTime2.Limits = [-100 100];
            app.slider_vesperTime2.ValueChangedFcn = createCallbackFcn(app, @slider_vesperTime2ValueChanged, true);
            app.slider_vesperTime2.Enable = 'off';
            app.chkB_lockTrial2 = uicheckbox(app.panel_emgSync);
            app.chkB_lockTrial2.ValueChangedFcn = createCallbackFcn(app, @chkB_lockTrial2ValueChanged, true);
            app.chkB_lockTrial2.Enable = 'off';
            app.chkB_lockTrial2.Text = 'Lock Trial';
            app.button_resetAxes2 = uibutton(app.panel_emgSync, 'push');
            app.button_resetAxes2.ButtonPushedFcn = createCallbackFcn(app, @button_resetAxes2ButtonPushed, true);
            app.button_resetAxes2.Enable = 'off';
            app.button_resetAxes2.Text = 'reset axes';
            app.tab_filterData = uitab(app.tabg_group);
            app.tab_filterData.Title = 'Filter Data';
            app.panel_filterData = uipanel(app.tab_filterData);
            app.table_trialsMapped3 = uitable(app.panel_filterData);
            app.table_trialsMapped3.ColumnName = {'Trial'; 'Done'};
            app.table_trialsMapped3.RowName = {};
            app.table_trialsMapped3.CellSelectionCallback = createCallbackFcn(app, @table_trialsMapped3CellSelection, true);
            app.table_trialsMapped3.Enable = 'off';
            app.uiA_syncSignal3 = uiaxes(app.panel_filterData);
            xlabel(app.uiA_syncSignal3, 'Time (s)')
            ylabel(app.uiA_syncSignal3, 'Voltage (mV)')
            app.uiA_syncSignal3.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1];
            app.uiA_syncSignal3.YTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'; '1.1'};
            app.button_popTrials3 = uibutton(app.panel_filterData, 'push');
            app.button_popTrials3.ButtonPushedFcn = createCallbackFcn(app, @button_popTrials3ButtonPushed, true);
            app.button_popTrials3.Enable = 'off';
            app.button_popTrials3.Text = 'Populate Trials';
            app.button_genWave3 = uibutton(app.panel_filterData, 'push');
            app.button_genWave3.ButtonPushedFcn = createCallbackFcn(app, @button_genWave3ButtonPushed, true);
            app.button_genWave3.Enable = 'off';
            app.button_genWave3.Text = 'Generate Wave';
            app.chkB_lockTrial3 = uicheckbox(app.panel_filterData);
            app.chkB_lockTrial3.ValueChangedFcn = createCallbackFcn(app, @chkB_lockTrial3ValueChanged, true);
            app.chkB_lockTrial3.Enable = 'off';
            app.chkB_lockTrial3.Text = 'Lock Trial';
            app.chkB_plotBoth = uicheckbox(app.panel_filterData);
            app.chkB_plotBoth.ValueChangedFcn = createCallbackFcn(app, @chkB_plotBothValueChanged, true);
            app.chkB_plotBoth.Enable = 'off';
            app.chkB_plotBoth.Text = 'Plot Both';
            app.chkB_plotBoth.Value = true;
            app.chkB_invertCh2 = uicheckbox(app.panel_filterData);
            app.chkB_invertCh2.ValueChangedFcn = createCallbackFcn(app, @chkB_invertCh2ValueChanged, true);
            app.chkB_invertCh2.Enable = 'off';
            app.chkB_invertCh2.Text = 'Invert CH2';
            app.chkB_absolute = uicheckbox(app.panel_filterData);
            app.chkB_absolute.ValueChangedFcn = createCallbackFcn(app, @chkB_absoluteValueChanged, true);
            app.chkB_absolute.Enable = 'off';
            app.chkB_absolute.Text = 'Absolute';
            app.chkB_scaleMv = uicheckbox(app.panel_filterData);
            app.chkB_scaleMv.ValueChangedFcn = createCallbackFcn(app, @chkB_scaleMvValueChanged, true);
            app.chkB_scaleMv.Enable = 'off';
            app.chkB_scaleMv.Text = 'Scale to mV';
            app.chkB_scaleMv.Value = true;
            app.button_applyFilter = uibutton(app.panel_filterData, 'push');
            app.button_applyFilter.ButtonPushedFcn = createCallbackFcn(app, @button_applyFilterButtonPushed, true);
            app.button_applyFilter.Enable = 'off';
            app.button_applyFilter.Text = 'Apply Filter';
            app.button_finishTrial = uibutton(app.panel_filterData, 'push');
            app.button_finishTrial.ButtonPushedFcn = createCallbackFcn(app, @button_finishTrialButtonPushed, true);
            app.button_finishTrial.Enable = 'off';
            app.button_finishTrial.Text = 'Finish Trial';
            app.label_lowPass = uilabel(app.panel_filterData);
            app.label_lowPass.Text = 'Low-Pass at (Hz)';
            app.editF_lowPass = uieditfield(app.panel_filterData, 'numeric');
            app.editF_lowPass.Enable = 'off';
            app.label_highPass = uilabel(app.panel_filterData);
            app.label_highPass.Text = 'High-Pass at (Hz)';
            app.editF_highPass = uieditfield(app.panel_filterData, 'numeric');
            app.editF_highPass.Enable = 'off';
            app.label_noFilter = uilabel(app.panel_filterData);
            app.label_noFilter.Text = '0 = no filter';
            app.tab_saveAll = uitab(app.tabg_group);
            app.tab_saveAll.Title = 'Save All';
            app.panel_saveAll = uipanel(app.tab_saveAll);
            app.button_saveAllAndExit = uibutton(app.panel_saveAll, 'push');
            app.button_saveAllAndExit.ButtonPushedFcn = createCallbackFcn(app, @button_saveAllAndExitButtonPushed, true);
            app.button_saveAllAndExit.Text = 'SAVE ALL AND EXIT!!!!!!!';
            app.button_prev = uibutton(app.vesperDataImport, 'push');
            app.button_prev.ButtonPushedFcn = createCallbackFcn(app, @button_prevButtonPushed, true);
            app.button_prev.Enable = 'off';
            app.button_prev.Text = '<<Prev';
            app.button_next = uibutton(app.vesperDataImport, 'push');
            app.button_next.ButtonPushedFcn = createCallbackFcn(app, @button_nextButtonPushed, true);
            app.button_next.Enable = 'off';
            app.button_next.Text = 'Next>>';
            app.label_errorMsg = uilabel(app.vesperDataImport);
            app.label_errorMsg.Text = '';
            % Show the figure after all components are created
            app.vesperDataImport.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vesperDataImportGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.vesperDataImport)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.vesperDataImport)
        end
    end
end