classdef quickEmgPlotGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        quickEmgPlot      matlab.ui.Figure
        listB_filesA      matlab.ui.control.Label
        listB_filesAvail  matlab.ui.control.ListBox
        button_refresh    matlab.ui.control.Button
        button_plotCh1    matlab.ui.control.Button
        button_plotCh2    matlab.ui.control.Button
        button_changeDir  matlab.ui.control.Button
        GainLabel         matlab.ui.control.Label
        editF_gain        matlab.ui.control.NumericEditField
        label_dir         matlab.ui.control.Label
        chkB_scale        matlab.ui.control.CheckBox
        label_title       matlab.ui.control.Label
        button_plotBoth   matlab.ui.control.Button
        chkB_preGain      matlab.ui.control.CheckBox
        label_freq        matlab.ui.control.Label
        editF_freq        matlab.ui.control.NumericEditField
        chkB_invert       matlab.ui.control.CheckBox
    end

%---------------------------- CONSTANT PROPERTIES ---------------------------------%    
    
    properties (Access = private, Constant = true)
    end
    
    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'quickEmgPlotGui';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
        
        % important/ relevant output from this app
        savedVarName = 'quickEmgPlot';
        
        % the full app, required to populate this app with a previous session
        % this contains ALL components, even those stored in savedVarName
        savedAppName = 'quickEmgPlotGuiApp';
        
        % relevant variables to save in savedVarName
        % savedAppName saves this plus everything else too
        %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
        varsToSaveinStd =  {'appVersion'};
    end

%---------------------------- VARIABLE PROPERTIES --------------------------------%
    properties (Access = private)
        %where the files are
        workingDir;
        
        %hardcoded on vesper device
        preGain = 10;
        vref = 2.42;
        resolution = 2^16;

        %imported data containers
        emgraw;
        ch1data;
        ch2data;
        ch1dataRaw;
        ch2dataRaw;
        ch1dataPre;
        ch2dataPre;
        time;
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%
    methods (Access = private)
        
        function readEmgFile(app)
            app.emgraw  = readtable(fullfile(app.workingDir, app.listB_filesAvail.Value), 'ReadVariableNames', true);
            app.time    = linspace(0, size(app.emgraw,1)/app.editF_freq.Value, size(app.emgraw,1));
            
            app.ch1dataRaw = app.emgraw.CH1;
            app.ch2dataRaw = app.emgraw.CH2;
            
            app.ch1data = app.emgraw.CH1*app.vref/app.resolution;
            app.ch2data = app.emgraw.CH2*app.vref/app.resolution;
            
            app.ch1dataPre = app.ch1data/(app.preGain*app.editF_gain.Value);
            app.ch2dataPre = app.ch2data/(app.preGain*app.editF_gain.Value);
        end%readEmgFile
    
        function displayFiles(app)
            %fill with files and exclude . and ..
            tfiles = cellstr(ls(app.workingDir));
            tfiles = tfiles(3:end);
            tfiles = tfiles(contains(tfiles, '.csv'));
            app.listB_filesAvail.Items = tfiles;
        end%displayFiles
%-----------------------------------------------------------------------------------%
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %select user's home as the starting point
            app.workingDir     = getUserDir();
            app.label_dir.Text = app.workingDir;
            
            displayFiles(app);
        end

        % Button pushed function: button_changeDir
        function button_changeDirPushed(app, event)
           app.workingDir     = uigetdir(app.workingDir);
           app.label_dir.Text = app.workingDir;
           displayFiles(app);
        end

        % Button pushed function: button_refresh
        function button_refreshPushed(app, event)
            displayFiles(app);
        end

        % Value changed function: chkB_scale
        function chkB_scaleValueChanged(app, event)
            value = app.chkB_scale.Value;
            if(value)
                app.chkB_preGain.Enable = 'on';
                app.chkB_preGain.Value  = 0;
            else
                app.chkB_preGain.Enable = 'off';
                app.chkB_preGain.Value  = 0;
            end
        end

        % Button pushed function: button_plotCh1
        function button_plotCh1Pushed(app, event)
            %get the data
            readEmgFile(app);
            
            %plot the data post amplification
            if(app.chkB_scale.Value && ~app.chkB_preGain.Value)
                %plot data
                plot(app.time, app.ch1data);
                tax = gca;
                %fix axis
                tax.YLimMode = 'manual';
                tax.YLim = [-app.vref, app.vref];
                tax.YLabel.String = 'Volts';
                tax.XLabel.String = 'Time (s)';
                
            %plot the data in volts before the amplification    
            elseif(app.chkB_scale.Value && app.chkB_preGain.Value)
                %plot data
                plot(app.time, app.ch1dataPre);
                tax = gca;
                %fix axis
                tax.YLimMode = 'manual';
                tlim = app.vref/(app.preGain*app.editF_gain.Value);
                tax.YLim = [-tlim, tlim];
                %TODO -- make this into milivolts
                tax.YLabel.String = 'Volts (pre-amplification)';
                tax.XLabel.String = 'Time (s)';
                
            %plot the raw data
            else
                %plot data
                plot(app.time, app.ch1dataRaw);
                tax = gca;
                %fix axis
                tax.YLimMode = 'auto';
                tax.YLabel.String = 'Bits';
                tax.XLabel.String = 'Time (s)';
            end
        end

        % Button pushed function: button_plotCh2
        function button_plotCh2Pushed(app, event)
            %get the data
            readEmgFile(app);
            
            %plot the data post amplification
            if(app.chkB_scale.Value && ~app.chkB_preGain.Value)
                %plot data
                plot(app.time, app.ch2data);
                tax = gca;
                %fix axis
                tax.YLimMode = 'manual';
                tax.YLim = [-app.vref, app.vref];
                tax.YLabel.String = 'Volts';
                tax.XLabel.String = 'Time (s)';
                
            %plot the data in volts before the amplification    
            elseif(app.chkB_scale.Value && app.chkB_preGain.Value)
                %plot data
                plot(app.time, app.ch2dataPre);
                tax = gca;
                %fix axis
                tax.YLimMode = 'manual';
                tlim = app.vref/(app.preGain*app.editF_gain.Value);
                tax.YLim = [-tlim, tlim];
                %TODO -- make this into milivolts
                tax.YLabel.String = 'Volts (pre-amplification)';
                tax.XLabel.String = 'Time (s)';
                
            %plot the raw data
            else
                %plot data
                plot(app.time, app.ch2dataRaw);
                tax = gca;
                %fix axis
                tax.YLimMode = 'auto';
                tax.YLabel.String = 'Bits';
                tax.XLabel.String = 'Time (s)';
            end
        end

        % Button pushed function: button_plotBoth
        function button_plotBothButtonPushed(app, event)
            %get the data
            readEmgFile(app);
            
            %plot the data post amplification
            if(app.chkB_scale.Value && ~app.chkB_preGain.Value)
                %plot data
                plot(app.time, app.ch1data);
                hold("on");
                if(app.chkB_invert.Value)
                    plot(app.time, (-1*app.ch2data));
                else
                    plot(app.time, app.ch2data);
                end
                hold("off");
                tax = gca;
                %fix axis
                tax.YLimMode = 'manual';
                tax.YLim = [-app.vref, app.vref];
                tax.YLabel.String = 'Volts';
                tax.XLabel.String = 'Time (s)';
                
            %plot the data in volts before the amplification    
            elseif(app.chkB_scale.Value && app.chkB_preGain.Value)
                %plot data
                plot(app.time, app.ch1dataPre);
                hold("on");
                if(app.chkB_invert.Value)
                    plot(app.time, (-1*app.ch2dataPre));
                else
                    plot(app.time, app.ch2dataPre);
                end
                hold("off");
                tax = gca;
                %fix axis
                tax.YLimMode = 'manual';
                tlim = app.vref/(app.preGain*app.editF_gain.Value);
                tax.YLim = [-tlim, tlim];
                %TODO -- make this into milivolts
                tax.YLabel.String = 'Volts (pre-amplification)';
                tax.XLabel.String = 'Time (s)';
                
            %plot the raw data
            else
                %plot data
                plot(app.time, app.ch1dataRaw);
                hold("on");
                if(app.chkB_invert.Value)
                    plot(app.time, (-1*app.ch2dataRaw));
                else
                    plot(app.time, app.ch2dataRaw);
                end
                hold("off");
                tax = gca;
                %fix axis
                tax.YLimMode = 'auto';
                tax.YLabel.String = 'Bits';
                tax.XLabel.String = 'Time (s)';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.quickEmgPlot = uifigure('Visible', 'off');
            app.quickEmgPlot.Name = 'UI Figure';
            app.listB_filesA = uilabel(app.quickEmgPlot);
            app.listB_filesA.Text = 'Files Availiable';
            app.listB_filesAvail = uilistbox(app.quickEmgPlot);
            app.listB_filesAvail.Items = {};
            app.listB_filesAvail.Value = {};
            app.button_refresh = uibutton(app.quickEmgPlot, 'push');
            app.button_refresh.ButtonPushedFcn = createCallbackFcn(app, @button_refreshPushed, true);
            app.button_refresh.Text = 'Refresh';
            app.button_plotCh1 = uibutton(app.quickEmgPlot, 'push');
            app.button_plotCh1.ButtonPushedFcn = createCallbackFcn(app, @button_plotCh1Pushed, true);
            app.button_plotCh1.Text = 'Plot Ch1';
            app.button_plotCh2 = uibutton(app.quickEmgPlot, 'push');
            app.button_plotCh2.ButtonPushedFcn = createCallbackFcn(app, @button_plotCh2Pushed, true);
            app.button_plotCh2.Text = 'Plot Ch2';
            app.button_changeDir = uibutton(app.quickEmgPlot, 'push');
            app.button_changeDir.ButtonPushedFcn = createCallbackFcn(app, @button_changeDirPushed, true);
            app.button_changeDir.Text = 'Change Dir';
            app.GainLabel = uilabel(app.quickEmgPlot);
            app.GainLabel.Text = 'Gain';
            app.editF_gain = uieditfield(app.quickEmgPlot, 'numeric');
            app.editF_gain.Limits = [0 12];
            app.editF_gain.Value = 6;
            app.label_dir = uilabel(app.quickEmgPlot);
            app.label_dir.Text = '';
            app.chkB_scale = uicheckbox(app.quickEmgPlot);
            app.chkB_scale.ValueChangedFcn = createCallbackFcn(app, @chkB_scaleValueChanged, true);
            app.chkB_scale.Text = 'Scale';
            app.chkB_scale.Value = true;
            app.label_title = uilabel(app.quickEmgPlot);
            app.label_title.Text = 'This will plot EMG data from the Vesper device.';
            app.button_plotBoth = uibutton(app.quickEmgPlot, 'push');
            app.button_plotBoth.ButtonPushedFcn = createCallbackFcn(app, @button_plotBothButtonPushed, true);
            app.button_plotBoth.Text = 'Plot Both';
            app.chkB_preGain = uicheckbox(app.quickEmgPlot);
            app.chkB_preGain.Text = 'pre gain';
            app.label_freq = uilabel(app.quickEmgPlot);
            app.label_freq.Text = 'EMG freq';
            app.editF_freq = uieditfield(app.quickEmgPlot, 'numeric');
            app.editF_freq.Limits = [0 8000];
            app.editF_freq.Value = 2000;
            app.chkB_invert = uicheckbox(app.quickEmgPlot);
            app.chkB_invert.Text = 'invert ch2';
            % Show the figure after all components are created
            app.quickEmgPlot.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = quickEmgPlotGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.quickEmgPlot)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.quickEmgPlot)
        end
    end
end